classdef AllTrackAnalysisWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AllTrackAnalysisUIFigure       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ExportDataPanel                matlab.ui.container.Panel
        SavetomatButton                matlab.ui.control.Button
        SavetocsvButton                matlab.ui.control.Button
        FoldernotSetLabel              matlab.ui.control.Label
        SetSaveFolderButton            matlab.ui.control.Button
        DataFilterPanel                matlab.ui.container.Panel
        ActiveCheckBox                 matlab.ui.control.CheckBox
        DataFilterButton               matlab.ui.control.Button
        HistogramSettingsPanel         matlab.ui.container.Panel
        NumberofBinsEditField          matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel     matlab.ui.control.Label
        RightBorderEditField           matlab.ui.control.NumericEditField
        RightBorderEditFieldLabel      matlab.ui.control.Label
        LeftBorderEditField            matlab.ui.control.NumericEditField
        LeftBorderEditFieldLabel       matlab.ui.control.Label
        PropertiesPanel                matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        MobilityTab                    matlab.ui.container.Tab
        SelectionButtonGroup           matlab.ui.container.ButtonGroup
        ConfinementRatioButton         matlab.ui.control.RadioButton
        DriftParametersButton          matlab.ui.control.RadioButton
        VelocityParametersButton       matlab.ui.control.RadioButton
        ClassificationfromSwiftButton  matlab.ui.control.RadioButton
        MSDfromInternMSDFitButton      matlab.ui.control.RadioButton
        JumpAnglesButton               matlab.ui.control.RadioButton
        CumulativeMeanJumpDistancesButton  matlab.ui.control.RadioButton
        MeanJumpDistancesButton        matlab.ui.control.RadioButton
        DiffusionParametersButton      matlab.ui.control.RadioButton
        CumulativeJumpDistancesButton  matlab.ui.control.RadioButton
        JumpDistancesButton            matlab.ui.control.RadioButton
        StatisticsTab                  matlab.ui.container.Tab
        SelectionButtonGroup_2         matlab.ui.container.ButtonGroup
        ROISizeButton                  matlab.ui.control.RadioButton
        NumberofJumpsPerTrackButton    matlab.ui.control.RadioButton
        AverageTrackLengthButton       matlab.ui.control.RadioButton
        CumulativeTrackLengthButton    matlab.ui.control.RadioButton
        TrackStepsButton               matlab.ui.control.RadioButton
        MovieSelectionPanel            matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        RightPanel                     matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        AdditionalOptionsPanel         matlab.ui.container.Panel
        ConfinementRatioButtonGroup    matlab.ui.container.ButtonGroup
        Switch_3                       matlab.ui.control.Switch
        NetLengthButton                matlab.ui.control.RadioButton
        LengthButton                   matlab.ui.control.RadioButton
        RatioLengthNetLengthButton     matlab.ui.control.RadioButton
        DiffParametersButtonGroup      matlab.ui.container.ButtonGroup
        DButton                        matlab.ui.control.RadioButton
        MSDButton                      matlab.ui.control.RadioButton
        UnitsPanel                     matlab.ui.container.Panel
        Switch                         matlab.ui.control.Switch
        MSDPanel                       matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        DButton_2                      matlab.ui.control.RadioButton
        MSDButton_2                    matlab.ui.control.RadioButton
        AlphaButton                    matlab.ui.control.RadioButton
        Switch_2                       matlab.ui.control.Switch
        ComputeButton                  matlab.ui.control.Button
        PointstoFitEditField           matlab.ui.control.NumericEditField
        PointstoFitEditFieldLabel      matlab.ui.control.Label
        JumpAnglesButtonGroup          matlab.ui.container.ButtonGroup
        XYZButton_2                    matlab.ui.control.RadioButton
        XYButton_2                     matlab.ui.control.RadioButton
        JumpDistancesButtonGroup       matlab.ui.container.ButtonGroup
        XYZButton                      matlab.ui.control.RadioButton
        XYButton                       matlab.ui.control.RadioButton
        ZButton                        matlab.ui.control.RadioButton
        YButton                        matlab.ui.control.RadioButton
        XButton                        matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        VisualisationWindow
        DataFilterWindow % Window to set advanced filters
        TrackData % Data directly from the main app
        DataToAnalyze %data that will be used for the calculations, might be concatenated with other movies
        FilteredDataToAnalyze %after advanced filtering, this data is used
        FilteredIDs %ids left after filtering
        IsUnitFlag %remembers if pixel/frame or unit/unit
        
        CalculatedDataStruct %contains all the calculated valus from the data
        FilterCalculatedDataStruct %contains the data after filtering
        PolAxes %polar axes for the angles
        Min %value for the axes as left border
        Max %value for the axes as right border
        Unitsused %text to add for units
        
        DataFilterStruct % contains the filter set in the filterWindow
        FilterdIDS %ids left after filtering
    end
    
    methods
        function grabFilter(app)
            %gets the filter nodes from the DataFilterWindow
            app.DataFilterStruct = app.DataFilterWindow.DataFilterStruct;
            getFilteredIDs(app);
            managePlots(app);
        end
    end
    
    methods (Static)
        function struc = setSaveStruct()
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            %s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            struc = s;
        end
    end
    
    methods (Access = private)
        %GUI methods
        function updateMovieList(app)
            %adds all the files from the visualisation window. if more then
            %one, also add an all tracks option
            names = string([]);
            for i = 1:size(app.TrackData,1)
                names(i) = string(app.TrackData{i,2});
            end
            if size(app.TrackData,1) > 1
                names(end+1) = "All Tracks";
            end
            app.ListBox.Items = names;
        end
        
        function [lb, rb, hc] = getHistPlotProps(app)
            %gets the current settings in the gui for the histogram
            lb = app.LeftBorderEditField.Value;
            rb = app.RightBorderEditField.Value;
            hc = app.NumberofBinsEditField.Value;
        end
        
        function manageExtraPanel(app)
            %enables/disables the extra panels depending on the active
            %category
            set(app.AdditionalOptionsPanel.Children, "Visible", "off");
            app.UnitsPanel.Visible = 1;
            if app.JumpDistancesButton.Value || app.CumulativeJumpDistancesButton.Value || app.MeanJumpDistancesButton.Value || app.CumulativeMeanJumpDistancesButton.Value
                app.JumpDistancesButtonGroup.Visible = 1;
            elseif app.JumpAnglesButton.Value
                app.JumpAnglesButtonGroup.Visible = 1;
            elseif app.MSDfromInternMSDFitButton.Value
                app.MSDPanel.Visible = 1;
            elseif app.DiffusionParametersButton.Value
                app.DiffParametersButtonGroup.Visible = 1;
            elseif app.ConfinementRatioButton.Value
                app.ConfinementRatioButtonGroup.Visible = 1;
            elseif app.CumulativeTrackLengthButton.Value || app.AverageTrackLengthButton.Value 
                app.XYButton.Visible = 1;
                app.XYZButton.Visible = 1;
            end
        end
        
        function setDataToAnalyze(app)
           %decides the data that will be used for the analysis. if all
           %tracks is used, will concatenate all datafiles to one big file
            if size(app.TrackData,1) == 1
                app.DataToAnalyze = app.TrackData{1,1};
                return
            end
            name = string(app.ListBox.Value);
            if name == "All Tracks"
                app.DataToAnalyze = catTrackDataRename(app.TrackData);
            else
                idx = find(strcmp(app.TrackData(:,2),name));
                app.DataToAnalyze = app.TrackData{idx,1};
            end
            %puts data into the right format, pixel/frame
            app.IsUnitFlag = 0;
            manageUnits(app);
        end
        
        function manageUnits(app)
            %puts data into the right format to be used for the
            %visualisation. will also convert between px/Frame and
            %unit/unit
            if app.IsUnitFlag == 0 %has not been run
                type = split(convertCharsToStrings(app.VisualisationWindow.ImportSettingsStruct.customUnits.DataType),"/");
                comparison = strcmp(type, ["Pixel"; "Frame"]);
                if comparison %pixel/frame
                    %if pixel/frame, nothing much has to be done
                elseif comparison(1) & (~comparison(2))%Pixel/Unit
                    %if pixel/unit, must convert unit frame
                    time = app.DataToAnalyze(:,2);
                    time = round(time/app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep,0);
                    app.DataToAnalyze(:,2) = time;
                elseif (~comparison(1)) & comparison(2)%Unit/Frame
                    %if unit/frame, must convert unit to pixel with the scale
                    xyzdata = app.DataToAnalyze(:,3:5);
                    xyzdata = xyzdata/app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                    app.DataToAnalyze(:,3:5) = xyzdata;
                else
                    %if unit/unit, must convert both
                    time = app.DataToAnalyze(:,2);
                    time = round(time/app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep,0);
                    app.DataToAnalyze(:,2) = time;
                    xyzdata = app.DataToAnalyze(:,3:5);
                    xyzdata = xyzdata/app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                    app.DataToAnalyze(:,3:5) = xyzdata;
                end
                items = app.Switch.Items;
                unittext = [app.VisualisationWindow.ImportSettingsStruct.customUnits.LengthUnit "/" app.VisualisationWindow.ImportSettingsStruct.customUnits.TimeUnit];
                items{2} = convertStringsToChars(join(unittext,""));
                app.Switch.Items = items;
                %app.CalculatedDataStruct = setSaveStruct(app);
                app.IsUnitFlag = 1;
            else % has been run
                %nothing
            end
        end       
        
        function switchUnits(app)
            %if switch from px/frame to unit/unit 
            %convert data
            setDataToAnalyze(app);
            if app.Switch.Value == "Pixel/Frame"
                %nothing as managing is done in data setting
            else
                %convert data to units. %data is in px/frame
                %time = app.DataToAnalyze(:,2);
                %time = time*app.VisualisationWindow.ImportSettingsStruct.customUnits.Timestep;
                %app.DataToAnalyze(:,2) = time;
                xyzdata = app.DataToAnalyze(:,3:5);
                xyzdata = xyzdata*app.VisualisationWindow.ImportSettingsStruct.customUnits.Pixelsize;
                app.DataToAnalyze(:,3:5) = xyzdata;
            end
            %clear calculations
            app.CalculatedDataStruct = app.setSaveStruct();
            app.FilterCalculatedDataStruct = app.setSaveStruct();
            %plot anew current window
            managePlots(app);
        end
        
        function finalIDs = findTrackIDsbyFilterNodes(app)
            nodes = app.DataFilterStruct;
            involvedIDs = {};
            %for every node build a filter
            for i = 1:size(nodes,1)
                %{filtertype, filterprop, filterextraprop, filterminval, filtermaxval, filterlogical}
                data = nodes(i).NodeData;
                propfieldname = data{2};
                extrapropfieldname = split(data{3},".");
                %numerical data to filter for
                if size(extrapropfieldname,1) == 1
                    fielddata = getfield(app.CalculatedDataStruct,propfieldname, extrapropfieldname{1});
                else
                    fielddata = getfield(app.CalculatedDataStruct,propfieldname, extrapropfieldname{1},extrapropfieldname{2});
                end
                %need to differ between id-value and id-multiValue pairs
                multval = (cell2mat(cellfun(@(x) size(x,1),fielddata,'UniformOutput',false)));
                multval = mean(multval(:,2));
                if multval > 1 %multivalues to ids
                    %need to flatten the data
                    newfielddata = zeros(size(fielddata,1),2);
                    for k = 1:size(fielddata,1)
                        tmp = fielddata(k,:);
                        tmpid = tmp{1};
                        tmpdat = tmp{2};
                        tmpdat = mean(tmpdat(:,2));
                        newfielddata(k,:) = [tmpid, tmpdat];
                    end
                    fielddata = newfielddata;
                else %only one value per id
                    %this contains the data we want to filter
                    fielddata = cell2mat(fielddata);
                end

                %check for include exclude
                %extra thing for swift data type as they are encoded as
                %numnbers

                if data{1} == 1 %include
                    ids = fielddata(fielddata(:,2) >= data{4} & fielddata(:,2) <= data{5},1);
                else
                    ids = fielddata(fielddata(:,2) < data{4} & fielddata(:,2) > data{5},1);
                end

                involvedIDs{i} = {data{6}, ids};
            end
            %now connect the filters by their logicals
            %check that there are more then 1 connection to do
            if size(nodes,1) > 1
                startids = involvedIDs{1}{2};
                for i = 2:size(nodes,1)
                    nextids = involvedIDs{i}{2};
                    if involvedIDs{i}{1} == 1 %and
                        Lia = ismember(startids,nextids);
                        startids = startids(Lia);
                    else %or
                        tmp = [startids; nextids];
                        startids = unique(tmp);
                    end
                end
                finalIDs = startids;
            else %just one
                finalIDs = involvedIDs{1}{2};
            end
            finalIDs = sort(finalIDs);
            app.FilterdIDS = finalIDs;
        end
        
        function getFilteredIDs(app)
            %make sure that calc
            calculateAllIfNecessary(app);
            %buidl filter from the nodes and get ids
            finalIDs = findTrackIDsbyFilterNodes(app);
            %set the filtered ids
            if isempty(finalIDs)
                f = msgbox("No tracks found under these conditions");
            else
                %get the respective data
                filteridx = [];
                for i = 1:numel(finalIDs)%for every track id
                    trackid = finalIDs(i);
                    idx = find(app.DataToAnalyze(:,1)==trackid);
                    filteridx = [filteridx;idx];
                end
                app.FilteredDataToAnalyze = app.DataToAnalyze(filteridx,:);
            end
            %calculate the filtered data
        end
        
        function calculateAllIfNecessary(app)
            if isempty(app.CalculatedDataStruct.JumpDist.X)
               plotJumpDistances(app, 0);
               cla(app.UIAxes);
            end
            if isempty(app.CalculatedDataStruct.CumJumpDist.X)
               plotCumJumpDist(app, 0);
               cla(app.UIAxes);
            end
            if isempty(app.CalculatedDataStruct.MeanJumpDist.X)
               plotMeanJumpDist(app, 0);
               cla(app.UIAxes);
            end
            if isempty(app.CalculatedDataStruct.CumMeanJumpDist.X)
               plotMeanCumJumpDistances(app, 0);
               cla(app.UIAxes);
            end
           if isempty(app.CalculatedDataStruct.JumpAngles.XY)
               ids = unique(app.DataToAnalyze(:,1));
                for i = 1:size(ids,1)
                    data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                    calculateJumpAngles(app, data, "Calc");
                end       
           end
           if isempty(app.CalculatedDataStruct.SwiftParams.MSD)
               plotDiffusionParameters(app, 0);
               cla(app.UIAxes);
           end
          if isempty(app.CalculatedDataStruct.InternMSD.XY.MSDclass)
               plotInternMSDValue(app, 0);
               cla(app.UIAxes);
          end
          if isempty(app.CalculatedDataStruct.SwiftParams.MSD)
               plotSwiftType(app, 0);
               cla(app.UIAxes);
          end 
          if isempty(app.CalculatedDataStruct.TrackLength.Steps)
               plotTrackSteps(app, 0);
               cla(app.UIAxes);
          end
          if isempty(app.CalculatedDataStruct.TrackLength.NetLength)
               plotTrackNetLength(app, 0);
               cla(app.UIAxes);
          end
          if isempty(app.CalculatedDataStruct.TrackLength.AbsLength)
               plotTrackAbsLength(app, 0);
               cla(app.UIAxes);
          end
          if isempty(app.CalculatedDataStruct.TrackLength.ConfRatio.XY)
               plotTrackRatio(app, 0);
               cla(app.UIAxes);
          end
          if isempty(app.CalculatedDataStruct.TrackLength.CumLen)
               plotCumTrackLength(app, 0);
               cla(app.UIAxes);
          end
          if isempty(app.CalculatedDataStruct.TrackLength.MeanLen)
               plotAvgTrackLength(app, 0);
               cla(app.UIAxes);
          end
          %managePlots(app);
        end

        %Plot Methods
        
        function setMinMaxValue(app, min, max)
            %sets the edit field values depending on the data that will be
            %plotted
            app.LeftBorderEditField.Value = min;
            app.RightBorderEditField.Value = max;
        end
        
        function updatePlot(app)
            %redraws the plot
            [lb, rb, ~] = getHistPlotProps(app);
            xlim(app.UIAxes,[lb rb]);
        end
        
        function [minv, maxv] = plotJumpDistances(app, filterflag)
            %plot the jump distances as a histogram
            [~, ~, hc] = getHistPlotProps(app);
            cla(app.UIAxes);
            if filterflag == 0 %no filter used
                if isempty(app.CalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    ids = unique(app.DataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        calculateJumpDistances(app, data, "Calc");
                    end
                end
            else %filter used
                if isempty(app.FilterCalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    ids = unique(app.FilteredDataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.FilteredDataToAnalyze(app.FilteredDataToAnalyze(:,1) == ids(i),:);
                        calculateJumpDistances(app, data, "Filter");
                    end
                end
            end
            %decide what to plot and plot it
            if app.XButton.Value
                if filterflag == 0 %no filter
                    data = cell2mat(app.CalculatedDataStruct.JumpDist.X(:,2));
                else %with filter
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpDist.X(:,2));
                end
                minv = min(data(:,2));
                maxv = max(data(:,2));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Jump Distance Distribution for X");

            elseif app.YButton.Value
                if filterflag == 0 %no filter
                    data = cell2mat(app.CalculatedDataStruct.JumpDist.Y(:,2));
                else %with filter
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpDist.Y(:,2));
                end
                minv = min(data(:,2));
                maxv = max(data(:,2));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Jump Distance Distribution for Y");
                
            elseif app.ZButton.Value
                if filterflag == 0 %no filter
                    data = cell2mat(app.CalculatedDataStruct.JumpDist.Z(:,2));
                else %with filter
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpDist.Z(:,2));
                end
                minv = min(data(:,2));
                maxv = max(data(:,2));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Jump Distance Distribution for Z");
                
            elseif app.XYButton.Value
                if filterflag == 0 %no filter
                    data = cell2mat(app.CalculatedDataStruct.JumpDist.XY(:,2));
                else %with filter
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpDist.XY(:,2));
                end
                minv = min(data(:,2));
                maxv = max(data(:,2));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Jump Distance Distribution for XY");
                
            elseif app.XYZButton.Value
                if filterflag == 0 %no filter
                    data = cell2mat(app.CalculatedDataStruct.JumpDist.XYZ(:,2));
                else %with filter
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpDist.XYZ(:,2));
                end
                minv = min(data(:,2));
                maxv = max(data(:,2));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Jump Distance Distribution for XYZ");
            end
            if app.Switch.Value == "Pixel/Frame"
                xlabel(app.UIAxes, "Jump Distance [px]");
            else
                xlabel(app.UIAxes, sprintf("Jump Distance [%s]", app.Unitsused{1}));
            end
            ylabel(app.UIAxes, "Counts");
        end
        
        function [minv, maxv] = plotCumJumpDist(app, filterflag)
            %plots the cumulative jump disatnces
            [~, ~, hc] = getHistPlotProps(app);
            cla(app.UIAxes);
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    plotJumpDistances(app, filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.FilterCalculatedDataStruct.CumJumpDist.X)
                    calculateCumJumpDistances(app,"Filter");
                end
            else
                if isempty(app.CalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    plotJumpDistances(app, filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.CalculatedDataStruct.CumJumpDist.X)
                    calculateCumJumpDistances(app,"Calc");
                end
            end
            %decide what to plot and plot
            if app.XButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.CumJumpDist.X(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.CumJumpDist.X(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Cumulative Jump Distance Distribution for X");
            elseif app.YButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.CumJumpDist.Y(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.CumJumpDist.Y(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Cumulative Jump Distance Distribution for Y");
            elseif app.ZButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.CumJumpDist.Z(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.CumJumpDist.Z(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Cumulative Jump Distance Distribution for Z");
            elseif app.XYButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.CumJumpDist.XY(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.CumJumpDist.XY(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Cumulative Jump Distance Distribution for XY");
            elseif app.XYZButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.CumJumpDist.XYZ(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.CumJumpDist.XYZ(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Cumulative Jump Distance Distribution for XYZ");
            end
            if app.Switch.Value == "Pixel/Frame"
                xlabel(app.UIAxes, "Jump Distance [px]");
            else
                xlabel(app.UIAxes, sprintf("Jump Distance [%s]", app.Unitsused{1}));
            end
            ylabel(app.UIAxes, "Counts");
        end
        
        function [minv, maxv] = plotMeanJumpDist(app, filterflag)
            %calculates and plots the mean jump distance
            [lb, rb, hc] = getHistPlotProps(app);
            cla(app.UIAxes);
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    plotJumpDistances(app,filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.FilterCalculatedDataStruct.MeanJumpDist.X)
                    calculateMeanJumpDistanes(app,"Filter");
                end
            else
                if isempty(app.CalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    plotJumpDistances(app, filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.CalculatedDataStruct.MeanJumpDist.X)
                    calculateMeanJumpDistanes(app, "Calc");
                end
            end
            
            %decide what to plot and plot it
            if app.XButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.MeanJumpDist.X(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.MeanJumpDist.X(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[lb rb]);
                title(app.UIAxes, "Mean Jump Distance Distribution for X");
            elseif app.YButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.MeanJumpDist.Y(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.MeanJumpDist.Y(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[lb rb]);
                title(app.UIAxes, "Mean Jump Distance Distribution for Y");
            elseif app.ZButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.MeanJumpDist.Z(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.MeanJumpDist.Z(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[lb rb]);
                title(app.UIAxes, "Mean Jump Distance Distribution for Z");
            elseif app.XYButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.MeanJumpDist.XY(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.MeanJumpDist.XY(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[lb rb]);
                title(app.UIAxes, "Mean Jump Distance Distribution for XY");
            elseif app.XYZButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.MeanJumpDist.XYZ(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.MeanJumpDist.XYZ(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[lb rb]);
                title(app.UIAxes, "Mean Jump Distance Distribution for XYZ");
            end
             if app.Switch.Value == "Pixel/Frame"
                xlabel(app.UIAxes, "Mean Jump Distance [px]");
            else
                xlabel(app.UIAxes, sprintf("Jump Distance [%s]", app.Unitsused{1}));
            end
            ylabel(app.UIAxes, "Counts");
        end
        
        function [minv, maxv] = plotMeanCumJumpDistances(app, filterflag)
            %calculates and plots the mean cumulative jump distance
            [~, ~, hc] = getHistPlotProps(app);
            cla(app.UIAxes);
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.JumpDist.X)
                %do the calculations
                plotJumpDistances(app, "Filter");
                cla(app.UIAxes);
                end
                if isempty(app.FilterCalculatedDataStruct.MeanJumpDist.X)
                    calculateMeanJumpDistanes(app, "Filter");
                end
                if isempty(app.FilterCalculatedDataStruct.CumMeanJumpDist.X)
                    calculateMeanCumJumpDistances(app, "Filter");
                end
            else
                if isempty(app.CalculatedDataStruct.JumpDist.X)
                    %do the calculations
                    plotJumpDistances(app, "Calc");
                    cla(app.UIAxes);
                end
                if isempty(app.CalculatedDataStruct.MeanJumpDist.X)
                    calculateMeanJumpDistanes(app, "Calc");
                end
                if isempty(app.CalculatedDataStruct.CumMeanJumpDist.X)
                    calculateMeanCumJumpDistances(app, "Calc");
                end
            end
            
            %decide what to plot and plot it
            if app.XButton.Value
                if filterflag
                    data = app.FilterCalculatedDataStruct.CumMeanJumpDist.X(:,2);
                else
                    data = app.CalculatedDataStruct.CumMeanJumpDist.X(:,2);
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Mean Cumulative Jump Distance Distribution for X");
            elseif app.YButton.Value
                if filterflag
                    data = app.FilterCalculatedDataStruct.CumMeanJumpDist.Y(:,2);
                else
                    data = app.CalculatedDataStruct.CumMeanJumpDist.Y(:,2);
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Mean Cumulative Jump Distance Distribution for Y");
            elseif app.ZButton.Value
                if filterflag
                    data = app.FilterCalculatedDataStruct.CumMeanJumpDist.Z(:,2);
                else
                    data = app.CalculatedDataStruct.CumMeanJumpDist.Z(:,2);
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Mean Cumulative Jump Distance Distribution for Z");
            elseif app.XYButton.Value
                if filterflag
                    data = app.FilterCalculatedDataStruct.CumMeanJumpDist.XY(:,2);
                else
                    data = app.CalculatedDataStruct.CumMeanJumpDist.XY(:,2);
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Mean Cumulative Jump Distance Distribution for XY");
            elseif app.XYZButton.Value
                if filterflag
                    data = app.FilterCalculatedDataStruct.CumMeanJumpDist.XYZ(:,2);
                else
                    data = app.CalculatedDataStruct.CumMeanJumpDist.XYZ(:,2);
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Mean Cumulative Jump Distance Distribution for XYZ");
            end
             if app.Switch.Value == "Pixel/Frame"
                xlabel(app.UIAxes, "Mean Cumulative Jump Distance [px]");
            else
                xlabel(app.UIAxes, sprintf("Jump Distance [%s]", app.Unitsused{1}));
            end
            ylabel(app.UIAxes, "Counts");
        end
        
        function plotJumpAngles(app, filterflag)
            %plots and calculates the jump angles. might take a while
            d = uiprogressdlg(app.AllTrackAnalysisUIFigure,'Title','Calculating Angles', 'Indeterminate','on');
            drawnow
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.JumpAngles.XY)
                ids = unique(app.FilteredDataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.FilteredDataToAnalyze(app.FilteredDataToAnalyze(:,1) == ids(i),:);
                        calculateJumpAngles(app, data, "Filter");
                    end       
                end
            else
                if isempty(app.CalculatedDataStruct.JumpAngles.XY)
                ids = unique(app.DataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        calculateJumpAngles(app, data, "Calc");
                    end       
                end
            end
           
            cla(app.UIAxes);
            app.UIAxes.Visible = 0;
            app.PolAxes = polaraxes(app.Panel);
            if app.XYButton_2.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpAngles.XY(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.JumpAngles.XY(:,2));
                end
            elseif app.XYZButton_2.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.JumpAngles.XYZ(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.JumpAngles.XYZ(:,2));
                end
            end
            polarhistogram(app.PolAxes,data,12);
            delete(d);
            title(app.PolAxes, "Jump Angle Distribtuion in °")
        end
        
        function [minv, maxv] = plotDiffusionParameters(app, filterflag)
            %gets the data from the previous analysis and plots it
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.SwiftParams.MSD)
                    ids = unique(app.FilteredDataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.FilteredDataToAnalyze(app.FilteredDataToAnalyze(:,1) == ids(i),:);
                        getSwiftDiffusionParamters(app, data, "Filter");
                    end
                end
            else
                if isempty(app.CalculatedDataStruct.SwiftParams.MSD)
                    ids = unique(app.DataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        getSwiftDiffusionParamters(app, data, "Calc");
                    end
                end
            end
             
             [~, ~, hc] = getHistPlotProps(app);
             cla(app.UIAxes);
            %decide what to plot and plot it
            if app.MSDButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.SwiftParams.MSD(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.SwiftParams.MSD(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "MSD Distribtuion from Swift");
                xlabel(app.UIAxes, "MSD [µm²/s]");
                ylabel(app.UIAxes, "Counts");
            elseif app.DButton.Value
                if filterflag
                    data = cell2mat(app.FilterCalculatedDataStruct.SwiftParams.D(:,2));
                else
                    data = cell2mat(app.CalculatedDataStruct.SwiftParams.D(:,2));
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Diffusion coefficient Distribtuion from Swift");
                xlabel(app.UIAxes, "D [µm²/s]");
                ylabel(app.UIAxes, "Counts");
            end
        end
        
        function [minv, maxv] = plotInternMSDValue(app, filterflag)
            [~, ~, hc] = getHistPlotProps(app);
            cla(app.UIAxes);
            switch app.Switch_2.Value
                case "2D"
                    if isempty(app.CalculatedDataStruct.InternMSD.XY.MSDclass)
                        calculateMSD(app, "Calc");
                        return
                    end
                    if filterflag
                        calculateMSD(app,"Filter");
                    end
                    if app.AlphaButton.Value
                        if filterflag
                            data = (app.FilterCalculatedDataStruct.InternMSD.XY.Alpha);
                        else
                            data = (app.CalculatedDataStruct.InternMSD.XY.Alpha);
                        end
                        minv = min(data(:));
                        maxv = max(data(:));
                        edges = linspace(minv, maxv, hc);
                        histogram(app.UIAxes,data, edges);
                        xlim(app.UIAxes,[minv maxv]);
                        title(app.UIAxes, "Alpha Value Distribtuion");
                        xlabel(app.UIAxes, "Alpha");
                        ylabel(app.UIAxes, "Counts");
                    elseif app.MSDButton_2.Value
                        if filterflag
                            data = (app.FilterCalculatedDataStruct.InternMSD.XY.a);
                        else
                            data = (app.CalculatedDataStruct.InternMSD.XY.a);
                        end
                        minv = min(data(:));
                        maxv = max(data(:));
                        edges = linspace(minv, maxv, hc);
                        histogram(app.UIAxes,data, edges);
                        xlim(app.UIAxes,[minv maxv]);
                        title(app.UIAxes, "MSD Distribtuion");
                        txt = [app.Unitsused{1} "²/" app.Unitsused{2} "²"];
                        xlabel(app.UIAxes, sprintf("MSD [%s]",txt));
                        ylabel(app.UIAxes, "Counts");
                    elseif app.DButton_2.Value
                        if filterflag
                            data = (app.FilterCalculatedDataStruct.InternMSD.XY.d);
                        else
                            data = (app.CalculatedDataStruct.InternMSD.XY.d);
                        end
                        minv = min(data(:));
                        maxv = max(data(:));
                        edges = linspace(minv, maxv, hc);
                        histogram(app.UIAxes,data, edges);
                        xlim(app.UIAxes,[minv maxv]);
                        title(app.UIAxes, "Diffusion Coefficient Distribution");
                        txt = [app.Unitsused{1} "²/" app.Unitsused{2}];
                        xlabel(app.UIAxes, sprintf("D [%s]",txt));
                        ylabel(app.UIAxes, "Counts");
                    end
                case "3D"
                    if isempty(app.CalculatedDataStruct.InternMSD.XYZ.MSDclass)
                        %calculateMSD(app);
                        return
                    end
                    if filterflag
                        calculateMSD(app,"Filter");
                    end
                    if app.AlphaButton.Value
                        if filterflag
                            data = (app.FilterCalculatedDataStruct.InternMSD.XYZ.Alpha);
                        else
                            data = (app.CalculatedDataStruct.InternMSD.XYZ.Alpha);
                        end
                        minv = min(data(:));
                        maxv = max(data(:));
                        edges = linspace(minv, maxv, hc);
                        histogram(app.UIAxes,data, edges);
                        xlim(app.UIAxes,[minv maxv]);
                        title(app.UIAxes, "Alpha Value Distribtuion");
                        xlabel(app.UIAxes, "Alpha");
                        ylabel(app.UIAxes, "Counts");
                    elseif app.MSDButton_2.Value
                        if filterflag
                            data = (app.FilterCalculatedDataStruct.InternMSD.XYZ.a);
                        else
                            data = (app.CalculatedDataStruct.InternMSD.XYZ.a);
                        end
                        minv = min(data(:));
                        maxv = max(data(:));
                        edges = linspace(minv, maxv, hc);
                        histogram(app.UIAxes,data, edges);
                        xlim(app.UIAxes,[minv maxv]);
                        title(app.UIAxes, "MSD Distribtuion");
                        txt = [app.Unitsused{1} "²/" app.Unitsused{2} "²"];
                        xlabel(app.UIAxes, sprintf("MSD [%s]",txt));
                        ylabel(app.UIAxes, "Counts");
                    elseif app.DButton_2.Value
                        if filterflag
                            data = (app.FilterCalculatedDataStruct.InternMSD.XYZ.d);
                        else
                            data = (app.CalculatedDataStruct.InternMSD.XYZ.d);
                        end
                        minv = min(data(:));
                        maxv = max(data(:));
                        edges = linspace(minv, maxv, hc);
                        histogram(app.UIAxes,data, edges);
                        xlim(app.UIAxes,[minv maxv]);
                        title(app.UIAxes, "Diffusion Coefficient Distribution");
                        txt = [app.Unitsused{1} "²/" app.Unitsused{2}];
                        xlabel(app.UIAxes, sprintf("D [%s]",txt));
                        ylabel(app.UIAxes, "Counts");
                    end
            end 
        end
        
        function [minv, maxv] = plotSwiftType(app, filterflag)
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.SwiftParams.MSD)
                    ids = app.FilterdIDS;
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        getSwiftDiffusionParamters(app, data, "Filter");
                    end
                end 
            else
                if isempty(app.CalculatedDataStruct.SwiftParams.MSD)
                    ids = unique(app.DataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        getSwiftDiffusionParamters(app, data, "Calc");
                    end
                end 
            end
            
            if filterflag
                data = cell2mat(app.FilterCalculatedDataStruct.SwiftParams.type); 
            else
               data = cell2mat(app.CalculatedDataStruct.SwiftParams.type); 
            end
            %none 0 immob1 diff 2 dire3 dirdiff 4 other 5
  
            minv = -0.5;
            maxv = 4.5;
            edges = [-0.5, 0.5, 1.5, 2.5, 3.5, 4.5];
            histogram(app.UIAxes,data(:,2), edges);
            xlim(app.UIAxes,[minv maxv]);
            xticks(app.UIAxes, [0 1 2 3 4]);
            xticklabels(app.UIAxes, {'None','Immobile','Diffusion','Directed','DirectedDiffusion'})
            %text(app.UIAxes, 0.2, 0.9,"None",'Units','normalized'); %0
            %text(app.UIAxes, 0.40, 0.9,"Immobile",'Units','normalized'); %1
            %text(app.UIAxes, 0.63, 0.9,"Diffusion",'Units','normalized'); %2
            %text(app.UIAxes, 0.85, 0.9,"Otherwise",'Units','normalized'); %5          
        end
        
        function [minv, maxv] = plotTrackSteps(app, filterflag)
            cla(app.UIAxes)
            [~, ~, hc] = getHistPlotProps(app);
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.TrackLength.Steps)
                    ids = app.FilterdIDS;
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        calculateStepNumbers(app, data, "Filter");
                    end  
                end
            else
                if isempty(app.CalculatedDataStruct.TrackLength.Steps)
                    ids = unique(app.DataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        calculateStepNumbers(app, data, "Calc");
                    end  
                end
            end
            if filterflag
                data = cell2mat(app.FilterCalculatedDataStruct.TrackLength.Steps(:,2));
            else
                data = cell2mat(app.CalculatedDataStruct.TrackLength.Steps(:,2));
            end
            
            minv = min(data(:));
            maxv = max(data(:));
            edges = linspace(minv, maxv, hc);
            histogram(app.UIAxes,data, edges);
            xlim(app.UIAxes,[minv maxv]);
            title(app.UIAxes, "Track Length");
            xlabel(app.UIAxes, "Track length [steps]");
            ylabel(app.UIAxes, "Counts");
        end
 
        function [minv, maxv] = plotTrackRatio(app, filterflag)
            %includes ratio, net dist and abs dist. net dist and abs dist
            %need to be calculate first. net dist has extra function, abs
            %dist needs jump distances
            [~, ~, hc] = getHistPlotProps(app);
            cla(app.UIAxes);
            if filterflag == 0 %no filter used
                if isempty(app.CalculatedDataStruct.JumpDist.X)
                    plotJumpDistances(app,filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.CalculatedDataStruct.TrackLength.NetLength.XY)
                    %do the calculations
                    ids = unique(app.DataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.DataToAnalyze(app.DataToAnalyze(:,1) == ids(i),:);
                        calculateNetDistance(app, data, "Calc");
                    end
                end
                if isempty(app.CalculatedDataStruct.TrackLength.AbsLength.XY)
                    getTotalTrackLengths(app, "Calc");
                end
                if isempty(app.CalculatedDataStruct.TrackLength.ConfRatio.XY)
                    calculateConfinedRatio(app,"Calc");
                end
            else %filter used
                if isempty(app.FilterCalculatedDataStruct.JumpDist.X)
                    plotJumpDistances(app,filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.FilterCalculatedDataStruct.TrackLength.NetLength.XY)
                    %do the calculations
                    ids = unique(app.FilteredDataToAnalyze(:,1));
                    for i = 1:size(ids,1)
                        data = app.FilteredDataToAnalyze(app.FilteredDataToAnalyze(:,1) == ids(i),:);
                        calculateNetDistance(app, data, "Filter");
                    end
                end
                if isempty(app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY)
                    getTotalTrackLengths(app, "Filter");
                end
                if isempty(app.FilterCalculatedDataStruct.TrackLength.ConfRatio.XY)
                    calculateConfinedRatio(app,"Filter");
                end
            end
            %decide what to plot due to selected button
            if app.RatioLengthNetLengthButton.Value
                if filterflag
                    if app.Switch_3.Value == "2D"
                        data = (app.FilterCalculatedDataStruct.TrackLength.ConfRatio.XY(:,2));
                    else
                        data = (app.FilterCalculatedDataStruct.TrackLength.ConfRatio.XYZ(:,2));
                    end
                else
                    if app.Switch_3.Value == "2D"
                        data = (app.CalculatedDataStruct.TrackLength.ConfRatio.XY(:,2));
                    else
                        data = (app.CalculatedDataStruct.TrackLength.ConfRatio.XYZ(:,2));
                    end
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Confinement Ratio Distribution of Tracks");
                xlabel(app.UIAxes, "Ratio Total Distance/Netto Distance");
                ylabel(app.UIAxes, "Counts");
            elseif app.LengthButton.Value
                if filterflag
                    if app.Switch_3.Value == "2D"
                        data = (app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY(:,2));
                    else
                        data = (app.FilterCalculatedDataStruct.TrackLength.AbsLength.XYZ(:,2));
                    end
                else
                    if app.Switch_3.Value == "2D"
                        data = (app.CalculatedDataStruct.TrackLength.AbsLength.XY(:,2));
                    else
                        data = (app.CalculatedDataStruct.TrackLength.AbsLength.XYZ(:,2));
                    end
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Absolute Length Distribution of Tracks");
                if app.Switch.Value == "Pixel/Frame"
                    xlabel(app.UIAxes, "Total Track Distance [px]");
                else
                    xlabel(app.UIAxes, sprintf("Total Track Distance Distance [%s]", app.Unitsused{1}));
                end
                ylabel(app.UIAxes, "Counts");
            elseif app.NetLengthButton.Value
                if filterflag
                    if app.Switch_3.Value == "2D"
                        data = cell2mat(app.FilterCalculatedDataStruct.TrackLength.NetLength.XY(:,2));
                    else
                        data = cell2mat(app.FilterCalculatedDataStruct.TrackLength.NetLength.XYZ(:,2));
                    end
                else
                    if app.Switch_3.Value == "2D"
                        data = cell2mat(app.CalculatedDataStruct.TrackLength.NetLength.XY(:,2));
                    else
                        data = cell2mat(app.CalculatedDataStruct.TrackLength.NetLength.XYZ(:,2));
                    end
                end
                minv = min(data(:));
                maxv = max(data(:));
                edges = linspace(minv, maxv, hc);
                histogram(app.UIAxes,data, edges);
                xlim(app.UIAxes,[minv maxv]);
                title(app.UIAxes, "Netto Length Distribution of Tracks");
                if app.Switch.Value == "Pixel/Frame"
                    xlabel(app.UIAxes, "Netto Track Distance [px]");
                else
                    xlabel(app.UIAxes, sprintf("Netto Track Distance [%s]", app.Unitsused{1}));
                end
                    ylabel(app.UIAxes, "Counts");
                end
  
        end
        
        function [minv, maxv] = plotCumTrackLength(app, filterflag)
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY)
                    plotTrackRatio(app, filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.FilterCalculatedDataStruct.TrackLength.CumLen)
                    calculateCumTrackLength(app, "Filter");
                end
            else
                if isempty(app.CalculatedDataStruct.TrackLength.AbsLength.XY)
                    plotTrackRatio(app, filterflag);
                    cla(app.UIAxes);
                end
                if isempty(app.CalculatedDataStruct.TrackLength.CumLen)
                    calculateCumTrackLength(app, "Calc");
                end
            end
            
            cla(app.UIAxes)
            %[lb, rb, hc] = getHistPlotProps(app);
            if filterflag
                if app.XYButton.Value
                    data = (app.FilterCalculatedDataStruct.TrackLength.CumLen);
                else
                    data = (app.FilterCalculatedDataStruct.TrackLength.CumLen);
                end
            else
                if app.XYButton.Value
                    data = (app.CalculatedDataStruct.TrackLength.CumLen);
                else
                    data = (app.CalculatedDataStruct.TrackLength.CumLen);
                end

            end
            
            minv = min(data(:,2));
            maxv = max(data(:,2));
            plot(app.UIAxes,data(:,2));
            xlim(app.UIAxes,[minv maxv]);
            title(app.UIAxes, "Cumulative Track Length");
            xlabel(app.UIAxes, "Track length [steps]");
            ylabel(app.UIAxes, "Counts");
        end
        
        function [minv, maxv] = plotAvgTrackLength(app, filterflag)
            if filterflag
                if isempty(app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY)
                    plotTrackRatio(app, filterflag);
                    cla(app.UIAxes)
                end
                if isempty(app.FilterCalculatedDataStruct.TrackLength.MeanLen.XY)
                    calculateMeanTrackLength(app, "Filter")
                end
            else
                if isempty(app.CalculatedDataStruct.TrackLength.AbsLength.XY)
                    plotTrackRatio(app, filterflag);
                    cla(app.UIAxes)
                end
                if isempty(app.CalculatedDataStruct.TrackLength.MeanLen.XY)
                    calculateMeanTrackLength(app, "Calc")
                end
            end
            if filterflag
                dataxy = (app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY);
                dataxyz = (app.FilterCalculatedDataStruct.TrackLength.AbsLength.XYZ);
                data = [dataxy(:,2), dataxyz(:,2)];
            else
                dataxy = (app.CalculatedDataStruct.TrackLength.AbsLength.XY);
                dataxyz = (app.CalculatedDataStruct.TrackLength.AbsLength.XYZ);
                data = [dataxy(:,2), dataxyz(:,2)];
            end
            
            minv = 0;
            maxv = 2;
            boxplot(app.UIAxes,data, "Labels", {'2D', '3D'});
            title(app.UIAxes, "Average Track Length");
            if app.Switch.Value == "Pixel/Frame"
                    ylabel(app.UIAxes, "Average Total Track Distance [px]");
                else
                    ylabel(app.UIAxes, sprintf("Average Total Track Distance Distance [%s]", app.Unitsused{1}));
            end
           
            xlabel(app.UIAxes, "Dimension");
            %scatter(app.UIAxes,ones(size(data,1),1), data(:,1));
            %hold on
            %scatter(app.UIAxes,1,app.CalculatedDataStruct.TrackLength.MeanLen, 10, "red");
            %xlim(app.UIAxes,[minv maxv]);
        end
        
        function managePlots(app)
            cla(app.UIAxes);
            xticks(app.UIAxes, "auto");
            xticklabels(app.UIAxes, "auto");
            %controls which plot is shown by gui feedback
            filterflag = app.ActiveCheckBox.Value;
            if filterflag
                calculateAllIfNecessary(app);
            end
            tab = app.TabGroup.SelectedTab.Title;
            %clear the axes, ready for plot
            try
                cla(app.PolAxes);
                app.PolAxes.Visible = 0;
            end
            app.UIAxes.Visible = 1;
            %check what to plot
            if tab == "Mobility"
                if app.JumpDistancesButton.Value
                    [minv, maxv] = plotJumpDistances(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.CumulativeJumpDistancesButton.Value
                    [minv, maxv] = plotCumJumpDist(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.MeanJumpDistancesButton.Value
                    [minv, maxv] = plotMeanJumpDist(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.CumulativeMeanJumpDistancesButton.Value
                    [minv, maxv] = plotMeanCumJumpDistances(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.JumpAnglesButton.Value
                    plotJumpAngles(app, filterflag);
                elseif app.DiffusionParametersButton.Value
                    [minv, maxv] = plotDiffusionParameters(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.ConfinementRatioButton.Value
                    [minv, maxv] = plotTrackRatio(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.MSDfromInternMSDFitButton.Value
                    try
                        [minv, maxv] = plotInternMSDValue(app, filterflag);
                        setMinMaxValue(app, minv, maxv);
                    end
                elseif app.ClassificationfromSwiftButton.Value
                   [minv, maxv] = plotSwiftType(app, filterflag);
                   setMinMaxValue(app, minv, maxv);
                elseif app.VelocityParametersButton.Value
                    %need to fill this in the future
                elseif app.DriftParametersButton.Value
                    %need to fill this in the future
                end
            elseif tab == "Statistics"
                if app.TrackStepsButton.Value
                    [minv, maxv] = plotTrackSteps(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.CumulativeTrackLengthButton.Value
                    [minv, maxv] = plotCumTrackLength(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.AverageTrackLengthButton.Value
                    [minv, maxv] = plotAvgTrackLength(app, filterflag);
                    setMinMaxValue(app, minv, maxv);
                elseif app.NumberofJumpsPerTrackButton.Value
                    %nothing yet
                elseif app.ROISizeButton.Value
                    %nothing yet
                end
            end
            axis 'auto y'
            close all
        end

        %Data Calculation methods

        function calculateJumpDistances(app, SingleTrackData, destinationStruc)
            startframe = min(SingleTrackData(:,2));
            endframe = max(SingleTrackData(:,2));
            duration = size(SingleTrackData,1);
            
            xdist = zeros(duration,2);
            ydist = zeros(duration,2);
            zdist = zeros(duration,2);
            xydist = zeros(duration,2);
            xyzdist = zeros(duration,2);
            
            for i = 1:duration-1
                xdist(i,:) = [i, (SingleTrackData(i,3) - SingleTrackData(i+1,3))];
                ydist(i,:) = [i, (SingleTrackData(i,4) - SingleTrackData(i+1,4))];
                zdist(i,:) = [i, (SingleTrackData(i,5) - SingleTrackData(i+1,5))];
                xydist(i,:) = [i, sqrt(xdist(i,2)^2+ydist(i,2)^2)];
                xyzdist(i,:) = [i, sqrt(xdist(i,2)^2+ydist(i,2)^2+zdist(i,2)^2)];
            end
            if destinationStruc == "Calc"
            app.CalculatedDataStruct.JumpDist.X(end+1,:) = {SingleTrackData(1,1), xdist};
            app.CalculatedDataStruct.JumpDist.Y(end+1,:) = {SingleTrackData(1,1), ydist};
            app.CalculatedDataStruct.JumpDist.Z(end+1,:) = {SingleTrackData(1,1), zdist};
            app.CalculatedDataStruct.JumpDist.XY(end+1,:) = {SingleTrackData(1,1), xydist};
            app.CalculatedDataStruct.JumpDist.XYZ(end+1,:) = {SingleTrackData(1,1), xyzdist};
            elseif destinationStruc == "Filter"
            app.FilterCalculatedDataStruct.JumpDist.X(end+1,:) = {SingleTrackData(1,1), xdist};
            app.FilterCalculatedDataStruct.JumpDist.Y(end+1,:) = {SingleTrackData(1,1), ydist};
            app.FilterCalculatedDataStruct.JumpDist.Z(end+1,:) = {SingleTrackData(1,1), zdist};
            app.FilterCalculatedDataStruct.JumpDist.XY(end+1,:) = {SingleTrackData(1,1), xydist};
            app.FilterCalculatedDataStruct.JumpDist.XYZ(end+1,:) = {SingleTrackData(1,1), xyzdist};
            end
        end
        
        function calculateCumJumpDistances(app, destinationStruc)
            if destinationStruc == "Calc"
                datX = app.CalculatedDataStruct.JumpDist.X;
                datY = app.CalculatedDataStruct.JumpDist.Y;
                datZ = app.CalculatedDataStruct.JumpDist.Z;
                datXY = app.CalculatedDataStruct.JumpDist.XY;
                datXYZ = app.CalculatedDataStruct.JumpDist.XYZ;
            elseif destinationStruc == "Filter"
                datX = app.FilterCalculatedDataStruct.JumpDist.X;
                datY = app.FilterCalculatedDataStruct.JumpDist.Y;
                datZ = app.FilterCalculatedDataStruct.JumpDist.Z;
                datXY = app.FilterCalculatedDataStruct.JumpDist.XY;
                datXYZ = app.FilterCalculatedDataStruct.JumpDist.XYZ;
            end
            
            cX = cellfun(@sum,datX(:,2), 'UniformOutput',0);
            for i = 1:size(cX,1)
                if cX{i} == 0
                    cX{i} = [0,0];
                end
            end
            cX = cell2mat(cX(:));
            cX = {cell2mat(datX(:,1)), cX(:,2)};
            cY = cellfun(@sum,datY(:,2), 'UniformOutput',0);
            for i = 1:size(cY,1)
                if cY{i} == 0
                    cY{i} = [0,0];
                end
            end
            cY = cell2mat(cY(:));
            cY = {cell2mat(datY(:,1)), cY(:,2)};
            cZ = cellfun(@sum,datZ(:,2), 'UniformOutput',0);
            for i = 1:size(cZ,1)
                if cZ{i} == 0
                    cZ{i} = [0,0];
                end
            end
            cZ = cell2mat(cZ(:));
            cZ = {cell2mat(datZ(:,1)), cZ(:,2)};
            cXY = cellfun(@sum,datXY(:,2), 'UniformOutput',0);
            for i = 1:size(cXY,1)
                if cXY{i} == 0
                    cXY{i} = [0,0];
                end
            end
            cXY = cell2mat(cXY(:));
            cXY = {cell2mat(datXY(:,1)), cXY(:,2)};
            cXYZ = cellfun(@sum,datXYZ(:,2), 'UniformOutput',0);
            for i = 1:size(cXYZ,1)
                if cXYZ{i} == 0
                    cXYZ{i} = [0,0];
                end
            end
            cXYZ = cell2mat(cXYZ(:));
            cXYZ = {cell2mat(datXYZ(:,1)), cXYZ(:,2)};
            
            if destinationStruc == "Calc"
            app.CalculatedDataStruct.CumJumpDist.X = cX;
            app.CalculatedDataStruct.CumJumpDist.Y = cY;
            app.CalculatedDataStruct.CumJumpDist.Z = cZ;
            app.CalculatedDataStruct.CumJumpDist.XY = cXY;
            app.CalculatedDataStruct.CumJumpDist.XYZ = cXYZ;
            elseif destinationStruc == "Filter"
            app.FilterCalculatedDataStruct.CumJumpDist.X = cX;
            app.FilterCalculatedDataStruct.CumJumpDist.Y = cY;
            app.FilterCalculatedDataStruct.CumJumpDist.Z = cZ;
            app.FilterCalculatedDataStruct.CumJumpDist.XY = cXY;
            app.FilterCalculatedDataStruct.CumJumpDist.XYZ = cXYZ;
            end
        end
        
        function calculateMeanJumpDistanes(app, destinationStruc)
            if destinationStruc == "Calc"
                datX = app.CalculatedDataStruct.JumpDist.X;
                datY = app.CalculatedDataStruct.JumpDist.Y;
                datZ = app.CalculatedDataStruct.JumpDist.Z;
                datXY = app.CalculatedDataStruct.JumpDist.XY;
                datXYZ = app.CalculatedDataStruct.JumpDist.XYZ;
            elseif destinationStruc == "Filter"
                datX = app.FilterCalculatedDataStruct.JumpDist.X;
                datY = app.FilterCalculatedDataStruct.JumpDist.Y;
                datZ = app.FilterCalculatedDataStruct.JumpDist.Z;
                datXY = app.FilterCalculatedDataStruct.JumpDist.XY;
                datXYZ = app.FilterCalculatedDataStruct.JumpDist.XYZ;
            end
            
            mX = cellfun(@mean,datX(:,2), 'UniformOutput',0);
            for i = 1:size(mX,1)
                if mX{i} == 0
                    mX{i} = [0,0];
                end
            end
            mX = cell2mat(mX);
            mX = {datX(:,1), mX(:,2)};
            mY = cellfun(@mean,datY(:,2), 'UniformOutput',0);
            for i = 1:size(mY,1)
                if mY{i} == 0
                    mY{i} = [0,0];
                end
            end
            mY = cell2mat(mY);
            mY = {datY(:,1), mY(:,2)};
            mZ = cellfun(@mean,datZ(:,2), 'UniformOutput',0);
            for i = 1:size(mZ,1)
                if mZ{i} == 0
                    mZ{i} = [0,0];
                end
            end
            mZ = cell2mat(mZ);
            mZ = {datZ(:,1), mZ(:,2)};
            mXY = cellfun(@mean,datXY(:,2), 'UniformOutput',0);
            for i = 1:size(mXY,1)
                if mXY{i} == 0
                    mXY{i} = [0,0];
                end
            end
            mXY = cell2mat(mXY);
            mXY = {datXY(:,1), mXY(:,2)};
            mXYZ = cellfun(@mean,datXYZ(:,2), 'UniformOutput',0);
            for i = 1:size(mXYZ,1)
                if mXYZ{i} == 0
                    mXYZ{i} = [0,0];
                end
            end
            mXYZ = cell2mat(mXYZ);
            mXYZ = {datXYZ(:,1), mXYZ(:,2)};
            
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.MeanJumpDist.X = mX;
                app.CalculatedDataStruct.MeanJumpDist.Y = mY;
                app.CalculatedDataStruct.MeanJumpDist.Z = mZ;
                app.CalculatedDataStruct.MeanJumpDist.XY = mXY;
                app.CalculatedDataStruct.MeanJumpDist.XYZ = mXYZ;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.MeanJumpDist.X = mX;
                app.FilterCalculatedDataStruct.MeanJumpDist.Y = mY;
                app.FilterCalculatedDataStruct.MeanJumpDist.Z = mZ;
                app.FilterCalculatedDataStruct.MeanJumpDist.XY = mXY;
                app.FilterCalculatedDataStruct.MeanJumpDist.XYZ = mXYZ;
            end
        end
        
        function calculateMeanCumJumpDistances(app, destinationStruc)
            if destinationStruc == "Calc"
                datX = app.CalculatedDataStruct.MeanJumpDist.X{1,2};
                datY = app.CalculatedDataStruct.MeanJumpDist.Y{1,2};
                datZ = app.CalculatedDataStruct.MeanJumpDist.Z{1,2};
                datXY = app.CalculatedDataStruct.MeanJumpDist.XY{1,2};
                datXYZ = app.CalculatedDataStruct.MeanJumpDist.XYZ{1,2};
            elseif destinationStruc == "Filter"
                datX = app.FilterCalculatedDataStruct.MeanJumpDist.X{1,2};
                datY = app.FilterCalculatedDataStruct.MeanJumpDist.Y{1,2};
                datZ = app.FilterCalculatedDataStruct.MeanJumpDist.Z{1,2};
                datXY = app.FilterCalculatedDataStruct.MeanJumpDist.XY{1,2};
                datXYZ = app.FilterCalculatedDataStruct.MeanJumpDist.XYZ{1,2};
            end
            
            mcX = zeros(size(datX,1),2);
            mcY = zeros(size(datX,1),2);
            mcZ = zeros(size(datX,1),2);
            mcXY = zeros(size(datX,1),2);
            mcXYZ = zeros(size(datX,1),2);
            
            tX = 0;
            tY = 0;
            tZ = 0;
            tXY = 0;
            tXYZ = 0;
            
            for i = 1:size(datX,1)
                tX = tX+datX(i,1);
                mcX(i,:) = [i, tX];
                tY = tY+datY(i,1);
                mcY(i,:) = [i, tY];
                tZ = tZ+datZ(i,1);
                mcZ(i,:) = [i, tX];
                tXY = tXY+datXY(i,1);
                mcXY(i,:) = [i, tXY];
                tXYZ = tXYZ+datXYZ(i,1);
                mcXYZ(i,:) = [i, tXYZ];
            end
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.CumMeanJumpDist.X = mcX;
                app.CalculatedDataStruct.CumMeanJumpDist.Y = mcY;
                app.CalculatedDataStruct.CumMeanJumpDist.Z = mcZ;
                app.CalculatedDataStruct.CumMeanJumpDist.XY = mcXY;
                app.CalculatedDataStruct.CumMeanJumpDist.XYZ = mcXYZ;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.CumMeanJumpDist.X = mcX;
                app.FilterCalculatedDataStruct.CumMeanJumpDist.Y = mcY;
                app.FilterCalculatedDataStruct.CumMeanJumpDist.Z = mcZ;
                app.FilterCalculatedDataStruct.CumMeanJumpDist.XY = mcXY;
                app.FilterCalculatedDataStruct.CumMeanJumpDist.XYZ = mcXYZ;
            end
        end
        
        function calculateJumpAngles(app, SingleTrackData, destinationStruc)
            %number of angles
            number = size(SingleTrackData,1)-2;
            anglesxy = zeros(number,1);
            anglesxyz = zeros(number,1);

            for i = 1:number
                %vector1 P1-P2                
                p1xyz = SingleTrackData(i,3:5);
                p2xyz = SingleTrackData(i+1,3:5);
                p3xyz = SingleTrackData(i+2,3:5);
                p1xy = p1xyz;
                p1xy(3) = 0;
                p2xy = p2xyz;
                p2xy(3) = 0;
                p3xy = p3xyz;
                p3xy(3) = 0;
                
                uxy = p2xy-p1xy;
                uxyz = p2xyz-p1xyz;
                vxy = p3xy-p2xy;
                vxyz = p3xyz-p2xyz;

                anglesxy(i) = atan2(norm(cross(uxy,vxy)),dot(uxy,vxy));
                anglesxyz(i) = atan2(norm(cross(uxyz,vxyz)),dot(uxyz,vxyz));
            end
            
            anglesxy = rad2deg(anglesxy);
            anglesxyz = rad2deg(anglesxyz);
            
            if destinationStruc == "Calc" 
                app.CalculatedDataStruct.JumpAngles.XY(end+1,:) = {SingleTrackData(1,1), anglesxy};
                app.CalculatedDataStruct.JumpAngles.XYZ(end+1,:) = {SingleTrackData(1,1), anglesxyz};
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.JumpAngles.XY(end+1,:) = {SingleTrackData(1,1), anglesxy};
                app.FilterCalculatedDataStruct.JumpAngles.XYZ(end+1,:) = {SingleTrackData(1,1), anglesxyz};
            end
        end
        
        function getSwiftDiffusionParamters(app, SingleTrackData, destinationStruc)
            %msd +err, d + err, type
            if destinationStruc == "Calc" 
                app.CalculatedDataStruct.SwiftParams.MSD(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,12)};
                app.CalculatedDataStruct.SwiftParams.MSDerr(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,13)};
                app.CalculatedDataStruct.SwiftParams.D(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,7)};
                app.CalculatedDataStruct.SwiftParams.Derr(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,8)};
                app.CalculatedDataStruct.SwiftParams.type(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,14)};
            %type 0 none 1 immob 2 diff 3 other
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.SwiftParams.MSD(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,12)};
                app.FilterCalculatedDataStruct.SwiftParams.MSDerr(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,13)};
                app.FilterCalculatedDataStruct.SwiftParams.D(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,7)};
                app.FilterCalculatedDataStruct.SwiftParams.Derr(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,8)};
                app.FilterCalculatedDataStruct.SwiftParams.type(end+1,:) = {SingleTrackData(1,1),SingleTrackData(1,14)};
            end
        end
        
        function calculateMSD(app, destinationStruc)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            if destinationStruc == "Calc"
                trackids = unique(app.DataToAnalyze(:,1));
            elseif destinationStruc == "Filter"
                trackids = app.FilterdIDS;
            end
            fitval = app.PointstoFitEditField.Value;
            data = cell(size(trackids,1),1);
            
            if flag3d == 0
                for i = 1:size(trackids,1)
                    if size(app.DataToAnalyze(app.DataToAnalyze(:,1) == trackids(i),1),1) >= fitval
                        data{i} = app.DataToAnalyze(app.DataToAnalyze(:,1) == trackids(i),2:4);
                    end
                end
                lyzer = msdanalyzer(2,"px","frame");
            else 
                for i = 1:size(trackids,1)
                    if size(app.DataToAnalyze(app.DataToAnalyze(:,1) == trackids(i),1),1) >= fitval
                        data{i} = app.DataToAnalyze(app.DataToAnalyze(:,1) == trackids(i),2:5);
                    end
                end
                lyzer = msdanalyzer(3,"px","frame");
            end
            data = data(~cellfun('isempty',data));
            lyzer = addAll(lyzer,data);
            lyzer = computeMSD(lyzer);

            lyzer = fitMSD(lyzer,fitval);
            lyzer = fitLogLogMSD(lyzer,fitval);
            
            if destinationStruc == "Calc"
                if flag3d
                    dimf = 3;
                    app.CalculatedDataStruct.InternMSD.XYZ.MSDclass = lyzer;
                    app.CalculatedDataStruct.InternMSD.XYZ.Alpha = lyzer.loglogfit.alpha;
                    app.CalculatedDataStruct.InternMSD.XYZ.a = lyzer.lfit.a;
                    app.CalculatedDataStruct.InternMSD.XYZ.d = lyzer.lfit.a*1/(2*dimf); 
                else
                    dimf = 2;
                    app.CalculatedDataStruct.InternMSD.XY.MSDclass = lyzer;
                    app.CalculatedDataStruct.InternMSD.XY.Alpha = lyzer.loglogfit.alpha;
                    app.CalculatedDataStruct.InternMSD.XY.a = lyzer.lfit.a;
                    app.CalculatedDataStruct.InternMSD.XY.d = lyzer.lfit.a*1/(2*dimf);  
                end
            elseif destinationStruc == "Filter"
                if flag3d
                    dimf = 3;
                    app.FilterCalculatedDataStruct.InternMSD.XYZ.MSDclass = lyzer;
                    app.FilterCalculatedDataStruct.InternMSD.XYZ.Alpha = lyzer.loglogfit.alpha;
                    app.FilterCalculatedDataStruct.InternMSD.XYZ.a = lyzer.lfit.a;
                    app.FilterCalculatedDataStruct.InternMSD.XYZ.d = lyzer.lfit.a*1/(2*dimf); 
                else
                    dimf = 2;
                    app.FilterCalculatedDataStruct.InternMSD.XY.MSDclass = lyzer;
                    app.FilterCalculatedDataStruct.InternMSD.XY.Alpha = lyzer.loglogfit.alpha;
                    app.FilterCalculatedDataStruct.InternMSD.XY.a = lyzer.lfit.a;
                    app.FilterCalculatedDataStruct.InternMSD.XY.d = lyzer.lfit.a*1/(2*dimf);  
                end
            end
        end
        
        function calculateVelocity(app)
            %here could be your code!
        end
        
        function calculateDrift(app)
            %here could be your code!
        end
        
        function getTotalTrackLengths(app, destinationStruc)
            %add up jumpdist xy(z) for every track
            %check that jumpdist has been calculated is done in plot
            %function
            if destinationStruc == "Calc"
                datXY = app.CalculatedDataStruct.JumpDist.XY;
                datXYZ = app.CalculatedDataStruct.JumpDist.XYZ;
            elseif destinationStruc == "Filter"
                datXY = app.FilterCalculatedDataStruct.JumpDist.XY;
                datXYZ = app.FilterCalculatedDataStruct.JumpDist.XYZ;
            end
            totdistXY = zeros(size(datXY));
            totdistXYZ = zeros(size(datXYZ));
            %using for loop, results in cell array with [id, length]
            for i = 1:size(datXY,1)
                tmpXYdat = sum(datXY{i,2}(:,2));
                tmpXYid = datXY{i,1};
                tmpXYZdat = sum(datXYZ{i,2}(:,2));
                tmpXYZid = datXYZ{i,1};
                
                totdistXY(i,1:2) = [tmpXYid, tmpXYdat];
                totdistXYZ(i,1:2) = [tmpXYZid, tmpXYZdat];
            end
            
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.TrackLength.AbsLength.XY = totdistXY;
                app.CalculatedDataStruct.TrackLength.AbsLength.XYZ = totdistXYZ;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY = totdistXY;
                app.FilterCalculatedDataStruct.TrackLength.AbsLength.XYZ = totdistXYZ;
            end
        end
        
        function calculateNetDistance(app,SingleTrackData, destinationStruc)
            %   s.TrackLength.NetLength = {};
            startframe = 1;
            
            xdist = (SingleTrackData(startframe,3) - SingleTrackData(end,3));
            ydist = (SingleTrackData(startframe,4) - SingleTrackData(end,4));
            zdist = (SingleTrackData(startframe,5) - SingleTrackData(end,5));
            xydist = [SingleTrackData(1,1), sqrt(xdist^2+ydist^2)];
            xyzdist = [SingleTrackData(1,1), sqrt(xdist^2+ydist^2+zdist^2)];
            
            if destinationStruc == "Calc" 
                app.CalculatedDataStruct.TrackLength.NetLength.XY(end+1,:) = {xydist(1,1), xydist(1,2)};
                app.CalculatedDataStruct.TrackLength.NetLength.XYZ(end+1,:) = {xyzdist(1,1), xyzdist(1,2)};
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.NetLength.XY(end+1,:) = {xydist(1,1), xydist(1,2)};
                app.FilterCalculatedDataStruct.TrackLength.NetLength.XYZ(end+1,:) = {xyzdist(1,1), xyzdist(1,2)};
            end
        end
        
        function calculateConfinedRatio(app,destinationStruc)
            %the ratio between total distances walked vs net distances
            if destinationStruc == "Calc"
                totaldatXY = app.CalculatedDataStruct.TrackLength.AbsLength.XY;
                totaldatXYZ = app.CalculatedDataStruct.TrackLength.AbsLength.XYZ;
                netdatXY =  app.CalculatedDataStruct.TrackLength.NetLength.XY;
                netdatXYZ = app.CalculatedDataStruct.TrackLength.NetLength.XYZ;
            elseif destinationStruc == "Filter"
                totaldatXY = app.FilterCalculatedDataStruct.TrackLength.AbsLength.XY;
                totaldatXYZ = app.FilterCalculatedDataStruct.TrackLength.AbsLength.XYZ;
                netdatXY =  app.FilterCalculatedDataStruct.TrackLength.NetLength.XY;
                netdatXYZ = app.FilterCalculatedDataStruct.TrackLength.NetLength.XYZ;
            end
            
            ids = totaldatXYZ(:,1);
            ratioXY = totaldatXY(:,2)./cell2mat(netdatXY(:,2));
            ratioXYZ = totaldatXYZ(:,2)./cell2mat(netdatXYZ(:,2));
            
            ratioXY = [ids, ratioXY];
            ratioXYZ = [ids, ratioXYZ];
            
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.TrackLength.ConfRatio.XY = ratioXY;
                app.CalculatedDataStruct.TrackLength.ConfRatio.XYZ = ratioXYZ;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.ConfRatio.XY = ratioXY;
                app.FilterCalculatedDataStruct.TrackLength.ConfRatio.XYZ = ratioXYZ;
            end 
        end
        
        function calculateCumTrackLength(app, destinationStruc)
            if destinationStruc == "Calc"
                if isempty(app.CalculatedDataStruct.TrackLength.Steps)
                    plotTrackSteps(app, 0)
                end
                data = cell2mat(app.CalculatedDataStruct.TrackLength.Steps);
            elseif destinationStruc == "Filter"
                if isempty(app.FilterCalculatedDataStruct.TrackLength.Steps)
                    plotTrackSteps(app, 1)
                end
                data = cell2mat(app.FilterCalculatedDataStruct.TrackLength.Steps);
            end
            data = data(:,2);
            cumLen = zeros(size(data,1),2)+1;
            for i = 2:size(data,1)
                cumLen(i,:) = [i, data(i)+cumLen(i-1,2)];
            end
            if destinationStruc == "Calc"
            app.CalculatedDataStruct.TrackLength.CumLen = cumLen;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.CumLen = cumLen;
            end
        end
        
        function calculateMeanTrackLength(app, destinationStruc)
            if destinationStruc == "Calc" 
                data = app.CalculatedDataStruct.TrackLength.AbsLength;
            elseif destinationStruc == "Filter"
                data = app.FilterCalculatedDataStruct.TrackLength.AbsLength;
            end
            meanTrackLengthXY = mean(data.XY(:,2));
            meanTrackLengthXYZ = mean(data.XYZ(:,2));
            if destinationStruc == "Calc" 
                app.CalculatedDataStruct.TrackLength.MeanLen.XY = meanTrackLengthXY;
                app.CalculatedDataStruct.TrackLength.MeanLen.XYZ = meanTrackLengthXYZ;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.MeanLen.XY = meanTrackLengthXY;
                app.FilterCalculatedDataStruct.TrackLength.MeanLen.XYZ = meanTrackLengthXY;
            end
        end
        
        function calculateStepNumbers(app, SingleTrackData, destinationStruc)
            if destinationStruc == "Calc"
                trackid = SingleTrackData(1,1);
            elseif destinationStruc == "Filter"
                trackid = SingleTrackData(1,1);
            end
            len = size(SingleTrackData,1);
            
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.TrackLength.Steps(end+1,:) = {trackid, len};
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.Steps(end+1,:) = {trackid, len};
            end
        end
        
        function getRoiSizeVolume(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin)
            app.VisualisationWindow = visualisationwin;
            app.Unitsused = {app.VisualisationWindow.ImportSettingsStruct.customUnits.LengthUnit, app.VisualisationWindow.ImportSettingsStruct.customUnits.TimeUnit};
            app.TrackData = visualisationwin.getTrackDataForAnalysis();
            app.CalculatedDataStruct = app.setSaveStruct();
            app.FilterCalculatedDataStruct = app.setSaveStruct();
            app.IsUnitFlag = 0;
            updateMovieList(app);
            setDataToAnalyze(app);
            manageUnits(app);
            manageExtraPanel(app);
            managePlots(app);
            app.IsUnitFlag = 0;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            setDataToAnalyze(app);
            app.CalculatedDataStruct = app.setSaveStruct();
            app.FilterCalculatedDataStruct = app.setSaveStruct();
            if app.ActiveCheckBox.Value
                grabFilter(app);
            end
            %manageUnits(app);
            manageExtraPanel(app);
            managePlots(app);
        end

        % Selection changed function: SelectionButtonGroup
        function SelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectionButtonGroup.SelectedObject;
            %app.PolAxes = polaraxes();
            manageExtraPanel(app);
            managePlots(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switchUnits(app);            
        end

        % Value changed function: LeftBorderEditField
        function LeftBorderEditFieldValueChanged(app, event)
            value = app.LeftBorderEditField.Value;
            updatePlot(app);
        end

        % Value changed function: RightBorderEditField
        function RightBorderEditFieldValueChanged(app, event)
            value = app.RightBorderEditField.Value;
            updatePlot(app);
        end

        % Value changed function: NumberofBinsEditField
        function NumberofBinsEditFieldValueChanged(app, event)
            value = app.NumberofBinsEditField.Value;
            managePlots(app);
        end

        % Selection changed function: JumpDistancesButtonGroup
        function JumpDistancesButtonGroupSelectionChanged(app, event)
            selectedButton = app.JumpDistancesButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Selection changed function: JumpAnglesButtonGroup
        function JumpAnglesButtonGroupSelectionChanged(app, event)
            selectedButton = app.JumpAnglesButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Selection changed function: DiffParametersButtonGroup
        function DiffParametersButtonGroupSelectionChanged(app, event)
            selectedButton = app.DiffParametersButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            d = uiprogressdlg(app.AllTrackAnalysisUIFigure,'Title','Calculating MSD', 'Indeterminate','on');
            drawnow
            if app.ActiveCheckBox.Value
                destinationStruc = "Filter"
            else
                destinationStruc = "Calc"
            end
            calculateMSD(app, destinationStruc);
            delete(d);
            try
                [minv, maxv] = plotInternMSDValue(app, filterflag);
                setMinMaxValue(app, minv, maxv);
            end
            %managePlots(app);
        end

        % Selection changed function: SelectionButtonGroup_2
        function SelectionButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.SelectionButtonGroup_2.SelectedObject;
            managePlots(app);
        end

        % Button down function: StatisticsTab
        function StatisticsTabButtonDown(app, event)
            managePlots(app);
        end

        % Button pushed function: DataFilterButton
        function DataFilterButtonPushed(app, event)
            if isa(app.DataFilterWindow, "DataFilter")
                app.DataFilterWindow.DataFilterUIFigure.Visible = "on";
            else
                app.DataFilterWindow = DataFilter(app);
            end
            %need to calculate all properties that need to be calculated
            %with the current data
            
        end

        % Close request function: AllTrackAnalysisUIFigure
        function AllTrackAnalysisUIFigureCloseRequest(app, event)
            delete(app.DataFilterWindow);
            delete(app)
            
        end

        % Selection changed function: ConfinementRatioButtonGroup
        function ConfinementRatioButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConfinementRatioButtonGroup.SelectedObject;
            managePlots(app);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            managePlots(app);
        end

        % Value changed function: ActiveCheckBox
        function ActiveCheckBoxValueChanged(app, event)
            value = app.ActiveCheckBox.Value;
            managePlots(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AllTrackAnalysisUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {880, 880};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {350, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AllTrackAnalysisUIFigure = uifigure('Visible', 'off');
            app.AllTrackAnalysisUIFigure.Name = 'All Track Analysis';
            app.AllTrackAnalysisUIFigure.Icon = '3rdPeak1.png';
            app.AllTrackAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @AllTrackAnalysisUIFigureCloseRequest, true);
            app.AllTrackAnalysisUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AllTrackAnalysisUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MovieSelectionPanel = uipanel(app.LeftPanel);
            app.MovieSelectionPanel.Title = 'Movie Selection';
            app.ListBox = uilistbox(app.MovieSelectionPanel);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Tooltip = {'Select a file to analyze. Select "All" for all files in the list.'};
            app.PropertiesPanel = uipanel(app.LeftPanel);
            app.PropertiesPanel.Title = 'Properties';
            app.TabGroup = uitabgroup(app.PropertiesPanel);
            app.MobilityTab = uitab(app.TabGroup);
            app.MobilityTab.Title = 'Mobility';
            app.SelectionButtonGroup = uibuttongroup(app.MobilityTab);
            app.SelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionButtonGroupSelectionChanged, true);
            app.SelectionButtonGroup.Title = 'Selection';
            app.JumpDistancesButton = uiradiobutton(app.SelectionButtonGroup);
            app.JumpDistancesButton.Tooltip = {'Visualizes the Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.JumpDistancesButton.Text = 'Jump Distances';
            app.JumpDistancesButton.Value = true;
            app.CumulativeJumpDistancesButton = uiradiobutton(app.SelectionButtonGroup);
            app.CumulativeJumpDistancesButton.Tooltip = {'Visualizes the Cumulative Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.CumulativeJumpDistancesButton.Text = 'Cumulative Jump Distances';
            app.DiffusionParametersButton = uiradiobutton(app.SelectionButtonGroup);
            app.DiffusionParametersButton.Tooltip = {'Shows the Diffusion parametesr from the Swift calculation.'};
            app.DiffusionParametersButton.Text = 'Diffusion Parameters';
            app.MeanJumpDistancesButton = uiradiobutton(app.SelectionButtonGroup);
            app.MeanJumpDistancesButton.Tooltip = {'Visualizes the Mean Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.MeanJumpDistancesButton.Text = 'Mean Jump Distances';
            app.CumulativeMeanJumpDistancesButton = uiradiobutton(app.SelectionButtonGroup);
            app.CumulativeMeanJumpDistancesButton.Tooltip = {'Visualizes Cumulative Mean the Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.CumulativeMeanJumpDistancesButton.Text = 'Cumulative Mean Jump Distances';
            app.JumpAnglesButton = uiradiobutton(app.SelectionButtonGroup);
            app.JumpAnglesButton.Tooltip = {'Visualizes the Jump Angle Distribution of the data. The Additional Options allow for the selection of the dimensionality of the data.'};
            app.JumpAnglesButton.Text = 'Jump Angles';
            app.MSDfromInternMSDFitButton = uiradiobutton(app.SelectionButtonGroup);
            app.MSDfromInternMSDFitButton.Tooltip = {'Calculates the MSD based on the localisation data anew in the software.'};
            app.MSDfromInternMSDFitButton.Text = 'MSD from Intern MSD Fit';
            app.ClassificationfromSwiftButton = uiradiobutton(app.SelectionButtonGroup);
            app.ClassificationfromSwiftButton.Tooltip = {'Plots the classification of the diffusion type from Swift analysis.'};
            app.ClassificationfromSwiftButton.Text = 'Classification from Swift';
            app.VelocityParametersButton = uiradiobutton(app.SelectionButtonGroup);
            app.VelocityParametersButton.Text = 'Velocity Parameters';
            app.DriftParametersButton = uiradiobutton(app.SelectionButtonGroup);
            app.DriftParametersButton.Text = 'Drift Parameters';
            app.ConfinementRatioButton = uiradiobutton(app.SelectionButtonGroup);
            app.ConfinementRatioButton.Tooltip = {'Visualizes Cumulative Mean the Jump Distance Distribution. The additional options below the figure allow to select the dimensionality of the data.'};
            app.ConfinementRatioButton.Text = 'Confinement Ratio';
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.StatisticsTab.ButtonDownFcn = createCallbackFcn(app, @StatisticsTabButtonDown, true);
            app.SelectionButtonGroup_2 = uibuttongroup(app.StatisticsTab);
            app.SelectionButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SelectionButtonGroup_2SelectionChanged, true);
            app.SelectionButtonGroup_2.Title = 'Selection';
            app.TrackStepsButton = uiradiobutton(app.SelectionButtonGroup_2);
            app.TrackStepsButton.Text = 'Track Steps';
            app.TrackStepsButton.Value = true;
            app.CumulativeTrackLengthButton = uiradiobutton(app.SelectionButtonGroup_2);
            app.CumulativeTrackLengthButton.Text = 'Cumulative Track Length';
            app.AverageTrackLengthButton = uiradiobutton(app.SelectionButtonGroup_2);
            app.AverageTrackLengthButton.Text = 'Average Track Length';
            app.NumberofJumpsPerTrackButton = uiradiobutton(app.SelectionButtonGroup_2);
            app.NumberofJumpsPerTrackButton.Text = 'Number of Jumps Per Track';
            app.ROISizeButton = uiradiobutton(app.SelectionButtonGroup_2);
            app.ROISizeButton.Text = 'ROI Size';
            app.HistogramSettingsPanel = uipanel(app.LeftPanel);
            app.HistogramSettingsPanel.Title = 'Histogram Settings';
            app.LeftBorderEditFieldLabel = uilabel(app.HistogramSettingsPanel);
            app.LeftBorderEditFieldLabel.Text = 'Left Border';
            app.LeftBorderEditField = uieditfield(app.HistogramSettingsPanel, 'numeric');
            app.LeftBorderEditField.ValueChangedFcn = createCallbackFcn(app, @LeftBorderEditFieldValueChanged, true);
            app.RightBorderEditFieldLabel = uilabel(app.HistogramSettingsPanel);
            app.RightBorderEditFieldLabel.Text = 'Right Border';
            app.RightBorderEditField = uieditfield(app.HistogramSettingsPanel, 'numeric');
            app.RightBorderEditField.ValueChangedFcn = createCallbackFcn(app, @RightBorderEditFieldValueChanged, true);
            app.RightBorderEditField.Value = 100;
            app.NumberofBinsEditFieldLabel = uilabel(app.HistogramSettingsPanel);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.HistogramSettingsPanel, 'numeric');
            app.NumberofBinsEditField.Limits = [1 Inf];
            app.NumberofBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBinsEditFieldValueChanged, true);
            app.NumberofBinsEditField.Value = 20;
            app.DataFilterPanel = uipanel(app.LeftPanel);
            app.DataFilterPanel.Title = 'Data Filter';
            app.DataFilterButton = uibutton(app.DataFilterPanel, 'push');
            app.DataFilterButton.ButtonPushedFcn = createCallbackFcn(app, @DataFilterButtonPushed, true);
            app.DataFilterButton.Text = 'Data Filter';
            app.ActiveCheckBox = uicheckbox(app.DataFilterPanel);
            app.ActiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActiveCheckBoxValueChanged, true);
            app.ActiveCheckBox.Text = 'Active';
            app.ExportDataPanel = uipanel(app.LeftPanel);
            app.ExportDataPanel.Title = 'Export Data';
            app.SetSaveFolderButton = uibutton(app.ExportDataPanel, 'push');
            app.SetSaveFolderButton.Text = 'Set Save Folder';
            app.FoldernotSetLabel = uilabel(app.ExportDataPanel);
            app.FoldernotSetLabel.Text = 'Folder not Set';
            app.SavetocsvButton = uibutton(app.ExportDataPanel, 'push');
            app.SavetocsvButton.Text = 'Save to csv';
            app.SavetomatButton = uibutton(app.ExportDataPanel, 'push');
            app.SavetomatButton.Text = 'Save to mat';
            app.RightPanel = uipanel(app.GridLayout);
            app.AdditionalOptionsPanel = uipanel(app.RightPanel);
            app.AdditionalOptionsPanel.Title = 'Additional Options';
            app.JumpDistancesButtonGroup = uibuttongroup(app.AdditionalOptionsPanel);
            app.JumpDistancesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JumpDistancesButtonGroupSelectionChanged, true);
            app.JumpDistancesButtonGroup.Title = 'Jump Distances';
            app.XButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.YButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.ZButton.Text = 'Z';
            app.XYButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.XYButton.Text = 'XY';
            app.XYZButton = uiradiobutton(app.JumpDistancesButtonGroup);
            app.XYZButton.Text = 'XYZ';
            app.JumpAnglesButtonGroup = uibuttongroup(app.AdditionalOptionsPanel);
            app.JumpAnglesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @JumpAnglesButtonGroupSelectionChanged, true);
            app.JumpAnglesButtonGroup.Title = 'Jump Angles';
            app.XYButton_2 = uiradiobutton(app.JumpAnglesButtonGroup);
            app.XYButton_2.Text = 'XY';
            app.XYButton_2.Value = true;
            app.XYZButton_2 = uiradiobutton(app.JumpAnglesButtonGroup);
            app.XYZButton_2.Text = 'XYZ';
            app.MSDPanel = uipanel(app.AdditionalOptionsPanel);
            app.MSDPanel.Title = 'MSD';
            app.PointstoFitEditFieldLabel = uilabel(app.MSDPanel);
            app.PointstoFitEditFieldLabel.Text = 'Points to Fit';
            app.PointstoFitEditField = uieditfield(app.MSDPanel, 'numeric');
            app.PointstoFitEditField.Limits = [2 Inf];
            app.PointstoFitEditField.Value = 5;
            app.ComputeButton = uibutton(app.MSDPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.Switch_2 = uiswitch(app.MSDPanel, 'slider');
            app.Switch_2.Items = {'2D', '3D'};
            app.Switch_2.Value = '2D';
            app.ButtonGroup = uibuttongroup(app.MSDPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AlphaButton = uiradiobutton(app.ButtonGroup);
            app.AlphaButton.Text = 'Alpha';
            app.AlphaButton.Value = true;
            app.MSDButton_2 = uiradiobutton(app.ButtonGroup);
            app.MSDButton_2.Text = 'MSD';
            app.DButton_2 = uiradiobutton(app.ButtonGroup);
            app.DButton_2.Text = 'D';
            app.UnitsPanel = uipanel(app.AdditionalOptionsPanel);
            app.UnitsPanel.Title = 'Units';
            app.Switch = uiswitch(app.UnitsPanel, 'slider');
            app.Switch.Items = {'Pixel/Frame', 'Unit/Unit'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Pixel/Frame';
            app.DiffParametersButtonGroup = uibuttongroup(app.AdditionalOptionsPanel);
            app.DiffParametersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DiffParametersButtonGroupSelectionChanged, true);
            app.DiffParametersButtonGroup.Title = 'Diff Parameters';
            app.MSDButton = uiradiobutton(app.DiffParametersButtonGroup);
            app.MSDButton.Text = 'MSD';
            app.MSDButton.Value = true;
            app.DButton = uiradiobutton(app.DiffParametersButtonGroup);
            app.DButton.Text = 'D';
            app.ConfinementRatioButtonGroup = uibuttongroup(app.AdditionalOptionsPanel);
            app.ConfinementRatioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConfinementRatioButtonGroupSelectionChanged, true);
            app.ConfinementRatioButtonGroup.Title = 'Confinement Ratio';
            app.RatioLengthNetLengthButton = uiradiobutton(app.ConfinementRatioButtonGroup);
            app.RatioLengthNetLengthButton.Text = 'Ratio (Length/NetLength)';
            app.RatioLengthNetLengthButton.Value = true;
            app.LengthButton = uiradiobutton(app.ConfinementRatioButtonGroup);
            app.LengthButton.Text = 'Length';
            app.NetLengthButton = uiradiobutton(app.ConfinementRatioButtonGroup);
            app.NetLengthButton.Text = 'Net Length';
            app.Switch_3 = uiswitch(app.ConfinementRatioButtonGroup, 'slider');
            app.Switch_3.Items = {'2D', '3D'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '2D';
            app.Panel = uipanel(app.RightPanel);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.AllTrackAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AllTrackAnalysisWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AllTrackAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AllTrackAnalysisUIFigure)
        end
    end
end