classdef ImportSettingsPreprocessingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportSettingsUIFigure          matlab.ui.Figure
        SetSettingsButton               matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UnitDataConstellationButtonGroup  matlab.ui.container.ButtonGroup
        UnitUnitButton                  matlab.ui.control.RadioButton
        UnitFrameButton                 matlab.ui.control.RadioButton
        PixelUnitButton                 matlab.ui.control.RadioButton
        PixelFrameButton                matlab.ui.control.RadioButton
        TimeUnitEditField               matlab.ui.control.EditField
        TimeUnitEditFieldLabel          matlab.ui.control.Label
        MetricLengthUnitEditField       matlab.ui.control.EditField
        MetricLengthUnitEditFieldLabel  matlab.ui.control.Label
        TimestepinUnitEditField         matlab.ui.control.NumericEditField
        TimestepinUnitEditFieldLabel    matlab.ui.control.Label
        PixelsizeinUnitEditField        matlab.ui.control.NumericEditField
        PixelsizeinUnitEditFieldLabel   matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        CustomDataTypeRowContentsPanel  matlab.ui.container.Panel
        BackgroundEditField             matlab.ui.control.NumericEditField
        BackgroundEditFieldLabel        matlab.ui.control.Label
        PhotonErrorEditField            matlab.ui.control.NumericEditField
        PhotonErrorEditFieldLabel       matlab.ui.control.Label
        PhotonsEditField                matlab.ui.control.NumericEditField
        PhotonsEditFieldLabel           matlab.ui.control.Label
        ZErrorEditField                 matlab.ui.control.NumericEditField
        ZErrorEditFieldLabel            matlab.ui.control.Label
        XYErrorEditField                matlab.ui.control.NumericEditField
        XYErrorEditFieldLabel           matlab.ui.control.Label
        SkipheaderLinesEditField        matlab.ui.control.NumericEditField
        SkipheaderLinesEditFieldLabel   matlab.ui.control.Label
        RowNumberLabel_2                matlab.ui.control.Label
        RowNumberLabel                  matlab.ui.control.Label
        SeparatorEditField              matlab.ui.control.EditField
        SeparatorEditFieldLabel         matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        FrameNrEditField                matlab.ui.control.NumericEditField
        FrameNrEditFieldLabel           matlab.ui.control.Label
        ImportSettingsLabel             matlab.ui.control.Label
        ImportDataTypeButtonGroup       matlab.ui.container.ButtonGroup
        PicassoLocalisationsButton      matlab.ui.control.RadioButton
        CustomLocalisationscsvButton    matlab.ui.control.RadioButton
        CustomLocalisationsmatButton    matlab.ui.control.RadioButton
        SMAPLocalisationsButton         matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        PreprocessWindow % Link to Preprocess Window
        ImportSettingsStruct % A Struct to save all the import settings set on this window
        OldSettingsStruct %Struct that might hold old settings that have been used before in this session
    end
    
    methods (Access = private)
        
         function app = checkImportDataType(app)
            %Depending on the file type used, one does need the extra
            %options or not, so the fields needed will be controlled by
            %this function
            if app.PicassoLocalisationsButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
                app.PixelFrameButton.Enable = "off";
                app.PixelUnitButton.Enable = "off";
            end
            if app.SMAPLocalisationsButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
                app.PixelFrameButton.Enable = "off";
                app.PixelUnitButton.Enable = "off";
            end
            if app.CustomLocalisationsmatButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.PixelFrameButton.Enable = "on";
                app.PixelUnitButton.Enable = "on";
            end
            if app.CustomLocalisationscsvButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.PixelFrameButton.Enable = "on";
                app.PixelUnitButton.Enable = "on";
            end
        end
        
        function app = adjustUnitName(app)
            %Puts the name of the units set in the edit field in the
            %respective labels of the app to make association more easily
            %(hopefully)
            metricUnit = app.MetricLengthUnitEditField.Value;
            timeUnit = app.TimeUnitEditField.Value;
            %Split the strings
            txt1 = split(convertCharsToStrings(app.PixelUnitButton.Text),"/");
            txt2 = split(convertCharsToStrings(app.UnitFrameButton.Text),"/");
            txt3 = split(convertCharsToStrings(app.UnitUnitButton.Text),"/");
            txt4 = split(convertCharsToStrings(app.PixelsizeinUnitEditFieldLabel.Text));
            txt5 = split(convertCharsToStrings(app.TimestepinUnitEditFieldLabel.Text));
            %add the unit name
            txt1(2) = timeUnit;
            txt2(1) = metricUnit;
            txt3(1) = metricUnit;
            txt3(2) = timeUnit;
            txt4(end) = metricUnit;
            txt5(end) = timeUnit;
            %join the strings and put them into place
            txt1 = join(txt1, "/");
            txt2 = join(txt2, "/");
            txt3 = join(txt3, "/");
            txt4 = join(txt4);
            txt5 = join(txt5);
            app.PixelUnitButton.Text = convertStringsToChars(txt1);
            app.UnitFrameButton.Text = convertStringsToChars(txt2);
            app.UnitUnitButton.Text = convertStringsToChars(txt3);
            app.PixelsizeinUnitEditFieldLabel.Text = convertStringsToChars(txt4);
            app.TimestepinUnitEditFieldLabel.Text = convertStringsToChars(txt5);
        end
        
        function app = checkSettingsChanged(app)
            %check if settings have changed. Will lead to clearance of data
            %in main app to be ready for new data type
            if isa(app.OldSettingsStruct, "struct")
                %there is an old setting
                if isequaln(app.OldSettingsStruct, app.ImportSettingsStruct)
                    %its the same as the old one
                    app.PreprocessWindow.setSettingsChanged(0);
                else
                    %settings have changed
                    app.PreprocessWindow.setSettingsChanged(1);
                end
            else
                %no settings set before, therefore no change
                app.PreprocessWindow.setSettingsChanged(0);
            end
        end
        
        function structure = packImportSettings(app)
            %Packs all the settings in the window into one struct that will
            %be passed back to the Validation app if the window is closed
            %via save-button or set button
            cont.type = app.ImportDataTypeButtonGroup.SelectedObject.Text;
            cont.islocs = 1;
            %Units
            cont.customUnits.DataType = app.UnitDataConstellationButtonGroup.SelectedObject.Text;
            cont.customUnits.LengthUnit = app.MetricLengthUnitEditField.Value;
            cont.customUnits.TimeUnit = app.TimeUnitEditField.Value;
            cont.customUnits.Pixelsize = app.PixelsizeinUnitEditField.Value;
            cont.customUnits.Timestep = app.TimestepinUnitEditField.Value;
            %Custom CSV, mat file structure
            %Import for CSV
            cont.separator = app.SeparatorEditField.Value;
            cont.headlerlines = app.SkipheaderLinesEditField.Value;
            %Column organisation
            cont.framenr = app.FrameNrEditField.Value;
            cont.xpos = app.XPositionEditField.Value;
            cont.xyerr = app.XYErrorEditField.Value;
            cont.zerr = app.ZErrorEditField.Value;
            cont.ypos = app.YPositionEditField.Value;
            cont.zpos = app.ZPositionEditField.Value;
            cont.int = app.PhotonsEditField.Value;
            cont.interr = app.PhotonErrorEditField.Value;
            cont.bg = app.BackgroundEditField.Value;
            
            structure = cont;
        end
        
        function loadSettings(app, loadfile)
            %make sure that a settings file is actually loaded
            if isfield(loadfile,"xyerr") && isfield(loadfile,"customUnits")
            else
                error("Not a preprocessing import settings file!")
            end
            
            app.ImportSettingsStruct = loadfile;
            
            %Change the settings in the window
            switch loadfile.type
                case "SMAP-Localisations"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SMAPLocalisationsButton;
                case "Custom Localisations mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomLocalisationsmatButton;
                case "Custom Localisations csv"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomLocalisationscsvButton;
                case "Picasso-Localisations"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.PicassoLocalisationsButton;
            end
            %adjust the values in the window according to the saved
            %settings
            impdattype = split(convertCharsToStrings(loadfile.customUnits.DataType),"/");
            comparison = strcmp(impdattype, ["Pixel"; "Frame"]);
            if comparison %both a true
                app.UnitDataConstellationButtonGroup.SelectedObject = app.PixelFrameButton;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                app.UnitDataConstellationButtonGroup.SelectedObject = app.PixelUnitButton;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                app.UnitDataConstellationButtonGroup.SelectedObject = app.UnitFrameButton;
            else
                app.UnitDataConstellationButtonGroup.SelectedObject = app.UnitUnitButton;
            end
            %unit settings
            app.MetricLengthUnitEditField.Value = loadfile.customUnits.LengthUnit;
            app.TimeUnitEditField.Value = loadfile.customUnits.TimeUnit;
            app.PixelsizeinUnitEditField.Value = loadfile.customUnits.Pixelsize;
            app.TimestepinUnitEditField.Value = loadfile.customUnits.Timestep;
            %csv import settings
            app.SeparatorEditField.Value = loadfile.separator;
            app.SkipheaderLinesEditField.Value = loadfile.headlerlines;
            %data format settings
            app.FrameNrEditField.Value = loadfile.framenr;
            app.XPositionEditField.Value = loadfile.xpos;
            app.XYErrorEditField.Value = loadfile.xyerr;
            app.YPositionEditField.Value = loadfile.ypos;
            app.ZErrorEditField.Value = loadfile.zerr;
            app.ZPositionEditField.Value = loadfile.zpos;
            app.PhotonsEditField.Value = loadfile.int;
            app.PhotonErrorEditField.Value = loadfile.interr;
            app.BackgroundEditField.Value = loadfile.bg;
        end
    end
    
    methods (Access = public)
        
        function SettingsStruct = getSettings(app)
            SettingsStruct = app.ImportSettingsStruct;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, preprocesswin)
            %set link to calling app
            app.PreprocessWindow = preprocesswin;
            %Set the unit names
            app.adjustUnitName();
            %For standart smap import, the custom data type field is not needed
            app.CustomDataTypeRowContentsPanel.Enable = "off";
            children = app.CustomDataTypeRowContentsPanel.Children;
            set(children, "Enable", "off");
        end

        % Close request function: ImportSettingsUIFigure
        function ImportSettingsUIFigureCloseRequest(app, event)
            app.PreprocessWindow.SettingsButton.Enable = "on";
            %we keep the window if we want to change the settings later
            app.ImportSettingsUIFigure.Visible = "off";
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.ImportSettingsStruct = packImportSettings(app);
            %saves the settings also in a folder of choise so one might
            %import it later.
            filterspec = {'*.mat'};
            [f, p] = uiputfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end

            fulfile = fullfile(p,f);
            settings = app.ImportSettingsStruct;
            save(fulfile, "settings");
            
            checkSettingsChanged(app);
            app.OldSettingsStruct = app.ImportSettingsStruct;
            %returns control to the calling window
            app.PreprocessWindow.SettingsButton.Enable = "on";
            app.PreprocessWindow.SelectFolderButton.Enable = "off";
            app.PreprocessWindow.SelectMainFolderButton.Enable = "off";
            app.ImportSettingsUIFigure.Visible = "off";
            
            app.PreprocessWindow.updateSettings();
        end

        % Selection changed function: ImportDataTypeButtonGroup
        function ImportDataTypeButtonGroupSelectionChanged(app, event)
            checkImportDataType(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            %Loads a saved settings file
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            loadfile = loadfile.settings;
            %checks if settings are different
            try
                app.OldSettingsStruct = app.ImportSettingsStruct;
            catch
            end
            loadSettings(app, loadfile);
            checkSettingsChanged(app);
            
            checkImportDataType(app);
            app.adjustUnitName();
        end

        % Button pushed function: SetSettingsButton
        function SetSettingsButtonPushed(app, event)
            %Packs the current settings
            app.ImportSettingsStruct = packImportSettings(app);
            %checks if settings have changed
            checkSettingsChanged(app);
            app.OldSettingsStruct = app.ImportSettingsStruct;
            %enables the gui and hides the settings
            app.PreprocessWindow.SettingsButton.Enable = "on";
            app.PreprocessWindow.SelectFilesButton.Enable = "on";
            app.PreprocessWindow.Switch.Enable = "on";
            %SideloadButtons
            app.PreprocessWindow.LoadmaskedfilesButton.Enable = "on";
            app.PreprocessWindow.DataFilterPanel.Enable = "on";
            app.PreprocessWindow.LoadprecisionfilteredfilesButton.Enable = "on";
            app.PreprocessWindow.DriftCorrectionPanel.Enable = "on";
            app.PreprocessWindow.LoadintesityfilteredfilesButton.Enable = "on";
            app.PreprocessWindow.SwiftAutomatedTrackingPanel.Enable = "on";
            app.PreprocessWindow.LoaddriftcorrectedfilesButton.Enable = "on";

            app.ImportSettingsUIFigure.Visible = "off";
            %sends the settings to calling app
            app.PreprocessWindow.updateSettings();
        end

        % Value changed function: MetricLengthUnitEditField
        function MetricLengthUnitEditFieldValueChanged(app, event)
            value = app.MetricLengthUnitEditField.Value;
            app.adjustUnitName();
        end

        % Value changed function: TimeUnitEditField
        function TimeUnitEditFieldValueChanged(app, event)
            value = app.TimeUnitEditField.Value;
            app.adjustUnitName();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportSettingsUIFigure = uifigure('Visible', 'off');
            app.ImportSettingsUIFigure.Name = 'Import Settings';
            app.ImportSettingsUIFigure.Icon = '3rdPeak1.png';
            app.ImportSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportSettingsUIFigureCloseRequest, true);
            app.ImportDataTypeButtonGroup = uibuttongroup(app.ImportSettingsUIFigure);
            app.ImportDataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImportDataTypeButtonGroupSelectionChanged, true);
            app.ImportDataTypeButtonGroup.Title = 'Import Data Type';
            app.SMAPLocalisationsButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SMAPLocalisationsButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.SMAPLocalisationsButton.Text = 'SMAP-Localisations';
            app.SMAPLocalisationsButton.Value = true;
            app.CustomLocalisationsmatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomLocalisationsmatButton.Tooltip = {'Custom .mat File containing Localisation data following the pattern given below.'};
            app.CustomLocalisationsmatButton.Text = 'Custom Localisations mat';
            app.CustomLocalisationscsvButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomLocalisationscsvButton.Tooltip = {'Custom .csv File containing Localisation data following the pattern given below.'};
            app.CustomLocalisationscsvButton.Text = 'Custom Localisations csv';
            app.PicassoLocalisationsButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.PicassoLocalisationsButton.Tooltip = {'Localisation data made in Picasso as a .hdf5 file. Picasso data will be interet as Unit/Frame.'};
            app.PicassoLocalisationsButton.Text = 'Picasso-Localisations';
            app.ImportSettingsLabel = uilabel(app.ImportSettingsUIFigure);
            app.ImportSettingsLabel.Text = 'Import Settings';
            app.CustomDataTypeRowContentsPanel = uipanel(app.ImportSettingsUIFigure);
            app.CustomDataTypeRowContentsPanel.Title = 'Custom Data Type - Row Contents';
            app.FrameNrEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.FrameNrEditFieldLabel.Text = 'Frame Nr.';
            app.FrameNrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.FrameNrEditField.Value = 1;
            app.XPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.XPositionEditField.Value = 2;
            app.YPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.YPositionEditField.Value = 3;
            app.ZPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ZPositionEditField.Value = 4;
            app.SeparatorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SeparatorEditFieldLabel.Text = 'Separator';
            app.SeparatorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'text');
            app.SeparatorEditField.Value = ',';
            app.RowNumberLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel.Text = 'Row Number';
            app.RowNumberLabel_2 = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel_2.Text = 'Row Number';
            app.SkipheaderLinesEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SkipheaderLinesEditFieldLabel.Text = 'Skip header Lines';
            app.SkipheaderLinesEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.SkipheaderLinesEditField.Value = 9;
            app.XYErrorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.XYErrorEditFieldLabel.Text = 'XY Error';
            app.XYErrorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.XYErrorEditField.Value = 5;
            app.ZErrorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ZErrorEditFieldLabel.Text = 'Z Error';
            app.ZErrorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ZErrorEditField.Value = 6;
            app.PhotonsEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.PhotonsEditFieldLabel.Text = 'Photons';
            app.PhotonsEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.PhotonsEditField.Value = 7;
            app.PhotonErrorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.PhotonErrorEditFieldLabel.Text = 'Photon Error';
            app.PhotonErrorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.PhotonErrorEditField.Value = 8;
            app.BackgroundEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.BackgroundEditFieldLabel.Text = 'Background';
            app.BackgroundEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.BackgroundEditField.Value = 9;
            app.SaveSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Tooltip = {'Will set the import settings for the calling app. It will also open a dialogue to select a save location for the selected properties. After that the window will close.'};
            app.SaveSettingsButton.Text = 'Save Settings';
            app.Panel = uipanel(app.ImportSettingsUIFigure);
            app.Panel.Title = 'Import Data Units';
            app.PixelsizeinUnitEditFieldLabel = uilabel(app.Panel);
            app.PixelsizeinUnitEditFieldLabel.Text = 'Pixel size in Unit';
            app.PixelsizeinUnitEditField = uieditfield(app.Panel, 'numeric');
            app.PixelsizeinUnitEditField.Limits = [0.0001 Inf];
            app.PixelsizeinUnitEditField.Tooltip = {'Your Pixelsize in the given length unit.'};
            app.PixelsizeinUnitEditField.Value = 160;
            app.TimestepinUnitEditFieldLabel = uilabel(app.Panel);
            app.TimestepinUnitEditFieldLabel.Text = 'Timestep in Unit';
            app.TimestepinUnitEditField = uieditfield(app.Panel, 'numeric');
            app.TimestepinUnitEditField.Limits = [1e-05 Inf];
            app.TimestepinUnitEditField.Tooltip = {'Time between consectuive frames in the given time unit'};
            app.TimestepinUnitEditField.Value = 40;
            app.MetricLengthUnitEditFieldLabel = uilabel(app.Panel);
            app.MetricLengthUnitEditFieldLabel.Text = 'Metric Length Unit';
            app.MetricLengthUnitEditField = uieditfield(app.Panel, 'text');
            app.MetricLengthUnitEditField.ValueChangedFcn = createCallbackFcn(app, @MetricLengthUnitEditFieldValueChanged, true);
            app.MetricLengthUnitEditField.Tooltip = {'Enter the metic length unit abbreviation of your xyz data. Usually mm, µm or nm.'};
            app.MetricLengthUnitEditField.Value = 'nm';
            app.TimeUnitEditFieldLabel = uilabel(app.Panel);
            app.TimeUnitEditFieldLabel.Text = 'Time Unit';
            app.TimeUnitEditField = uieditfield(app.Panel, 'text');
            app.TimeUnitEditField.ValueChangedFcn = createCallbackFcn(app, @TimeUnitEditFieldValueChanged, true);
            app.TimeUnitEditField.Tooltip = {'Enter the unit of time that matches the timestep used, e.g ns, ms, µs, s.'};
            app.TimeUnitEditField.Value = 'ms';
            app.UnitDataConstellationButtonGroup = uibuttongroup(app.Panel);
            app.UnitDataConstellationButtonGroup.Tooltip = {'Panel to determine the format of the data that will be loaded.'};
            app.UnitDataConstellationButtonGroup.Title = 'Unit Data Constellation';
            app.PixelFrameButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.PixelFrameButton.Enable = 'off';
            app.PixelFrameButton.Text = 'Pixel/Frame';
            app.PixelUnitButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.PixelUnitButton.Enable = 'off';
            app.PixelUnitButton.Text = 'Pixel/Unit';
            app.UnitFrameButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.UnitFrameButton.Text = 'Unit/Frame';
            app.UnitFrameButton.Value = true;
            app.UnitUnitButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.UnitUnitButton.Text = 'Unit/Unit';
            app.LoadSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Tooltip = {'Will open a file selecte dialogue to allow for loading of settings that have ben previously saved. You will need to "Set" the settings for them to be used by the software.'};
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SetSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.SetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetSettingsButtonPushed, true);
            app.SetSettingsButton.Tooltip = {'Will set the import settings for the calling app. This will close the settings window and will not save the options.'};
            app.SetSettingsButton.Text = 'Set Settings';
            % Show the figure after all components are created
            app.ImportSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportSettingsPreprocessingWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportSettingsUIFigure)
        end
    end
end