classdef ImportSettingsValidationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportSettingsUIFigure          matlab.ui.Figure
        CustomDataTypeRowContentsPanel  matlab.ui.container.Panel
        TrackIDEditField                matlab.ui.control.NumericEditField
        TrackIDEditField_2Label         matlab.ui.control.Label
        DiffusionTypeStatesEditField    matlab.ui.control.EditField
        DiffusionTypeStatesEditFieldLabel  matlab.ui.control.Label
        TrackLengthEditField            matlab.ui.control.NumericEditField
        TrackLengthEditFieldLabel       matlab.ui.control.Label
        DiffusionTypeEditField          matlab.ui.control.NumericEditField
        DiffusionTypeEditField_2Label   matlab.ui.control.Label
        MeanJumpDistErrEditField        matlab.ui.control.NumericEditField
        MeanJumpDistErrEditFieldLabel   matlab.ui.control.Label
        MeanJumpDistEditField           matlab.ui.control.NumericEditField
        MeanJumpDistEditFieldLabel      matlab.ui.control.Label
        DerrorEditField                 matlab.ui.control.NumericEditField
        DerrorEditFieldLabel            matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.NumericEditField
        IntensityEditField_2Label       matlab.ui.control.Label
        JumpDistanceEditField           matlab.ui.control.NumericEditField
        JumpDistanceEditFieldLabel      matlab.ui.control.Label
        SkipHeaderLinesEditField        matlab.ui.control.NumericEditField
        SkipHeaderLinesEditFieldLabel   matlab.ui.control.Label
        RowNumberLabel_4                matlab.ui.control.Label
        RowNumberLabel_3                matlab.ui.control.Label
        SeparatorEditField              matlab.ui.control.EditField
        SeparatorEditField_2Label       matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditField_2Label               matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditField_2Label       matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditField_2Label       matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditField_2Label       matlab.ui.control.Label
        FrameNrEditField                matlab.ui.control.NumericEditField
        FrameNrEditField_2Label         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        SetSettingsButton               matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        UnitDataConstellationButtonGroup  matlab.ui.container.ButtonGroup
        UnitUnitButton                  matlab.ui.control.RadioButton
        UnitFrameButton                 matlab.ui.control.RadioButton
        PixelUnitButton                 matlab.ui.control.RadioButton
        PixelFrameButton                matlab.ui.control.RadioButton
        TimeUnitEditField               matlab.ui.control.EditField
        TimeUnitEditFieldLabel          matlab.ui.control.Label
        MetricLengthUnitEditField       matlab.ui.control.EditField
        MetricLengthUnitEditFieldLabel  matlab.ui.control.Label
        TimestepinUnitEditField         matlab.ui.control.NumericEditField
        TimestepinUnitEditFieldLabel    matlab.ui.control.Label
        PixelsizeinUnitEditField        matlab.ui.control.NumericEditField
        PixelsizeinUnitEditFieldLabel   matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        ImportSettingsLabel             matlab.ui.control.Label
        ImportDataTypeButtonGroup       matlab.ui.container.ButtonGroup
        PicassoLocalisationsButton      matlab.ui.control.RadioButton
        CustomLocalisationscsvButton    matlab.ui.control.RadioButton
        CustomLocalisationsmatButton    matlab.ui.control.RadioButton
        SMAPLocalisationsButton         matlab.ui.control.RadioButton
        CustomTrackdatamatButton        matlab.ui.control.RadioButton
        CustomTrackdataCSVButton        matlab.ui.control.RadioButton
        SwiftMinDataCSVButton           matlab.ui.control.RadioButton
        SwiftAllDataCSVButton           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        ValidationWindow % Link to Validation Window
        ImportSettingsStruct % A Struct to save all the import settings set on this window
        OldSettingsStruct %Struct that might hold old settings that have been used before in this session
    end
    
    methods (Access = private)
        
        function app = checkImportDataType(app)
            %Depending on the file type used, one does need the extra
            %options or not, so the fields needed will be controlled by
            %this function
            if app.SwiftAllDataCSVButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.SwiftMinDataCSVButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.CustomTrackdataCSVButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
            if app.CustomTrackdatamatButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.SeparatorEditField.Enable = "off";
                app.SkipHeaderLinesEditField.Enable = "off";
            end
            if app.SMAPLocalisationsButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.PicassoLocalisationsButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
                app.PixelFrameButton.Enable = "off";
                app.PixelUnitButton.Enable = "off";
            end
            if app.CustomLocalisationsmatButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.TrackIDEditField.Enable = "off";
                app.JumpDistanceEditField.Enable = "off";
                app.DEditField.Enable = "off";
                app.DerrorEditField.Enable = "off";
                app.SeparatorEditField.Enable = "off";
                app.SkipHeaderLinesEditField.Enable = "off";
                app.TrackLengthEditField.Enable = "off";
                app.MeanJumpDistEditField.Enable = "off";
                app.MeanJumpDistErrEditField.Enable = "off";
                app.DiffusionTypeEditField.Enable = "off";
                app.DiffusionTypeStatesEditField.Enable = "off";
            end
            if app.CustomLocalisationscsvButton.Value == 1
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
                app.TrackIDEditField.Enable = "off";
                app.JumpDistanceEditField.Enable = "off";
                app.DEditField.Enable = "off";
                app.DerrorEditField.Enable = "off";
                app.SeparatorEditField.Enable = "off";
                app.SkipHeaderLinesEditField.Enable = "off";
                app.TrackLengthEditField.Enable = "off";
                app.MeanJumpDistEditField.Enable = "off";
                app.MeanJumpDistErrEditField.Enable = "off";
                app.DiffusionTypeEditField.Enable = "off";
                app.DiffusionTypeStatesEditField.Enable = "off";
            end
        end
        
        function app = adjustUnitName(app)
            %Puts the name of the units set in the edit field in the
            %respective labels of the app to make association more easily
            %(hopefully)
            metricUnit = app.MetricLengthUnitEditField.Value;
            timeUnit = app.TimeUnitEditField.Value;
            %Split the strings
            txt1 = split(convertCharsToStrings(app.PixelUnitButton.Text),"/");
            txt2 = split(convertCharsToStrings(app.UnitFrameButton.Text),"/");
            txt3 = split(convertCharsToStrings(app.UnitUnitButton.Text),"/");
            txt4 = split(convertCharsToStrings(app.PixelsizeinUnitEditFieldLabel.Text));
            txt5 = split(convertCharsToStrings(app.TimestepinUnitEditFieldLabel.Text));
            %add the unit name
            txt1(2) = timeUnit;
            txt2(1) = metricUnit;
            txt3(1) = metricUnit;
            txt3(2) = timeUnit;
            txt4(end) = metricUnit;
            txt5(end) = timeUnit;
            %join the strings and put them into place
            txt1 = join(txt1, "/");
            txt2 = join(txt2, "/");
            txt3 = join(txt3, "/");
            txt4 = join(txt4);
            txt5 = join(txt5);
            app.PixelUnitButton.Text = convertStringsToChars(txt1);
            app.UnitFrameButton.Text = convertStringsToChars(txt2);
            app.UnitUnitButton.Text = convertStringsToChars(txt3);
            app.PixelsizeinUnitEditFieldLabel.Text = convertStringsToChars(txt4);
            app.TimestepinUnitEditFieldLabel.Text = convertStringsToChars(txt5);
        end
        
        function app = checkSettingsChanged(app)
            %check if settings have changed. Will lead to clearance of data
            %in main app to be ready for new data type
            if isa(app.OldSettingsStruct, "struct")
                %there is an old setting
                if isequaln(app.OldSettingsStruct, app.ImportSettingsStruct)
                    %its the same as the old one
                    app.ValidationWindow.setSettingsChanged(0);
                else
                    %settings have changed
                    app.ValidationWindow.setSettingsChanged(1);
                end
            else
                %no settings set before, therefore no change
                app.ValidationWindow.setSettingsChanged(0);
            end
        end
        
        function structure = packImportSettings(app)
            %Packs all the settings in the window into one struct that will
            %be passed back to the Validation app if the window is closed
            %via save-button
            cont.type = app.ImportDataTypeButtonGroup.SelectedObject.Text;
            if cont.type == "SMAP-Localisations" || cont.type == "Picasso-Localisations" || cont.type == "Custom Localisations mat" || cont.type == "Custom Localisations csv"
                cont.islocs = 1;
            else
                cont.islocs = 0;
            end
            %Units
            cont.customUnits.DataType = app.UnitDataConstellationButtonGroup.SelectedObject.Text;
            cont.customUnits.LengthUnit = app.MetricLengthUnitEditField.Value;
            cont.customUnits.TimeUnit = app.TimeUnitEditField.Value;
            cont.customUnits.Pixelsize = app.PixelsizeinUnitEditField.Value;
            cont.customUnits.Timestep = app.TimestepinUnitEditField.Value;
            %Custom CSV, mat file structure
            %Import for CSV
            cont.separator = app.SeparatorEditField.Value;
            cont.headlerlines = app.SkipHeaderLinesEditField.Value;
            %Column organisation
            cont.trackid = app.TrackIDEditField.Value;
            cont.framenr = app.FrameNrEditField.Value;
            cont.xpos = app.XPositionEditField.Value;
            cont.ypos = app.YPositionEditField.Value;
            cont.zpos = app.ZPositionEditField.Value;
            cont.jumpdist = app.JumpDistanceEditField.Value;
            cont.d = app.DEditField.Value;
            cont.derr = app.DerrorEditField.Value;
            cont.int = app.IntensityEditField.Value;
            cont.Tracklen = app.TrackLengthEditField.Value;
            cont.meanjumpdist = app.MeanJumpDistEditField.Value;
            cont.meanjumpdisterr = app.MeanJumpDistErrEditField.Value;
            cont.difftype = app.DiffusionTypeEditField.Value;
            cont.diffstates = app.DiffusionTypeStatesEditField.Value;
            
            structure = cont;
        end
        
        function loadSettings(app, loadfile)
            %make sure that a settings file is actually loaded
            if isfield(loadfile,"trackid") && isfield(loadfile,"customUnits")
            else
                error("Not a import settings file!")
            end
            
            app.ImportSettingsStruct = loadfile;
            
            %Change the settings in the window
            switch loadfile.type
                case "Swift-AllData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftAllDataCSVButton;
                case "Swift-MinData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftMinDataCSVButton;
                case "Custom Trackdata CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomTrackdataCSVButton;
                case "Custom Trackdata .mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomTrackdatamatButton;
                case "SMAP-Localisations"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SMAPLocalisationsButton;
                case "Custom Localisations mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomLocalisationsmatButton;
                case "Custom Localisations csv"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomLocalisationscsvButton;
            end
            %adjust the values in the window according to the saved
            %settings
            impdattype = split(convertCharsToStrings(loadfile.customUnits.DataType),"/");
            comparison = strcmp(impdattype, ["Pixel"; "Frame"]);
            if comparison %both a true
                app.UnitDataConstellationButtonGroup.SelectedObject = app.PixelFrameButton;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                app.UnitDataConstellationButtonGroup.SelectedObject = app.PixelUnitButton;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                app.UnitDataConstellationButtonGroup.SelectedObject = app.UnitFrameButton;
            else
                app.UnitDataConstellationButtonGroup.SelectedObject = app.UnitUnitButton;
            end
            app.MetricLengthUnitEditField.Value = loadfile.customUnits.LengthUnit;
            app.TimeUnitEditField.Value = loadfile.customUnits.TimeUnit;
            app.PixelsizeinUnitEditField.Value = loadfile.customUnits.Pixelsize;
            app.TimestepinUnitEditField.Value = loadfile.customUnits.Timestep;
            
            app.SeparatorEditField.Value = loadfile.separator;
            app.SkipheaderLinesEditField.Value = loadfile.headlerlines;

            app.TrackIDEditField.Value = loadfile.trackid;
            app.FrameNrEditField.Value = loadfile.framenr;
            app.XPositionEditField.Value = loadfile.xpos;
            app.YPositionEditField.Value = loadfile.ypos;
            app.ZPositionEditField.Value = loadfile.zpos;
            app.JumpDistanceEditField.Value = loadfile.jumpdist;
            app.DEditField.Value = loadfile.d;
            app.DerrorEditField.Value = loadfile.derr;
            app.IntensityEditField.Value = loadfile.int;
            app.TrackLengthEditField.Value = loadfile.Tracklen;
            app.MeanJumpDistEditField.Value = loadfile.meanjumpdist;
            app.MeanJumpDistErrEditField.Value = loadfile.meanjumpdisterr;
            app.DiffusionTypeEditField.Value = loadfile.difftype;
            app.DiffusionTypeStatesEditField.Value = loadfile.diffstates;
        end
    end
    
    methods (Access = public)
        
        function SettingsStruct = getSettings(app)
            SettingsStruct = app.ImportSettingsStruct;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, validationwin)
            %set link to calling app
            app.ValidationWindow = validationwin;
            %Set the unit names
            app.adjustUnitName();
            %For standart swift import, the custom data type field is not needed
            app.CustomDataTypeRowContentsPanel.Enable = "off";
            children = app.CustomDataTypeRowContentsPanel.Children;
            set(children, "Enable", "off");
        end

        % Close request function: ImportSettingsUIFigure
        function ImportSettingsUIFigureCloseRequest(app, event)
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ImportSettingsUIFigure.Visible = "off";
            %delete(app)
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.ImportSettingsStruct = packImportSettings(app);
            %saves the settings also in a folder of choise so one might
            %import it later.
            filterspec = {'*.mat'};
            [f, p] = uiputfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end

            fulfile = fullfile(p,f);
            settings = app.ImportSettingsStruct;
            save(fulfile, "settings");
            
            checkSettingsChanged(app);
            app.OldSettingsStruct = app.ImportSettingsStruct;
            
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ValidationWindow.BrowseButton_2.Enable = "off";
            app.ImportSettingsUIFigure.Visible = "off";
            
            app.ValidationWindow.updateSettings();
        end

        % Selection changed function: ImportDataTypeButtonGroup
        function ImportDataTypeButtonGroupSelectionChanged(app, event)
            checkImportDataType(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            try
                loadfile = loadfile.settings;
            catch
                warning("Not a settings file!");
            end
            %checks if settings are different
            try
                app.OldSettingsStruct = app.ImportSettingsStruct;
            end
            loadSettings(app, loadfile);
            checkSettingsChanged(app);
            
            checkImportDataType(app);
            app.adjustUnitName();
        end

        % Button pushed function: SetSettingsButton
        function SetSettingsButtonPushed(app, event)
            %Packs the current settings
            app.ImportSettingsStruct = packImportSettings(app);
            %checks if settings have changed
            checkSettingsChanged(app);
            app.OldSettingsStruct = app.ImportSettingsStruct;
            %enables the gui and hides the settings
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ValidationWindow.BrowseButton_2.Enable = "off";
            app.ImportSettingsUIFigure.Visible = "off";
            %sends the settings to calling app
            app.ValidationWindow.updateSettings();
        end

        % Value changed function: MetricLengthUnitEditField
        function MetricLengthUnitEditFieldValueChanged(app, event)
            value = app.MetricLengthUnitEditField.Value;
            app.adjustUnitName();
        end

        % Value changed function: TimeUnitEditField
        function TimeUnitEditFieldValueChanged(app, event)
            value = app.TimeUnitEditField.Value;
            app.adjustUnitName();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportSettingsUIFigure = uifigure('Visible', 'off');
            app.ImportSettingsUIFigure.Name = 'Import Settings';
            app.ImportSettingsUIFigure.Icon = '3rdPeak1.png';
            app.ImportSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImportSettingsUIFigureCloseRequest, true);
            app.ImportDataTypeButtonGroup = uibuttongroup(app.ImportSettingsUIFigure);
            app.ImportDataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImportDataTypeButtonGroupSelectionChanged, true);
            app.ImportDataTypeButtonGroup.Title = 'Import Data Type';
            app.SwiftAllDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftAllDataCSVButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.SwiftAllDataCSVButton.Text = 'Swift-AllData CSV';
            app.SwiftAllDataCSVButton.Value = true;
            app.SwiftMinDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftMinDataCSVButton.Tooltip = {'Minimal Output Data Type from the Swift Tracking Software.'};
            app.SwiftMinDataCSVButton.Text = 'Swift-MinData CSV';
            app.CustomTrackdataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomTrackdataCSVButton.Tooltip = {'Will enalbe the custom section below to allow for loading of custom csv data.'};
            app.CustomTrackdataCSVButton.Text = 'Custom Trackdata CSV';
            app.CustomTrackdatamatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomTrackdatamatButton.Tooltip = {'Will enable the custom data type area below to allow for custom mat file loading'; ''};
            app.CustomTrackdatamatButton.Text = 'Custom Trackdata .mat';
            app.SMAPLocalisationsButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SMAPLocalisationsButton.Tooltip = {'Localisation data from the SMAP software.'};
            app.SMAPLocalisationsButton.Text = 'SMAP-Localisations';
            app.CustomLocalisationsmatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomLocalisationsmatButton.Tooltip = {'Custom Localisation data in a .mat file. Will enable the custom file format panel below.'};
            app.CustomLocalisationsmatButton.Text = 'Custom Localisations mat';
            app.CustomLocalisationscsvButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomLocalisationscsvButton.Tooltip = {'Custom Localisation data in a .mat file. Will enable the custom file format panel below.'};
            app.CustomLocalisationscsvButton.Text = 'Custom Localisations csv';
            app.PicassoLocalisationsButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.PicassoLocalisationsButton.Tooltip = {'Localisation data made in Picasso as a .hdf5 file. Picasso data will be interpret as Unit/Frame.'};
            app.PicassoLocalisationsButton.Text = 'Picasso-Localisations';
            app.ImportSettingsLabel = uilabel(app.ImportSettingsUIFigure);
            app.ImportSettingsLabel.Text = 'Import Settings';
            app.SaveSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Tooltip = {'Will open a save file dialogue to select the save location for the settings file. Will set the settings and then close the window.'};
            app.SaveSettingsButton.Text = 'Save Settings';
            app.Panel = uipanel(app.ImportSettingsUIFigure);
            app.Panel.Title = 'Import Data Units';
            app.PixelsizeinUnitEditFieldLabel = uilabel(app.Panel);
            app.PixelsizeinUnitEditFieldLabel.Text = 'Pixel size in Unit';
            app.PixelsizeinUnitEditField = uieditfield(app.Panel, 'numeric');
            app.PixelsizeinUnitEditField.Limits = [0.0001 Inf];
            app.PixelsizeinUnitEditField.Tooltip = {'Your Pixelsize in the given unit'};
            app.PixelsizeinUnitEditField.Value = 160;
            app.TimestepinUnitEditFieldLabel = uilabel(app.Panel);
            app.TimestepinUnitEditFieldLabel.Text = 'Timestep in Unit';
            app.TimestepinUnitEditField = uieditfield(app.Panel, 'numeric');
            app.TimestepinUnitEditField.Limits = [1e-05 Inf];
            app.TimestepinUnitEditField.Tooltip = {'Time between consectuive frames in your given unit..'};
            app.TimestepinUnitEditField.Value = 40;
            app.MetricLengthUnitEditFieldLabel = uilabel(app.Panel);
            app.MetricLengthUnitEditFieldLabel.Text = 'Metric Length Unit';
            app.MetricLengthUnitEditField = uieditfield(app.Panel, 'text');
            app.MetricLengthUnitEditField.ValueChangedFcn = createCallbackFcn(app, @MetricLengthUnitEditFieldValueChanged, true);
            app.MetricLengthUnitEditField.Tooltip = {'Enter the metic length unit abbreviation of your xyz data. Usually mm, µm or nm.'};
            app.MetricLengthUnitEditField.Value = 'nm';
            app.TimeUnitEditFieldLabel = uilabel(app.Panel);
            app.TimeUnitEditFieldLabel.Text = 'Time Unit';
            app.TimeUnitEditField = uieditfield(app.Panel, 'text');
            app.TimeUnitEditField.ValueChangedFcn = createCallbackFcn(app, @TimeUnitEditFieldValueChanged, true);
            app.TimeUnitEditField.Tooltip = {'Enter the unit of your timestep. Usually µs, ms or s.'};
            app.TimeUnitEditField.Value = 'ms';
            app.UnitDataConstellationButtonGroup = uibuttongroup(app.Panel);
            app.UnitDataConstellationButtonGroup.Tooltip = {'Define the constellation of your custom data here.'};
            app.UnitDataConstellationButtonGroup.Title = 'Unit Data Constellation';
            app.PixelFrameButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.PixelFrameButton.Text = 'Pixel/Frame';
            app.PixelUnitButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.PixelUnitButton.Text = 'Pixel/Unit';
            app.UnitFrameButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.UnitFrameButton.Text = 'Unit/Frame';
            app.UnitFrameButton.Value = true;
            app.UnitUnitButton = uiradiobutton(app.UnitDataConstellationButtonGroup);
            app.UnitUnitButton.Text = 'Unit/Unit';
            app.LoadSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Tooltip = {'Will open a file selecte dialogue to allow for loading of settings that have ben previously saved. You will need to "Set" the settings for them to be used by the software.'};
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SetSettingsButton = uibutton(app.ImportSettingsUIFigure, 'push');
            app.SetSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetSettingsButtonPushed, true);
            app.SetSettingsButton.Tooltip = {'Will set the settings for the main app. This will close the current window.'};
            app.SetSettingsButton.Text = 'Set Settings';
            app.CustomDataTypeRowContentsPanel = uipanel(app.ImportSettingsUIFigure);
            app.CustomDataTypeRowContentsPanel.Title = 'Custom Data Type - Row Contents';
            app.Panel_2 = uipanel(app.CustomDataTypeRowContentsPanel);
            app.FrameNrEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.FrameNrEditField_2Label.Text = 'Frame Nr.';
            app.FrameNrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.FrameNrEditField.Limits = [0 Inf];
            app.FrameNrEditField.Value = 2;
            app.XPositionEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.XPositionEditField_2Label.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.XPositionEditField.Limits = [0 Inf];
            app.XPositionEditField.Value = 3;
            app.YPositionEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.YPositionEditField_2Label.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.YPositionEditField.Limits = [0 Inf];
            app.YPositionEditField.Value = 4;
            app.ZPositionEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ZPositionEditField_2Label.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ZPositionEditField.Limits = [0 Inf];
            app.ZPositionEditField.Value = 5;
            app.DEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DEditField_2Label.Text = 'D';
            app.DEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DEditField.Limits = [0 Inf];
            app.DEditField.Tooltip = {'Diffusion coefficient'};
            app.DEditField.Value = 8;
            app.SeparatorEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SeparatorEditField_2Label.Text = 'Separator';
            app.SeparatorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'text');
            app.SeparatorEditField.Value = ',';
            app.RowNumberLabel_3 = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel_3.Text = 'Row Number';
            app.RowNumberLabel_4 = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel_4.Text = 'Row Number';
            app.SkipHeaderLinesEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SkipHeaderLinesEditFieldLabel.Text = 'Skip Header Lines';
            app.SkipHeaderLinesEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.SkipHeaderLinesEditField.Limits = [0 Inf];
            app.SkipHeaderLinesEditField.Value = 9;
            app.JumpDistanceEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.JumpDistanceEditFieldLabel.Text = 'Jump Distance';
            app.JumpDistanceEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.JumpDistanceEditField.Limits = [0 Inf];
            app.JumpDistanceEditField.Tooltip = {'Mean swaured displacement'};
            app.JumpDistanceEditField.Value = 6;
            app.IntensityEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.IntensityEditField_2Label.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.IntensityEditField.Limits = [0 Inf];
            app.IntensityEditField.Value = 7;
            app.DerrorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DerrorEditFieldLabel.Text = 'D error';
            app.DerrorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DerrorEditField.Limits = [0 Inf];
            app.DerrorEditField.Tooltip = {'Diffusion coefficient error'};
            app.DerrorEditField.Value = 9;
            app.MeanJumpDistEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.MeanJumpDistEditFieldLabel.Text = 'Mean Jump Dist';
            app.MeanJumpDistEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.MeanJumpDistEditField.Limits = [0 Inf];
            app.MeanJumpDistEditField.Tooltip = {'Mean swaured displacement'};
            app.MeanJumpDistEditField.Value = 11;
            app.MeanJumpDistErrEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.MeanJumpDistErrEditFieldLabel.Text = 'Mean Jump Dist Err';
            app.MeanJumpDistErrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.MeanJumpDistErrEditField.Limits = [0 Inf];
            app.MeanJumpDistErrEditField.Tooltip = {'Mean swaured displacement'};
            app.MeanJumpDistErrEditField.Value = 12;
            app.DiffusionTypeEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DiffusionTypeEditField_2Label.Text = 'Diffusion Type';
            app.DiffusionTypeEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DiffusionTypeEditField.Limits = [0 Inf];
            app.DiffusionTypeEditField.Tooltip = {'The column in which the diffusion type is stated. Is connected to the text box below.'};
            app.DiffusionTypeEditField.Value = 13;
            app.TrackLengthEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.TrackLengthEditFieldLabel.Text = 'Track Length';
            app.TrackLengthEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.TrackLengthEditField.Limits = [0 Inf];
            app.TrackLengthEditField.Tooltip = {'Mean swaured displacement'};
            app.TrackLengthEditField.Value = 10;
            app.DiffusionTypeStatesEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DiffusionTypeStatesEditFieldLabel.Text = 'Diffusion Type States';
            app.DiffusionTypeStatesEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'text');
            app.DiffusionTypeStatesEditField.Tooltip = {'Define here the classification of your diffusion states from the software used from tracking. At the moment five different states can be taken into account. Enter in this field the name/value of the state how it will appear in the data set. For swift, states will be named "none, immobile, diffsuion, directed or dir_diffusion".'};
            app.DiffusionTypeStatesEditField.Value = 'none, immobile, diffusion, directed, dir_diffusion';
            app.TrackIDEditField_2Label = uilabel(app.CustomDataTypeRowContentsPanel);
            app.TrackIDEditField_2Label.Text = 'Track ID';
            app.TrackIDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.TrackIDEditField.Limits = [0 Inf];
            app.TrackIDEditField.Value = 1;
            % Show the figure after all components are created
            app.ImportSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportSettingsValidationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportSettingsUIFigure)
        end
    end
end