classdef ImportSettingsValidationWindow1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadSettingsButton              matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        Panel                           matlab.ui.container.Panel
        TimestepinmsEditField           matlab.ui.control.NumericEditField
        TimestepinmsEditFieldLabel      matlab.ui.control.Label
        PixelsizeinNmEditField          matlab.ui.control.NumericEditField
        PixelsizeinNmEditFieldLabel     matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        CustomDataTypeRowContentsPanel  matlab.ui.container.Panel
        DiffusionTypeEditField          matlab.ui.control.NumericEditField
        DiffusionTypeEditFieldLabel     matlab.ui.control.Label
        SkipHeaderLinesEditField        matlab.ui.control.NumericEditField
        SkipHeaderLinesEditFieldLabel   matlab.ui.control.Label
        RowNumberLabel_2                matlab.ui.control.Label
        RowNumberLabel                  matlab.ui.control.Label
        SeparatorEditField              matlab.ui.control.EditField
        SeparatorEditFieldLabel         matlab.ui.control.Label
        ErrorMSDEditField               matlab.ui.control.NumericEditField
        ErrorMSDEditFieldLabel          matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.NumericEditField
        IntensityEditFieldLabel         matlab.ui.control.Label
        DerrorEditField                 matlab.ui.control.NumericEditField
        DerrorEditFieldLabel            matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        MSDEditField                    matlab.ui.control.NumericEditField
        MSDEditFieldLabel               matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        FrameNrEditField                matlab.ui.control.NumericEditField
        FrameNrEditFieldLabel           matlab.ui.control.Label
        TrackIDEditField                matlab.ui.control.NumericEditField
        TrackIDEditFieldLabel           matlab.ui.control.Label
        ImportSettingsLabel             matlab.ui.control.Label
        ImportDataTypeButtonGroup       matlab.ui.container.ButtonGroup
        CustommatButton                 matlab.ui.control.RadioButton
        CustomCSVButton                 matlab.ui.control.RadioButton
        SwiftMinDataCSVButton           matlab.ui.control.RadioButton
        SwiftAllDataCSVButton           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        ValidationWindow % Link to Validation Window
        ImportSettingsStruct % A Struct to save all the import settings set on this window
    end
    
    methods (Access = private)
        
        function app = checkImportDataType(app)
            %Depending on the file type used, one does need the extra
            %options so I opt them out
            if app.SwiftAllDataCSVButton.Value == 1
                app.Panel.Enable = "off";
                app.Switch.Enable = "off";
                children = app.Panel.Children;
                set(children, "Enable", "off");
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.SwiftMinDataCSVButton.Value == 1
                app.Switch.Enable = "off";
                app.Panel.Enable = "off";
                children = app.Panel.Children;
                set(children, "Enable", "off");
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.CustomCSVButton.Value == 1
                app.Switch.Enable = "on";
                app.Panel.Enable = "on";
                children = app.Panel.Children;
                set(children, "Enable", "on");
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
            if app.CustommatButton.Value == 1
                app.Switch.Enable = "on";
                app.Panel.Enable = "on";
                children = app.Panel.Children;
                set(children, "Enable", "on");
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
        end
        
        function structure = saveImportSettings(app)
            %Packs all the settings in the window into one struct that will
            %be passed back to the Validation app if the window is closed
            %via save-button
            cont.type = app.ImportDataTypeButtonGroup.SelectedObject.Text;
            
            switch app.Switch.Value
                case "No"
                    cont.customUnits.Used = 0;
                case "Yes"
                    cont.customUnits.Used = 1;
            end
            cont.customUnits.Pixelsize = app.PixelsizeinNmEditField.Value;
            cont.customUnits.Timestep = app.TimestepinmsEditField.Value;
            
            cont.separator = app.SeparatorEditField.Value;
            cont.headlerlines = app.SkipHeaderLinesEditField.Value;
            
            cont.trackid = app.TrackIDEditField.Value;
            cont.framenr = app.FrameNrEditField.Value;
            cont.xpos = app.XPositionEditField.Value;
            cont.d = app.DEditField.Value;
            cont.ypos = app.YPositionEditField.Value;
            cont.derr = app.DerrorEditField.Value;
            cont.zpos = app.ZPositionEditField.Value;
            cont.int = app.IntensityEditField.Value;
            cont.msd = app.MSDEditField.Value;
            cont.msderr = app.ErrorMSDEditField.Value;
            cont.difftype = app.DiffusionTypeEditField.Value;
            
            structure = cont;
        end
    end
    
    methods (Access = public)
        
        function SettingsStruct = getSettings(app)
            SettingsStruct = app.ImportSettingsStruct;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, validationwin)
            app.ValidationWindow = validationwin;
            %For standart swift import, the other fields are not needed
                app.Panel.Enable = "off";
                app.Switch.Enable = "off";
                children = app.Panel.Children;
                set(children, "Enable", "off");
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.ImportSettingsStruct = saveImportSettings(app);
            %saves the settings also in the current folder so one might
            %import it later
            filterspec = {'*.mat'};
            [f, p] = uiputfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end

            fulfile = fullfile(p,f);
            settings = app.ImportSettingsStruct;
            save(fulfile, "settings");
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ValidationWindow.BrowseButton_2.Enable = "on";
            app.UIFigure.Visible = "off";
            
            app.ValidationWindow.updateSettings();
        end

        % Selection changed function: ImportDataTypeButtonGroup
        function ImportDataTypeButtonGroupSelectionChanged(app, event)
            checkImportDataType(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            loadfile = loadfile.settings;
            
            %make sure that a settings file is actually loaded
            if isfield(loadfile,"trackid") && isfield(loadfile,"customUnits")
            else
                error("Not a import settings file!")
            end
            
            app.ImportSettingsStruct = loadfile;
            
            %Change the settings in the window
            switch loadfile.type
                case "Swift-AllData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftAllDataCSVButton;
                case "Swift-MinData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftMinDataCSVButton;
                case "Custom CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomCSVButton;
                case "Custom .mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustommatButton;
            end
            
            switch loadfile.customUnits.Used
                case 0
                    app.Switch.Value = "No";
                case 1
                    app.Switch.Value = "Yes";
            end
            
            app.PixelsizeinNmEditField.Value = loadfile.customUnits.Pixelsize;
            app.TimestepinmsEditField.Value = loadfile.customUnits.Timestep;
            
            app.SeparatorEditField.Value = loadfile.separator;
            app.SkipHeaderLinesEditField.Value = loadfile.headlerlines;

            app.TrackIDEditField.Value = loadfile.trackid;
            app.FrameNrEditField.Value = loadfile.framenr;
            app.XPositionEditField.Value = loadfile.xpos;
            app.DEditField.Value = loadfile.d;
            app.YPositionEditField.Value = loadfile.ypos;
            app.DerrorEditField.Value = loadfile.derr;
            app.ZPositionEditField.Value = loadfile.zpos;
            app.IntensityEditField.Value = loadfile.int;
            app.MSDEditField.Value = loadfile.msd;
            app.ErrorMSDEditField.Value = loadfile.msderr;
            app.DiffusionTypeEditField.Value = loadfile.difftype;
            
            checkImportDataType(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImportDataTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ImportDataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImportDataTypeButtonGroupSelectionChanged, true);
            app.ImportDataTypeButtonGroup.Title = 'Import Data Type';
            app.SwiftAllDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftAllDataCSVButton.Tooltip = {'All-Output Data Type from the Swift Tracking Software.'};
            app.SwiftAllDataCSVButton.Text = 'Swift-AllData CSV';
            app.SwiftAllDataCSVButton.Value = true;
            app.SwiftMinDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftMinDataCSVButton.Tooltip = {'Minimal Output Data Type from the Swift Tracking Software.'};
            app.SwiftMinDataCSVButton.Text = 'Swift-MinData CSV';
            app.CustomCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomCSVButton.Tooltip = {'Will enalbe the custom section below to allow for loading of custom file data.'};
            app.CustomCSVButton.Text = 'Custom CSV';
            app.CustommatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustommatButton.Tooltip = {'Will enable the custom data type area below to allow for custom file loading'; ''};
            app.CustommatButton.Text = 'Custom .mat';
            app.ImportSettingsLabel = uilabel(app.UIFigure);
            app.ImportSettingsLabel.Text = 'Import Settings';
            app.CustomDataTypeRowContentsPanel = uipanel(app.UIFigure);
            app.CustomDataTypeRowContentsPanel.Title = 'Custom Data Type - Row Contents';
            app.TrackIDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.TrackIDEditFieldLabel.Text = 'Track ID';
            app.TrackIDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.TrackIDEditField.Limits = [0 Inf];
            app.TrackIDEditField.Value = 1;
            app.FrameNrEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.FrameNrEditFieldLabel.Text = 'Frame Nr.';
            app.FrameNrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.FrameNrEditField.Limits = [0 Inf];
            app.FrameNrEditField.Value = 2;
            app.XPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.XPositionEditField.Limits = [0 Inf];
            app.XPositionEditField.Value = 3;
            app.YPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.YPositionEditField.Limits = [0 Inf];
            app.YPositionEditField.Value = 4;
            app.ZPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ZPositionEditField.Limits = [0 Inf];
            app.ZPositionEditField.Value = 5;
            app.MSDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.MSDEditFieldLabel.Text = 'MSD';
            app.MSDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.MSDEditField.Limits = [0 Inf];
            app.MSDEditField.Tooltip = {'Mean swaured displacement'};
            app.MSDEditField.Value = 32;
            app.DEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DEditField.Limits = [0 Inf];
            app.DEditField.Tooltip = {'Diffusion coefficient'};
            app.DEditField.Value = 11;
            app.DerrorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DerrorEditFieldLabel.Text = 'D error';
            app.DerrorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DerrorEditField.Limits = [0 Inf];
            app.DerrorEditField.Tooltip = {'Diffusion coefficient error'};
            app.DerrorEditField.Value = 13;
            app.IntensityEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.IntensityEditField.Limits = [0 Inf];
            app.IntensityEditField.Value = 5;
            app.ErrorMSDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ErrorMSDEditFieldLabel.Text = 'Error MSD';
            app.ErrorMSDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ErrorMSDEditField.Limits = [0 Inf];
            app.ErrorMSDEditField.Tooltip = {'Error of the mean squared displacement'};
            app.ErrorMSDEditField.Value = 33;
            app.SeparatorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SeparatorEditFieldLabel.Text = 'Separator';
            app.SeparatorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'text');
            app.SeparatorEditField.Value = ',';
            app.RowNumberLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel.Text = 'Row Number';
            app.RowNumberLabel_2 = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel_2.Text = 'Row Number';
            app.SkipHeaderLinesEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SkipHeaderLinesEditFieldLabel.Text = 'Skip Header Lines';
            app.SkipHeaderLinesEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.SkipHeaderLinesEditField.Limits = [0 Inf];
            app.SkipHeaderLinesEditField.Value = 9;
            app.DiffusionTypeEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DiffusionTypeEditFieldLabel.Text = 'Diffusion Type';
            app.DiffusionTypeEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DiffusionTypeEditField.Limits = [0 Inf];
            app.DiffusionTypeEditField.Tooltip = {'Swift defines a diffusion type as either "none", "immobile" or "diffusion". If also available in your data, add the column here.'};
            app.DiffusionTypeEditField.Value = 34;
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Tooltip = {'Will open a save file dialogue to select the save location for the settings file. Will then close the window.'};
            app.SaveSettingsButton.Text = 'Save Settings';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Processing is done in Nanometer/Frame. If data is different, please indicate here';
            app.PixelsizeinNmEditFieldLabel = uilabel(app.Panel);
            app.PixelsizeinNmEditFieldLabel.Text = 'Pixel size in Nm';
            app.PixelsizeinNmEditField = uieditfield(app.Panel, 'numeric');
            app.PixelsizeinNmEditField.Limits = [1e-05 Inf];
            app.PixelsizeinNmEditField.Tooltip = {'Your Pixelsize in Nanometer'};
            app.PixelsizeinNmEditField.Value = 160;
            app.TimestepinmsEditFieldLabel = uilabel(app.Panel);
            app.TimestepinmsEditFieldLabel.Text = 'Timestep in ms';
            app.TimestepinmsEditField = uieditfield(app.Panel, 'numeric');
            app.TimestepinmsEditField.Limits = [1e-05 Inf];
            app.TimestepinmsEditField.Tooltip = {'Time between consectuive frames in miliseconds.'};
            app.TimestepinmsEditField.Value = 40;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.Tooltip = {'Enables/Disables the convertment of units if necessary. Default output from Swift is Nm/frame.'};
            app.Switch.Value = 'No';
            app.LoadSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Tooltip = {'Will open a file selecte dialogue to allow for loading of settings that have ben previously saved. Will close the window afterwards.'};
            app.LoadSettingsButton.Text = 'Load Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportSettingsValidationWindow1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end