classdef PreprocessingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingWindowUIFigure     matlab.ui.Figure
        ResolutionValidationPanel       matlab.ui.container.Panel
        FRCErrorLabel                   matlab.ui.control.Label
        FRCValueLabel                   matlab.ui.control.Label
        CalculateFRCButton              matlab.ui.control.Button
        FSCErrorLabel                   matlab.ui.control.Label
        FSCValueLabel                   matlab.ui.control.Label
        CalculateFSCButton              matlab.ui.control.Button
        DriftCorrectionPanel            matlab.ui.container.Panel
        CellFilePatternEditField        matlab.ui.control.EditField
        CellFilePatternLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        FilePairsFoundLabel             matlab.ui.control.Label
        BeadFilePatternEditField        matlab.ui.control.EditField
        BeadFilePatternLabel            matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        GetFilesButton_2                matlab.ui.control.Button
        SelectMainFolderButton_2        matlab.ui.control.Button
        PerformDriftCorrectionCheckBox  matlab.ui.control.CheckBox
        SwiftParameterEstimationPanel   matlab.ui.container.Panel
        DataTypeButton                  matlab.ui.control.Button
        PreciisonZValue                 matlab.ui.control.Label
        PrecisionZLabel                 matlab.ui.control.Label
        ExpNoiseRateValue               matlab.ui.control.Label
        DiffractionLimitValue           matlab.ui.control.Label
        PreciisonValue                  matlab.ui.control.Label
        ExpDisplacementValue            matlab.ui.control.Label
        p_BleachValue                   matlab.ui.control.Label
        ExpNoiseRateLabel               matlab.ui.control.Label
        DiffractionLimitLabel           matlab.ui.control.Label
        PrecisionXYLabel                matlab.ui.control.Label
        ExpDisplacementLabel            matlab.ui.control.Label
        p_BleachLabel                   matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        SaveLocationPanel               matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        NoFolderSelectedLabel           matlab.ui.control.Label
        SetSaveFolderButton             matlab.ui.control.Button
        DataFilterPanel                 matlab.ui.container.Panel
        UnitUsedLabel_5                 matlab.ui.control.Label
        UnitUsedLabel_4                 matlab.ui.control.Label
        UnitUsedLabel_3                 matlab.ui.control.Label
        UnitUsedLabel_2                 matlab.ui.control.Label
        UnitUsedLabel                   matlab.ui.control.Label
        MaxEditField_4                  matlab.ui.control.NumericEditField
        MaxEditField_4Label             matlab.ui.control.Label
        MinEditField_7                  matlab.ui.control.NumericEditField
        MinEditField_7Label             matlab.ui.control.Label
        FilterActiveCheckBoxIntensity   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPrecisionZ  matlab.ui.control.CheckBox
        FilterActiveCheckBoxPrecisionXY  matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionZ   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionY   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionX   matlab.ui.control.CheckBox
        FilterbyIntensityLabel          matlab.ui.control.Label
        SigmaEditField_3                matlab.ui.control.NumericEditField
        SigmaEditField_3Label           matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        MaxEditField_3                  matlab.ui.control.NumericEditField
        MaxEditField_3Label             matlab.ui.control.Label
        MinEditField_6                  matlab.ui.control.NumericEditField
        MinEditField_6Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_5                  matlab.ui.control.NumericEditField
        MinEditField_5Label             matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField_4                  matlab.ui.control.NumericEditField
        MinEditField_4Label             matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        XYLabel                         matlab.ui.control.Label
        FilterbyPrecisionLabel          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        UseManualPolymaskCheckBox       matlab.ui.control.CheckBox
        FilterbyPositionLabel           matlab.ui.control.Label
        StartProcessingButton           matlab.ui.control.Button
        LocalisationDataPreprocessingLabel  matlab.ui.control.Label
        LoadDataPanel                   matlab.ui.container.Panel
        SettingsButton                  matlab.ui.control.Button
        DataTypeLabel                   matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FilesFoundNumber                matlab.ui.control.Label
        FilesFoundLabel                 matlab.ui.control.Label
        GetFilesButton                  matlab.ui.control.Button
        FileStringPatternEditField      matlab.ui.control.EditField
        FileStringPatternEditFieldLabel  matlab.ui.control.Label
        SelectMainFolderButton          matlab.ui.control.Button
        SelectFilesButton               matlab.ui.control.Button
        NestedFolderStructureLabel      matlab.ui.control.Label
        AllfilesinasinglefolderLabel    matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        FolderStructureLabel            matlab.ui.control.Label
    end

    properties (Access = private)
        Mainapp % calling selection window
        SettingsWindow %window to select the import data type
        SettingsChanged %bool to save the value if settings have changed or not
        TrackSettingsWindow %settings window to select also a different data type as swift to estimate tracking parameters
        
        LocalisationData %Localisations after import into the app
        FileLocations %List of files to process
        MainFolder %Main Folder for subfolder processing
        
        SaveLocation %Folder where to save the data to
        
        maskedLocs %localisation after XYZ masking
        precisionLocs %localisations after precision filter
        intensityLocs %localisations after intensity filter
        
        tracks %import from track file
        CalculatedDataStruc %for MJD and N
    end
    
    properties (Access = public)
        ImportSettingsStruct %contains all the information about the data that will be processed
        TrackSettingsStruct %struct to save the track settings
    end

    methods (Static)
        
    end
    
    methods (Access = public)
        
        function tracks = getTracks(app)
            tracks = app.tracks;
        end
        
        function setUnitUsed(app)
            %changes the GUI to the selected units. Purely visual.
            unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            unittext = join(["[" unit "]"],"");
            app.UnitUsedLabel.Text = unittext;
            app.UnitUsedLabel_2.Text = unittext;
            app.UnitUsedLabel_3.Text = unittext;
            app.UnitUsedLabel_4.Text = unittext;
            app.UnitUsedLabel_5.Text = unittext;
        end
        
        function setSettingsChanged(app, value)
            %Bool to control if file settings have changed in between
            %operations
            app.SettingsChanged = value;
        end
        
        function updateSettings(app)
            %grabs the settings from the settings dialogue window
            if isa(app.SettingsWindow, "ImportSettingsPreprocessingWindow")
                app.ImportSettingsStruct = app.SettingsWindow.getSettings();
                setUnitUsed(app);
            else
                %Nothing
            end
        end
        
        function updateTrackSettings(app)
            %if there is a settings window, get the settings
            if isa(app.TrackSettingsWindow, "ImportSettingsTracksPreprocessingWindow")
                app.TrackSettingsStruct = app.TrackSettingsWindow.getSettings();
                app.SelectFileButton.Enable ="on";
                app.CalculateFSCButton.Enable ="on";
            else
                %Nothing
            end
        end
    end
    
    methods (Access = private)
        
        function struc = setSaveStruct(app)
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            %s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            struc = s;
        end
        
        function performSanityCheck(app)
            %function to control that all necessary inputs have been made
            %for data processing, will enable the processing button
            if ~isempty(app.FileLocations) && ~isempty(app.SaveLocation)
                app.StartProcessingButton.Enable = "on";
            else
                app.StartProcessingButton.Enable = "off";
            end
        end
 
        function setvalues = getSettingsSet(app)
            %packs all the settings into one file so they can be saved
            %during the processing
            filelocs = "locs";
            for i = 1:size(app.FileLocations,1)
                filelocs(i) = app.FileLocations{i,1};
            end
            setvalues.files = filelocs;
            setvalues.XYZ.XY.PolymaskUsed = app.UseManualPolymaskCheckBox.Value;
            setvalues.XYZ.XY.X.Used = app.FilterActiveCheckBoxPositionX.Value;
            setvalues.XYZ.XY.X.Min = app.MinEditField_4.Value;
            setvalues.XYZ.XY.X.Max = app.MaxEditField.Value;
            setvalues.XYZ.XY.Y.Used = app.FilterActiveCheckBoxPositionY.Value;
            setvalues.XYZ.XY.Y.Min = app.MinEditField_5.Value;
            setvalues.XYZ.XY.Y.Max = app.MaxEditField_2.Value;
            setvalues.XYZ.Z.Used = app.FilterActiveCheckBoxPositionZ.Value;
            setvalues.XYZ.Z.Min = app.MinEditField_6.Value;
            setvalues.XYZ.Z.Max = app.MaxEditField_3.Value;
            setvalues.precision.XY.Used = app.FilterActiveCheckBoxPrecisionXY.Value;
            setvalues.precision.XY.sigma = app.SigmaEditField.Value;
            setvalues.precision.Z.Used = app.FilterActiveCheckBoxPrecisionZ.Value;
            setvalues.precision.Z.sigma = app.SigmaEditField_3.Value;
            setvalues.intensity.Used = app.FilterActiveCheckBoxIntensity.Value;
            setvalues.intensity.Min = app.MinEditField_7.Value;
            setvalues.intensity.Max = app.MaxEditField_4.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %set link to main app
            app.Mainapp = mainapp;
            app.CalculatedDataStruc = setSaveStruct(app);
            %disable file selection until settings are set
            app.SelectFilesButton.Enable = "off";
            app.SelectMainFolderButton.Enable = "off";
            app.Switch.Enable = "off";
            app.GetFilesButton.Enable = "off";
            app.StartProcessingButton.Enable = "off";
            app.SelectFileButton.Enable = "off";
            app.CalculateFSCButton.Enable = "off";
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.SettingsWindow = ImportSettingsPreprocessingWindow(app);
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            %Button to select the trace data matching the microscopy data.
            %Get the settings
            updateSettings(app);
            %check if settings changed. if yes clear the data
            if app.SettingsChanged
                app.FileLocations = [];
            end
            if app.Switch_2.Value == "Localisation Data Processing"
                %Load the locs
                app.getLocFilelocationSingleFolder();
                %make sure that the data is not empty, then set the GUI
                if ~isempty(app.LocalisationData)
                end
            else
                %Load image files and preprocess them to get rid of beads
                %etc
                % Display uigetfile dialog
                        filterspec = {'*.tiff'};
                        [f, p] = uigetfile(filterspec, 'MultiSelect','on');
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        if isa(f,"char") %only one file selected, convert to cell 
                            b = f;
                            f = cell(1,1);
                            f{1,1} = b;
                        end
                        files = f.';
                        fullfilenames = cellfun(@(x) join([p,x]), files, 'UniformOutput', false);
                        app.FileLocations = fullfilenames;
            end
            app.FilesFoundNumber.Text = int2str(size(app.FileLocations,1));
            performSanityCheck(app);
        end

        % Button pushed function: SelectMainFolderButton
        function SelectMainFolderButtonPushed(app, event)
            app.MainFolder = uigetdir;
            if ~isempty(app.MainFolder)
                app.GetFilesButton.Enable = "on";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.FileLocations = [];
            if value == "Single Folder"
                app.SelectMainFolderButton.Enable = "off";
                app.SelectFilesButton.Enable = "on";
            else
                app.SelectMainFolderButton.Enable = "on";
                app.SelectFilesButton.Enable = "off";
            end
        end

        % Button pushed function: GetFilesButton
        function GetFilesButtonPushed(app, event)
            %get the folder and the file name to search in/for
            filestring = app.FileStringPatternEditField.Value;
            mainfolder = app.MainFolder;
            %deconstruct the filename so we know what we are looking for
            filename = split(filestring,".");
            extensions = filename{end};
            filename = filestring(1:end-size(extensions,2));
            %part before and after the counter number %d
            try
                numberpos = strfind(filename, "%d");
            catch
                msgbox("You are missing the placeholder for the number %d");
                return
            end
            splitname = split(filename,"%d");
            if numberpos == 1 %number first
                expression = ['\d*', (splitname{1}), extensions];
            else %number not first
                if size(splitname,1) == 2
                    expression = [(splitname{1}),'\d*', (splitname{2}), extensions];
                else
                    expression = [(splitname{1}),'\d*', extensions];
                end
            end
            
            %now that we know what we are looking for, we need to find it
            %get all the files with the matching extension
            extensions = sprintf("**/*.%s", extensions);
            cd(mainfolder);
            files = dir(extensions);
            %now get the files that match the expression
            interest = regexpi({files.name}, expression, "match");
            interestIndex = [];
            for i = 1:size(interest,2)
                if isempty(interest{i})
                    %nothing to do here
                else
                    interestIndex = [interestIndex, i];
                end
            end
            interestFilePath = {};
            for i = 1:size(interestIndex,2)
                k = interestIndex(i);
                interestFilePath(i) = {join([files(k).folder, "/", files(k).name],"")};
            end
            app.FileLocations = interestFilePath.';
            %now we got the path to the files we are interested in. we are
            %happy.   
            app.FilesFoundNumber.Text = int2str(size(app.FileLocations,1));
            performSanityCheck(app);
        end

        % Value changed function: MinEditField_4
        function MinEditField_4ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionX.Value = 1;            
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.FilterActiveCheckBoxPositionX.Value = 1;   
        end

        % Value changed function: MinEditField_5
        function MinEditField_5ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionY.Value = 1;               
        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionY.Value = 1;    
        end

        % Value changed function: MinEditField_6
        function MinEditField_6ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionZ.Value = 1;            
        end

        % Value changed function: MaxEditField_3
        function MaxEditField_3ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionZ.Value = 1;            
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            app.FilterActiveCheckBoxPrecisionXY.Value = 1;            
        end

        % Value changed function: SigmaEditField_3
        function SigmaEditField_3ValueChanged(app, event)
            app.FilterActiveCheckBoxPrecisionZ.Value = 1;            
        end

        % Value changed function: MinEditField_7
        function MinEditField_7ValueChanged(app, event)
            app.FilterActiveCheckBoxIntensity.Value = 1;            
        end

        % Value changed function: MaxEditField_4
        function MaxEditField_4ValueChanged(app, event)
            app.FilterActiveCheckBoxIntensity.Value = 1;            
        end

        % Value changed function: UseManualPolymaskCheckBox
        function UseManualPolymaskCheckBoxValueChanged(app, event)
            value = app.UseManualPolymaskCheckBox.Value;
            if value == 1 %polymask enabled
                app.FilterActiveCheckBoxPositionX.Value = 0;
                app.FilterActiveCheckBoxPositionY.Value = 0;
                %dont use the usual filter
                app.FilterActiveCheckBoxPositionX.Enable = "off";
                app.FilterActiveCheckBoxPositionY.Enable = "off";
                app.MinEditField_4.Enable = "off";
                app.MaxEditField.Enable = "off";
                app.MinEditField_5.Enable = "off";
                app.MaxEditField_2.Enable = "off";
            else %make elemts available again
                app.FilterActiveCheckBoxPositionX.Enable = "on";
                app.FilterActiveCheckBoxPositionY.Enable = "on";
                app.MinEditField_4.Enable = "on";
                app.MaxEditField.Enable = "on";
                app.MinEditField_5.Enable = "on";
                app.MaxEditField_2.Enable = "on";
            end
        end

        % Button pushed function: SetSaveFolderButton
        function SetSaveFolderButtonPushed(app, event)
            app.SaveLocation = uigetdir;
            if ~isempty(app.SaveLocation)
                app.NoFolderSelectedLabel.Text = app.SaveLocation;
            end
            performSanityCheck(app);
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Processing', 'Indeterminate','on');
            drawnow
            if app.Switch_2.Value == "Image Data Processing"
                %Perform the image division. Divide bead from cell if
                %necessary. Do it with all the collected images.
                files = app.FileLocations;
                for i = 1:size(files,1)
                    processBeadsAndCellImage(files{i,1}, app);
                end
            else
                performLocalisationPreprocessing(app);
                calculatePrecision(app);
                calculateDiffractionLimit(app);
                calculateExpNoiseRate(app);
            end
            delete(d);
        end

        % Close request function: PreprocessingWindowUIFigure
        function PreprocessingWindowUIFigureCloseRequest(app, event)
            app.Mainapp.rdPeakUIFigure.Visible = "on";
            delete(app)
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            %select a tracked  file to estimate the exp displacement
            %and pbleach.
            %load file
            tmp = app.ImportSettingsStruct;
            app.ImportSettingsStruct = app.TrackSettingsStruct;
            
            app.tracks = loadTrackDataFileSelection(app, 1, 1);
            app.tracks = app.tracks{1,1};
            % Display uigetfile dialog
            %filterspec = {'*.csv'};
            %[f, p] = uigetfile(filterspec);
            %loadfile = (fullfile(p,f));
            % Make sure user didn't cancel uigetfile dialog
           % if ~ischar(p)
            %    error("No file selected")
            %end
            %app.tracks = ImportSwiftAsTrc(loadfile, 1);
            
            %check datatype, calcuate extra stuff if necessary
            if size(app.tracks,2)<7
                %no MJD and no N calculated yet
                ids = unique(app.tracks(:,1));
                for i = 1:size(ids)
                    singltrack = app.tracks(app.tracks(:,1)==ids(i),:);
                    calculateJumpDistances(app, singltrack, "Calc");
                    calculateStepNumbers(app, singltrack, "Calc");
                end
                calculateMeanJumpDistanes(app, "Calc");
            else
                %all data present to be used
            end
            %calculate things
            calculateExpDisplacement(app);
            calculatePBleach(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            %tmp button for shortcut
            
        end

        % Callback function
        function ButtonPushed2(app, event)
            calculatePBleach(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value == "Localisation Data Processing"
                app.DataFilterPanel.Visible = "on";
                app.SelectFileButton.Enable = "off";
                app.SelectFileButton.Visible = "off";
                app.SelectMainFolderButton.Enable = "off";
                app.GetFilesButton.Enable = "off";
                app.Switch.Enable = "off";
                app.StartProcessingButton.Enable = "off";
                performSanityCheck(app);
            else
                app.DataFilterPanel.Visible = "off";
                app.SelectFileButton.Enable = "on";
                app.SelectFileButton.Visible = "on";
                app.SelectMainFolderButton.Enable = "on";
                app.GetFilesButton.Enable = "on";
                app.Switch.Enable = "on";
                app.StartProcessingButton.Enable = "on";
            end
        end

        % Button pushed function: DataTypeButton
        function DataTypeButtonPushed(app, event)
            app.DataTypeButton.Enable = "off";
            app.TrackSettingsWindow = ImportSettingsTracksPreprocessingWindow(app);
        end

        % Button pushed function: CalculateFSCButton
        function CalculateFSCButtonPushed(app, event)
            [finalvoxsize,error] = calculateFSC(app);
            app.FSCValueLabel.Text = int2str(finalvoxsize);
            app.FSCErrorLabel.Text = join(["+- " int2str(error)]);
        end

        % Button pushed function: CalculateFRCButton
        function CalculateFRCButtonPushed(app, event)
            frc = calculateFRC(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessingWindowUIFigure.Name = 'Preprocessing Window';
            app.PreprocessingWindowUIFigure.Icon = '3rdPeak1.png';
            app.PreprocessingWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessingWindowUIFigureCloseRequest, true);
            app.LoadDataPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.LoadDataPanel.Tooltip = {'Panel that controls the loading of the data. Can either process locaisation data or .tiff images.'};
            app.LoadDataPanel.Title = 'Load Data';
            app.FolderStructureLabel = uilabel(app.LoadDataPanel);
            app.FolderStructureLabel.Text = 'Folder Structure';
            app.Switch = uiswitch(app.LoadDataPanel, 'slider');
            app.Switch.Items = {'Single Folder', 'Nested Folder'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'This switch determines the search for the file names. if all files are located in one folder (Single Folder) or if they are organized in a layered subfolders (Nested Folder Structure). In case of a nested structure, one selects the main folder and defines the file name pattern below. Then one has to press "Get Files" to search for files matching the pattern.'};
            app.Switch.Value = 'Single Folder';
            app.AllfilesinasinglefolderLabel = uilabel(app.LoadDataPanel);
            app.AllfilesinasinglefolderLabel.Text = 'All files in a single folder';
            app.NestedFolderStructureLabel = uilabel(app.LoadDataPanel);
            app.NestedFolderStructureLabel.Text = 'Nested Folder Structure';
            app.SelectFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Tooltip = {'Opens a file selection dialogue based on the DataType settings set before. Will alow you to select multiple files from a single folder.'};
            app.SelectFilesButton.Text = 'Select Files';
            app.SelectMainFolderButton = uibutton(app.LoadDataPanel, 'push');
            app.SelectMainFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMainFolderButtonPushed, true);
            app.SelectMainFolderButton.Tooltip = {'Opens a folder select dialogue to select uppermost folder of the nested folder structure to be searched in for files matching the string pattern.'};
            app.SelectMainFolderButton.Text = 'Select Main Folder';
            app.FileStringPatternEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FileStringPatternEditFieldLabel.Text = 'File String Pattern';
            app.FileStringPatternEditField = uieditfield(app.LoadDataPanel, 'text');
            app.FileStringPatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.GetFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.GetFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GetFilesButtonPushed, true);
            app.GetFilesButton.Tooltip = {'After selecting the main folder and defining the File String Pattern, press this button to search for your files. The number of files found will be shown at "Files Found"'};
            app.GetFilesButton.Text = 'Get Files';
            app.FilesFoundLabel = uilabel(app.LoadDataPanel);
            app.FilesFoundLabel.Text = 'Files Found:';
            app.FilesFoundNumber = uilabel(app.LoadDataPanel);
            app.FilesFoundNumber.Text = '0';
            app.Switch_2 = uiswitch(app.LoadDataPanel, 'slider');
            app.Switch_2.Items = {'Localisation Data Processing', 'Image Data Masking'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'This switch defines if either localisatioon data will be processed or .tif files. For tif files, a mask can  be drawn to separate a reference bead from biological data. Useful if the bead interferes with the localisation process.'};
            app.Switch_2.Value = 'Localisation Data Processing';
            app.DataTypeLabel = uilabel(app.LoadDataPanel);
            app.DataTypeLabel.Text = 'Data Type';
            app.SettingsButton = uibutton(app.LoadDataPanel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Tooltip = {'Opens the Settings Window to determine the type of the localisation file.'};
            app.SettingsButton.Text = 'Settings';
            app.LocalisationDataPreprocessingLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.LocalisationDataPreprocessingLabel.Text = 'Localisation Data Preprocessing';
            app.StartProcessingButton = uibutton(app.PreprocessingWindowUIFigure, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Tooltip = {'Starts the filtering of the data and correct for drift if selected. Will save the processed localisations in a format to be used by swift for tracking (csv). Also some parameters for tracking will be calculated and displayed.'};
            app.StartProcessingButton.Text = 'Start Processing';
            app.DataFilterPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.DataFilterPanel.Tooltip = {'Panel to control the filters applied to your localisation data. Usually, localisation can be quite noisy. To remove unwanted localisation, we can either use their coordinates, their precision value or their intensity.'};
            app.DataFilterPanel.Title = 'Data Filter';
            app.FilterbyPositionLabel = uilabel(app.DataFilterPanel);
            app.FilterbyPositionLabel.Text = 'Filter by Position';
            app.UseManualPolymaskCheckBox = uicheckbox(app.DataFilterPanel);
            app.UseManualPolymaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseManualPolymaskCheckBoxValueChanged, true);
            app.UseManualPolymaskCheckBox.Tooltip = {'If this is set active, a window for each of your files will be openend that allows you to manually draw around your region of interest. Press space to start drawing. Use left click to set the boundaries of your region and double click to finish. The same window will open again in case there are multiple regions of interest or a manual error occured in the first process. Repeat the process described above, or, if everything is already done, press Enter to move on to the next file.'};
            app.UseManualPolymaskCheckBox.Text = 'Use Manual Polymask';
            app.XLabel = uilabel(app.DataFilterPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.DataFilterPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.DataFilterPanel);
            app.ZLabel.Text = 'Z';
            app.FilterbyPrecisionLabel = uilabel(app.DataFilterPanel);
            app.FilterbyPrecisionLabel.Text = 'Filter by Precision';
            app.XYLabel = uilabel(app.DataFilterPanel);
            app.XYLabel.Text = 'XY';
            app.ZLabel_2 = uilabel(app.DataFilterPanel);
            app.ZLabel_2.Text = 'Z';
            app.MinEditField_4Label = uilabel(app.DataFilterPanel);
            app.MinEditField_4Label.Text = 'Min';
            app.MinEditField_4 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_4.ValueChangedFcn = createCallbackFcn(app, @MinEditField_4ValueChanged, true);
            app.MinEditField_4.Tooltip = {'Defines the minimal value of the X Coordinate to be kept.'};
            app.MaxEditFieldLabel = uilabel(app.DataFilterPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Tooltip = {'Defines the maximal value of the X Coordinate to be kept.'};
            app.MinEditField_5Label = uilabel(app.DataFilterPanel);
            app.MinEditField_5Label.Text = 'Min';
            app.MinEditField_5 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_5.ValueChangedFcn = createCallbackFcn(app, @MinEditField_5ValueChanged, true);
            app.MinEditField_5.Tooltip = {'Defines the minimal value of the Y Coordinate to be kept.'};
            app.MaxEditField_2Label = uilabel(app.DataFilterPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MaxEditField_2.Tooltip = {'Defines the maximal value of the Y Coordinate to be kept.'};
            app.MinEditField_6Label = uilabel(app.DataFilterPanel);
            app.MinEditField_6Label.Text = 'Min';
            app.MinEditField_6 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_6.ValueChangedFcn = createCallbackFcn(app, @MinEditField_6ValueChanged, true);
            app.MinEditField_6.Tooltip = {'Defines the minimal value of the Z Coordinate to be kept.'};
            app.MaxEditField_3Label = uilabel(app.DataFilterPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_3ValueChanged, true);
            app.MaxEditField_3.Tooltip = {'Defines the maximal value of the Z Coordinate to be kept.'};
            app.SigmaEditFieldLabel = uilabel(app.DataFilterPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.DataFilterPanel, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Tooltip = {'Give the minimal localisation precision allowed for XY..'};
            app.SigmaEditField_3Label = uilabel(app.DataFilterPanel);
            app.SigmaEditField_3Label.Text = 'Sigma';
            app.SigmaEditField_3 = uieditfield(app.DataFilterPanel, 'numeric');
            app.SigmaEditField_3.ValueChangedFcn = createCallbackFcn(app, @SigmaEditField_3ValueChanged, true);
            app.SigmaEditField_3.Tooltip = {'Give the minimal localisation precision allowed for Z..'};
            app.FilterbyIntensityLabel = uilabel(app.DataFilterPanel);
            app.FilterbyIntensityLabel.Text = 'Filter by Intensity';
            app.FilterActiveCheckBoxPositionX = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPositionX.Tooltip = {'Shows if the filter will be used or not'};
            app.FilterActiveCheckBoxPositionX.Text = 'Filter Active';
            app.FilterActiveCheckBoxPositionY = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPositionY.Tooltip = {'Shows if the filter will be used or not'};
            app.FilterActiveCheckBoxPositionY.Text = 'Filter Active';
            app.FilterActiveCheckBoxPositionZ = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPositionZ.Tooltip = {'Shows if the filter will be used or not'};
            app.FilterActiveCheckBoxPositionZ.Text = 'Filter Active';
            app.FilterActiveCheckBoxPrecisionXY = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPrecisionXY.Tooltip = {'Shows if the filter will be used or not.'};
            app.FilterActiveCheckBoxPrecisionXY.Text = 'Filter Active';
            app.FilterActiveCheckBoxPrecisionZ = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPrecisionZ.Tooltip = {'Shows if the filter will be used or not.'};
            app.FilterActiveCheckBoxPrecisionZ.Text = 'Filter Active';
            app.FilterActiveCheckBoxIntensity = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxIntensity.Tooltip = {'Shows if the filter will be used or not.'};
            app.FilterActiveCheckBoxIntensity.Text = 'Filter Active';
            app.MinEditField_7Label = uilabel(app.DataFilterPanel);
            app.MinEditField_7Label.Text = 'Min';
            app.MinEditField_7 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_7.ValueChangedFcn = createCallbackFcn(app, @MinEditField_7ValueChanged, true);
            app.MinEditField_7.Tooltip = {'Defines the minimal intensity value for your localisation that will be kept.'};
            app.MaxEditField_4Label = uilabel(app.DataFilterPanel);
            app.MaxEditField_4Label.Text = 'Max';
            app.MaxEditField_4 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_4ValueChanged, true);
            app.MaxEditField_4.Tooltip = {'Defines the maximal intensity value of the localisations to be kept.'};
            app.UnitUsedLabel = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel.Text = ' ';
            app.UnitUsedLabel_2 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_2.Text = ' ';
            app.UnitUsedLabel_3 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_3.Text = ' ';
            app.UnitUsedLabel_4 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_4.Text = ' ';
            app.UnitUsedLabel_5 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_5.Text = ' ';
            app.SaveLocationPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.SaveLocationPanel.Tooltip = {'Panel to set a save location.'};
            app.SaveLocationPanel.Title = 'Save Location';
            app.SetSaveFolderButton = uibutton(app.SaveLocationPanel, 'push');
            app.SetSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveFolderButtonPushed, true);
            app.SetSaveFolderButton.Tooltip = {'Select a save folder in which the results will be saved to. A subfolder per analysis will be generated depending on the name given to the right. The foldername will increase in number if the name is already used.'};
            app.SetSaveFolderButton.Text = 'Set Save Folder';
            app.NoFolderSelectedLabel = uilabel(app.SaveLocationPanel);
            app.NoFolderSelectedLabel.Tooltip = {'Shows the current save folder path.'};
            app.NoFolderSelectedLabel.Text = 'No Folder Selected';
            app.EditField = uieditfield(app.SaveLocationPanel, 'text');
            app.EditField.Tooltip = {'Folder name to save the analysed data to. Default is Analysis.'};
            app.EditField.Value = 'Analysis';
            app.SwiftParameterEstimationPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.SwiftParameterEstimationPanel.Tooltip = {'Panel to display determined parameters that can be used for tracking parameter estimation.'};
            app.SwiftParameterEstimationPanel.Title = ' (Swift) Parameter Estimation';
            app.SelectFileButton = uibutton(app.SwiftParameterEstimationPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Tooltip = {'Select a tracked file to estimate the displacement and the bleach value. A few iterations of adjusting this value and re-tracking should lead to a convergance to a suitable value.'};
            app.SelectFileButton.Text = 'Select File';
            app.p_BleachLabel = uilabel(app.SwiftParameterEstimationPanel);
            app.p_BleachLabel.Tooltip = {'The probability of a particle to bleach between frames. '};
            app.p_BleachLabel.Text = 'p_Bleach';
            app.ExpDisplacementLabel = uilabel(app.SwiftParameterEstimationPanel);
            app.ExpDisplacementLabel.Tooltip = {'The distance a particle will move between frames.'};
            app.ExpDisplacementLabel.Text = 'Exp Displacement';
            app.PrecisionXYLabel = uilabel(app.SwiftParameterEstimationPanel);
            app.PrecisionXYLabel.Tooltip = {'Precision of XY determined from the localisation data.'};
            app.PrecisionXYLabel.Text = 'PrecisionXY';
            app.DiffractionLimitLabel = uilabel(app.SwiftParameterEstimationPanel);
            app.DiffractionLimitLabel.Tooltip = {'Minimal distance of two points to be separated.'};
            app.DiffractionLimitLabel.Text = 'Diffraction Limit';
            app.ExpNoiseRateLabel = uilabel(app.SwiftParameterEstimationPanel);
            app.ExpNoiseRateLabel.Tooltip = {'The amount of background signal'};
            app.ExpNoiseRateLabel.Text = 'Exp Noise Rate';
            app.p_BleachValue = uilabel(app.SwiftParameterEstimationPanel);
            app.p_BleachValue.Text = 'p_Bleach';
            app.ExpDisplacementValue = uilabel(app.SwiftParameterEstimationPanel);
            app.ExpDisplacementValue.Text = 'Exp Displacement';
            app.PreciisonValue = uilabel(app.SwiftParameterEstimationPanel);
            app.PreciisonValue.Text = 'PrecisonXY';
            app.DiffractionLimitValue = uilabel(app.SwiftParameterEstimationPanel);
            app.DiffractionLimitValue.Text = 'Diffraction Limit';
            app.ExpNoiseRateValue = uilabel(app.SwiftParameterEstimationPanel);
            app.ExpNoiseRateValue.Text = 'Exp Noise Rate';
            app.PrecisionZLabel = uilabel(app.SwiftParameterEstimationPanel);
            app.PrecisionZLabel.Tooltip = {'Precision of Z determined from the localisation data.'};
            app.PrecisionZLabel.Text = 'PrecisionZ';
            app.PreciisonZValue = uilabel(app.SwiftParameterEstimationPanel);
            app.PreciisonZValue.Text = 'PrecisonZ';
            app.DataTypeButton = uibutton(app.SwiftParameterEstimationPanel, 'push');
            app.DataTypeButton.ButtonPushedFcn = createCallbackFcn(app, @DataTypeButtonPushed, true);
            app.DataTypeButton.Tooltip = {'Opens a window to select the datatype of tracks to be loaded to refine the parameters used for tracking.'};
            app.DataTypeButton.Text = 'Data Type';
            app.DriftCorrectionPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.DriftCorrectionPanel.Tooltip = {'Panel to determine thefiles for the drift correction.'};
            app.DriftCorrectionPanel.Title = 'Drift Correction';
            app.PerformDriftCorrectionCheckBox = uicheckbox(app.DriftCorrectionPanel);
            app.PerformDriftCorrectionCheckBox.Tooltip = {'Determines if your data will be drift corrected using a mean shift approach.'};
            app.PerformDriftCorrectionCheckBox.Text = 'Perform Drift Correction';
            app.SelectMainFolderButton_2 = uibutton(app.DriftCorrectionPanel, 'push');
            app.SelectMainFolderButton_2.Tooltip = {'Select the uppermost folder of your subfolder hirachy.'};
            app.SelectMainFolderButton_2.Text = 'Select Main Folder';
            app.GetFilesButton_2 = uibutton(app.DriftCorrectionPanel, 'push');
            app.GetFilesButton_2.Tooltip = {'After selecting the main folder and defining the File String Pattern, press this button to search for your files.'};
            app.GetFilesButton_2.Text = 'Get Files';
            app.Switch_3 = uiswitch(app.DriftCorrectionPanel, 'slider');
            app.Switch_3.Items = {'No Reference Bead', 'Reference Bead'};
            app.Switch_3.Tooltip = {'If "No Reference Bead" is selected, data will be shift corrected using the intrinsic shift in the locations per file. This will work with the files selected in the "Load Data" panel. Localisation will be filtered first and then corrected for drift. If "Reference Bead" is chosen, one will have to point the software to the localisation files of the beads and cells respectively. Will overwrite the files selected in the "Load Data" panel. Cells will be processed depending on the filters used and then the drift determined by the bead localisation will be applied to the cell.'};
            app.Switch_3.Value = 'No Reference Bead';
            app.BeadFilePatternLabel = uilabel(app.DriftCorrectionPanel);
            app.BeadFilePatternLabel.Text = 'Bead File Pattern';
            app.BeadFilePatternEditField = uieditfield(app.DriftCorrectionPanel, 'text');
            app.BeadFilePatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.FilePairsFoundLabel = uilabel(app.DriftCorrectionPanel);
            app.FilePairsFoundLabel.Text = 'File Pairs Found:';
            app.Label = uilabel(app.DriftCorrectionPanel);
            app.Label.Text = '0';
            app.CellFilePatternLabel = uilabel(app.DriftCorrectionPanel);
            app.CellFilePatternLabel.Text = 'Cell File Pattern';
            app.CellFilePatternEditField = uieditfield(app.DriftCorrectionPanel, 'text');
            app.CellFilePatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.ResolutionValidationPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.ResolutionValidationPanel.Title = 'Resolution Validation';
            app.CalculateFSCButton = uibutton(app.ResolutionValidationPanel, 'push');
            app.CalculateFSCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFSCButtonPushed, true);
            app.CalculateFSCButton.Tooltip = {'Will calculate the Fourier Shell Correlation of tracked data to give an estimation of the resolution in the file. If the amount of data is low, the FSC might underestimate the resolution, espacially for tracks. '};
            app.CalculateFSCButton.Text = 'Calculate FSC';
            app.FSCValueLabel = uilabel(app.ResolutionValidationPanel);
            app.FSCValueLabel.Text = 'FSC Value';
            app.FSCErrorLabel = uilabel(app.ResolutionValidationPanel);
            app.FSCErrorLabel.Text = 'FSC Error';
            app.CalculateFRCButton = uibutton(app.ResolutionValidationPanel, 'push');
            app.CalculateFRCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFRCButtonPushed, true);
            app.CalculateFRCButton.Tooltip = {'Will calculate the Fourier Ring Correlation of tracked data to give an estimation of the resolution in the file. If the amount of data is low, the FRC might underestimate the resolution, espacially for tracks. '};
            app.CalculateFRCButton.Text = 'Calculate FRC';
            app.FRCValueLabel = uilabel(app.ResolutionValidationPanel);
            app.FRCValueLabel.Text = 'FRC Value';
            app.FRCErrorLabel = uilabel(app.ResolutionValidationPanel);
            app.FRCErrorLabel.Text = 'FRCError';
            % Show the figure after all components are created
            app.PreprocessingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessingWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreprocessingWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingWindowUIFigure)
        end
    end
end