classdef SingleTrackAnalysisWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SingleTrackAnalysisUIFigure    matlab.ui.Figure
        GridLayout7                    matlab.ui.container.GridLayout
        MSDValuesPanel                 matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        InternRvalLabel                matlab.ui.control.Label
        InternRLabel                   matlab.ui.control.Label
        InternAlphaValLabel            matlab.ui.control.Label
        InternAlphaLabel               matlab.ui.control.Label
        InternDValLabel                matlab.ui.control.Label
        InternDLabel                   matlab.ui.control.Label
        SwiftDValueLabel               matlab.ui.control.Label
        SwiftDLabel                    matlab.ui.control.Label
        IntensityoptionsButtonGroup    matlab.ui.container.ButtonGroup
        CummulativeButton              matlab.ui.control.RadioButton
        MeanButton                     matlab.ui.control.RadioButton
        DimensionsPanel                matlab.ui.container.Panel
        Switch_4                       matlab.ui.control.Switch
        Switch_3                       matlab.ui.control.Switch
        MSDfitselectionPanel           matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        LengthCheckOverrideCheckBox    matlab.ui.control.CheckBox
        NumberofPointstofitEditField   matlab.ui.control.NumericEditField
        NumberofPointstofitEditFieldLabel  matlab.ui.control.Label
        AnglesPanel                    matlab.ui.container.Panel
        TrackSelectionPanel            matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        NextButton                     matlab.ui.control.Button
        TrackIDDropDown                matlab.ui.control.DropDown
        TrackIDDropDownLabel           matlab.ui.control.Label
        PreviousButton                 matlab.ui.control.Button
        IntensityAngleMSDPanel         matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        MSDAx                          matlab.ui.control.UIAxes
        IntensityAx                    matlab.ui.control.UIAxes
        JumpDistancesPanel             matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        FurtherDataAnalysisPanel       matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ConfinementRatioValueLabel     matlab.ui.control.Label
        TotaltrajectoryTimeValueLabel  matlab.ui.control.Label
        NettodistValueLabel            matlab.ui.control.Label
        TotaldistValueLabel            matlab.ui.control.Label
        ConfinementRatioLabel          matlab.ui.control.Label
        TotalTrajectoryTimeLabel       matlab.ui.control.Label
        NettodistancetraveledLabel     matlab.ui.control.Label
        TotaldistancetravelledLabel    matlab.ui.control.Label
        ZJumpDist                      matlab.ui.control.UIAxes
        XYZJumpDist                    matlab.ui.control.UIAxes
        YJumpDist                      matlab.ui.control.UIAxes
        XJumpDist                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        VisualisationWin % the connection to the calling main app
        
        TrackData %Data of a single track directly from the main app
        PixelFrameTrackData %TrackData converted into Pixel/frame format
        Zdata %keeps the z positions
        PixelZdata %z positions of the converted data
        
        TrackID%ID of current track
        
        Pixelsize%size of a pixel
        Timestepsize %size of the timestep between frames
        LengthUnit %unit of the length
        TimeUnit %unit of the time
        
        Polaxes %polar axes element for the jump angles
        
        SaveStructure %structured array to save the calculated values
    end
    
    methods (Static)
        function struc = setSaveStruct()
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            %s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            s.Intensities = {};
            struc = s;
        end
    end
    
    methods (Access = private)
        
        function checkNextIdPresent(app)
            %checks if a id is available before or after the current one and
            %disables the respective button if necesasry
            prevBu = app.PreviousButton;
            nextBu = app.NextButton;
            %make sure that both are enabled
            prevBu.Enable = 1;
            nextBu.Enable = 1;
            allids = app.VisualisationWin.getAllIds();
            lastid = length(allids);
            idx = find(allids == app.TrackIDDropDown.Value);
            %disable Previous if first and next if last
            if idx == 1
                prevBu.Enable = 0;
            elseif idx == lastid
                nextBu.Enable = 0;
            end
        end
        
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.VisualisationWin.ImportSettingsStruct.customUnits.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.PixelFrameTrackData = app.TrackData;
                app.TrackData(:,2) = round(app.TrackData(:,2)*app.Timestepsize,0);
                app.TrackData(:,3:5) = app.TrackData(:,3:5)*app.Pixelsize;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.Timestepsize,0);
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,2) = time;
                app.TrackData(:,3:5) = app.TrackData(:,3:5)*app.Pixelsize;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                xyzdata = app.TrackData(:,3:5);
                xyzdata = xyzdata/app.Pixelsize;
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,3:5) = xyzdata;
                app.TrackData(:,2) = app.TrackData(:,2)*app.Timestepsize;
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.Timestepsize,0);
                xyzdata = app.TrackData(:,3:5);
                xyzdata = xyzdata/app.Pixelsize;
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,2) = time;
                app.PixelFrameTrackData(:,3:5) = xyzdata;
            end
        end

        function setUnitName(app)
            %adds the units from the import settings to the switch in the
            %bottom right
            items = app.Switch_4.Items;
            name = join([app.LengthUnit "/" app.TimeUnit],"");
            name = convertStringsToChars(name);
            items{2} = name;
            app.Switch_4.Items = items;
        end
        
        function getNewTrack(app,ID)
            %grabs new track data from the main app when a new track id is
            %chosen
            app.TrackData = getTrackbyID(app.VisualisationWin, ID);
        end
        
        function switch3dData(app)
            switch app.Switch_3.Value
                case "2D"
                    %flatten 3d data
                    app.PixelZdata = app.PixelFrameTrackData(:,5);
                    app.Zdata = app.TrackData(:,5);
                    app.TrackData(:,5) = 0;
                    app.PixelFrameTrackData(:,5) = 0;
                case "3D"
                    %recover 3d positions
                    if ~isempty(app.Zdata)
                        app.TrackData(:,5) = app.Zdata;
                        app.PixelFrameTrackData(:,5) = app.PixelZdata;
                    end
            end
        end
        
        function populateGraphs(app)
            %Calculates the data and calls the plotting function
            app.SaveStructure = app.setSaveStruct();
            %prepare 2d data
            switch3dData(app);
            %decide if 2d or 3d
            if app.Switch_3.Value == "2D"
                dimension = 2;
            else
                dimension = 3;
            end
            %decide if pixel/Frame or unit
            if app.Switch_4.Value == "Pixel/Frame"
                isPixel = 1;
            else 
                isPixel = 0;
            end
            %therefore decide the data format
            if isPixel
                tmptrackdata = app.PixelFrameTrackData;
            else
                tmptrackdata = app.TrackData;
            end
            %decide if mean or cummulative intensity
            if app.MeanButton.Value
                isMean = 1;
            else
                isMean = 0;
            end
            %generate the data and plot it
            app.SaveStructure = calculateDataSingleTrackAnalysis(tmptrackdata, dimension, app.NumberofPointstofitEditField.Value, app.LengthCheckOverrideCheckBox.Value, app.Timestepsize, app.SaveStructure);
            outputLabels = plotAllFiguresSingleTrackAnalysis(app.SaveStructure, app.XJumpDist, app.YJumpDist, app.ZJumpDist, app.XYZJumpDist, dimension, isPixel, app.TimeUnit, app.LengthUnit, app.Polaxes, app.IntensityAx, isMean, ~isPixel, app.Timestepsize, app.Pixelsize, app.MSDAx, app.NumberofPointstofitEditField.Value);
            setMSDAnalysisLabels(app, outputLabels);
            setFurtherDataAnalysisLabels(app);
        end 
        
        function setMSDAnalysisLabels(app, outputlabels)
            app.SwiftDValueLabel.Text = string(app.SaveStructure.SwiftParams.D{1,1});
            app.InternDValLabel.Text = outputlabels(1);
            app.InternAlphaValLabel.Text = outputlabels(2);
            app.InternRvalLabel.Text = outputlabels(3);
        end
        
        function setFurtherDataAnalysisLabels(app)
            %Sets the labels in teh further data analysis tab in accordance to the
            %calculated values 
            if app.Switch_4.Value == "Pixel/Frame"
                %labels
                app.TotaldistancetravelledLabel.Text = "Total Distance Travelled [px]";
                app.NettodistancetraveledLabel.Text = "Netto Distance Travelled [px]";
                app.TotalTrajectoryTimeLabel.Text = "Total Trajectory Steps";
                app.ConfinementRatioLabel.Text = "Confinement Ratio";
            else
                %labels
                app.TotaldistancetravelledLabel.Text = sprintf("Total Distance Travelled [%s]",app.LengthUnit);
                app.NettodistancetraveledLabel.Text = sprintf("Netto Distance Travelled [%s]", app.LengthUnit);
                app.TotalTrajectoryTimeLabel.Text = "Total Trajectory Steps";
                app.ConfinementRatioLabel.Text = "Confinement Ratio";
            end
            %set the values in the GUI
            app.TotaldistValueLabel.Text = num2str(app.SaveStructure.TrackLength.AbsLength.XYZ{1,2});
            app.NettodistValueLabel.Text = num2str(app.SaveStructure.TrackLength.NetLength.XYZ{1,2});
            app.TotaltrajectoryTimeValueLabel.Text = num2str(app.SaveStructure.TrackLength.Steps{1,2});
            app.ConfinementRatioValueLabel.Text = num2str(app.SaveStructure.TrackLength.ConfRatio.XYZ{1,2});
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin, trackdata, ID)
            %grab the data from the visualisation window
            app.VisualisationWin = visualisationwin;
            app.Polaxes = polaraxes(app.AnglesPanel);
            app.TrackData = trackdata;
            app.TrackID = ID;
            %get the units and conversion factors
            app.Pixelsize = app.VisualisationWin.ImportSettingsStruct.customUnits.Pixelsize;
            app.Timestepsize = app.VisualisationWin.ImportSettingsStruct.customUnits.Timestep;
            app.LengthUnit = app.VisualisationWin.ImportSettingsStruct.customUnits.LengthUnit;
            app.TimeUnit = app.VisualisationWin.ImportSettingsStruct.customUnits.TimeUnit;
            adjustDataAndScale(app);
            %set the gui layout
            setUnitName(app);
            app.TrackIDDropDown.Items = visualisationwin.getAllIds();
            app.TrackIDDropDown.Value = num2str(ID);
            %generate save structure
            app.SaveStructure = app.setSaveStruct();
            %calculate and plot the data
            populateGraphs(app);     
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            %Switch to decide between 2D and 3D data
            value = app.Switch_3.Value;
            %calculate and plot the data
            populateGraphs(app);  
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            %Switch between Pixel/Frame and Unit/Unit Dimensions
            value = app.Switch_4.Value;
            %recalculate the data and plot it
            populateGraphs(app); 
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged(app, event)
            %new track id is choosen from drop down menu
            %get new id
            value = app.TrackIDDropDown.Value;
            ID = str2double(value);
            %set new data
            app.Zdata = [];
            app.PixelZdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(ID);
            adjustDataAndScale(app);
            app.SaveStructure = app.setSaveStruct();
            %calcualte new data
            populateGraphs(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %gets the previous id in the list
            allids = app.VisualisationWin.getAllIds();
            idx = find(allids == app.TrackIDDropDown.Value);
            idx = idx-1;
            id = str2double(allids(idx));
            %gets new data associated to new id
            app.Zdata = [];
            app.PixelZdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(id);
            app.TrackID = app.TrackData(1,1);
            app.TrackIDDropDown.Value = num2str(app.TrackID);
            adjustDataAndScale(app);
            app.SaveStructure = app.setSaveStruct();
            %calculates and plots the data
            populateGraphs(app);
            %checks if there are more ids
            checkNextIdPresent(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %gets the previous id in the list
            allids = app.VisualisationWin.getAllIds();
            idx = find(allids == app.TrackIDDropDown.Value);
            idx = idx+1;
            id = str2double(allids(idx));
            %gets new data associated to new id
            app.Zdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(id);
            app.TrackID = app.TrackData(1,1);
            app.TrackIDDropDown.Value = num2str(app.TrackID);
            adjustDataAndScale(app);
            app.SaveStructure = app.setSaveStruct();
            %calculates and plots the data
            populateGraphs(app);
            %checks if there are more ids
            checkNextIdPresent(app);
        end

        % Selection changed function: IntensityoptionsButtonGroup
        function IntensityoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.IntensityoptionsButtonGroup.SelectedObject;
            populateGraphs(app);
        end

        % Value changed function: NumberofPointstofitEditField
        function NumberofPointstofitEditFieldValueChanged(app, event)
            value = app.NumberofPointstofitEditField.Value;
            populateGraphs(app);
        end

        % Callback function
        function PercentageofPointstofitEditFieldValueChanged(app, event)
            value = app.PercentageofPointstofitEditField.Value;
            plotMSD(app);
        end

        % Callback function
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            plotMSD(app);
        end

        % Close request function: SingleTrackAnalysisUIFigure
        function SingleTrackAnalysisUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: LengthCheckOverrideCheckBox
        function LengthCheckOverrideCheckBoxValueChanged(app, event)
            value = app.LengthCheckOverrideCheckBox.Value;
            populateGraphs(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SingleTrackAnalysisUIFigure = uifigure('Visible', 'off');
            app.SingleTrackAnalysisUIFigure.Name = 'Single Track Analysis';
            app.SingleTrackAnalysisUIFigure.Icon = '3rdPeak1.png';
            app.SingleTrackAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @SingleTrackAnalysisUIFigureCloseRequest, true);
            app.GridLayout7 = uigridlayout(app.SingleTrackAnalysisUIFigure);
            app.JumpDistancesPanel = uipanel(app.GridLayout7);
            app.JumpDistancesPanel.Title = 'Jump Distances';
            app.GridLayout2 = uigridlayout(app.JumpDistancesPanel);
            app.XJumpDist = uiaxes(app.GridLayout2);
            title(app.XJumpDist, 'Jump Distance in X')
            xlabel(app.XJumpDist, 'X')
            ylabel(app.XJumpDist, 'Y')
            zlabel(app.XJumpDist, 'Z')
            app.YJumpDist = uiaxes(app.GridLayout2);
            title(app.YJumpDist, 'Jump Distance Y')
            xlabel(app.YJumpDist, 'X')
            ylabel(app.YJumpDist, 'Y')
            zlabel(app.YJumpDist, 'Z')
            app.XYZJumpDist = uiaxes(app.GridLayout2);
            title(app.XYZJumpDist, 'Mean Jump Distance XYZ')
            xlabel(app.XYZJumpDist, 'X')
            ylabel(app.XYZJumpDist, 'Y')
            zlabel(app.XYZJumpDist, 'Z')
            app.ZJumpDist = uiaxes(app.GridLayout2);
            title(app.ZJumpDist, 'Jump Distance in Z')
            xlabel(app.ZJumpDist, 'X')
            ylabel(app.ZJumpDist, 'Y')
            zlabel(app.ZJumpDist, 'Z')
            app.FurtherDataAnalysisPanel = uipanel(app.GridLayout2);
            app.FurtherDataAnalysisPanel.Title = 'Further Data Analysis';
            app.GridLayout = uigridlayout(app.FurtherDataAnalysisPanel);
            app.TotaldistancetravelledLabel = uilabel(app.GridLayout);
            app.TotaldistancetravelledLabel.Text = 'Total distance travelled';
            app.NettodistancetraveledLabel = uilabel(app.GridLayout);
            app.NettodistancetraveledLabel.Text = 'Netto distance traveled';
            app.TotalTrajectoryTimeLabel = uilabel(app.GridLayout);
            app.TotalTrajectoryTimeLabel.Text = 'Total Trajectory Time';
            app.ConfinementRatioLabel = uilabel(app.GridLayout);
            app.ConfinementRatioLabel.Text = 'Confinement Ratio';
            app.TotaldistValueLabel = uilabel(app.GridLayout);
            app.TotaldistValueLabel.Text = 'TotaldistValue';
            app.NettodistValueLabel = uilabel(app.GridLayout);
            app.NettodistValueLabel.Text = 'NettodistValue';
            app.TotaltrajectoryTimeValueLabel = uilabel(app.GridLayout);
            app.TotaltrajectoryTimeValueLabel.Text = 'TotaltrajectoryTimeValue';
            app.ConfinementRatioValueLabel = uilabel(app.GridLayout);
            app.ConfinementRatioValueLabel.Text = 'ConfinementRatioValue';
            app.IntensityAngleMSDPanel = uipanel(app.GridLayout7);
            app.IntensityAngleMSDPanel.Title = 'Intensity - Angle - MSD';
            app.GridLayout3 = uigridlayout(app.IntensityAngleMSDPanel);
            app.IntensityAx = uiaxes(app.GridLayout3);
            title(app.IntensityAx, 'Intensity')
            xlabel(app.IntensityAx, 'X')
            ylabel(app.IntensityAx, 'Y')
            zlabel(app.IntensityAx, 'Z')
            app.MSDAx = uiaxes(app.GridLayout3);
            title(app.MSDAx, 'MSD')
            xlabel(app.MSDAx, 'X')
            ylabel(app.MSDAx, 'Y')
            zlabel(app.MSDAx, 'Z')
            app.TrackSelectionPanel = uipanel(app.GridLayout7);
            app.TrackSelectionPanel.Title = 'Track Selection';
            app.GridLayout4 = uigridlayout(app.TrackSelectionPanel);
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = {'Jump to the previous track in the ID list.'};
            app.PreviousButton.Text = 'Previous';
            app.TrackIDDropDownLabel = uilabel(app.GridLayout4);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.GridLayout4);
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged, true);
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Jump to the next track in the ID list.'};
            app.NextButton.Text = 'Next';
            app.AnglesPanel = uipanel(app.GridLayout7);
            app.AnglesPanel.Title = 'Angles';
            app.MSDfitselectionPanel = uipanel(app.GridLayout7);
            app.MSDfitselectionPanel.Title = 'MSD fit selection';
            app.GridLayout5 = uigridlayout(app.MSDfitselectionPanel);
            app.NumberofPointstofitEditFieldLabel = uilabel(app.GridLayout5);
            app.NumberofPointstofitEditFieldLabel.Text = 'Number of Points to fit';
            app.NumberofPointstofitEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NumberofPointstofitEditField.Limits = [2 Inf];
            app.NumberofPointstofitEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointstofitEditFieldValueChanged, true);
            app.NumberofPointstofitEditField.Value = 5;
            app.LengthCheckOverrideCheckBox = uicheckbox(app.GridLayout5);
            app.LengthCheckOverrideCheckBox.ValueChangedFcn = createCallbackFcn(app, @LengthCheckOverrideCheckBoxValueChanged, true);
            app.LengthCheckOverrideCheckBox.Text = 'Length Check Override';
            app.DimensionsPanel = uipanel(app.GridLayout7);
            app.DimensionsPanel.Title = 'Dimensions';
            app.Switch_3 = uiswitch(app.DimensionsPanel, 'slider');
            app.Switch_3.Items = {'2D', '3D'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Tooltip = {'Allows to switch between 2D and 3D analysis.'};
            app.Switch_3.Value = '2D';
            app.Switch_4 = uiswitch(app.DimensionsPanel, 'slider');
            app.Switch_4.Items = {'Pixel/Frame', 'Unit/Unit'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Tooltip = {'Used to convert the px/frame into um/s if other units are needed. Adjust the values below according to your imaging settings.'};
            app.Switch_4.Value = 'Pixel/Frame';
            app.IntensityoptionsButtonGroup = uibuttongroup(app.GridLayout7);
            app.IntensityoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IntensityoptionsButtonGroupSelectionChanged, true);
            app.IntensityoptionsButtonGroup.Title = 'Intensity options';
            app.MeanButton = uiradiobutton(app.IntensityoptionsButtonGroup);
            app.MeanButton.Tooltip = {'Plots the mean intensity over time.'};
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.CummulativeButton = uiradiobutton(app.IntensityoptionsButtonGroup);
            app.CummulativeButton.Tooltip = {'Plots the cummulaive intensity over time'};
            app.CummulativeButton.Text = 'Cummulative';
            app.MSDValuesPanel = uipanel(app.GridLayout7);
            app.MSDValuesPanel.Title = 'MSD Values';
            app.GridLayout6 = uigridlayout(app.MSDValuesPanel);
            app.SwiftDLabel = uilabel(app.GridLayout6);
            app.SwiftDLabel.Text = 'Swift D';
            app.SwiftDValueLabel = uilabel(app.GridLayout6);
            app.SwiftDValueLabel.Text = 'SwiftDValue';
            app.InternDLabel = uilabel(app.GridLayout6);
            app.InternDLabel.Text = 'Intern D';
            app.InternDValLabel = uilabel(app.GridLayout6);
            app.InternDValLabel.Text = 'Intern DVal';
            app.InternAlphaLabel = uilabel(app.GridLayout6);
            app.InternAlphaLabel.Text = 'Intern Alpha';
            app.InternAlphaValLabel = uilabel(app.GridLayout6);
            app.InternAlphaValLabel.Text = 'Intern AlphaVal';
            app.InternRLabel = uilabel(app.GridLayout6);
            app.InternRLabel.Text = 'Intern R²';
            app.InternRvalLabel = uilabel(app.GridLayout6);
            app.InternRvalLabel.Text = 'Intern R²val';
            % Show the figure after all components are created
            app.SingleTrackAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleTrackAnalysisWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SingleTrackAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SingleTrackAnalysisUIFigure)
        end
    end
end