classdef ImportSettingsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadSettingsButton              matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        Panel                           matlab.ui.container.Panel
        TimestepinmsEditField           matlab.ui.control.NumericEditField
        TimestepinmsEditFieldLabel      matlab.ui.control.Label
        PixelsizeinNmEditField          matlab.ui.control.NumericEditField
        PixelsizeinNmEditFieldLabel     matlab.ui.control.Label
        SaveSettingsButton              matlab.ui.control.Button
        CustomDataTypeRowContentsPanel  matlab.ui.container.Panel
        SkipheaderLinesEditField        matlab.ui.control.NumericEditField
        SkipheaderLinesEditFieldLabel   matlab.ui.control.Label
        RowNumberLabel_2                matlab.ui.control.Label
        RowNumberLabel                  matlab.ui.control.Label
        SeparatorEditField              matlab.ui.control.EditField
        SeparatorEditFieldLabel         matlab.ui.control.Label
        DiffusionTypeEditField          matlab.ui.control.NumericEditField
        DiffusionTypeEditFieldLabel     matlab.ui.control.Label
        ErrorMSDEditField               matlab.ui.control.NumericEditField
        ErrorMSDEditFieldLabel          matlab.ui.control.Label
        ErrorZEditField                 matlab.ui.control.NumericEditField
        ErrorZEditFieldLabel            matlab.ui.control.Label
        ErrorYEditField                 matlab.ui.control.NumericEditField
        ErrorYEditFieldLabel            matlab.ui.control.Label
        ErrorXEditField                 matlab.ui.control.NumericEditField
        ErrorXEditFieldLabel            matlab.ui.control.Label
        MSDEditField                    matlab.ui.control.NumericEditField
        MSDEditFieldLabel               matlab.ui.control.Label
        ZPositionEditField              matlab.ui.control.NumericEditField
        ZPositionEditFieldLabel         matlab.ui.control.Label
        YPositionEditField              matlab.ui.control.NumericEditField
        YPositionEditFieldLabel         matlab.ui.control.Label
        XPositionEditField              matlab.ui.control.NumericEditField
        XPositionEditFieldLabel         matlab.ui.control.Label
        FrameNrEditField                matlab.ui.control.NumericEditField
        FrameNrEditFieldLabel           matlab.ui.control.Label
        TrackIDEditField                matlab.ui.control.NumericEditField
        TrackIDEditFieldLabel           matlab.ui.control.Label
        ImportSettingsLabel             matlab.ui.control.Label
        ImportDataTypeButtonGroup       matlab.ui.container.ButtonGroup
        CustommatButton                 matlab.ui.control.RadioButton
        CustomCSVButton                 matlab.ui.control.RadioButton
        SwiftMinDataCSVButton           matlab.ui.control.RadioButton
        SwiftAllDataCSVButton           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        ValidationWindow % Link to Validation Window
        ImportSettingsStruct % A Struct to save all the import settings set on this window
    end
    
    methods (Access = private)
        
        function app = checkImportDataType(app)
            %Depending on the file type used, one does need the extra
            %options so I opt them out
            if app.SwiftAllDataCSVButton.Value == 1
                app.Panel.Enable = "off";
                app.Switch.Enable = "off";
                children = app.Panel.Children;
                set(children, "Enable", "off");
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.SwiftMinDataCSVButton.Value == 1
                app.Switch.Enable = "off";
                app.Panel.Enable = "off";
                children = app.Panel.Children;
                set(children, "Enable", "off");
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
            end
            if app.CustomCSVButton.Value == 1
                app.Switch.Enable = "on";
                app.Panel.Enable = "on";
                children = app.Panel.Children;
                set(children, "Enable", "on");
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
            if app.CustommatButton.Value == 1
                app.Switch.Enable = "on";
                app.Panel.Enable = "on";
                children = app.Panel.Children;
                set(children, "Enable", "on");
                app.CustomDataTypeRowContentsPanel.Enable = "on";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "on");
            end
        end
        
        function structure = saveImportSettings(app)
            %Packs all the settings in the window into one struct that will
            %be passed back to the Validation app if the window is closed
            %via save-button
            cont.type = app.ImportDataTypeButtonGroup.SelectedObject.Text;
            
            switch app.Switch.Value
                case "No"
                    cont.customUnits.Used = 0;
                case "Yes"
                    cont.customUnits.Used = 1;
            end
            cont.customUnits.Pixelsize = app.PixelsizeinNmEditField.Value;
            cont.customUnits.Timestep = app.TimestepinmsEditField.Value;
            
            cont.separator = app.SeparatorEditField.Value;
            cont.headlerlines = app.SkipheaderLinesEditField.Value;
            
            cont.trackid = app.TrackIDEditField.Value;
            cont.framenr = app.FrameNrEditField.Value;
            cont.xpos = app.XPositionEditField.Value;
            cont.xerr = app.ErrorXEditField.Value;
            cont.ypos = app.YPositionEditField.Value;
            cont.yerr = app.ErrorYEditField.Value;
            cont.zpos = app.ZPositionEditField.Value;
            cont.zerr = app.ErrorZEditField.Value;
            cont.msd = app.MSDEditField.Value;
            cont.msderr = app.ErrorMSDEditField.Value;
            cont.difftype = app.DiffusionTypeEditField.Value;
            
            structure = cont;
        end
    end
    
    methods (Access = public)
        
        function SettingsStruct = getSettings(app)
            SettingsStruct = app.ImportSettingsStruct;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, validationwin)
            app.ValidationWindow = validationwin;
            %For standart swift import, the other fields are not needed
                app.Panel.Enable = "off";
                app.Switch.Enable = "off";
                children = app.Panel.Children;
                set(children, "Enable", "off");
                app.CustomDataTypeRowContentsPanel.Enable = "off";
                children = app.CustomDataTypeRowContentsPanel.Children;
                set(children, "Enable", "off");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            app.ImportSettingsStruct = saveImportSettings(app);
            %saves the settings also in the current folder so one might
            %import it later
            curfolder = pwd;
            file = "settings.mat";
            fulfile = fullfile(curfolder,file);
            settings = app.ImportSettingsStruct;
            save(fulfile, "settings");
            app.ValidationWindow.ImportSettingsButton.Enable = "on";
            app.ValidationWindow.BrowseButton_2.Enable = "on";
            app.UIFigure.Visible = "off";
            
            app.ValidationWindow.updateSettings();
        end

        % Selection changed function: ImportDataTypeButtonGroup
        function ImportDataTypeButtonGroupSelectionChanged(app, event)
            checkImportDataType(app);
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            loadfile = loadfile.settings;
            
            %make sure that a settings file is actually loaded
            if isfield(loadfile,"trackid") && isfield(loadfile,"customUnits")
            else
                error("Not a import settings file!")
            end
            
            app.ImportSettingsStruct = loadfile;
            
            %Change the settings in the window
            switch loadfile.type
                case "Swift-AllData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftAllDataCSVButton;
                case "Swift-MinData CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.SwiftMinDataCSVButton;
                case "Custom CSV"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustomCSVButton;
                case "Custom .mat"
                    app.ImportDataTypeButtonGroup.SelectedObject = app.CustommatButton;
            end
            
            switch loadfile.customUnits.Used
                case 0
                    app.Switch.Value = "No";
                case 1
                    app.Switch.Value = "Yes";
            end
            
            app.PixelsizeinNmEditField.Value = loadfile.customUnits.Pixelsize;
            app.TimestepinmsEditField.Value = loadfile.customUnits.Timestep;
            
            app.SeparatorEditField.Value = loadfile.separator;
            app.SkipheaderLinesEditField.Value = loadfile.headlerlines;

            app.TrackIDEditField.Value = loadfile.trackid;
            app.FrameNrEditField.Value = loadfile.framenr;
            app.XPositionEditField.Value = loadfile.xpos;
            app.ErrorXEditField.Value = loadfile.xerr;
            app.YPositionEditField.Value = loadfile.ypos;
            app.ErrorYEditField.Value = loadfile.yerr;
            app.ZPositionEditField.Value = loadfile.zpos;
            app.ErrorZEditField.Value = loadfile.zerr;
            app.MSDEditField.Value = loadfile.msd;
            app.ErrorMSDEditField.Value = loadfile.msderr;
            app.DiffusionTypeEditField.Value = loadfile.difftype;
            
            checkImportDataType(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImportDataTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ImportDataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImportDataTypeButtonGroupSelectionChanged, true);
            app.ImportDataTypeButtonGroup.Title = 'Import Data Type';
            app.SwiftAllDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftAllDataCSVButton.Text = 'Swift-AllData CSV';
            app.SwiftAllDataCSVButton.Value = true;
            app.SwiftMinDataCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.SwiftMinDataCSVButton.Text = 'Swift-MinData CSV';
            app.CustomCSVButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustomCSVButton.Text = 'Custom CSV';
            app.CustommatButton = uiradiobutton(app.ImportDataTypeButtonGroup);
            app.CustommatButton.Text = 'Custom .mat';
            app.ImportSettingsLabel = uilabel(app.UIFigure);
            app.ImportSettingsLabel.Text = 'Import Settings';
            app.CustomDataTypeRowContentsPanel = uipanel(app.UIFigure);
            app.CustomDataTypeRowContentsPanel.Title = 'Custom Data Type - Row Contents';
            app.TrackIDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.TrackIDEditFieldLabel.Text = 'Track ID';
            app.TrackIDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.TrackIDEditField.Value = 1;
            app.FrameNrEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.FrameNrEditFieldLabel.Text = 'Frame Nr.';
            app.FrameNrEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.FrameNrEditField.Value = 2;
            app.XPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.XPositionEditFieldLabel.Text = 'X Position';
            app.XPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.XPositionEditField.Value = 3;
            app.YPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.YPositionEditFieldLabel.Text = 'Y Position';
            app.YPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.YPositionEditField.Value = 4;
            app.ZPositionEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ZPositionEditFieldLabel.Text = 'Z Position';
            app.ZPositionEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ZPositionEditField.Value = 5;
            app.MSDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.MSDEditFieldLabel.Text = 'MSD';
            app.MSDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.MSDEditField.Value = 32;
            app.ErrorXEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ErrorXEditFieldLabel.Text = 'Error X';
            app.ErrorXEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ErrorXEditField.Value = 9;
            app.ErrorYEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ErrorYEditFieldLabel.Text = 'Error Y';
            app.ErrorYEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ErrorYEditField.Value = 10;
            app.ErrorZEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ErrorZEditFieldLabel.Text = 'Error Z';
            app.ErrorZEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ErrorZEditField.Value = 11;
            app.ErrorMSDEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.ErrorMSDEditFieldLabel.Text = 'Error MSD';
            app.ErrorMSDEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.ErrorMSDEditField.Value = 33;
            app.DiffusionTypeEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.DiffusionTypeEditFieldLabel.Text = 'Diffusion Type';
            app.DiffusionTypeEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.DiffusionTypeEditField.Value = 34;
            app.SeparatorEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SeparatorEditFieldLabel.Text = 'Separator';
            app.SeparatorEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'text');
            app.SeparatorEditField.Value = ',';
            app.RowNumberLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel.Text = 'Row Number';
            app.RowNumberLabel_2 = uilabel(app.CustomDataTypeRowContentsPanel);
            app.RowNumberLabel_2.Text = 'Row Number';
            app.SkipheaderLinesEditFieldLabel = uilabel(app.CustomDataTypeRowContentsPanel);
            app.SkipheaderLinesEditFieldLabel.Text = 'Skip header Lines';
            app.SkipheaderLinesEditField = uieditfield(app.CustomDataTypeRowContentsPanel, 'numeric');
            app.SkipheaderLinesEditField.Value = 9;
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Processing is done in Nm/Frame. If data is different, please indicate here';
            app.PixelsizeinNmEditFieldLabel = uilabel(app.Panel);
            app.PixelsizeinNmEditFieldLabel.Text = 'Pixel size in Nm';
            app.PixelsizeinNmEditField = uieditfield(app.Panel, 'numeric');
            app.TimestepinmsEditFieldLabel = uilabel(app.Panel);
            app.TimestepinmsEditFieldLabel.Text = 'Timestep in ms';
            app.TimestepinmsEditField = uieditfield(app.Panel, 'numeric');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'No', 'Yes'};
            app.Switch.Value = 'No';
            app.LoadSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportSettingsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end