classdef PreprocessingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PreprocessingWindowUIFigure     matlab.ui.Figure
        ResolutionValidationPanel       matlab.ui.container.Panel
        FRCErrorLabel                   matlab.ui.control.Label
        FRCValueLabel                   matlab.ui.control.Label
        CalculateFRCButton              matlab.ui.control.Button
        ErrorvalLabel                   matlab.ui.control.Label
        FSCValueLabel                   matlab.ui.control.Label
        CalculateFSCButton              matlab.ui.control.Button
        DriftCorrectionPanel            matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        FilesFoundLabel_2               matlab.ui.control.Label
        BeadFilePatternEditField        matlab.ui.control.EditField
        BeadFilePatternEditFieldLabel   matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        GetFilesButton_2                matlab.ui.control.Button
        SelectMainFolderButton_2        matlab.ui.control.Button
        CellFilePatternEditField        matlab.ui.control.EditField
        CellFilePatternEditFieldLabel   matlab.ui.control.Label
        PerformDriftCorrectionCheckBox  matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        DataTypeButton                  matlab.ui.control.Button
        PreciisonZValue                 matlab.ui.control.Label
        PrecisonZLabel                  matlab.ui.control.Label
        ExpNoiseRateValue               matlab.ui.control.Label
        DiffractionLimitValue           matlab.ui.control.Label
        PreciisonValue                  matlab.ui.control.Label
        ExpDisplacementValue            matlab.ui.control.Label
        p_BleachValue                   matlab.ui.control.Label
        ExpNoiseRateLabel               matlab.ui.control.Label
        DiffractionLimitLabel           matlab.ui.control.Label
        PrecisonXYLabel                 matlab.ui.control.Label
        ExpDisplacementLabel            matlab.ui.control.Label
        p_BleachLabel                   matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        SwiftParameterEstimationLabel   matlab.ui.control.Label
        SaveDataPanel                   matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        NoFolderSelectedLabel           matlab.ui.control.Label
        SetSaveFolderButton             matlab.ui.control.Button
        DataFilterPanel                 matlab.ui.container.Panel
        UnitUsedLabel_5                 matlab.ui.control.Label
        UnitUsedLabel_4                 matlab.ui.control.Label
        UnitUsedLabel_3                 matlab.ui.control.Label
        UnitUsedLabel_2                 matlab.ui.control.Label
        UnitUsedLabel                   matlab.ui.control.Label
        MaxEditField_4                  matlab.ui.control.NumericEditField
        MaxEditField_4Label             matlab.ui.control.Label
        MinEditField_7                  matlab.ui.control.NumericEditField
        MinEditField_7Label             matlab.ui.control.Label
        FilterActiveCheckBoxIntensity   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPrecisionZ  matlab.ui.control.CheckBox
        FilterActiveCheckBoxPrecisionXY  matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionZ   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionY   matlab.ui.control.CheckBox
        FilterActiveCheckBoxPositionX   matlab.ui.control.CheckBox
        IntensityLabel                  matlab.ui.control.Label
        SigmaEditField_3                matlab.ui.control.NumericEditField
        SigmaEditField_3Label           matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        SigmaEditFieldLabel             matlab.ui.control.Label
        MaxEditField_3                  matlab.ui.control.NumericEditField
        MaxEditField_3Label             matlab.ui.control.Label
        MinEditField_6                  matlab.ui.control.NumericEditField
        MinEditField_6Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MinEditField_5                  matlab.ui.control.NumericEditField
        MinEditField_5Label             matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditField_4                  matlab.ui.control.NumericEditField
        MinEditField_4Label             matlab.ui.control.Label
        ZLabel_2                        matlab.ui.control.Label
        XYLabel                         matlab.ui.control.Label
        FilterbyPrecisionLabel          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        UseManualPolymaskCheckBox       matlab.ui.control.CheckBox
        FilterbyPositionLabel           matlab.ui.control.Label
        StartProcessingButton           matlab.ui.control.Button
        LocalisationDataPreprocessingLabel  matlab.ui.control.Label
        LoadDataPanel                   matlab.ui.container.Panel
        Switch_2                        matlab.ui.control.Switch
        FilesFoundNumber                matlab.ui.control.Label
        FilesFoundLabel                 matlab.ui.control.Label
        GetFilesButton                  matlab.ui.control.Button
        FileStringPatternEditField      matlab.ui.control.EditField
        FileStringPatternEditFieldLabel  matlab.ui.control.Label
        SelectMainFolderButton          matlab.ui.control.Button
        SelectFilesButton               matlab.ui.control.Button
        NestedFolderStructureLabel      matlab.ui.control.Label
        AllfilesinasinglefolderLabel    matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SettingsButton                  matlab.ui.control.Button
        FolderStructureLabel            matlab.ui.control.Label
        DataTypeLabel                   matlab.ui.control.Label
    end

    properties (Access = private)
        Mainapp % calling selection window
        SettingsWindow %window to select the import data type
        SettingsChanged %bool to save the value if settings have changed or not
        TrackSettingsWindow %settings window to select also a different data type as swift to estimate tracking parameters
        
        LocalisationData %Localisations after import into the app
        FileLocations %List of files to process
        MainFolder %Main Folder for subfolder processing
        
        SaveLocation %Folder where to save the data to
        
        maskedLocs %localisation after XYZ masking
        precisionLocs %localisations after precision filter
        intensityLocs %localisations after intensity filter
        
        tracks %import from track file
        CalculatedDataStruc %for MJD and N
    end
    
    properties (Access = public)
        ImportSettingsStruct %contains all the information about the data that will be processed
        TrackSettingsStruct %struct to save the track settings
    end

    methods (Static)
        
    end
    
    methods (Access = public)
        
        function tracks = getTracks(app)
            tracks = app.tracks;
        end
        
        function setUnitUsed(app)
            %changes the GUI to the selected units. Purely visual.
            unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            unittext = join(["[" unit "]"],"");
            app.UnitUsedLabel.Text = unittext;
            app.UnitUsedLabel_2.Text = unittext;
            app.UnitUsedLabel_3.Text = unittext;
            app.UnitUsedLabel_4.Text = unittext;
            app.UnitUsedLabel_5.Text = unittext;
        end
        
        function setSettingsChanged(app, value)
            %Bool to control if file settings have changed in between
            %operations
            app.SettingsChanged = value;
        end
        
        function updateSettings(app)
            %grabs the settings from the settings dialogue window
            if isa(app.SettingsWindow, "ImportSettingsPreprocessingWindow")
                app.ImportSettingsStruct = app.SettingsWindow.getSettings();
                setUnitUsed(app);
            else
                %Nothing
            end
        end
        
        function updateTrackSettings(app)
            %if there is a settings window, get the settings
            if isa(app.TrackSettingsWindow, "ImportSettingsTracksPreprocessingWindow")
                app.TrackSettingsStruct = app.TrackSettingsWindow.getSettings();
                app.SelectFileButton.Enable ="on";
                app.CalculateFSCButton.Enable ="on";
            else
                %Nothing
            end
        end
    end
    
    methods (Access = private)
        
        function struc = setSaveStruct(app)
            %sets the properties for the calculated values so they dont
            %need to be calcualted again and again
            s = struct();
            s.JumpDist.X = {};
            s.JumpDist.Y = {};
            s.JumpDist.Z = {};
            s.JumpDist.XY = {};
            s.JumpDist.XYZ = {};
            s.CumJumpDist.X = {};
            s.CumJumpDist.Y = {};
            s.CumJumpDist.Z = {};
            s.CumJumpDist.XY = {};
            s.CumJumpDist.XYZ = {};
            s.MeanJumpDist.X = {};
            s.MeanJumpDist.Y = {};
            s.MeanJumpDist.Z = {};
            s.MeanJumpDist.XY = {};
            s.MeanJumpDist.XYZ = {};
            s.CumMeanJumpDist.X = {};
            s.CumMeanJumpDist.Y = {};
            s.CumMeanJumpDist.Z = {};
            s.CumMeanJumpDist.XY = {};
            s.CumMeanJumpDist.XYZ = {};
            s.JumpAngles.XY = {};
            s.JumpAngles.XYZ = {};
            s.SwiftParams.MSD = {};
            s.SwiftParams.MSDerr = {};
            s.SwiftParams.D = {};
            s.SwiftParams.Derr = {};
            s.SwiftParams.type = {};
            s.InternMSD.XY.MSDclass = [];
            s.InternMSD.XY.Alpha = {};
            s.InternMSD.XY.a = {};
            s.InternMSD.XY.d = {};
            s.InternMSD.XYZ.MSDclass = [];
            s.InternMSD.XYZ.Alpha = {};
            s.InternMSD.XYZ.a = {};
            s.InternMSD.XYZ.d = {};
            s.TrackLength.Steps = {};
            s.TrackLength.NetLength.XY = {};
            s.TrackLength.NetLength.XYZ = {};
            s.TrackLength.ConfRatio.XY = {};
            s.TrackLength.ConfRatio.XYZ = {};
            s.TrackLength.AbsLength.XY = {};
            s.TrackLength.AbsLength.XYZ = {};
            s.TrackLength.CumLen = {};
            %s.TrackLength.CumLen = {};
            s.TrackLength.MeanLen.XY = {};
            s.TrackLength.MeanLen.XYZ = {};
            struc = s;
        end
        
        function performSanityCheck(app)
            %function to control that all necessary inputs have been made
            %for data processing, will enable the processing button
            if ~isempty(app.FileLocations) && ~isempty(app.SaveLocation)
                app.StartProcessingButton.Enable = "on";
            else
                app.StartProcessingButton.Enable = "off";
            end
        end
        
        function foldername = iterateSaveFoldername(app, counter)
            %will find a folder name that has not be used to save in
            foldername = join([app.EditField.Value, "_", counter],"");
            if exist(foldername, 'dir')
                counter = counter + 1;
                foldername = iterateSaveFoldername(app, counter);
            else
                mkdir(foldername);
            end
        end
        
        function setvalues = getSettingsSet(app)
            %packs all the settings into one file so they can be saved
            %during the processing
            filelocs = "locs";
            for i = 1:size(app.FileLocations,1)
                filelocs(i) = app.FileLocations{i,1};
            end
            setvalues.files = filelocs;
            setvalues.XYZ.XY.PolymaskUsed = app.UseManualPolymaskCheckBox.Value;
            setvalues.XYZ.XY.X.Used = app.FilterActiveCheckBoxPositionX.Value;
            setvalues.XYZ.XY.X.Min = app.MinEditField_4.Value;
            setvalues.XYZ.XY.X.Max = app.MaxEditField.Value;
            setvalues.XYZ.XY.Y.Used = app.FilterActiveCheckBoxPositionY.Value;
            setvalues.XYZ.XY.Y.Min = app.MinEditField_5.Value;
            setvalues.XYZ.XY.Y.Max = app.MaxEditField_2.Value;
            setvalues.XYZ.Z.Used = app.FilterActiveCheckBoxPositionZ.Value;
            setvalues.XYZ.Z.Min = app.MinEditField_6.Value;
            setvalues.XYZ.Z.Max = app.MaxEditField_3.Value;
            setvalues.precision.XY.Used = app.FilterActiveCheckBoxPrecisionXY.Value;
            setvalues.precision.XY.sigma = app.SigmaEditField.Value;
            setvalues.precision.Z.Used = app.FilterActiveCheckBoxPrecisionZ.Value;
            setvalues.precision.Z.sigma = app.SigmaEditField_3.Value;
            setvalues.intensity.Used = app.FilterActiveCheckBoxIntensity.Value;
            setvalues.intensity.Min = app.MinEditField_7.Value;
            setvalues.intensity.Max = app.MaxEditField_4.Value;
        end
        
        function performLocalisationPreprocessing(app)
            %Main function for the preprocessing of the localisation data
            %set save location and go there
            cd(app.SaveLocation);
            counter = 1;
            foldername = iterateSaveFoldername(app, counter);
            cd(foldername);
            %grab file list and load files depending on settings
            app.loadLocs();
            %make sure there are no negative values for XY
            for i = 1:size(app.LocalisationData,1)
                tmpdata = app.LocalisationData{i,1};
                xdat = tmpdata(:,2);
                ydat = tmpdata(:,3);
                xdat(xdat<0) = 0;
                ydat(ydat<0) = 0;
                tmpdata(:,2:3) = [xdat,ydat];
                app.LocalisationData{i,1} = tmpdata;
            end
            clear xdat ydat tmpdata;
            %perform XYZ with either polymask or abs values
            if app.UseManualPolymaskCheckBox.Value
                %perform cellmask
                %make folder to save the mask
                mkdir Cellmask;
                cd Cellmask;
                for i = 1:size(app.LocalisationData,1)
                    tmpdata = app.LocalisationData{i,1};
                    maxX = max(tmpdata(:,2));
                    maxY = max(tmpdata(:,3));
                    maxXY = max([maxX, maxY]);
                    tmpmaskdat = CellMaskV2(tmpdata, [round(maxXY*1.1) round(maxXY*1.1)], pwd, i);
                    for j = 1:size(tmpmaskdat,2)
                        app.maskedLocs{end+1,1} = tmpmaskdat{1,j};
                        app.maskedLocs{end,2} = app.LocalisationData{i,2};
                    end
                end
                cd ..;
            elseif app.FilterActiveCheckBoxPositionX.Value || app.FilterActiveCheckBoxPositionY.Value
                %use the filter in XY
                for i = 1:size(app.LocalisationData,1)
                    tmpdata = app.LocalisationData{i,1};
                    if app.FilterActiveCheckBoxPositionX.Value
                        %filter by X value
                        usrminx = app.MinEditField_4.Value;
                        usrmaxx = app.MaxEditField.Value;
                        tmpdata = tmpdata(tmpdata(:,2)>= usrminx & tmpdata(:,2)<= usrmaxx,:);
                    end
                    if app.FilterActiveCheckBoxPositionY.Value
                        %filter by Y value
                        usrminy = app.MinEditField_5.Value;
                        usrmaxy = app.MaxEditField_2.Value;
                        tmpdata = tmpdata(tmpdata(:,3)>= usrminy & tmpdata(:,3)<= usrmaxy,:);
                    end
                    app.maskedLocs{i,1} = tmpdata;
                    app.maskedLocs{i,2} = app.LocalisationData{i,2};
                end
            else
                %neither XY or polymask
                app.maskedLocs = app.LocalisationData;
            end
            %then do the Z
            if app.FilterActiveCheckBoxPositionZ.Value
                for i = 1:size(app.LocalisationData,1)
                    tmpdata = app.maskedLocs{i,1};
                    usrminz = app.MinEditField_6.Value;
                    usrmaxz = app.MaxEditField_3.Value;
                    tmpdata = tmpdata(tmpdata(:,4)>= usrminz & tmpdata(:,4)<= usrmaxz,:);
                    app.maskedLocs{i,1} = tmpdata;
                end
            end
            %perform precisions
            if app.FilterActiveCheckBoxPrecisionXY.Value
                try
                    for i = 1:size(app.LocalisationData,1)
                        tmpdata = app.maskedLocs{i,1};
                        usrsigma = app.SigmaEditField.Value;
                        tmpdata = tmpdata(tmpdata(:,6)<= usrsigma & tmpdata(:,6)<= usrsigma,:);
                        app.precisionLocs{i,1} = tmpdata;
                        app.precisionLocs{i,2} = app.LocalisationData{i,2};
                    end
                catch
                end
            else
                app.precisionLocs = app.maskedLocs;
            end
            if app.FilterActiveCheckBoxPrecisionZ.Value
                try
                    for i = 1:size(app.LocalisationData,1)
                        tmpdata = app.precisionLocs{i,1};
                        usrsigma = app.SigmaEditField_3.Value;
                        tmpdata = tmpdata(tmpdata(:,7)<= usrsigma,:);
                        app.precisionLocs{i,1} = tmpdata;
                        app.precisionLocs{i,2} = app.LocalisationData{i,2};
                    end
                catch
                end
            end
            if ~app.FilterActiveCheckBoxPrecisionXY.Value && ~app.FilterActiveCheckBoxPrecisionZ.Value
                app.precisionLocs = app.maskedLocs;
            end
            %perform intensity
            if app.FilterActiveCheckBoxIntensity.Value
                try
                    for i = 1:size(app.LocalisationData,1)
                        tmpdata = app.precisionLocs{i,1};
                        usrminInt = app.MinEditField_7.Value;
                        usrmaxInt = app.MaxEditField_4.Value; 
                        tmpdata = tmpdata(tmpdata(:,8)>= usrminInt & tmpdata(:,8)<= usrmaxInt,:);
                        app.intensityLocs{i,1} = tmpdata;
                        app.intensityLocs{i,2} = app.LocalisationData{i,2};
                    end
                catch
                end       
            else
                app.intensityLocs = app.precisionLocs;
            end
            clear tmpdata tmpmaskdat;
            %save the localisations in mat and for swift
            datatosave = app.LocalisationData;
            save("SMAP_Localisations.mat","datatosave");
            datatosave = app.maskedLocs;
            save("maskedLocalisations.mat","datatosave");
            datatosave = app.precisionLocs;
            save("precisionFilteredLocalisations.mat","datatosave");
            datatosave = app.intensityLocs;
            save("intensityFilteredLocalisations.mat","datatosave");
            %save the filter settings
            setvalues = getSettingsSet(app);
            save("PropertiesUsed.mat","setvalues");
            %save for swift
            SavePeaksAsSwift(pwd, app.intensityLocs, size(app.intensityLocs,1), 1);
        end
        
        function getLocFilelocationSingleFolder(app)
            if isa(app.ImportSettingsStruct, "struct")
                %Check that settings has been set
                %get the type of import
                imptype = app.ImportSettingsStruct.type;
                switch imptype
                    case "Custom Localisations mat"
                         % Display uigetfile dialog
                        filterspec = {'*.mat'};
                        [f, p] = uigetfile(filterspec, 'MultiSelect','on');
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        if isa(f,"char") %only one file selected, convert to cell 
                            b = f;
                            f = cell(1,1);
                            f{1,1} = b;
                        end
                        files = f.';
                        fullfilenames = cellfun(@(x) join([p,x]), files, 'UniformOutput', false);
                        app.FileLocations = fullfilenames;
                        
                   case "Custom Localisations csv"
                         % Display uigetfile dialog
                        filterspec = {'*.csv'};
                        [f, p] = uigetfile(filterspec, 'MultiSelect','on');
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        if isa(f,"char") %only one file selected, convert to cell 
                            b = f;
                            f = cell(1,1);
                            f{1,1} = b;
                        end
                        files = f.';
                        fullfilenames = cellfun(@(x) join([p,x]), files, 'UniformOutput', false);
                        app.FileLocations = fullfilenames;
                case "SMAP-Localisations"
                         % Display uigetfile dialog
                        filterspec = {'*.mat'};
                        [f, p] = uigetfile(filterspec, 'MultiSelect','on');
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        if isa(f,"char") %only one file selected, convert to cell 
                            b = f;
                            f = cell(1,1);
                            f{1,1} = b;
                        end
                        files = f.';
                        fullfilenames = cellfun(@(x) join([p,x]), files, 'UniformOutput', false);
                        app.FileLocations = fullfilenames;
                end
            end
        end
        
        function loadLocs(app)
            %Loads the localisation data.
            %Depends on the Import Settings dialoge which file is able to
            %be selected

            if isa(app.ImportSettingsStruct, "struct")
                %Check that settings has been set
                %get the type of import
                imptype = app.ImportSettingsStruct.type;
                switch imptype
                    case "Custom Localisations mat"
                        loadfile = app.FileLocations;
                        locdata = {};
                        for i = 1:size(loadfile,1)
                            locdata{i,1} = loadCustomMat(app, loadfile{i});
                            locdata{i,2} = loadfile{i};
                        end
                        %make sure that locs are actually loaded
                        if size(locdata{1,1},2) < 5
                            error("Not a loc file!")
                        else
                            app.LocalisationData = loadfile;
                        end
                   case "Custom Localisations csv"
                         % Display uigetfile dialog
                        loadfile = app.FileLocations;
                        locdata = {};
                        for i = 1:size(loadfile,1)
                            locdata{i,1} = loadCustomCSV(app, loadfile{i});
                            locdata{i,2} = loadfile{i};
                        end
                        %make sure that locs are actually loaded
                        if size(locdata{1,1},2) < 5
                            error("Not a loc file!")
                        else
                            app.LocalisationData = loadfile;
                        end
                case "SMAP-Localisations"
                        loadfile = app.FileLocations;
                        locdata = {};
                        for i = 1:size(loadfile,1)
                            locdata{i,1} = loadSMAPLocalisations(loadfile{i});
                            locdata{i,2} = loadfile{i};
                        end
                        %make sure that locs are actually loaded
                        if size(locdata{1,1},2) < 5
                            error("Not a loc file!")
                        else
                            app.LocalisationData = locdata;
                        end
                end
            end
        end
    
        function calculatePrecision(app)
            %from SPTAnalyser; log-trans -> mean -> retrans
            %error column 6xy and 7z. data app.intensitylocs
            
            %grab data from all files
            allprecXY = [];
            allprecZ = [];
            
            for i = 1:size(app.intensityLocs,1)
                tmpdata = app.intensityLocs{i,1};
                allprecXY = [allprecXY; tmpdata(:,6)];
                allprecZ = [allprecZ; tmpdata(:,7)];
            end
            %logtransform the data
            logprecXY = log(allprecXY);
            logprecZ = log(allprecZ);
            %retransform and get the mean
            meanprecXY = exp(mean(logprecXY));
            meanprecZ = exp(mean(logprecZ));
            %plot the data and save
            h1 = histfit(allprecXY,[],'kernel');
            text(h1(1).Parent,-100,100, ["Mean Precision XY: " meanprecXY]);
            savefig(gcf, "PrecisionXY");
            saveas(gcf, "PrecisionXY.svg");
            close(gcf);
            h2 = histfit(allprecZ,[],'kernel');
            text(h2(1).Parent,-200,100, ["Mean Precision Z: " meanprecZ]);
            savefig(gcf, "PrecisionZ");
            saveas(gcf, "PrecisionZ.svg");
            close(gcf);
            %show the values for the mean in the GUI
            app.PreciisonValue.Text = int2str(meanprecXY);
            app.PreciisonZValue.Text = int2str(meanprecZ);
        end
        
        function calculateDiffractionLimit(app)
            %finding the nearest neighbour per frame in all files
            nn_mindist = {};
            %for every file
            for i = 1:size(app.intensityLocs,1)
                tmpdat = app.intensityLocs{i,1};
                %only frame xyz
                tmpdat = tmpdat(:,2:5);
                %for every frame
                frames = unique(tmpdat(:,1));
                framennmindist = zeros(size(frames,1),2);
                for j = 1:size(frames,1)
                    framenr = frames(j);
                    points = tmpdat(tmpdat(:,1) == framenr,:);
                    pointnndist = zeros(size(points,1),1);
                    %for every point
                    for k = 1:size(points,1)
                        qp = points(k,2:4);
                        distances = zeros(size(points,1),1);
                        %for every point
                        if size(points,1)>1
                            for p = 1:size(points,1)
                                rp = points(p,2:4);
                                distance = sqrt(((qp(1)-rp(1))^2)+((qp(2)-rp(2))^2)+((qp(2)-rp(2))^2));
                                distances(p) = distance;
                            end
                        end
                        distances(distances == 0) = Inf;
                        mintmppointdist = min(distances);
                        pointnndist(k) = mintmppointdist;
                    end
                    tmpframenndist = min(pointnndist);
                    framennmindist(j,1) = framenr;
                    framennmindist(j,2) = tmpframenndist;
                end
                nn_mindist{i,1} = framennmindist(framennmindist(:,2)<Inf & framennmindist(:,2)>0,:);
                nn_mindist{i,2} = app.intensityLocs{i,2};
            end
            clear framennmindist frames pc tmpdat dists pointnndist points qp;
            %now we plot the data and get the mean value
            %put them all together
            dataToPlot = zeros([1,2]);
            for i = 1:size(app.intensityLocs,1)
                nndist = nn_mindist{i,1}(:,2);
                nnumb = i*ones(size(nndist(:,1),1),1);
                dataPut = [nndist,nnumb];
                dataToPlot = [dataToPlot(:,:); dataPut];
            end
            dataToPlot(1,:) =  [];
            filenames = {};
            for i = 1:size(app.intensityLocs,1)
                filenames{i} = nn_mindist{i,2};
            end
            
            h1 = figure;

            boxchart(dataToPlot(:,2),dataToPlot(:,1));
            hold on;
            swarmchart(dataToPlot(:,2),dataToPlot(:,1));
            ylabel("Minimal Distance Per Frame [nm]");
            xlabel("Filename");
            xticks(1:1:size(app.intensityLocs,1));
            xticklabels(filenames);
            
            savefig(gcf, "DiffractionLimit");
            saveas(gcf, "DiffractionLimit.svg");
            close(gcf);
            
            nnmean = cellfun(@mean, nn_mindist(:,1), 'UniformOutput', false);
            nnmean = cell2mat(nnmean);
            nnmean = nnmean(:,2);
            nnmean = min(nnmean);
            app.DiffractionLimitValue.Text = int2str(nnmean);
        end
        
        function calculateExpNoiseRate(app)
            %nmbr of locs that: (phot-photerr)<= 2*mean(bg)/ nmbr of locs that: (phot-photerr)> 2*mean(bg)
            %8 pho, 9 photerr, 10 bg
            expnoise = zeros(size(app.intensityLocs,1),1);
            for i = 1:size(app.intensityLocs)
                tmpdat = app.intensityLocs{i,1};
                tmpdat = tmpdat(:,8:10);
                meanbg = mean(tmpdat(:,3));
                photvals = tmpdat(:,1)-tmpdat(:,2);
                locsin = size(photvals(photvals > 4*meanbg),1);
                locsout = size(photvals(photvals <= 4*meanbg),1);
                relation = locsout/locsin;
                expnoise(i) = relation;
            end
            expnoise = mean(expnoise);
            app.ExpNoiseRateValue.Text = sprintf('%.6f',expnoise);
        end
        
        function calculateExpDisplacement(app)
            %calculates the expected displacement by the mean jump distance
            %and the amount of data points from swift analysis
            %10=MJD, 14=motiontype 2 19 = n
            if size(app.tracks,2) >= 14
                tmpdat = app.tracks(app.tracks(:,14) == 2,:);%all diffusing things
                if size(tmpdat,1) < 1
                    tmpdat = app.tracks;
                end
                tmpdat = tmpdat(:,[10 19]);%MJD N
                tmpdat = tmpdat(tmpdat(:,1)~=0 | tmpdat(:,1)~=0,:);
                if size(tmpdat,2)>1
                    avgWghtMJD = sum(tmpdat(:,1).*tmpdat(:,2))/sum(tmpdat(:,2));
                else
                    warning("No tracks presen");
                end
            else
                %can not filter, take all
                tmpdat = [app.CalculatedDataStruc.MeanJumpDist.XYZ{1,2}, app.CalculatedDataStruct.TrackLength.Steps{1,2}];
                tmpdat = tmpdat(tmpdat(:,1)~=0 | tmpdat(:,1)~=0,:);
                avgWghtMJD = sum(tmpdat(:,1).*tmpdat(:,2))/sum(tmpdat(:,2));
            end
            app.ExpDisplacementValue.Text = sprintf('%.6f',avgWghtMJD);
        end
        
        function calculatePBleach(app)
            %the probability of a particle to vanish in the next frame via
            %bleaching. it is calculated via exponential decay
            %a*exp(-dt*k).
            %k can then be used for cumulative distribution function
            %1-exp(-dt*k) = probability of bleaching during one frame
            
            %get MJD-N frequencies
            if size(app.tracks,2) >= 19
                tmpdat = app.tracks(:,19);
            else
                tmpdat = app.CalculatedDataStruct.TrackLength.Steps{1,2};
            end
            tmpdat = tmpdat(tmpdat>0);

            [N,edges] = histcounts(tmpdat,'BinMethod','integers');
            timediff = app.ImportSettingsStruct.customUnits.Timestep;
            edges = edges(1:end-1) + diff(edges) - 0.5;
            edges = edges*timediff;
            edges = edges.';
            %build the decay
            decfreq = zeros(size(N,2),1);
            freqsum = sum(N);
            decfreq(1) = freqsum;
            for i = 1:size(N,2)-1
                freqsum = freqsum - N(i);
                decfreq(i+1) = freqsum;
            end
            %normalize it
            normdec = decfreq/sum(N);
            %fit exponential decay
            f = fit(edges,normdec,'exp1','StartPoint',[1,0]);
            
            pbleach = 1-exp(-(timediff)*-f.b);
            app.p_BleachValue.Text = sprintf('%.6f',pbleach);
            %plot
            h1 = figure;
            plot(edges,normdec);
            hold on
            plot(f);
            ylabel("Normalized Tracklength");
            labeltext = sprintf("Time [%s]", app.ImportSettingsStruct.customUnits.TimeUnit);
            xlabel(labeltext);
            text(gca, 1, 0.2, sprintf("pBleachValue: %.6f",pbleach));
            
            savefig(gcf, "Bleachdecay");
            saveas(gcf, "Bleachdecay.svg");
            close(gcf);
        end
        
        function calculateMeanJumpDistanes(app, destinationStruc)
            if destinationStruc == "Calc"
                datX = app.CalculatedDataStruct.JumpDist.X;
                datY = app.CalculatedDataStruct.JumpDist.Y;
                datZ = app.CalculatedDataStruct.JumpDist.Z;
                datXY = app.CalculatedDataStruct.JumpDist.XY;
                datXYZ = app.CalculatedDataStruct.JumpDist.XYZ;
            elseif destinationStruc == "Filter"
                datX = app.FilterCalculatedDataStruct.JumpDist.X;
                datY = app.FilterCalculatedDataStruct.JumpDist.Y;
                datZ = app.FilterCalculatedDataStruct.JumpDist.Z;
                datXY = app.FilterCalculatedDataStruct.JumpDist.XY;
                datXYZ = app.FilterCalculatedDataStruct.JumpDist.XYZ;
            end
            
            mX = cellfun(@mean,datX(:,2), 'UniformOutput',0);
            for i = 1:size(mX,1)
                if mX{i} == 0
                    mX{i} = [0,0];
                end
            end
            mX = cell2mat(mX);
            mX = {datX(:,1), mX(:,2)};
            mY = cellfun(@mean,datY(:,2), 'UniformOutput',0);
            for i = 1:size(mY,1)
                if mY{i} == 0
                    mY{i} = [0,0];
                end
            end
            mY = cell2mat(mY);
            mY = {datY(:,1), mY(:,2)};
            mZ = cellfun(@mean,datZ(:,2), 'UniformOutput',0);
            for i = 1:size(mZ,1)
                if mZ{i} == 0
                    mZ{i} = [0,0];
                end
            end
            mZ = cell2mat(mZ);
            mZ = {datZ(:,1), mZ(:,2)};
            mXY = cellfun(@mean,datXY(:,2), 'UniformOutput',0);
            for i = 1:size(mXY,1)
                if mXY{i} == 0
                    mXY{i} = [0,0];
                end
            end
            mXY = cell2mat(mXY);
            mXY = {datXY(:,1), mXY(:,2)};
            mXYZ = cellfun(@mean,datXYZ(:,2), 'UniformOutput',0);
            for i = 1:size(mXYZ,1)
                if mXYZ{i} == 0
                    mXYZ{i} = [0,0];
                end
            end
            mXYZ = cell2mat(mXYZ);
            mXYZ = {datXYZ(:,1), mXYZ(:,2)};
            
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.MeanJumpDist.X = mX;
                app.CalculatedDataStruct.MeanJumpDist.Y = mY;
                app.CalculatedDataStruct.MeanJumpDist.Z = mZ;
                app.CalculatedDataStruct.MeanJumpDist.XY = mXY;
                app.CalculatedDataStruct.MeanJumpDist.XYZ = mXYZ;
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.MeanJumpDist.X = mX;
                app.FilterCalculatedDataStruct.MeanJumpDist.Y = mY;
                app.FilterCalculatedDataStruct.MeanJumpDist.Z = mZ;
                app.FilterCalculatedDataStruct.MeanJumpDist.XY = mXY;
                app.FilterCalculatedDataStruct.MeanJumpDist.XYZ = mXYZ;
            end
        end
        
        function calculateStepNumbers(app, SingleTrackData, destinationStruc)
            if destinationStruc == "Calc"
                trackid = SingleTrackData(1,1);
            elseif destinationStruc == "Filter"
                trackid = SingleTrackData(1,1);
            end
            len = size(SingleTrackData,1);
            
            if destinationStruc == "Calc"
                app.CalculatedDataStruct.TrackLength.Steps(end+1,:) = {trackid, len};
            elseif destinationStruc == "Filter"
                app.FilterCalculatedDataStruct.TrackLength.Steps(end+1,:) = {trackid, len};
            end
        end
        
        function calculateJumpDistances(app, SingleTrackData, destinationStruc)
            startframe = min(SingleTrackData(:,2));
            endframe = max(SingleTrackData(:,2));
            duration = size(SingleTrackData,1);
            
            xdist = zeros(duration,2);
            ydist = zeros(duration,2);
            zdist = zeros(duration,2);
            xydist = zeros(duration,2);
            xyzdist = zeros(duration,2);
            
            for i = 1:duration-1
                xdist(i,:) = [i, (SingleTrackData(i,3) - SingleTrackData(i+1,3))];
                ydist(i,:) = [i, (SingleTrackData(i,4) - SingleTrackData(i+1,4))];
                zdist(i,:) = [i, (SingleTrackData(i,5) - SingleTrackData(i+1,5))];
                xydist(i,:) = [i, sqrt(xdist(i,2)^2+ydist(i,2)^2)];
                xyzdist(i,:) = [i, sqrt(xdist(i,2)^2+ydist(i,2)^2+zdist(i,2)^2)];
            end
            if destinationStruc == "Calc"
            app.CalculatedDataStruct.JumpDist.X(end+1,:) = {SingleTrackData(1,1), xdist};
            app.CalculatedDataStruct.JumpDist.Y(end+1,:) = {SingleTrackData(1,1), ydist};
            app.CalculatedDataStruct.JumpDist.Z(end+1,:) = {SingleTrackData(1,1), zdist};
            app.CalculatedDataStruct.JumpDist.XY(end+1,:) = {SingleTrackData(1,1), xydist};
            app.CalculatedDataStruct.JumpDist.XYZ(end+1,:) = {SingleTrackData(1,1), xyzdist};
            elseif destinationStruc == "Filter"
            app.FilterCalculatedDataStruct.JumpDist.X(end+1,:) = {SingleTrackData(1,1), xdist};
            app.FilterCalculatedDataStruct.JumpDist.Y(end+1,:) = {SingleTrackData(1,1), ydist};
            app.FilterCalculatedDataStruct.JumpDist.Z(end+1,:) = {SingleTrackData(1,1), zdist};
            app.FilterCalculatedDataStruct.JumpDist.XY(end+1,:) = {SingleTrackData(1,1), xydist};
            app.FilterCalculatedDataStruct.JumpDist.XYZ(end+1,:) = {SingleTrackData(1,1), xyzdist};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %set link to main app
            app.Mainapp = Mainapp;
            app.CalculatedDataStruc = setSaveStruct(app);
            %disable file selection until settings are set
            app.SelectFilesButton.Enable = "off";
            app.SelectMainFolderButton.Enable = "off";
            app.Switch.Enable = "off";
            app.GetFilesButton.Enable = "off";
            app.StartProcessingButton.Enable = "off";
            app.SelectFileButton.Enable = "off";
            app.CalculateFSCButton.Enable = "off";
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            app.SettingsWindow = ImportSettingsPreprocessingWindow(app);
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            %Button to select the trace data matching the microscopy data.
            %Get the settings
            updateSettings(app);
            %check if settings changed. if yes clear the data
            if app.SettingsChanged
                app.FileLocations = [];
            end
            if app.Switch_2.Value == "Localisation Data Processing"
                %Load the locs
                app.getLocFilelocationSingleFolder();
                %make sure that the data is not empty, then set the GUI
                if ~isempty(app.LocalisationData)
                end
            else
                %Load image files and preprocess them to get rid of beads
                %etc
                % Display uigetfile dialog
                        filterspec = {'*.tiff'};
                        [f, p] = uigetfile(filterspec, 'MultiSelect','on');
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        if isa(f,"char") %only one file selected, convert to cell 
                            b = f;
                            f = cell(1,1);
                            f{1,1} = b;
                        end
                        files = f.';
                        fullfilenames = cellfun(@(x) join([p,x]), files, 'UniformOutput', false);
                        app.FileLocations = fullfilenames;
            end
            app.FilesFoundNumber.Text = int2str(size(app.FileLocations,1));
            performSanityCheck(app);
        end

        % Button pushed function: SelectMainFolderButton
        function SelectMainFolderButtonPushed(app, event)
            app.MainFolder = uigetdir;
            if ~isempty(app.MainFolder)
                app.GetFilesButton.Enable = "on";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.FileLocations = [];
            if value == "Single Folder"
                app.SelectMainFolderButton.Enable = "off";
                app.SelectFilesButton.Enable = "on";
            else
                app.SelectMainFolderButton.Enable = "on";
                app.SelectFilesButton.Enable = "off";
            end
        end

        % Button pushed function: GetFilesButton
        function GetFilesButtonPushed(app, event)
            %get the folder and the file name to search in/for
            filestring = app.FileStringPatternEditField.Value;
            mainfolder = app.MainFolder;
            %deconstruct the filename so we know what we are looking for
            filename = split(filestring,".");
            extensions = filename{end};
            filename = filestring(1:end-size(extensions,2));
            %part before and after the counter number %d
            try
                numberpos = strfind(filename, "%d");
            catch
                msgbox("You are missing the placeholder for the number %d");
                return
            end
            splitname = split(filename,"%d");
            if numberpos == 1 %number first
                expression = ['\d*', (splitname{1}), extensions];
            else %number not first
                if size(splitname,1) == 2
                    expression = [(splitname{1}),'\d*', (splitname{2}), extensions];
                else
                    expression = [(splitname{1}),'\d*', extensions];
                end
            end
            
            %now that we know what we are looking for, we need to find it
            %get all the files with the matching extension
            extensions = sprintf("**/*.%s", extensions);
            cd(mainfolder);
            files = dir(extensions);
            %now get the files that match the expression
            interest = regexpi({files.name}, expression, "match");
            interestIndex = [];
            for i = 1:size(interest,2)
                if isempty(interest{i})
                    %nothing to do here
                else
                    interestIndex = [interestIndex, i];
                end
            end
            interestFilePath = {};
            for i = 1:size(interestIndex,2)
                k = interestIndex(i);
                interestFilePath(i) = {join([files(k).folder, "/", files(k).name],"")};
            end
            app.FileLocations = interestFilePath.';
            %now we got the path to the files we are interested in. we are
            %happy.   
            app.FilesFoundNumber.Text = int2str(size(app.FileLocations,1));
            performSanityCheck(app);
        end

        % Value changed function: MinEditField_4
        function MinEditField_4ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionX.Value = 1;            
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            app.FilterActiveCheckBoxPositionX.Value = 1;   
        end

        % Value changed function: MinEditField_5
        function MinEditField_5ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionY.Value = 1;               
        end

        % Value changed function: MaxEditField_2
        function MaxEditField_2ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionY.Value = 1;    
        end

        % Value changed function: MinEditField_6
        function MinEditField_6ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionZ.Value = 1;            
        end

        % Value changed function: MaxEditField_3
        function MaxEditField_3ValueChanged(app, event)
            app.FilterActiveCheckBoxPositionZ.Value = 1;            
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            app.FilterActiveCheckBoxPrecisionXY.Value = 1;            
        end

        % Value changed function: SigmaEditField_3
        function SigmaEditField_3ValueChanged(app, event)
            app.FilterActiveCheckBoxPrecisionZ.Value = 1;            
        end

        % Value changed function: MinEditField_7
        function MinEditField_7ValueChanged(app, event)
            app.FilterActiveCheckBoxIntensity.Value = 1;            
        end

        % Value changed function: MaxEditField_4
        function MaxEditField_4ValueChanged(app, event)
            app.FilterActiveCheckBoxIntensity.Value = 1;            
        end

        % Value changed function: UseManualPolymaskCheckBox
        function UseManualPolymaskCheckBoxValueChanged(app, event)
            value = app.UseManualPolymaskCheckBox.Value;
            if value == 1 %polymask enabled
                app.FilterActiveCheckBoxPositionX.Value = 0;
                app.FilterActiveCheckBoxPositionY.Value = 0;
                %dont use the usual filter
                app.FilterActiveCheckBoxPositionX.Enable = "off";
                app.FilterActiveCheckBoxPositionY.Enable = "off";
                app.MinEditField_4.Enable = "off";
                app.MaxEditField.Enable = "off";
                app.MinEditField_5.Enable = "off";
                app.MaxEditField_2.Enable = "off";
            else %make elemts available again
                app.FilterActiveCheckBoxPositionX.Enable = "on";
                app.FilterActiveCheckBoxPositionY.Enable = "on";
                app.MinEditField_4.Enable = "on";
                app.MaxEditField.Enable = "on";
                app.MinEditField_5.Enable = "on";
                app.MaxEditField_2.Enable = "on";
            end
        end

        % Button pushed function: SetSaveFolderButton
        function SetSaveFolderButtonPushed(app, event)
            app.SaveLocation = uigetdir;
            if ~isempty(app.SaveLocation)
                app.NoFolderSelectedLabel.Text = app.SaveLocation;
            end
            performSanityCheck(app);
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            d = uiprogressdlg(app.PreprocessingWindowUIFigure,'Title','Processing', 'Indeterminate','on');
            drawnow
            if app.Switch_2.Value == "Image Data Processing"
                %Perform the image division. Divide bead from cell if
                %necessary. Do it with all the collected images.
                files = app.FileLocations;
                for i = 1:size(files,1)
                    processBeadsAndCellImage(files{i,1}, app);
                end
            else
                performLocalisationPreprocessing(app);
                calculatePrecision(app);
                calculateDiffractionLimit(app);
                calculateExpNoiseRate(app);
            end
            delete(d);
        end

        % Close request function: PreprocessingWindowUIFigure
        function PreprocessingWindowUIFigureCloseRequest(app, event)
            app.Mainapp.rdPeakUIFigure.Visible = "on";
            delete(app)
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            %select a tracked  file to estimate the exp displacement
            %and pbleach.
            %load file
            tmp = app.ImportSettingsStruct;
            app.ImportSettingsStruct = app.TrackSettingsStruct;
            
            app.tracks = loadTrackDataFileSelection(app, 1, 1);
            app.tracks = app.tracks{1,1};
            % Display uigetfile dialog
            %filterspec = {'*.csv'};
            %[f, p] = uigetfile(filterspec);
            %loadfile = (fullfile(p,f));
            % Make sure user didn't cancel uigetfile dialog
           % if ~ischar(p)
            %    error("No file selected")
            %end
            %app.tracks = ImportSwiftAsTrc(loadfile, 1);
            
            %check datatype, calcuate extra stuff if necessary
            if size(app.tracks,2)<7
                %no MJD and no N calculated yet
                ids = unique(app.tracks(:,1));
                for i = 1:size(ids)
                    singltrack = app.tracks(app.tracks(:,1)==ids(i),:);
                    calculateJumpDistances(app, singltrack, "Calc");
                    calculateStepNumbers(app, singltrack, "Calc");
                end
                calculateMeanJumpDistanes(app, "Calc");
            else
                %all data present to be used
            end
            %calculate things
            calculateExpDisplacement(app);
            calculatePBleach(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            %tmp button for shortcut
            
        end

        % Callback function
        function ButtonPushed2(app, event)
            calculatePBleach(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value == "Localisation Data Processing"
                app.DataFilterPanel.Visible = "on";
                app.SelectFileButton.Enable = "off";
                app.SelectFileButton.Visible = "off";
                app.SelectMainFolderButton.Enable = "off";
                app.GetFilesButton.Enable = "off";
                app.Switch.Enable = "off";
                app.StartProcessingButton.Enable = "off";
                performSanityCheck(app);
            else
                app.DataFilterPanel.Visible = "off";
                app.SelectFileButton.Enable = "on";
                app.SelectFileButton.Visible = "on";
                app.SelectMainFolderButton.Enable = "on";
                app.GetFilesButton.Enable = "on";
                app.Switch.Enable = "on";
                app.StartProcessingButton.Enable = "on";
            end
        end

        % Button pushed function: DataTypeButton
        function DataTypeButtonPushed(app, event)
            app.DataTypeButton.Enable = "off";
            app.TrackSettingsWindow = ImportSettingsTracksPreprocessingWindow(app);
        end

        % Button pushed function: CalculateFSCButton
        function CalculateFSCButtonPushed(app, event)
            [finalvoxsize,error] = calculateFSC(app);
            app.FSCValueLabel.Text = int2str(finalvoxsize);
            app.ErrorvalLabel.Text = join(["+- " int2str(error)]);
        end

        % Button pushed function: CalculateFRCButton
        function CalculateFRCButtonPushed(app, event)
            frc = calculateFRC(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PreprocessingWindowUIFigure = uifigure('Visible', 'off');
            app.PreprocessingWindowUIFigure.Name = 'Preprocessing Window';
            app.PreprocessingWindowUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.PreprocessingWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @PreprocessingWindowUIFigureCloseRequest, true);
            app.LoadDataPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.LoadDataPanel.Tooltip = {'Panel to control the '; 'loading of the data that will be processed.'};
            app.LoadDataPanel.Title = 'Load Data';
            app.DataTypeLabel = uilabel(app.LoadDataPanel);
            app.DataTypeLabel.Text = 'Data Type';
            app.FolderStructureLabel = uilabel(app.LoadDataPanel);
            app.FolderStructureLabel.Text = 'Folder Structure';
            app.SettingsButton = uibutton(app.LoadDataPanel, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Tooltip = {'Opens the Settings Window to determine the type of file that will be used for loading the data.'};
            app.SettingsButton.Text = 'Settings';
            app.Switch = uiswitch(app.LoadDataPanel, 'slider');
            app.Switch.Items = {'Single Folder', 'Nested Folder'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'This switch determines if all files are located in one folder (Single Folder) or if they are organized in a layered subfolders.'};
            app.Switch.Value = 'Single Folder';
            app.AllfilesinasinglefolderLabel = uilabel(app.LoadDataPanel);
            app.AllfilesinasinglefolderLabel.Text = 'All files in a single folder';
            app.NestedFolderStructureLabel = uilabel(app.LoadDataPanel);
            app.NestedFolderStructureLabel.Text = 'Nested Folder Structure';
            app.SelectFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Tooltip = {'Lets you select localisation files from a single subfolder'};
            app.SelectFilesButton.Text = 'Select Files';
            app.SelectMainFolderButton = uibutton(app.LoadDataPanel, 'push');
            app.SelectMainFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMainFolderButtonPushed, true);
            app.SelectMainFolderButton.Tooltip = {'Select the uppermost folder of your subfolder hirachy.'};
            app.SelectMainFolderButton.Text = 'Select Main Folder';
            app.FileStringPatternEditFieldLabel = uilabel(app.LoadDataPanel);
            app.FileStringPatternEditFieldLabel.Text = 'File String Pattern';
            app.FileStringPatternEditField = uieditfield(app.LoadDataPanel, 'text');
            app.FileStringPatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.GetFilesButton = uibutton(app.LoadDataPanel, 'push');
            app.GetFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GetFilesButtonPushed, true);
            app.GetFilesButton.Tooltip = {'After selecting the main folder and defining the File String Pattern, press this button to search for your files.'};
            app.GetFilesButton.Text = 'Get Files';
            app.FilesFoundLabel = uilabel(app.LoadDataPanel);
            app.FilesFoundLabel.Text = 'Files Found:';
            app.FilesFoundNumber = uilabel(app.LoadDataPanel);
            app.FilesFoundNumber.Text = '0';
            app.Switch_2 = uiswitch(app.LoadDataPanel, 'slider');
            app.Switch_2.Items = {'Localisation Data Processing', 'Image Data Processing'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Localisation Data Processing';
            app.LocalisationDataPreprocessingLabel = uilabel(app.PreprocessingWindowUIFigure);
            app.LocalisationDataPreprocessingLabel.Text = 'Localisation Data Preprocessing';
            app.StartProcessingButton = uibutton(app.PreprocessingWindowUIFigure, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Tooltip = {'Starts the filtering of the data and will save the filtered locations in a format to be used by swift for tracking. Also some parameters for the swift tracking will be calculated and displayed.'};
            app.StartProcessingButton.Text = 'Start Processing';
            app.DataFilterPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.DataFilterPanel.Title = 'Data Filter';
            app.FilterbyPositionLabel = uilabel(app.DataFilterPanel);
            app.FilterbyPositionLabel.Text = 'Filter by Position';
            app.UseManualPolymaskCheckBox = uicheckbox(app.DataFilterPanel);
            app.UseManualPolymaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseManualPolymaskCheckBoxValueChanged, true);
            app.UseManualPolymaskCheckBox.Tooltip = {'If this button is active, manually draw a mask around your cells to remove the background localisations.'; 'Can not be used with XY filter.'};
            app.UseManualPolymaskCheckBox.Text = 'Use Manual Polymask';
            app.XLabel = uilabel(app.DataFilterPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.DataFilterPanel);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.DataFilterPanel);
            app.ZLabel.Text = 'Z';
            app.FilterbyPrecisionLabel = uilabel(app.DataFilterPanel);
            app.FilterbyPrecisionLabel.Text = 'Filter by Precision';
            app.XYLabel = uilabel(app.DataFilterPanel);
            app.XYLabel.Text = 'XY';
            app.ZLabel_2 = uilabel(app.DataFilterPanel);
            app.ZLabel_2.Text = 'Z';
            app.MinEditField_4Label = uilabel(app.DataFilterPanel);
            app.MinEditField_4Label.Text = 'Min';
            app.MinEditField_4 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_4.ValueChangedFcn = createCallbackFcn(app, @MinEditField_4ValueChanged, true);
            app.MinEditField_4.Tooltip = {'Defines the minimal value of the X Coordinate to be kept.'};
            app.MaxEditFieldLabel = uilabel(app.DataFilterPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Tooltip = {'Defines the maximal value of the X Coordinate to be kept.'};
            app.MinEditField_5Label = uilabel(app.DataFilterPanel);
            app.MinEditField_5Label.Text = 'Min';
            app.MinEditField_5 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_5.ValueChangedFcn = createCallbackFcn(app, @MinEditField_5ValueChanged, true);
            app.MinEditField_5.Tooltip = {'Defines the minimal value of the Y Coordinate to be kept.'};
            app.MaxEditField_2Label = uilabel(app.DataFilterPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_2ValueChanged, true);
            app.MaxEditField_2.Tooltip = {'Defines the maximalvalue of the Y Coordinate to be kept.'};
            app.MinEditField_6Label = uilabel(app.DataFilterPanel);
            app.MinEditField_6Label.Text = 'Min';
            app.MinEditField_6 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_6.ValueChangedFcn = createCallbackFcn(app, @MinEditField_6ValueChanged, true);
            app.MinEditField_6.Tooltip = {'Defines the minimal value of the Z Coordinate to be kept.'};
            app.MaxEditField_3Label = uilabel(app.DataFilterPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.MaxEditField_3 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField_3.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_3ValueChanged, true);
            app.MaxEditField_3.Tooltip = {'Defines the maximalvalue of the Z Coordinate to be kept.'};
            app.SigmaEditFieldLabel = uilabel(app.DataFilterPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.DataFilterPanel, 'numeric');
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Tooltip = {'Allows to filter the localisations by their localisation precision in XY.'};
            app.SigmaEditField_3Label = uilabel(app.DataFilterPanel);
            app.SigmaEditField_3Label.Text = 'Sigma';
            app.SigmaEditField_3 = uieditfield(app.DataFilterPanel, 'numeric');
            app.SigmaEditField_3.ValueChangedFcn = createCallbackFcn(app, @SigmaEditField_3ValueChanged, true);
            app.SigmaEditField_3.Tooltip = {'Allows to filter the localisations by their localisation precision in Z.'};
            app.IntensityLabel = uilabel(app.DataFilterPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.FilterActiveCheckBoxPositionX = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPositionX.Text = 'Filter Active';
            app.FilterActiveCheckBoxPositionY = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPositionY.Text = 'Filter Active';
            app.FilterActiveCheckBoxPositionZ = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPositionZ.Text = 'Filter Active';
            app.FilterActiveCheckBoxPrecisionXY = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPrecisionXY.Text = 'Filter Active';
            app.FilterActiveCheckBoxPrecisionZ = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxPrecisionZ.Text = 'Filter Active';
            app.FilterActiveCheckBoxIntensity = uicheckbox(app.DataFilterPanel);
            app.FilterActiveCheckBoxIntensity.Text = 'Filter Active';
            app.MinEditField_7Label = uilabel(app.DataFilterPanel);
            app.MinEditField_7Label.Text = 'Min';
            app.MinEditField_7 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MinEditField_7.ValueChangedFcn = createCallbackFcn(app, @MinEditField_7ValueChanged, true);
            app.MinEditField_7.Tooltip = {'Defines the minimal intensity value  to be kept.'};
            app.MaxEditField_4Label = uilabel(app.DataFilterPanel);
            app.MaxEditField_4Label.Text = 'Max';
            app.MaxEditField_4 = uieditfield(app.DataFilterPanel, 'numeric');
            app.MaxEditField_4.ValueChangedFcn = createCallbackFcn(app, @MaxEditField_4ValueChanged, true);
            app.MaxEditField_4.Tooltip = {'Defines the maximal intensity value  to be kept.'};
            app.UnitUsedLabel = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel.Text = ' ';
            app.UnitUsedLabel_2 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_2.Text = ' ';
            app.UnitUsedLabel_3 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_3.Text = ' ';
            app.UnitUsedLabel_4 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_4.Text = ' ';
            app.UnitUsedLabel_5 = uilabel(app.DataFilterPanel);
            app.UnitUsedLabel_5.Text = ' ';
            app.SaveDataPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.SaveDataPanel.Title = 'Save Data';
            app.SetSaveFolderButton = uibutton(app.SaveDataPanel, 'push');
            app.SetSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SetSaveFolderButtonPushed, true);
            app.SetSaveFolderButton.Tooltip = {'Select a save folder where the results will be saved to. A subfolder per analysis will be generated, so there is no need to change it once set.'};
            app.SetSaveFolderButton.Text = 'Set Save Folder';
            app.NoFolderSelectedLabel = uilabel(app.SaveDataPanel);
            app.NoFolderSelectedLabel.Text = 'No Folder Selected';
            app.EditField = uieditfield(app.SaveDataPanel, 'text');
            app.EditField.Tooltip = {'Folder name to save the analysed data to. Default is Analysis.'};
            app.EditField.Value = 'Analysis';
            app.Panel = uipanel(app.PreprocessingWindowUIFigure);
            app.Panel.Title = ' ';
            app.SwiftParameterEstimationLabel = uilabel(app.Panel);
            app.SwiftParameterEstimationLabel.Text = 'Swift Parameter Estimation';
            app.SelectFileButton = uibutton(app.Panel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Tooltip = {'Select a tracked file from swift to estimate the displacement and the bleach value. A few iterations of this process should lead to a convergance to a suitable value.'};
            app.SelectFileButton.Text = 'Select File';
            app.p_BleachLabel = uilabel(app.Panel);
            app.p_BleachLabel.Tooltip = {'The probability of a particle to bleach between frames. '};
            app.p_BleachLabel.Text = 'p_Bleach';
            app.ExpDisplacementLabel = uilabel(app.Panel);
            app.ExpDisplacementLabel.Tooltip = {'The distance a particle will move between frames.'};
            app.ExpDisplacementLabel.Text = 'Exp Displacement';
            app.PrecisonXYLabel = uilabel(app.Panel);
            app.PrecisonXYLabel.Tooltip = {'Precision of XY determined from the localisation data.'};
            app.PrecisonXYLabel.Text = 'PrecisonXY';
            app.DiffractionLimitLabel = uilabel(app.Panel);
            app.DiffractionLimitLabel.Tooltip = {'Minimal distance of two points to be separated.'};
            app.DiffractionLimitLabel.Text = 'Diffraction Limit';
            app.ExpNoiseRateLabel = uilabel(app.Panel);
            app.ExpNoiseRateLabel.Tooltip = {'The amount of background signal'};
            app.ExpNoiseRateLabel.Text = 'Exp Noise Rate';
            app.p_BleachValue = uilabel(app.Panel);
            app.p_BleachValue.Text = 'p_Bleach';
            app.ExpDisplacementValue = uilabel(app.Panel);
            app.ExpDisplacementValue.Text = 'Exp Displacement';
            app.PreciisonValue = uilabel(app.Panel);
            app.PreciisonValue.Text = 'PrecisonXY';
            app.DiffractionLimitValue = uilabel(app.Panel);
            app.DiffractionLimitValue.Text = 'Diffraction Limit';
            app.ExpNoiseRateValue = uilabel(app.Panel);
            app.ExpNoiseRateValue.Text = 'Exp Noise Rate';
            app.PrecisonZLabel = uilabel(app.Panel);
            app.PrecisonZLabel.Tooltip = {'Precision of Z determined from the localisation data.'};
            app.PrecisonZLabel.Text = 'PrecisonZ';
            app.PreciisonZValue = uilabel(app.Panel);
            app.PreciisonZValue.Text = 'PrecisonZ';
            app.DataTypeButton = uibutton(app.Panel, 'push');
            app.DataTypeButton.ButtonPushedFcn = createCallbackFcn(app, @DataTypeButtonPushed, true);
            app.DataTypeButton.Text = 'Data Type';
            app.DriftCorrectionPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.DriftCorrectionPanel.Title = 'Drift Correction';
            app.PerformDriftCorrectionCheckBox = uicheckbox(app.DriftCorrectionPanel);
            app.PerformDriftCorrectionCheckBox.Text = 'Perform Drift Correction';
            app.CellFilePatternEditFieldLabel = uilabel(app.DriftCorrectionPanel);
            app.CellFilePatternEditFieldLabel.Text = 'Cell File  Pattern';
            app.CellFilePatternEditField = uieditfield(app.DriftCorrectionPanel, 'text');
            app.CellFilePatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.SelectMainFolderButton_2 = uibutton(app.DriftCorrectionPanel, 'push');
            app.SelectMainFolderButton_2.Tooltip = {'Select the uppermost folder of your subfolder hirachy.'};
            app.SelectMainFolderButton_2.Text = 'Select Main Folder';
            app.GetFilesButton_2 = uibutton(app.DriftCorrectionPanel, 'push');
            app.GetFilesButton_2.Tooltip = {'After selecting the main folder and defining the File String Pattern, press this button to search for your files.'};
            app.GetFilesButton_2.Text = 'Get Files';
            app.Switch_3 = uiswitch(app.DriftCorrectionPanel, 'slider');
            app.Switch_3.Items = {'No Reference Bead', 'Reference Bead'};
            app.Switch_3.Value = 'No Reference Bead';
            app.BeadFilePatternEditFieldLabel = uilabel(app.DriftCorrectionPanel);
            app.BeadFilePatternEditFieldLabel.Text = 'Bead File  Pattern';
            app.BeadFilePatternEditField = uieditfield(app.DriftCorrectionPanel, 'text');
            app.BeadFilePatternEditField.Tooltip = {'Define a string pattern here to look for files of interest. Use "%d" as a counting placeholder. E.g File_1; File_2; File_%d.'};
            app.FilesFoundLabel_2 = uilabel(app.DriftCorrectionPanel);
            app.FilesFoundLabel_2.Text = 'Files Found:';
            app.Label = uilabel(app.DriftCorrectionPanel);
            app.Label.Text = '0';
            app.ResolutionValidationPanel = uipanel(app.PreprocessingWindowUIFigure);
            app.ResolutionValidationPanel.Title = 'Resolution Validation';
            app.CalculateFSCButton = uibutton(app.ResolutionValidationPanel, 'push');
            app.CalculateFSCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFSCButtonPushed, true);
            app.CalculateFSCButton.Text = 'Calculate FSC';
            app.FSCValueLabel = uilabel(app.ResolutionValidationPanel);
            app.FSCValueLabel.Text = 'FSC Value';
            app.ErrorvalLabel = uilabel(app.ResolutionValidationPanel);
            app.ErrorvalLabel.Text = 'Errorval';
            app.CalculateFRCButton = uibutton(app.ResolutionValidationPanel, 'push');
            app.CalculateFRCButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFRCButtonPushed, true);
            app.CalculateFRCButton.Text = 'Calculate FRC';
            app.FRCValueLabel = uilabel(app.ResolutionValidationPanel);
            app.FRCValueLabel.Text = 'FRC Value';
            app.FRCErrorLabel = uilabel(app.ResolutionValidationPanel);
            app.FRCErrorLabel.Text = 'FRCError';
            % Show the figure after all components are created
            app.PreprocessingWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreprocessingWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PreprocessingWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PreprocessingWindowUIFigure)
        end
    end
end