classdef ROIVisualisationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ArrangeROIsPanel                matlab.ui.container.Panel
        RotateselectedROIButton         matlab.ui.control.Button
        SelectROIsListBox               matlab.ui.control.ListBox
        SelectROIsListBoxLabel          matlab.ui.control.Label
        DimensionsandPlotPanel          matlab.ui.container.Panel
        AxisScaleSwitch                 matlab.ui.control.Switch
        AxisScaleSwitchLabel            matlab.ui.control.Label
        MinTrackLengthEditField         matlab.ui.control.NumericEditField
        MinTrackLengthEditFieldLabel    matlab.ui.control.Label
        ScaleZEditField                 matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel            matlab.ui.control.Label
        ScaleXYEditField                matlab.ui.control.NumericEditField
        ScaleXYEditFieldLabel           matlab.ui.control.Label
        ShowTracksSwitch                matlab.ui.control.Switch
        ShowTracksSwitchLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        ImageSettingsPanel              matlab.ui.container.Panel
        Switch_5                        matlab.ui.control.Switch
        OffsetZEditField                matlab.ui.control.NumericEditField
        OffsetZEditFieldLabel           matlab.ui.control.Label
        OffsetYEditField                matlab.ui.control.NumericEditField
        OffsetYEditFieldLabel           matlab.ui.control.Label
        OffsetXEditField                matlab.ui.control.NumericEditField
        OffsetXEditFieldLabel           matlab.ui.control.Label
        FlipButton                      matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        ImportROIsPanel                 matlab.ui.container.Panel
        LoadReferenceImageButton        matlab.ui.control.Button
        LoadROIsButton_2                matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        KaryogramPanel                  matlab.ui.container.Panel
        PlotKaryogramButton             matlab.ui.control.Button
        TrackTypeDropDown               matlab.ui.control.DropDown
        TrackTypeDropDownLabel          matlab.ui.control.Label
        NrTrackstoshowEditField         matlab.ui.control.NumericEditField
        NrTrackstoshowEditFieldLabel    matlab.ui.control.Label
        CompleteAnalysisPanel           matlab.ui.container.Panel
        TracksButton                    matlab.ui.control.Button
        SpotsButton                     matlab.ui.control.Button
        SupermapGenerationPanel         matlab.ui.container.Panel
        SuperpixelSizeZEditField        matlab.ui.control.NumericEditField
        SuperpixelSizeZEditFieldLabel   matlab.ui.control.Label
        TimeWindowsEditField            matlab.ui.control.NumericEditField
        TimeWindowsEditFieldLabel       matlab.ui.control.Label
        SuperpixelSizeXYEditField       matlab.ui.control.NumericEditField
        SuperpixelSizeXYEditFieldLabel  matlab.ui.control.Label
        DensityButton                   matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        DiffusionButton                 matlab.ui.control.Button
        SingleTrackAnalysisPanel        matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        TrackIDLabel                    matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        SelectTrackButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        ImportSettingsVisualisationWindow %Link to import settings window
        ImportSettingsStruct %Settings from the Import Settings Window
    end
    
    properties (Access = private)
        SelectionWindow % Link to selection window
        
        AllTrackAnalysisWindow %Link to AllTrack Analysis
        SingleTrackAnalysisWindow %Link to Single Track Analysis
        SpotStatisticsWindow %Link to spot Statistics Window
        
        
        ImageData %Raw image tiff data from the localisation video
        AveragedImageData %Averaged Intensity Projection of the tiff stack
        TrackData %Imported tracking data
        CurrentTrackFileData %Holds the tracks of the currently selected file
        FilteredTrackData %Only contains the filtered tracks
        Spothandles %Handles for spots to plot
        Trackhandles %Handles for tracks to plot
        TrackIDList %ID list for the track handles
        ROIList %List of all the saved ROIs
        SelectedTrackData %Data of the selected track form track selection
        SelectedTrackID %ID of the selected track
        StoreAxes%Keeps plottet things save
        
        Scalexy
        CurrentROI % Description
    end
    
    methods
        function updateSettings(app)
            if isa(app.ImportSettingsVisualisationWindow, "ImportSettingsVisualisationWindow")
                app.ImportSettingsStruct = app.ImportSettingsVisualisationWindow.getSettings();
            else
                %Nothing
            end
        end

        function tracks = getTracks(app)
            tracks = app.FilteredTrackData;
        end
        
        function IDs = getAllIds(app)
            IDs = app.TrackIDList;
        end
        
        function sz = getPixelsize(app)
            sz = app.ScaleXYEditField.Value;
        end
        
        function ts = getTimestep(app)
            ts = app.ImportSettingsStruct.customUnits.Timestep;
        end
        
        function trackdata = getTrackbyID(app, ID)
            trackdata = app.CurrentTrackFileData(app.CurrentTrackFileData(:,1) == ID,:);
        end
        
        function intensityData = getIntensityData(app)
            switch app.Switch_4.Value
                case "Current File"
                    flagdata = 0;
                case "All Files"
                    flagdata = 1;
            end
            flagbox = app.UsecurrentROIforAnalysisCheckBox.Value;
            intensityData = {};
            
            if flagdata
                %All Files
                for i = 1:size(app.TrackData,1)
                    intensityData{i,1} = app.TrackData{i,2};
                    data = app.TrackData{i,1};
                    intensityData{i,2} = data(:,18);
                end
            else
                %Current File
                intensityData{1,1} = app.ROIFileDropDown.Value;
                intensityData{1,2} = app.CurrentTrackFileData(:,18);
                
            end
            if flagbox
                %only roi data
                intensityData{1,1} = [app.ROIListDropDown.Value "-ROI"];
                value = app.ROIListDropDown.Value;
                idx = find(strcmp(app.ROIListDropDown.Items,value));
                datacell = app.ROIList(idx,:);
                intensityData{1,2} = datacell{1,3}(:,18);
            end
        end
        
        function trackdata = getCurrentData(app)
            %currentfile
            trackdata{1,2} = app.ROIFileDropDown.Value;
            trackdata{1,1} = app.CurrentTrackFileData(:,:);
        end
        
        function trackdata = getTrackAnalysisData(app)
            switch app.Switch_4.Value
                case "Current File"
                    flagdata = 0;
                case "All Files"
                    flagdata = 1;
            end
            flagbox = app.UsecurrentROIforAnalysisCheckBox.Value;
            trackdata = {};
            if flagdata
                %All Files
                trackdata = app.TrackData;
            else
                %currentfile
                trackdata{1,2} = app.ROIFileDropDown.Value;
                trackdata{1,1} = app.CurrentTrackFileData(:,:);
            end
            if flagbox
                %only roi data
                trackdata{1,1} = [app.ROIListDropDown.Value "-ROI"];
                value = app.ROIListDropDown.Value;
                idx = find(strcmp(app.ROIListDropDown.Items,value));
                datacell = app.ROIList(idx,:);
                trackdata{1,2} = datacell{1,3}(:,:);
            end
        end
        
    end


    methods (Access = private)
        
        function manageBackgroundImage(app)
            ax = app.UIAxes;
            imagedata = [];
            %colormap(ax,gray);
            switch app.Switch_5.Value
                case "Off"
                    %nothing
                    
                case "On"
                    %plot the background image
                    if app.AverageIntensityCheckBox.Value == 0
                        %select a frame for the image
                        frame = app.FrameEditField.Value;
                        imagedata = app.ImageData(:,:,frame);
                    else
                        %use the average intensity projection
                        if isempty(app.AveragedImageData)
                            imagedata = generateAverageIntensityProjection(app);
                            app.AveragedImageData = imagedata;
                        end
                        imagedata = app.AveragedImageData;
                    end
                    %Create surface for the image
                    startx = 0+app.OffsetXEditField.Value;
                    starty = 0+app.OffsetYEditField.Value;
                    x = startx:startx+size(imagedata,1)-1;
                    y = starty:starty+size(imagedata,2)-1;
                    z = app.OffsetZEditField.Value;
                    [X,Y,Z] = meshgrid(x,y,z);
                    
                    %get the image
                    %I = image(ax,imagedata,'CDataMapping','scaled');
                    I = imagedata;
                    
                    %need to normalize image to fit into the colormap
                    minval = min(min(imagedata));
                    maxval = max(max(imagedata));
                    
                    scale = 65535;
                    
                    normImage = ((I-minval)/(maxval-minval))*scale;

                    %place the image on the surface
                    %axes(ax);
                    %hold(app.UIAxes,"on");
                    h1 = figure();
                    set(h1,"Visible","off");
                    warp(X,Y,Z,normImage,scale);  
                    colormap(ax,"parula"); 
                    copyobj(h1.Children.Children,ax);
                    %hold(app.UIAxes,"off");
            end
        end
        
        function outputImage = generateAverageIntensityProjection(app)
            imagestack = app.ImageData;
            %generate the averageIntensityProjection
            [rows, columns, ~] = size(imagestack);
            outputImage = zeros(rows, columns);
            for col = 1 : columns
                for row = 1 : rows
                    ZVector = imagestack(row, col, :);
                    meanValue = ceil(mean(ZVector));
                    outputImage(row, col) = meanValue;
                end
            end
            %app.AveragedImageData = outputImage;
        end
        
        function filterTracksbyLength(app, tracklength)
            edges = unique(app.CurrentTrackFileData(:,1));
            counts = histc(app.CurrentTrackFileData(:,1),edges); %#ok<ADPROPLC> 
            interest = find(counts > tracklength);
            intracksnr = edges(interest); %#ok<ADPROPLC> 
            filtertracks = [];
            for j = 1:length(intracksnr)
                k = intracksnr(j);
                intresttrackind = find(app.CurrentTrackFileData(:,1) == k);
                filtertracks = cat(1,filtertracks, app.CurrentTrackFileData(intresttrackind,:));
            end
            if isempty(filtertracks)
                error("No tracks left after filtering!")
            end
            app.FilteredTrackData = filtertracks;
        end
        
        function updateTrackFileSelection(app)
            %Fills the track file selection drop down menue with the
            %availabe files
            filenames = app.TrackData(:,2);
            app.ROIFileDropDown.Items = filenames;
            app.ROIFileDropDown.Value = filenames(1);
        end
        
        function setCurrentTrackData(app)
            %Depending on the selected track file, the current available
            %data is stored in the currentTrackData property
            value = app.ROIFileDropDown.Value;
            index = find(contains(app.TrackData(:,2), value));
            app.CurrentTrackFileData = app.TrackData{index,1};
            %Update Track related information
            filterTracksbyLength(app, app.MinTrackLengthEditField.Value);
            updateTrackSelection(app);
        end
        
        function updateTrackSelection(app)
            %Fill the selection box with the unique ids of the individual
            %tracks
            IDs = unique(app.FilteredTrackData(:,1));
            IDs = IDs.';
            IDs = string(IDs);
            app.TrackIDList = IDs;
            app.TrackIDDropDown.Items = IDs;
        end
        
        function manageTrackOverlay(app)
            switch app.Switch.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            switch app.ShowTracksSwitch.Value
                case "On"
                    flagOn = 1;
                case "Off"
                    flagOn = 0;
            end
            switch app.Switch_6.Value
                case "All"
                    flagtrc = 1;
                case "Single Track"
                    flagtrc = 0;
            end
            
            scalexy = app.ScaleXYEditField.Value;
            scalez = app.ScaleZEditField.Value;
            scalexy = 1/scalexy;

            manageBackgroundImage(app);
            hold(app.UIAxes,"on");
            updateVisualisationTracesOverlay(app, flag3d, flagOn, flagtrc, scalexy, scalez, 1); %tracks
            
            app.Trackhandles = app.UIAxes.Children;
            idxs = zeros(size(app.Trackhandles,1),1);
            for i = 1:size(app.Trackhandles,1)
                idxs(i) = isa(app.Trackhandles(i), "matlab.graphics.chart.primitive.Line");
            end
            idxs = logical(idxs);
            app.Trackhandles = app.Trackhandles(idxs);
            
            for i = 1:numel(app.Trackhandles)
                % Add a new row to DataTip showing the DisplayName of the line
                app.Trackhandles(i).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Track',repmat({app.Trackhandles(i).DisplayName},size(app.Trackhandles(i).XData))); 
            end
            if flagtrc == 0
                updateVisualisationTracesOverlay(app, flag3d, flagOn, flagtrc, scalexy, scalez, 0); %spots
            end

            hold(app.UIAxes,"off");
        end
        
        function ID = getIDfromDataTip(app)
            lines = app.UIAxes.Children;
            for i = 1:size(lines,1)
                if ~isempty(lines(i).Children)
                datatip = lines(i).Children;
                end
            end
            ID = datatip.Content(end);
            ID = erase(ID,"Track ");
            ID = str2double(ID);
        end
        
        function updateFrameSliderLimits(app)
            if ~isempty(app.CurrentTrackFileData)
                %got some trackdata to work with, set slider limts
                %accordingly
                mint = 1;
                maxt = max(app.CurrentTrackFileData(:,2));
                limits = [mint, maxt];
                app.TimeSlider.Limits = limits;
            elseif ~isempty(app.ImageData)
                %ony an image has been loaded
                mint = 1;
                maxt = size(app.ImageData,3);
                limits = [mint, maxt];
                app.TimeSlider.Limits = limits;
            else
                app.TimeSlider.Limits = [1,100];
            end
        end
        
        function updateROIList(app)
            names = {};
            for i = 1:size(app.ROIList,1)
                names{i} = strcat(app.ROIList{i,4}, "-", app.ROIList{i,1});
            end
            names = vertcat(names{:});
            app.SelectROIsListBox.Items = names;
            
        end
        
        function switchROI(app)
            value = app.ROIListDropDown.Value;
            idx = find(strcmp(app.ROIListDropDown.Items,value));
            dim = size(app.ROIListDropDown.Items,2);
            datacell = app.ROIList(idx,:);
            try
                delete(app.CurrentROI);
            catch
            end
            roiidx = flip(linspace(1, dim, dim));
            idx = find(roiidx == idx);
            oldROI = app.StoreAxes.Children(idx);
            app.CurrentROI = copyobj(oldROI,app.UIAxes);       
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.SelectionWindow = mainapp;
            %app.LoadROIsButton_2.Enable = "off";
            app.ROIList = cell(0,4);
            app.StoreAxes = axes();
            set(gcf, "visible", "off")
            set(app.StoreAxes, "visible", "off")
        end

        % Callback function
        function ImportSettingsButtonPushed(app, event)
            app.ImportSettingsButton.Enable = "off";
            if isa(app.ImportSettingsVisualisationWindow, "ImportSettingsVisualisationWindow")
                app.ImportSettingsVisualisationWindow.UIFigure.Visible = "on";
            else
                app.ImportSettingsVisualisationWindow = ImportSettingsVisualisationWindow(app);
            end
            
        end

        % Button pushed function: TracksButton
        function TracksButtonPushed(app, event)
            app.AllTrackAnalysisWindow = AllTrackAnalysisWindow(app);
        end

        % Button pushed function: SpotsButton
        function SpotsButtonPushed(app, event)
            app.SpotStatisticsWindow = SpotStatisticsWindow(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            try
                ID = getIDfromDataTip(app);
                app.SelectedTrackID = ID;
                app.Label_3.Text = num2str(ID);
            end

            app.SelectedTrackData = app.FilteredTrackData(app.FilteredTrackData(:,1) == ID,:);
            app.SingleTrackAnalysisWindow = SingleTrackAnalysisWindow(app, app.SelectedTrackData, ID);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.SelectionWindow.UIFigure.Visible = "on";
            delete(app)
        end

        % Button pushed function: LoadROIsButton_2
        function LoadROIsButton_2Pushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec, 'MultiSelect','on');
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            %app.ROIList = cell();
            if isa(f,"char") %only one file selected, convert to cell 
                            b = f;
                            f = cell(1,1);
                            f{1,1} = b;
            end
            loadfile = cell(size(f,2),2);
            for i = 1:size(f,2)
                tmpfile = load(convertCharsToStrings(fullfile(p,f)));
                if size(tmpfile.ROIs,2) == 4 && size(tmpfile.ROIs,1) > 0
                    for j = 1:size(tmpfile.ROIs,1)
                        app.ROIList(end+1,:) = tmpfile.ROIs(j,:);
                        %app.StoreAxes = tmpfile.storeax;
                    end
                end
            end
            updateROIList(app);
            
            
            %updateTrackFileSelection(app);
            %setCurrentTrackData(app);

            %manageTrackOverlay(app);
        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            %Button to select the microscopy image data. Search in file
            %system. Will load tiff-stacks

            % Display uigetfile dialog
            filterspec = {'*.tif'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            %imagestack = tiffreadVolume(fullfile(p,f));
            warning('off', 'MATLAB:imagesci:tifftagsread:expectedTagDataFormat');
            w = warning('off', 'MATLAB:imagesci:tiffmexutils:libtiffWarning');
            
            d = uiprogressdlg(app.UIFigure,'Title','Loading Tiff', 'Indeterminate','on');
            drawnow
            
            imagestack = TIFFStack(fullfile(p,f));
            imagestack = cast(imagestack,"double");
            
            delete(d);
            %generate the averageIntensityProjection
            outputImage = generateAverageIntensityProjection(app);
            
            app.ImageData = imagestack;
            app.AveragedImageData = outputImage;
            
            updateFrameSliderLimits(app);
            manageBackgroundImage(app);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            %Rotates the image 90° counterclockwise
            app.ImageData = rot90(app.ImageData);
            generateAverageIntensityProjection(app);
            manageBackgroundImage(app);
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            %Flips/Mirrors the image in the background
            app.ImageData = flip(app.ImageData,2);
            generateAverageIntensityProjection(app);
            manageBackgroundImage(app);
        end

        % Callback function
        function AverageIntensityCheckBoxValueChanged(app, event)
            value = app.AverageIntensityCheckBox.Value;
            generateAverageIntensityProjection(app);
            manageBackgroundImage(app);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Callback function
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            manageBackgroundImage(app);
        end

        % Value changed function: OffsetXEditField
        function OffsetXEditFieldValueChanged(app, event)
            value = app.OffsetXEditField.Value;
            manageBackgroundImage(app);
        end

        % Value changed function: OffsetYEditField
        function OffsetYEditFieldValueChanged(app, event)
            value = app.OffsetYEditField.Value;
            manageBackgroundImage(app);
        end

        % Value changed function: OffsetZEditField
        function OffsetZEditFieldValueChanged(app, event)
            value = app.OffsetZEditField.Value;
            manageBackgroundImage(app);
        end

        % Value changed function: MinTrackLengthEditField
        function MinTrackLengthEditFieldValueChanged(app, event)
            value = app.MinTrackLengthEditField.Value;
            filterTracksbyLength(app, value);
            manageTrackOverlay(app);
        end

        % Callback function
        function ROIFileDropDownValueChanged(app, event)
            value = app.ROIFileDropDown.Value;
            setCurrentTrackData(app);
            cla(app.UIAxes);
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Callback function
        function TrackIDDropDownValueChanged(app, event)
            value = str2double(app.TrackIDDropDown.Value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            app.FrameEditField.Value = frame;
            cla(app.UIAxes);
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Callback function
        function PrevTracksButtonPushed(app, event)
            IDs = app.TrackIDList;
            %currID = str2double(app.TrackIDDropDown.Value);
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)-1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Callback function
        function NextTracksButtonPushed(app, event)
            IDs = app.TrackIDList;
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)+1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            value = app.TimeSlider.Value; % get the slider value
            rotate3d(app.UIAxes,'off');
	        key = event.Key; % get the pressed key value
            step = app.StepsizeEditField.Value;
            if strcmp(key,'leftarrow')
        	    value = value-step; % left value
            elseif strcmp(key,'rightarrow')
                value = value+step; % right value
            end
            app.TimeSlider.Value = value; % set the slider value
            app.TimepointEditField.Value = value;


        end

        % Button pushed function: SelectTrackButton
        function SelectTrackButtonPushed(app, event)
            datacursormode(app.UIFigure,"toggle");
            
        end

        % Size changed function: SingleTrackAnalysisPanel
        function SingleTrackAnalysisPanelSizeChanged(app, event)
            position = app.SingleTrackAnalysisPanel.Position;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            
        end

        % Value changed function: ShowTracksSwitch
        function ShowTracksSwitchValueChanged(app, event)
            value = app.ShowTracksSwitch.Value;
            manageTrackOverlay(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.Switch_3.Value = value;
            cla(app.UIAxes);
            manageTrackOverlay(app);
            switch value
                case "2D"
                    view(app.UIAxes,2);
                case "3D"
                    view(app.UIAxes,45,45);
            end
        end

        % Callback function
        function TimepointEditFieldValueChanged(app, event)
            value = app.TimepointEditField.Value;
            app.TimeSlider.Value = value;
            manageTrackOverlay(app);
        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            if datacursormode.Enable == "on"
                try
                    app.Label_3 = getIDfromDataTip(app);
                end
            end
        end

        % Callback function
        function TimeSliderValueChanged(app, event)
            value = ceil(app.TimeSlider.Value);
            app.TimepointEditField.Value = value;
            manageTrackOverlay(app);
        end

        % Callback function
        function TimeSliderValueChanging(app, event)
            value = ceil(app.TimeSlider.Value);
            app.TimepointEditField.Value = value;
            manageTrackOverlay(app);
        end

        % Callback function
        function Switch_6ValueChanged(app, event)
            value = app.Switch_6.Value;
            cla(app.UIAxes);
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: ScaleXYEditField
        function ScaleXYEditFieldValueChanged(app, event)
            value = app.ScaleXYEditField.Value;
            cla(app.UIAxes);
            manageTrackOverlay(app);
        end

        % Value changed function: ScaleZEditField
        function ScaleZEditFieldValueChanged(app, event)
            value = app.ScaleZEditField.Value;
            cla(app.UIAxes);
            manageTrackOverlay(app);
        end

        % Value changed function: AxisScaleSwitch
        function AxisScaleSwitchValueChanged(app, event)
            value = app.AxisScaleSwitch.Value;
            app.Scalexy = app.ScaleXYEditField.Value;
            switch value
                case "Auto"
                    app.ScaleXYEditField.Value = app.Scalexy;
                    axis(app.UIAxes,'normal')
                case "Equal"
                    app.ScaleXYEditField.Value = 1;
                    axis(app.UIAxes,'equal')
            end
            cla(app.UIAxes);
            manageTrackOverlay(app);
        end

        % Callback function
        function SelectROIButtonPushed(app, event)
            %first make sure that no other ROI is in the axes
            try
                delete(app.CurrentROI);
            catch
                             
            end
            
            switch app.Switch_3.Value
                case "2D"
                    roi = drawrectangle(app.UIAxes);
                case "3D"
                    roi = drawcuboid(app.UIAxes);
            end
            app.CurrentROI = roi;          
        end

        % Callback function
        function SaveROIButtonPushed(app, event)
            scale = app.ScaleXYEditField.Value;
            if strcmp(app.CurrentROI.Type,"images.roi.cuboid") || strcmp(app.CurrentROI.Type,"images.roi.rectangle")
                name = string(app.ROINameEditField.Value);
                roiobj = app.CurrentROI;
                roiobj = copyobj(roiobj,app.StoreAxes);
                dataidx = inROI(roiobj, app.FilteredTrackData(:,3)/scale,app.FilteredTrackData(:,4)/scale,app.FilteredTrackData(:,5)/app.ScaleZEditField.Value);
                if size(app.ROIList,1) == 0
                else
                    allROInames = [];
                    for i = 1:size(app.ROIList,1)
                        allROInames(i) = string(app.ROIList{i,1});
                    end
                    if any(string(allROInames) == name)
                        name = strcat(name, "_1");
                    end
                end
                
                app.ROIList{end+1,1} = name; %name
                app.ROIList{end,2} = roiobj; %object
                app.ROIList{end,3} = app.FilteredTrackData(dataidx,:);%data
                app.ROIList{end,4} = app.ROIFileDropDown.Value; %filename
                
                if app.SaveAnitROICheckBox.Value
                    app.ROIList{end+1,1} = ["Anti-" name]; %name
                    app.ROIList{end,2} = roiobj; %object
                    app.ROIList{end,3} = app.FilteredTrackData(~dataidx,:);%data
                    app.ROIList{end,4} = app.ROIFileDropDown.Value; %filename
                end
            end
            updateROIList(app);
            app.ROIListDropDown.Value = app.ROIListDropDown.Items(end);
     
        end

        % Callback function
        function ROIListDropDownValueChanged(app, event)
            switchROI(app)           
        end

        % Callback function
        function DeleteselectedROIButtonPushed(app, event)
            value = app.ROIListDropDown.Value;
            idx = find(strcmp(app.ROIListDropDown.Items, value));
            delete(app.StoreAxes.Children(idx));
            app.ROIList(idx,:) = [];
            updateROIList(app);
            app.ROIListDropDown.Value = app.ROIListDropDown.Items(1);
        end

        % Callback function
        function SaveallROIsforBatchAnalysisButtonPushed(app, event)
            if size(app.ROIList,1) > 0 
                % Display uigetfile dialog
                filterspec = {'*.mat'};
                [f, p] = uiputfile(filterspec);
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
    
                fulfile = fullfile(p,f);
                ROIs = app.ROIList;
                storeax = app.StoreAxes;
                save(fulfile, "ROIs", "storeax");
            end
        end

        % Callback function
        function LoadROIsButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            if size(loadfile,2) == 4 && size(loadfile,1) > 0
                app.ROIList = loadfile.ROIs;
                app.StoreAxes = loadfile.storeax;
                updateROIList(app);
            end
        end

        % Button pushed function: DiffusionButton
        function DiffusionButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = 0.04; %get from the import dialogue later
            nump = 1;
            filter = 0;
            filtersize = 3;
            
            makeVelocityMap(app, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize);
            
        end

        % Button pushed function: DriftButton
        function DriftButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = 0.04; %get from the import dialogue later
            nump = 1;
            filter = 0;
            filtersize = 3;
            makeDriftMap(app, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize);
        end

        % Button pushed function: DensityButton
        function DensityButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = 0.04; %get from the import dialogue later
            dopt = "DENS"; % "LOGDENS" "NPTS"
            filter = 0;
            filtersize = 1;
            
            makeDensityMap(app, flag3d, superpxsize, nmbrTimeWins, minAcqDT, dopt, filter, filtersize);
        end

        % Button pushed function: PlotKaryogramButton
        function PlotKaryogramButtonPushed(app, event)
            switch app.Switch.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            Karyogramm3DwithType(app.CurrentTrackFileData, app.NrTrackstoshowEditField.Value, flag3d, app.TrackTypeDropDown.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {970, 970, 970};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {970, 970};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x', 232};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImportROIsPanel = uipanel(app.LeftPanel);
            app.ImportROIsPanel.Title = 'Import ROIs';
            app.LoadROIsButton_2 = uibutton(app.ImportROIsPanel, 'push');
            app.LoadROIsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButton_2Pushed, true);
            app.LoadROIsButton_2.Tooltip = {'Allows the selection of mutliple track files defined by the import settings.'};
            app.LoadROIsButton_2.Text = 'Load ROIs';
            app.LoadReferenceImageButton = uibutton(app.ImportROIsPanel, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Tooltip = {'Can load a single reference image to be used as a background.'};
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.ImageSettingsPanel = uipanel(app.LeftPanel);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.RotateButton = uibutton(app.ImageSettingsPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Tooltip = {'Rotates the background image.'};
            app.RotateButton.Text = 'Rotate';
            app.FlipButton = uibutton(app.ImageSettingsPanel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Tooltip = {'Flips the background image.'};
            app.FlipButton.Text = 'Flip';
            app.OffsetXEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.OffsetXEditFieldLabel.Text = 'Offset X';
            app.OffsetXEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.OffsetXEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetXEditFieldValueChanged, true);
            app.OffsetXEditField.Tooltip = {'Offset for the image in X.'};
            app.OffsetYEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.OffsetYEditFieldLabel.Text = 'Offset Y';
            app.OffsetYEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.OffsetYEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetYEditFieldValueChanged, true);
            app.OffsetYEditField.Tooltip = {'Offset for the image in Y.'};
            app.OffsetZEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.OffsetZEditFieldLabel.Text = 'Offset Z';
            app.OffsetZEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.OffsetZEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetZEditFieldValueChanged, true);
            app.OffsetZEditField.Tooltip = {'Offset for the image in Z.'};
            app.Switch_5 = uiswitch(app.ImageSettingsPanel, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Tooltip = {'Plots the background image or not.'};
            app.DimensionsandPlotPanel = uipanel(app.LeftPanel);
            app.DimensionsandPlotPanel.Title = 'Dimensions and Plot';
            app.Switch = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Plots the tracks in 2D or 3D.'};
            app.Switch.Value = '2D';
            app.ShowTracksSwitchLabel = uilabel(app.DimensionsandPlotPanel);
            app.ShowTracksSwitchLabel.Text = 'Show Tracks';
            app.ShowTracksSwitch = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.ShowTracksSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowTracksSwitchValueChanged, true);
            app.ShowTracksSwitch.Tooltip = {'Enalbes/Disables the tracks.'};
            app.ScaleXYEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.ScaleXYEditFieldLabel.Text = 'Scale XY';
            app.ScaleXYEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.ScaleXYEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleXYEditFieldValueChanged, true);
            app.ScaleXYEditField.Tooltip = {'Scales the tracks according to the Value. Should match the pixel size of the background image.'};
            app.ScaleXYEditField.Value = 160;
            app.ScaleZEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.ScaleZEditField.Limits = [0.0001 Inf];
            app.ScaleZEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleZEditFieldValueChanged, true);
            app.ScaleZEditField.Tooltip = {'Scales the Tracks in Z. '};
            app.ScaleZEditField.Value = 1;
            app.MinTrackLengthEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.MinTrackLengthEditFieldLabel.Text = 'Min. Track Length';
            app.MinTrackLengthEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.MinTrackLengthEditField.Limits = [2 Inf];
            app.MinTrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthEditFieldValueChanged, true);
            app.MinTrackLengthEditField.Tooltip = {'Filters the tracks by a minimal track length given.'};
            app.MinTrackLengthEditField.Value = 5;
            app.AxisScaleSwitchLabel = uilabel(app.DimensionsandPlotPanel);
            app.AxisScaleSwitchLabel.Text = 'Axis Scale';
            app.AxisScaleSwitch = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.AxisScaleSwitch.Items = {'Auto', 'Equal'};
            app.AxisScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisScaleSwitchValueChanged, true);
            app.AxisScaleSwitch.Tooltip = {'Set axis scale to auto or equal.'};
            app.AxisScaleSwitch.Value = 'Auto';
            app.ArrangeROIsPanel = uipanel(app.LeftPanel);
            app.ArrangeROIsPanel.Title = 'Arrange ROIs';
            app.SelectROIsListBoxLabel = uilabel(app.ArrangeROIsPanel);
            app.SelectROIsListBoxLabel.Text = 'Select ROIs';
            app.SelectROIsListBox = uilistbox(app.ArrangeROIsPanel);
            app.RotateselectedROIButton = uibutton(app.ArrangeROIsPanel, 'push');
            app.RotateselectedROIButton.Tooltip = {'Rotates the background image.'};
            app.RotateselectedROIButton.Text = 'Rotate selected ROI';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.SingleTrackAnalysisPanel = uipanel(app.RightPanel);
            app.SingleTrackAnalysisPanel.Title = 'Single Track Analysis';
            app.SingleTrackAnalysisPanel.SizeChangedFcn = createCallbackFcn(app, @SingleTrackAnalysisPanelSizeChanged, true);
            app.SelectTrackButton = uibutton(app.SingleTrackAnalysisPanel, 'push');
            app.SelectTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrackButtonPushed, true);
            app.SelectTrackButton.Tooltip = {'Enables a point to select a track in the figure. Usefull  when all tracks are plotted. '};
            app.SelectTrackButton.Text = 'Select Track';
            app.AnalyzeButton = uibutton(app.SingleTrackAnalysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tooltip = {'Analyze the single track.'};
            app.AnalyzeButton.Text = 'Analyze';
            app.TrackIDLabel = uilabel(app.SingleTrackAnalysisPanel);
            app.TrackIDLabel.Text = 'TrackID:';
            app.Label_3 = uilabel(app.SingleTrackAnalysisPanel);
            app.Label_3.Text = '1';
            app.SupermapGenerationPanel = uipanel(app.RightPanel);
            app.SupermapGenerationPanel.Title = 'Supermap Generation';
            app.DiffusionButton = uibutton(app.SupermapGenerationPanel, 'push');
            app.DiffusionButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusionButtonPushed, true);
            app.DiffusionButton.Tooltip = {'Generates a diffusion map of the data.'};
            app.DiffusionButton.Text = 'Diffusion';
            app.Switch_2 = uiswitch(app.SupermapGenerationPanel, 'slider');
            app.Switch_2.Items = {'2D', '3D'};
            app.Switch_2.Tooltip = {'Determines if the supermaps will be generated in 2D or 3D.'};
            app.Switch_2.Value = '2D';
            app.DriftButton = uibutton(app.SupermapGenerationPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @DriftButtonPushed, true);
            app.DriftButton.Tooltip = {'Will generate a drift map of the data. Will depict it using arrows.'};
            app.DriftButton.Text = 'Drift';
            app.DensityButton = uibutton(app.SupermapGenerationPanel, 'push');
            app.DensityButton.ButtonPushedFcn = createCallbackFcn(app, @DensityButtonPushed, true);
            app.DensityButton.Tooltip = {'Generates a data density map of the data to pinpoint the number of detections in a given area.'};
            app.DensityButton.Text = 'Density';
            app.SuperpixelSizeXYEditFieldLabel = uilabel(app.SupermapGenerationPanel);
            app.SuperpixelSizeXYEditFieldLabel.Text = 'Superpixel Size XY';
            app.SuperpixelSizeXYEditField = uieditfield(app.SupermapGenerationPanel, 'numeric');
            app.SuperpixelSizeXYEditField.Tooltip = {'Defines the supermap pixel size in X and Y.'};
            app.SuperpixelSizeXYEditField.Value = 160;
            app.TimeWindowsEditFieldLabel = uilabel(app.SupermapGenerationPanel);
            app.TimeWindowsEditFieldLabel.Text = 'Time Windows';
            app.TimeWindowsEditField = uieditfield(app.SupermapGenerationPanel, 'numeric');
            app.TimeWindowsEditField.Tooltip = {'Will split the data in the determined number of time windows. Useful if something changes during the movie during a given time to compare before/after.'};
            app.TimeWindowsEditField.Value = 1;
            app.SuperpixelSizeZEditFieldLabel = uilabel(app.SupermapGenerationPanel);
            app.SuperpixelSizeZEditFieldLabel.Text = 'Superpixel Size Z';
            app.SuperpixelSizeZEditField = uieditfield(app.SupermapGenerationPanel, 'numeric');
            app.SuperpixelSizeZEditField.Tooltip = {'Defines the supermap pixel size.in Z'};
            app.SuperpixelSizeZEditField.Value = 160;
            app.CompleteAnalysisPanel = uipanel(app.RightPanel);
            app.CompleteAnalysisPanel.Title = 'Complete Analysis';
            app.SpotsButton = uibutton(app.CompleteAnalysisPanel, 'push');
            app.SpotsButton.ButtonPushedFcn = createCallbackFcn(app, @SpotsButtonPushed, true);
            app.SpotsButton.Tooltip = {'Perform a spot analysis. Only shows the intensities at the moment. '};
            app.SpotsButton.Text = 'Spots';
            app.TracksButton = uibutton(app.CompleteAnalysisPanel, 'push');
            app.TracksButton.ButtonPushedFcn = createCallbackFcn(app, @TracksButtonPushed, true);
            app.TracksButton.Tooltip = {'Perform an analysis on the tracks.'};
            app.TracksButton.Text = 'Tracks';
            app.KaryogramPanel = uipanel(app.RightPanel);
            app.KaryogramPanel.Title = 'Karyogram';
            app.NrTrackstoshowEditFieldLabel = uilabel(app.KaryogramPanel);
            app.NrTrackstoshowEditFieldLabel.Text = 'Nr. Tracks to show';
            app.NrTrackstoshowEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.NrTrackstoshowEditField.Limits = [0 Inf];
            app.NrTrackstoshowEditField.Tooltip = {'Defines how many tracks will be plotted. Zero means all tracks.'};
            app.TrackTypeDropDownLabel = uilabel(app.KaryogramPanel);
            app.TrackTypeDropDownLabel.Text = 'Track Type';
            app.TrackTypeDropDown = uidropdown(app.KaryogramPanel);
            app.TrackTypeDropDown.Items = {'All', 'Diffusion', 'Immobile', 'None'};
            app.TrackTypeDropDown.Tooltip = {'Filter the tracks by the tracktype.'};
            app.TrackTypeDropDown.Value = 'All';
            app.PlotKaryogramButton = uibutton(app.KaryogramPanel, 'push');
            app.PlotKaryogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotKaryogramButtonPushed, true);
            app.PlotKaryogramButton.Tooltip = {'Will polt an overview of all tracks available in an extra window, arranged in an square.'};
            app.PlotKaryogramButton.Text = 'Plot Karyogram';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIVisualisationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end