classdef SelectionWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        rdPeakUIFigure       matlab.ui.Figure
        Image                matlab.ui.control.Image
        ExitButton           matlab.ui.control.Button
        PreProcessingButton  matlab.ui.control.Button
        VisualisationButton  matlab.ui.control.Button
        ValidationButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        ValidationWindow % Link to the Validation Window
        VisualisationWindow % LInk to the Visualisation Window
        PreProcessingWindow % Link to the Batch process Window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: ValidationButton
        function ValidationButtonPushed(app, event)
            app.rdPeakUIFigure.Visible = "off";
            app.ValidationWindow = ValidationWindow(app);
        end

        % Button pushed function: VisualisationButton
        function VisualisationButtonPushed(app, event)
            app.rdPeakUIFigure.Visible = "off";
            app.VisualisationWindow = VisualisationWindow(app);
        end

        % Button pushed function: PreProcessingButton
        function PreProcessingButtonPushed(app, event)
            app.rdPeakUIFigure.Visible = "off";
            app.PreProcessingWindow = PreprocessingWindow(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.rdPeakUIFigure = uifigure('Visible', 'off');
            app.rdPeakUIFigure.Name = '3rd Peak';
            app.rdPeakUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.ValidationButton = uibutton(app.rdPeakUIFigure, 'push');
            app.ValidationButton.ButtonPushedFcn = createCallbackFcn(app, @ValidationButtonPushed, true);
            app.ValidationButton.Tooltip = {'Opens interface which helps to validate calculated positions in XYZ with the original microsocopy image/stack'};
            app.ValidationButton.Text = 'Validation';
            app.VisualisationButton = uibutton(app.rdPeakUIFigure, 'push');
            app.VisualisationButton.ButtonPushedFcn = createCallbackFcn(app, @VisualisationButtonPushed, true);
            app.VisualisationButton.Tooltip = {'Interface to visualize and analzyze the track data'};
            app.VisualisationButton.Text = 'Visualisation';
            app.PreProcessingButton = uibutton(app.rdPeakUIFigure, 'push');
            app.PreProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreProcessingButtonPushed, true);
            app.PreProcessingButton.Tooltip = {'Interface to analyze a subset of tracks previously selected in the Visualisation step.'};
            app.PreProcessingButton.Text = 'Pre Processing';
            app.ExitButton = uibutton(app.rdPeakUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.rdPeakUIFigure);
            app.Image.ImageSource = 'auxin6_3rdPeak1.png';
            % Show the figure after all components are created
            app.rdPeakUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SelectionWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.rdPeakUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.rdPeakUIFigure)
        end
    end
end