classdef SingleTrackAnalysisWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SingleTrackAnalysisUIFigure    matlab.ui.Figure
        MSDValuesPanel                 matlab.ui.container.Panel
        InternRvalLabel                matlab.ui.control.Label
        InternAlphaValLabel            matlab.ui.control.Label
        InternDValLabel                matlab.ui.control.Label
        InternRLabel                   matlab.ui.control.Label
        InternAlphaLabel               matlab.ui.control.Label
        InternDLabel                   matlab.ui.control.Label
        SwiftDValueLabel               matlab.ui.control.Label
        SwiftDLabel                    matlab.ui.control.Label
        IntensityoptionsButtonGroup    matlab.ui.container.ButtonGroup
        CummulativeButton              matlab.ui.control.RadioButton
        MeanButton                     matlab.ui.control.RadioButton
        DimensionsPanel                matlab.ui.container.Panel
        Switch_4                       matlab.ui.control.Switch
        Switch_3                       matlab.ui.control.Switch
        MSDfitselectionPanel           matlab.ui.container.Panel
        Switch_5                       matlab.ui.control.Switch
        PercentageofPointstofitEditField  matlab.ui.control.NumericEditField
        PercentageofPointstofitEditFieldLabel  matlab.ui.control.Label
        NumberofPointstofitEditField   matlab.ui.control.NumericEditField
        NumberofPointstofitEditFieldLabel  matlab.ui.control.Label
        AnglesPanel                    matlab.ui.container.Panel
        TrackSelectionPanel            matlab.ui.container.Panel
        TrackIDDropDown                matlab.ui.control.DropDown
        TrackIDDropDownLabel           matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        IntensityAngleMSDPanel         matlab.ui.container.Panel
        UIAxes2_6                      matlab.ui.control.UIAxes
        UIAxes2_5                      matlab.ui.control.UIAxes
        JumpDistancesPanel             matlab.ui.container.Panel
        FurtherDataAnalysisPanel       matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ConfinementRatioValueLabel     matlab.ui.control.Label
        TotaltrajectoryTimeValueLabel  matlab.ui.control.Label
        NettodistValueLabel            matlab.ui.control.Label
        TotaldistValueLabel            matlab.ui.control.Label
        ConfinementRatioLabel          matlab.ui.control.Label
        TotalTrajectoryTimeLabel       matlab.ui.control.Label
        NettodistancetraveledLabel     matlab.ui.control.Label
        TotaldistancetravelledLabel    matlab.ui.control.Label
        UIAxes2_4                      matlab.ui.control.UIAxes
        UIAxes2_3                      matlab.ui.control.UIAxes
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        VisualisationWin % the connection to the calling main app
        TrackData %Data of a single track directly from the main app
        PixelFrameTrackData %TrackData converted into Pixel/frame format
        TrackID%ID of current track
        Pixelsize%size of a pixel
        Timestepsize %size of the timestep between frames
        LengthUnit %unit of the length
        TimeUnit %unit of the time
        Zdata %keeps the z p
        
        Polaxes %polar axes element for the jump angles
        
        JumpDistX %jump distances in x direction to be used for different calculations
        JumpDistY %jump distances in y direction to be used for different calculations
        JumpDistZ %jump distances in z direction to be used for different calculations
        JumpDistXYZ %jump distances in xyz direction to be used for different calculations
        Intensities %intensities of the localisations of appliable
        MSD %msd from swift or different tracking
        MSDnew %msd from internal calculations
        Angles %calculated angles
        TotalDistances %calculated Total distance
        NettoDistance %calculated Netto distance
        TotalTrajectoryTime %calculated time
        ConfinementRatio %confinement ratio value
    end
    
    methods (Access = private)
        
        function checkNextIdPresent(app)
            %checks if a id is avialbe before or after the current one and
            %disables the respective button if necesasry
            prevBu = app.PreviousButton;
            nextBu = app.NextButton;
            %make sure that both are enabled
            prevBu.Enable = 1;
            nextBu.Enable = 1;
            allids = app.VisualisationWin.getAllIds();
            lastid = length(allids);
            idx = find(allids == app.TrackIDDropDown.Value);
            %disable Previous if first and next if last
            if idx == 1
                prevBu.Enable = 0;
            elseif idx == lastid
                nextBu.Enable = 0;
            end
        end
        
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.VisualisationWin.CurrentDataTypes.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.PixelFrameTrackData = app.TrackData;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.Timestepsize,0);
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,2) = time;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                xyzdata = app.TrackData(:,3:5);
                xyzdata = xyzdata/app.Pixelsize;
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,3:5) = xyzdata;
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.Timestepsize,0);
                xyzdata = app.TrackData(:,3:5);
                xyzdata = xyzdata/app.Pixelsize;
                app.PixelFrameTrackData = app.TrackData;
                app.PixelFrameTrackData(:,2) = time;
                app.PixelFrameTrackData(:,3:5) = xyzdata;
            end
        end

        function setUnitName(app)
            %adds the units from the import settings to the switch in the
            %bottom right
            items = app.Switch_4.Items;
            name = join([app.LengthUnit "/" app.TimeUnit],"");
            name = convertStringsToChars(name);
            items{2} = name;
            app.Switch_4.Items = items;
        end
        
        function getNewTrack(app,ID)
            %grabs new track data from the main app when a new track id is
            %chosen
            app.TrackData = getTrackbyID(app.VisualisationWin, ID);
        end
        
        function calculateJumpDistances(app)
            %calcualtes the jump distances in the different dimensions
            startframe = min(app.PixelFrameTrackData(:,2));
            endframe = max(app.PixelFrameTrackData(:,2));
            duration = endframe - startframe;
            %prepare some matrix
            xdist = zeros(duration,2);
            ydist = zeros(duration,2);
            zdist = zeros(duration,2);
            xyzdist = zeros(duration,2);
            %calculate the jump distance for every step in every dimension
            for i = startframe:endframe-1
                k = i - startframe +1;
                xdist(k,:) = [app.PixelFrameTrackData(k,2), (app.PixelFrameTrackData(k,3) - app.PixelFrameTrackData(k+1,3))];
                ydist(k,:) = [app.PixelFrameTrackData(k,2), (app.PixelFrameTrackData(k,4) - app.PixelFrameTrackData(k+1,4))];
                zdist(k,:) = [app.PixelFrameTrackData(k,2), (app.PixelFrameTrackData(k,5) - app.PixelFrameTrackData(k+1,5))];
                xyzdist(k,:) = [app.PixelFrameTrackData(k,2), sqrt(xdist(k,2)^2+ydist(k,2)^2+zdist(k,2)^2)];
            end
            %do we convert?
            if app.Switch_4.Value == "Pixel/Frame"
                %nothing
            else
                %so Unit/Unit
                unitsizes = [app.Timestepsize, app.Pixelsize];
                xdist(:,:) = xdist(:,:).*unitsizes;
                ydist(:,:) = ydist(:,:).*unitsizes;
                zdist(:,:) = zdist(:,:).*unitsizes;
                xyzdist(:,:) = xyzdist(:,:).*unitsizes;
            end
            %set data to variables
            app.JumpDistX = xdist;
            app.JumpDistY = ydist;
            app.JumpDistZ = zdist;
            app.JumpDistXYZ = xyzdist;
        end
        
        function getIntensityValues(app)
            %if possible get the intesity values from the data
            try
            startframe = min(app.PixelFrameTrackData(:,2));
            endframe = max(app.PixelFrameTrackData(:,2));
            duration = endframe - startframe;
            %pepare emtpy matrix
            intensities = zeros(duration,2);
            %get the intesity value
            for i = startframe:endframe
                k = i-startframe+1;
                intensities(k,:) = [app.PixelFrameTrackData(k,2), app.TrackData(k,18)];
            end
            %convert if necessary to the frame
            if app.Switch_4.Value == "Pixel/Frame"
                %nothing
            else
                intensities(:,1) = intensities(:,1)*(app.Timestepsize);
            end
            app.Intensities = intensities;
            end
        end
        
        function getMSD(app, flag3d)
            %calculates the MSD in 3d or 2d
            if flag3d == 0%2d
                data{1,1} = [app.PixelFrameTrackData(:,2), app.PixelFrameTrackData(:,3), app.PixelFrameTrackData(:,4)];
                lyzer = msdanalyzer(2,"px","frame");
            elseif flag3d == 1%3d
                data{1,1} = [app.PixelFrameTrackData(:,2), app.PixelFrameTrackData(:,3), app.PixelFrameTrackData(:,4), app.PixelFrameTrackData(:,5)];
                lyzer = msdanalyzer(3,"px","frame");
            end
            lyzer = addAll(lyzer,data);
            lyzer = computeMSD(lyzer);
            app.MSDnew = lyzer;
            try
            app.MSD = app.TrackData(:,17);
            end
        end
        
        function calculateAngles(app,flag3d)
            %calculates the angels between the steps
            %number of angles
            number = size(app.TrackData,1)-2;
            angles = zeros(number,1);

            for i = 1:number
                %vector1 P1-P2
                if flag3d == 0 %2d
                    p1 = app.PixelFrameTrackData(i,3:4);
                    p1(3) = 0;
                    p2 = app.PixelFrameTrackData(i+1,3:4);
                    p2(3) = 0;
                elseif flag3d == 1 %3d
                    p1 = app.PixelFrameTrackData(i,3:5);
                    p2 = app.PixelFrameTrackData(i+1,3:5);
                end
                u = p2-p1;
                %vector2 P2-P3
                if flag3d == 0%2d
                    p2 = app.PixelFrameTrackData(i+1,3:4);
                    p2(3) = 0;
                    p3 = app.PixelFrameTrackData(i+2,3:4);
                    p3(3) = 0;
                elseif flag3d == 1 %3d
                    p2 = app.PixelFrameTrackData(i+1,3:5);
                    p3 = app.PixelFrameTrackData(i+2,3:5);
                end
                v = p3-p2;
                %calculate angle between v1 and v2 (so 3 points)
                angles(i) = atan2(norm(cross(u,v)),dot(u,v));
            end
            %convert to degree
            app.Angles = rad2deg(angles);
        end
        
        function calculateFurtherData(app)
            %total distance
            app.TotalDistances = sum(app.JumpDistXYZ(:,2));
            %netto distance
            xdistdiff = app.PixelFrameTrackData(1,3) - app.PixelFrameTrackData(end,3);
            ydistdiff = app.PixelFrameTrackData(1,4) - app.PixelFrameTrackData(end,4);
            zdistdiff = app.PixelFrameTrackData(1,5) - app.PixelFrameTrackData(end,5);
            app.NettoDistance = sqrt(xdistdiff^2+ydistdiff^2+zdistdiff^2);
            %total trajectory time
            app.TotalTrajectoryTime = size(app.PixelFrameTrackData);
            app.TotalTrajectoryTime = app.TotalTrajectoryTime(1);
            %confinement ratio
            app.ConfinementRatio = app.NettoDistance/app.TotalDistances;
            %convert if necessary
            if app.Switch_4.Value == "Pixel/Frame"
                %labels
                app.TotaldistancetravelledLabel.Text = "Total Distance Travelled [px]";
                app.NettodistancetraveledLabel.Text = "Netto Distance Travelled [px]";
                app.TotalTrajectoryTimeLabel.Text = "Total Trajectory Time [frame]";
                app.ConfinementRatioLabel.Text = "Confinement Ratio";
            else
                app.NettoDistance = app.NettoDistance*app.Pixelsize;
                app.TotalTrajectoryTime = app.TotalTrajectoryTime*app.Timestepsize;
                %labels
                app.TotaldistancetravelledLabel.Text = sprintf("Total Distance Travelled [%s]",app.LengthUnit);
                app.NettodistancetraveledLabel.Text = sprintf("Netto Distance Travelled [%s]", app.LengthUnit);
                app.TotalTrajectoryTimeLabel.Text = sprintf("Total Trajectory Time [%s]", app.TimeUnit);
                app.ConfinementRatioLabel.Text = "Confinement Ratio";
            end
            %set the values in the GUI
            app.TotaldistValueLabel.Text = num2str(app.TotalDistances);
            app.NettodistValueLabel.Text = num2str(app.NettoDistance);
            app.TotaltrajectoryTimeValueLabel.Text = num2str(app.TotalTrajectoryTime);
            app.ConfinementRatioValueLabel.Text = num2str(app.ConfinementRatio);
        end
        
        function generateData(app)
            %calls all the functions needed to calculate the data
            switch app.Switch_3.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            calculateJumpDistances(app);
            getIntensityValues(app);
            getMSD(app, flag3d);
            calculateAngles(app,flag3d); 
        end
        
        function plotJDists(app, flagunits)
            %plots the jump distances in the axis elements
            %plotjdx
            plot(app.UIAxes2,app.JumpDistX(:,1), app.JumpDistX(:,2));
            %plotjdy
            plot(app.UIAxes2_2,app.JumpDistY(:,1), app.JumpDistY(:,2));
            %plotjdz
            plot(app.UIAxes2_3,app.JumpDistZ(:,1), app.JumpDistZ(:,2));
            %plotjdxyz
            plot(app.UIAxes2_4,app.JumpDistXYZ(:,1), app.JumpDistXYZ(:,2));
            %add labels
            if flagunits == 0%px/frame
                xlabeltext = "Time [frame]";
                ylabeltext = "Jump Distance [px]";
            elseif flagunits == 1%nm/s
                xlabeltext = sprintf("Time [%s]", app.TimeUnit);
                ylabeltext = sprintf("Jump Distance [%s]", app.LengthUnit);
            end
            %associate the labels with the axis
            xlabel(app.UIAxes2,xlabeltext);
            ylabel(app.UIAxes2, ylabeltext);
            xlabel(app.UIAxes2_2,xlabeltext);
            ylabel(app.UIAxes2_2, ylabeltext);
            xlabel(app.UIAxes2_3,xlabeltext);
            ylabel(app.UIAxes2_3, ylabeltext);
            xlabel(app.UIAxes2_4,xlabeltext);
            ylabel(app.UIAxes2_4, ylabeltext);
        end
        
        function plotIntensity(app, flagunits)
            %plots the intesity values
            %checks if mean or cumulative intesity needs to be plottet
            if app.MeanButton.Value
                flagopt = 0;
            else 
                flagopt = 1;
            end
            %as intesity is not always present
            try
            if flagopt == 0%mean over time
                plot(app.UIAxes2_5, app.Intensities(:,1), app.Intensities(:,2));
                %add labels
                ylabel(app.UIAxes2_5,"Mean Intensity");
                if flagunits == 0
                    xlabel(app.UIAxes2_5,"Time [frame]"); 
                elseif flagunits == 1
                    xlabel(app.UIAxes2_5, sprintf("Time [%s]", app.TimeUnit));
                end
            elseif flagopt == 1%max,cummulativ
                %add up the data
                newint = zeros(size(app.Intensities,1),1);
                newint(1) = app.Intensities(1,2);
                for i = 2:size(app.Intensities,1)
                    newint(i) = app.Intensities(i,2)+newint(i-1,1);
                end
                %plots
                plot(app.UIAxes2_5, app.Intensities(:,1), newint(:));
                %add labels
                ylabel(app.UIAxes2_5,"Cummulative Intensity");
                if flagunits == 0
                    xlabel(app.UIAxes2_5,"Time [frame]");
                elseif flagunits == 1
                    xlabel(app.UIAxes2_5, sprintf("Time [%s]", app.TimeUnit));
                end
            end
            end
        end
        
        function plotMSD(app)
            %plots the newly calculated msd
            %selects how many points will be used for fitting
            switch app.Switch_5.Value
                case "Abs"
                    fitval = app.NumberofPointstofitEditField.Value;
                    fitpoints = fitval;
                case "Percen"
                    fitval = app.PercentageofPointstofitEditField.Value;
                    if ceil(size(app.TrackData,1)*fitval/100) < 2
                        fitval = 2/size(app.TrackData(:),1);
                        app.PercentageofPointstofitEditField.Value = fitval;
                    end
                    fitpoints = ceil(size(app.TrackData,1)*fitval/100);
            end
            %starts plotting
            cla(app.UIAxes2_6);
            plotMeanMSD(app.MSDnew, app.UIAxes2_6, 1);
            close
            labelPlotMSD(app.MSDnew, app.UIAxes2_6);
            %fit msd
            app.MSDnew = fitMSD(app.MSDnew, fitval);
            app.MSDnew = fitLogLogMSD(app.MSDnew, fitval);
            %select the dimension
            switch app.Switch_3.Value
                case "2D"
                    dimf = 2;
                case "3D"
                    dimf = 3;
            end
            %plot the fit graphs
            xdat = linspace(0,fitpoints,fitpoints+1);
            %get the a and bvalue
            a = app.MSDnew.lfit.a;
            b = app.MSDnew.lfit.b;
            %b = 0;
            %get the uncertainty and alpha and diff coeff
            r2fit = app.MSDnew.lfit.r2fit;
            alpha = app.MSDnew.loglogfit.alpha;
            d = a*1/(2*dimf);
            ydat = xdat*a;
            hold(app.UIAxes2_6,"on");
            plot(app.UIAxes2_6,xdat,ydat);
            
            app.SwiftDValueLabel.Text = num2str(mean(app.MSD));
            app.InternDValLabel.Text = num2str(d);
            app.InternAlphaValLabel.Text = num2str(alpha);
            app.InternRvalLabel.Text = num2str(r2fit);
            
            %in case of unit/unit selection need to adjust the graph units
            %and labels
            if app.Switch_4.Value == "Pixel/Frame"
                %nothing
            else
                %adjustments text label
                xdatalabel = sprintf("Delay [%s]", app.TimeUnit);
                app.UIAxes2_6.XLabel.String = {xdatalabel};
                ydatalabel = sprintf("MSD [%s^2]", app.LengthUnit);
                app.UIAxes2_6.YLabel.String = {ydatalabel};
                %adjustments xticks delay
                xdataticks = str2double(app.UIAxes2_6.XTickLabel);
                xdataticks = xdataticks*app.Timestepsize;
                app.UIAxes2_6.XTickLabel = cellstr(num2str(xdataticks));
                %yticks MSD
                ydataticks = str2double(app.UIAxes2_6.YTickLabel);
                ydataticks = ydataticks*(app.Pixelsize*app.Pixelsize);
                app.UIAxes2_6.YTickLabel = cellstr(num2str(ydataticks));
            end
        end
        
        function plotAngles(app)
             polarhistogram(app.Polaxes,app.Angles,12);
        end
        
        function plotEverything(app)
            if app.Switch_4.Value == "Pixel/Frame"
                flagunits = 0;
            else
                flagunits = 1;
            end
            plotJDists(app, flagunits);
            plotIntensity(app, flagunits);
            plotMSD(app);
            plotAngles(app);
            calculateFurtherData(app);
        end
        
        function startup(app)
            %setup of data
            switch app.Switch_3.Value
                case "2D"
                    %flatten 3d data
                    app.Zdata = app.TrackData(:,5);
                    app.TrackData(:,5) = 0;
                case "3D"
                    %recover 3d positions
                    if mean(app.TrackData(:,5)) == 0
                        app.TrackData(:,5) = app.Zdata;
                    end
            end
            %format data to pixel/frame
            adjustDataAndScale(app);
            %generate the data and plot it
            generateData(app);
            plotEverything(app);   
        end
            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin, trackdata, ID)
            %grab the data from the visualisation window
            app.VisualisationWin = visualisationwin;
            app.Polaxes = polaraxes(app.AnglesPanel);
            app.TrackData = trackdata;
            app.TrackID = ID;
            %get the units and conversion factors
            app.Pixelsize = app.VisualisationWin.CurrentDataTypes.Pixelsize;
            app.Timestepsize = app.VisualisationWin.CurrentDataTypes.Timestep;
            app.LengthUnit = app.VisualisationWin.CurrentDataTypes.LengthUnit;
            app.TimeUnit = app.VisualisationWin.CurrentDataTypes.TimeUnit;
            %set the gui layout
            setUnitName(app);
            app.TrackIDDropDown.Items = visualisationwin.getAllIds();
            app.TrackIDDropDown.Value = num2str(ID);
            %calculate and plot the data
            startup(app);     
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value;
            switch app.Switch_3.Value
                case "2D"
                    %flatten 3d data
                    app.Zdata = app.TrackData(:,5);
                    app.TrackData(:,5) = 0;
                case "3D"
                    if mean(app.TrackData(:,5)) == 0
                        app.TrackData(:,5) = app.Zdata;
                    end 
            end
            adjustDataAndScale(app);
            generateData(app);
            plotEverything(app); 
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            generateData(app);
            plotEverything(app); 
        end

        % Callback function
        function PixelsizeinNmEditFieldValueChanged(app, event)
            value = app.PixelsizeinNmEditField.Value;
            if app.Switch_4.Value == "um/s"
                convert2pxframe2(app);
                app.Pixelsize = value;
                convert2ums(app);
            end
            generateData(app);
            plotEverything(app); 
        end

        % Callback function
        function MsperframeEditFieldValueChanged(app, event)
            value = app.MsperframeEditField.Value;
            if app.Switch_4.Value == "um/s"
                convert2pxframe(app);
                app.Timestepsize = value;
                convert2ums(app);
            end
            generateData(app);
            plotEverything(app); 
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged(app, event)
            %new track id is choosen from drop down menu
            %get new id
            value = app.TrackIDDropDown.Value;
            ID = str2double(value);
            %set new data
            app.Zdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(ID);
            %calcualte new data
            startup(app);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %gets the previous id in the list
            allids = app.VisualisationWin.getAllIds();
            idx = find(allids == app.TrackIDDropDown.Value);
            idx = idx-1;
            id = str2double(allids(idx));
            %gets new data associated to new id
            app.Zdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(id);
            app.TrackID = app.TrackData(1,1);
            app.TrackIDDropDown.Value = num2str(app.TrackID);
            %calculates and plots the data
            startup(app);
            %checks if there are more ids
            checkNextIdPresent(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            %gets the previous id in the list
            allids = app.VisualisationWin.getAllIds();
            idx = find(allids == app.TrackIDDropDown.Value);
            idx = idx+1;
            id = str2double(allids(idx));
            %gets new data associated to new id
            app.Zdata = [];
            app.TrackData = app.VisualisationWin.getTrackbyID(id);
            app.TrackID = app.TrackData(1,1);
            app.TrackIDDropDown.Value = num2str(app.TrackID);
            %calculates and plots the data
            startup(app);
            %checks if there are more ids
            checkNextIdPresent(app);
        end

        % Selection changed function: IntensityoptionsButtonGroup
        function IntensityoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.IntensityoptionsButtonGroup.SelectedObject;
            if app.Switch_4.Value == "Pixel/Frame"
                flagunits = 0;
            else
                flagunits = 1;
            end
            plotIntensity(app, flagunits);
        end

        % Value changed function: NumberofPointstofitEditField
        function NumberofPointstofitEditFieldValueChanged(app, event)
            value = app.NumberofPointstofitEditField.Value;
            plotMSD(app);
        end

        % Value changed function: PercentageofPointstofitEditField
        function PercentageofPointstofitEditFieldValueChanged(app, event)
            value = app.PercentageofPointstofitEditField.Value;
            plotMSD(app);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            plotMSD(app);
        end

        % Close request function: SingleTrackAnalysisUIFigure
        function SingleTrackAnalysisUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SingleTrackAnalysisUIFigure = uifigure('Visible', 'off');
            app.SingleTrackAnalysisUIFigure.Name = 'Single Track Analysis';
            app.SingleTrackAnalysisUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.SingleTrackAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @SingleTrackAnalysisUIFigureCloseRequest, true);
            app.JumpDistancesPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.JumpDistancesPanel.Title = 'Jump Distances';
            app.UIAxes2 = uiaxes(app.JumpDistancesPanel);
            title(app.UIAxes2, 'Jump Distance in X')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.JumpDistancesPanel);
            title(app.UIAxes2_2, 'Jump Distance Y')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.JumpDistancesPanel);
            title(app.UIAxes2_3, 'Jump Distance in Z')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_4 = uiaxes(app.JumpDistancesPanel);
            title(app.UIAxes2_4, 'Mean Jump Distance XYZ')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.FurtherDataAnalysisPanel = uipanel(app.JumpDistancesPanel);
            app.FurtherDataAnalysisPanel.Title = 'Further Data Analysis';
            app.GridLayout = uigridlayout(app.FurtherDataAnalysisPanel);
            app.TotaldistancetravelledLabel = uilabel(app.GridLayout);
            app.TotaldistancetravelledLabel.Text = 'Total distance travelled';
            app.NettodistancetraveledLabel = uilabel(app.GridLayout);
            app.NettodistancetraveledLabel.Text = 'Netto distance traveled';
            app.TotalTrajectoryTimeLabel = uilabel(app.GridLayout);
            app.TotalTrajectoryTimeLabel.Text = 'Total Trajectory Time';
            app.ConfinementRatioLabel = uilabel(app.GridLayout);
            app.ConfinementRatioLabel.Text = 'Confinement Ratio';
            app.TotaldistValueLabel = uilabel(app.GridLayout);
            app.TotaldistValueLabel.Text = 'TotaldistValue';
            app.NettodistValueLabel = uilabel(app.GridLayout);
            app.NettodistValueLabel.Text = 'NettodistValue';
            app.TotaltrajectoryTimeValueLabel = uilabel(app.GridLayout);
            app.TotaltrajectoryTimeValueLabel.Text = 'TotaltrajectoryTimeValue';
            app.ConfinementRatioValueLabel = uilabel(app.GridLayout);
            app.ConfinementRatioValueLabel.Text = 'ConfinementRatioValue';
            app.IntensityAngleMSDPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.IntensityAngleMSDPanel.Title = 'Intensity - Angle - MSD';
            app.UIAxes2_5 = uiaxes(app.IntensityAngleMSDPanel);
            title(app.UIAxes2_5, 'Intensity')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes2_6 = uiaxes(app.IntensityAngleMSDPanel);
            title(app.UIAxes2_6, 'MSD')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            zlabel(app.UIAxes2_6, 'Z')
            app.TrackSelectionPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.TrackSelectionPanel.Title = 'Track Selection';
            app.PreviousButton = uibutton(app.TrackSelectionPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = {'Jump to the previous track in the ID list.'};
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.TrackSelectionPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = {'Jump to the next track in the ID list.'};
            app.NextButton.Text = 'Next';
            app.TrackIDDropDownLabel = uilabel(app.TrackSelectionPanel);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.TrackSelectionPanel);
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged, true);
            app.AnglesPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.AnglesPanel.Title = 'Angles';
            app.MSDfitselectionPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.MSDfitselectionPanel.Title = 'MSD fit selection';
            app.NumberofPointstofitEditFieldLabel = uilabel(app.MSDfitselectionPanel);
            app.NumberofPointstofitEditFieldLabel.Text = 'Number of Points to fit';
            app.NumberofPointstofitEditField = uieditfield(app.MSDfitselectionPanel, 'numeric');
            app.NumberofPointstofitEditField.Limits = [2 Inf];
            app.NumberofPointstofitEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPointstofitEditFieldValueChanged, true);
            app.NumberofPointstofitEditField.Value = 5;
            app.PercentageofPointstofitEditFieldLabel = uilabel(app.MSDfitselectionPanel);
            app.PercentageofPointstofitEditFieldLabel.Text = 'Percentage of Points to fit';
            app.PercentageofPointstofitEditField = uieditfield(app.MSDfitselectionPanel, 'numeric');
            app.PercentageofPointstofitEditField.Limits = [1 100];
            app.PercentageofPointstofitEditField.ValueChangedFcn = createCallbackFcn(app, @PercentageofPointstofitEditFieldValueChanged, true);
            app.PercentageofPointstofitEditField.Value = 1;
            app.Switch_5 = uiswitch(app.MSDfitselectionPanel, 'slider');
            app.Switch_5.Items = {'Abs', 'Percen'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Tooltip = {'Defines if either an absolute number of MSD-timesteps or a percentage of the total length should be used for fitting.'};
            app.Switch_5.Value = 'Abs';
            app.DimensionsPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.DimensionsPanel.Title = 'Dimensions';
            app.Switch_3 = uiswitch(app.DimensionsPanel, 'slider');
            app.Switch_3.Items = {'2D', '3D'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Tooltip = {'Allows to switch between 2D and 3D analysis.'};
            app.Switch_3.Value = '2D';
            app.Switch_4 = uiswitch(app.DimensionsPanel, 'slider');
            app.Switch_4.Items = {'Pixel/Frame', 'Unit/Unit'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Tooltip = {'Used to convert the px/frame into um/s if other units are needed. Adjust the values below according to your imaging settings.'};
            app.Switch_4.Value = 'Pixel/Frame';
            app.IntensityoptionsButtonGroup = uibuttongroup(app.SingleTrackAnalysisUIFigure);
            app.IntensityoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IntensityoptionsButtonGroupSelectionChanged, true);
            app.IntensityoptionsButtonGroup.Title = 'Intensity options';
            app.MeanButton = uiradiobutton(app.IntensityoptionsButtonGroup);
            app.MeanButton.Tooltip = {'Plots the mean intensity over time.'};
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.CummulativeButton = uiradiobutton(app.IntensityoptionsButtonGroup);
            app.CummulativeButton.Tooltip = {'Plots the cummulaive intensity over time'};
            app.CummulativeButton.Text = 'Cummulative';
            app.MSDValuesPanel = uipanel(app.SingleTrackAnalysisUIFigure);
            app.MSDValuesPanel.Title = 'MSD Values';
            app.SwiftDLabel = uilabel(app.MSDValuesPanel);
            app.SwiftDLabel.Text = 'Swift D';
            app.SwiftDValueLabel = uilabel(app.MSDValuesPanel);
            app.SwiftDValueLabel.Text = 'SwiftDValue';
            app.InternDLabel = uilabel(app.MSDValuesPanel);
            app.InternDLabel.Text = 'Intern D';
            app.InternAlphaLabel = uilabel(app.MSDValuesPanel);
            app.InternAlphaLabel.Text = 'Intern Alpha';
            app.InternRLabel = uilabel(app.MSDValuesPanel);
            app.InternRLabel.Text = 'Intern R²';
            app.InternDValLabel = uilabel(app.MSDValuesPanel);
            app.InternDValLabel.Text = 'Intern DVal';
            app.InternAlphaValLabel = uilabel(app.MSDValuesPanel);
            app.InternAlphaValLabel.Text = 'Intern AlphaVal';
            app.InternRvalLabel = uilabel(app.MSDValuesPanel);
            app.InternRvalLabel.Text = 'Intern R²val';
            % Show the figure after all components are created
            app.SingleTrackAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleTrackAnalysisWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SingleTrackAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SingleTrackAnalysisUIFigure)
        end
    end
end