classdef SpotStatisticsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpotStatisticsUIFigure         matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        HistogramPropertiesPanel       matlab.ui.container.Panel
        NumberofBinsEditField          matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel     matlab.ui.control.Label
        RightLimitEditField            matlab.ui.control.NumericEditField
        RightLimitEditFieldLabel       matlab.ui.control.Label
        LeftLimitEditField             matlab.ui.control.NumericEditField
        LeftLimitEditFieldLabel        matlab.ui.control.Label
        StatisticSelectionButtonGroup  matlab.ui.container.ButtonGroup
        SpoterrorButton                matlab.ui.control.RadioButton
        SpotwidthButton                matlab.ui.control.RadioButton
        PeakspotintensityButton        matlab.ui.control.RadioButton
        ExportasCSVButton              matlab.ui.control.Button
        ExportasmatButton              matlab.ui.control.Button
        MovieSelectionPanel            matlab.ui.container.Panel
        MovieLabel                     matlab.ui.control.Label
        MovieSelectedLabel             matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Visualisationwindow % Description
        IntensityData
        Intensities
        Min
        Max
    end
    
    methods (Access = private)
        
        function updateMovieSelection(app)
            if size(app.IntensityData,1) > 1
                app.MovieLabel.Text = "All Tracks";
            end
            if size(app.IntensityData, 1) == 1
                if any(strcmp(app.IntensityData{1,1}, "ROI"))
                    app.MovieLabel.Text = "ROI";
                else
                    app.MovieLabel.Text = string(app.IntensityData{1,1});
                end
            end
        end
        
        function setMinMaxValue(app)
            app.Min = min(cellfun(@min,app.IntensityData(:,2)));
            app.Max = max(cellfun(@max,app.IntensityData(:,2)));
            app.LeftLimitEditField.Value = app.Min;
            app.RightLimitEditField.Value = app.Max;
        end
        
        function catIntensityData(app)
            filenr = size(app.IntensityData,1);
            if filenr > 1
                ints = [];
                for i = 1:filenr
                    ints = [ints; app.IntensityData{i,2}];
                end
                app.Intensities = ints;
            else
                app.Intensities = app.IntensityData{1,2};
            end
        end
        
        function updateHistogram(app)
            ll = app.LeftLimitEditField.Value;
            rl = app.RightLimitEditField.Value;
            nb = app.NumberofBinsEditField.Value;
            histogram(app.UIAxes, app.Intensities,nb);
            xlim(app.UIAxes,[ll rl]);
            titel("Intensity distribution");
            xlabel("Intensity");
            ylabel("Counts");
        end
    end
    
    methods (Access = public)
        
        function results = func2(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, visualisationwin)
            app.Visualisationwindow = visualisationwin;
            app.IntensityData = visualisationwin.getIntensityData();
            updateMovieSelection(app);
            catIntensityData(app);
            setMinMaxValue(app);
            
            updateHistogram(app);
        end

        % Value changed function: LeftLimitEditField
        function LeftLimitEditFieldValueChanged(app, event)
            value = app.LeftLimitEditField.Value;
            updateHistogram(app);
        end

        % Value changed function: RightLimitEditField
        function RightLimitEditFieldValueChanged(app, event)
            value = app.RightLimitEditField.Value;
            updateHistogram(app);
        end

        % Value changed function: NumberofBinsEditField
        function NumberofBinsEditFieldValueChanged(app, event)
            value = app.NumberofBinsEditField.Value;
            updateHistogram(app);
        end

        % Button pushed function: ExportasmatButton
        function ExportasmatButtonPushed(app, event)
            filterspec = {'*.mat'};
                [f, p] = uiputfile(filterspec);
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
    
                fulfile = fullfile(p,f);
                data = app.IntensityData;
                catdata = app.Intensities;
                save(fulfile, "data", "catdata");
        end

        % Button pushed function: ExportasCSVButton
        function ExportasCSVButtonPushed(app, event)
            filterspec = {'*.csv'};
                [f, p] = uiputfile(filterspec);
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
    
                fulfile = fullfile(p,f);
                data = writematrix(app.Intensities,"Intensities.csv");

                save(fulfile, "data");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpotStatisticsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {757, 757};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpotStatisticsUIFigure = uifigure('Visible', 'off');
            app.SpotStatisticsUIFigure.Name = 'SpotStatistics';
            app.SpotStatisticsUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.SpotStatisticsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpotStatisticsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MovieSelectionPanel = uipanel(app.LeftPanel);
            app.MovieSelectionPanel.Title = 'Movie Selection';
            app.MovieSelectedLabel = uilabel(app.MovieSelectionPanel);
            app.MovieSelectedLabel.Text = 'Movie Selected';
            app.MovieLabel = uilabel(app.MovieSelectionPanel);
            app.MovieLabel.Text = 'Movie';
            app.ExportasmatButton = uibutton(app.LeftPanel, 'push');
            app.ExportasmatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasmatButtonPushed, true);
            app.ExportasmatButton.Tooltip = {'Exports the data used to plot the histogram as a .mat file.'};
            app.ExportasmatButton.Text = 'Export as mat';
            app.ExportasCSVButton = uibutton(app.LeftPanel, 'push');
            app.ExportasCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasCSVButtonPushed, true);
            app.ExportasCSVButton.Tooltip = {'Exports the data used to plot the histogram as a .csv file.'};
            app.ExportasCSVButton.Text = 'Export as CSV';
            app.StatisticSelectionButtonGroup = uibuttongroup(app.LeftPanel);
            app.StatisticSelectionButtonGroup.Title = 'Statistic Selection';
            app.PeakspotintensityButton = uiradiobutton(app.StatisticSelectionButtonGroup);
            app.PeakspotintensityButton.Text = 'Peak spot intensity';
            app.PeakspotintensityButton.Value = true;
            app.SpotwidthButton = uiradiobutton(app.StatisticSelectionButtonGroup);
            app.SpotwidthButton.Enable = 'off';
            app.SpotwidthButton.Text = 'Spot width';
            app.SpoterrorButton = uiradiobutton(app.StatisticSelectionButtonGroup);
            app.SpoterrorButton.Enable = 'off';
            app.SpoterrorButton.Text = 'Spot error';
            app.HistogramPropertiesPanel = uipanel(app.LeftPanel);
            app.HistogramPropertiesPanel.Title = 'Histogram Properties';
            app.LeftLimitEditFieldLabel = uilabel(app.HistogramPropertiesPanel);
            app.LeftLimitEditFieldLabel.Text = 'Left Limit';
            app.LeftLimitEditField = uieditfield(app.HistogramPropertiesPanel, 'numeric');
            app.LeftLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LeftLimitEditFieldValueChanged, true);
            app.LeftLimitEditField.Tooltip = {'Defines the left border of the shown histogram. Data might extend furhter.'};
            app.RightLimitEditFieldLabel = uilabel(app.HistogramPropertiesPanel);
            app.RightLimitEditFieldLabel.Text = 'Right Limit';
            app.RightLimitEditField = uieditfield(app.HistogramPropertiesPanel, 'numeric');
            app.RightLimitEditField.ValueChangedFcn = createCallbackFcn(app, @RightLimitEditFieldValueChanged, true);
            app.RightLimitEditField.Tooltip = {'Defines the right border of the shown histogram. Data might extend furhter.'};
            app.RightLimitEditField.Value = 100;
            app.NumberofBinsEditFieldLabel = uilabel(app.HistogramPropertiesPanel);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.HistogramPropertiesPanel, 'numeric');
            app.NumberofBinsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofBinsEditFieldValueChanged, true);
            app.NumberofBinsEditField.Tooltip = {'Defines the number of bins used for the histogram.'};
            app.NumberofBinsEditField.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.SpotStatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpotStatisticsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpotStatisticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpotStatisticsUIFigure)
        end
    end
end