classdef ValidationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ValidationUIFigure            matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        KaryogramPanel                matlab.ui.container.Panel
        LocPrecZEditField             matlab.ui.control.NumericEditField
        LocPrecZEditFieldLabel        matlab.ui.control.Label
        LocPrecXYEditField            matlab.ui.control.NumericEditField
        LocPrecXYEditFieldLabel       matlab.ui.control.Label
        PlotKaryogramButton           matlab.ui.control.Button
        TrackTypeDropDown             matlab.ui.control.DropDown
        TrackTypeDropDownLabel        matlab.ui.control.Label
        NrTrackstoshowEditField       matlab.ui.control.NumericEditField
        NrTrackstoshowEditFieldLabel  matlab.ui.control.Label
        ImageTrackOverlayPanel        matlab.ui.container.Panel
        SpotsizeEditField             matlab.ui.control.NumericEditField
        SpotsizeEditFieldLabel        matlab.ui.control.Label
        PixelsizeinNmEditField        matlab.ui.control.NumericEditField
        PixelsizeinNmEditFieldLabel   matlab.ui.control.Label
        Switch_3                      matlab.ui.control.Switch
        RotateImageButton             matlab.ui.control.Button
        FlipImageButton               matlab.ui.control.Button
        TrackOffsetZEditField         matlab.ui.control.NumericEditField
        TrackOffsetZEditFieldLabel    matlab.ui.control.Label
        TrackOffsetYEditField         matlab.ui.control.NumericEditField
        TrackOffsetYEditFieldLabel    matlab.ui.control.Label
        TrackOffsetXEditField         matlab.ui.control.NumericEditField
        TrackOffsetXEditFieldLabel    matlab.ui.control.Label
        TrackIDDropDown               matlab.ui.control.DropDown
        TrackIDDropDownLabel          matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        DataImportPanel               matlab.ui.container.Panel
        MinTrackLengthEditField       matlab.ui.control.NumericEditField
        MinTrackLengthEditFieldLabel  matlab.ui.control.Label
        TrackDataLabel                matlab.ui.control.Label
        ImageDataLabel                matlab.ui.control.Label
        Switch_2                      matlab.ui.control.Switch
        BrowseButton_2                matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        ImportSettingsButton          matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        FrameEditField                matlab.ui.control.NumericEditField
        FrameEditFieldLabel           matlab.ui.control.Label
        StepSizeEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel        matlab.ui.control.Label
        Slider                        matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties
        ImportSettingsStruct %Settings from the Import Settings Window
    end
    properties (Access = private)
        ImportSettingsValidationWindow % Link to the Import Settings
        SelectionWindow %Link to Selection Window
        
        SettingsChangedFlag %Info if a new settings has been selected
        ImageData %Raw image tiff data from the localisation video
        TrackData %Imported tracking data
        Spothandles %Handles for spots to plot
        Trackhandles %Handles for tracks to plot
        TrackIDList %ID list for the track handles
    end

    methods

        function updateSettings(app)
            %grabs the settings from the settings dialogue window
            if isa(app.ImportSettingsValidationWindow, "ImportSettingsValidationWindow")
                app.ImportSettingsStruct = app.ImportSettingsValidationWindow.getSettings();
            else
                %Nothing
            end
        end
        
        function setSettingsChanged(app, value)
            app.SettingsChangedFlag = value;
        end
    end
    
    methods (Access = private)
        
        function tracks = loadCustomCSV(app, file)
            %internal adapter to load custom csv
            tracks = loadCustomCSVfun(app, file);
        end
        
        function tracks = loadCustomMat(app, file, type)
            %internal adapter to load mat
            tracks = loadCustomMatfun(app, file, type);
        end
        
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.ImportSettingsStruct.customUnits.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.PixelsizeinNmEditField.Value = 1;
                app.PixelsizeinNmEditFieldLabel.Text = "Pixelsize";
                app.TrackOffsetZEditField.Value = 0;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                app.PixelsizeinNmEditFieldLabel.Text = join(["Pixelsize in", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.PixelsizeinNmEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
                
                app.PixelsizeinNmEditFieldLabel.Text = join(["Pixelsize in", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.PixelsizeinNmEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            end
        end
        
        function loadTracks(app)
            %Loads the track data.
            %Depends on the Import Settings dialoge which file is able to
            %be selected
            %Filter a minimum given step amount
            
            tracklength = app.MinTrackLengthEditField.Value;
            if isa(app.ImportSettingsStruct, "struct")
                %Check that settings has been set
                %get the type of import
                imptype = app.ImportSettingsStruct.type;
                switch imptype
                    case "Swift-AllData CSV"
                        % Display uigetfile dialog
                        filterspec = {'*.csv'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = ImportSwiftAsTrc(fullfile(p,f),1);
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a trace file!")
                        else
                            app.TrackData = loadfile;
                            filterTracksbyLength(app, tracklength)
                        end
                    case "Swift-MinData CSV" %Need to che4ck what the output is from this mode
                        % Display uigetfile dialog
                        filterspec = {'*.csv'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = ImportSwiftAsTrc(fullfile(p,f),0);
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a trace file!")
                        else
                            app.TrackData = loadfile;
                            filterTracksbyLength(app, tracklength)
                        end
                    case "Custom CSV"
                        % Display uigetfile dialog
                        filterspec = {'*.csv'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = loadCustomCSVfun(app,fullfile(p,f));
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a trace file!")
                        else
                            app.TrackData = loadfile;
                            filterTracksbyLength(app, tracklength)
                        end
                    case "Custom .mat"
                         % Display uigetfile dialog
                        filterspec = {'*.mat'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = loadCustomMatfun(app,fullfile(p,f));
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a trace file!")
                        else
                            app.TrackData = loadfile;
                            filterTracksbyLength(app, tracklength)
                        end
                    case "Custom Localisations mat"
                         % Display uigetfile dialog
                        filterspec = {'*.mat'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = loadCustomMat(app,fullfile(p,f));
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a loc file!")
                        else
                            app.TrackData = loadfile;
                            %filterTracksbyLength(app, tracklength)
                        end
                   case "Custom Localisations csv"
                         % Display uigetfile dialog
                        filterspec = {'*.csv'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = loadCustomCSV(app,fullfile(p,f));
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a loc file!")
                        else
                            app.TrackData = loadfile;
                            %filterTracksbyLength(app, tracklength)
                        end
                case "SMAP-Localisations"
                         % Display uigetfile dialog
                        filterspec = {'*.mat'};
                        [f, p] = uigetfile(filterspec);
                        % Make sure user didn't cancel uigetfile dialog
                        if ~ischar(p)
                            error("No file selected")
                        end
                        loadfile = loadSMAPLocalisations(fullfile(p,f));
    
                        %make sure that traces are actually loaded
                        if size(loadfile,2) < 5
                            error("Not a loc file!")
                        else
                            app.TrackData = loadfile;
                            %filterTracksbyLength(app, tracklength)
                        end
                end
                app.adjustDataAndScale();
                KaryogramDiffusionTypeSelection(app);
            end
        end
        
        function updateFigureImage(app)
            %function to update the background microscopy image
            if isa(app.ImageData, "double")
                try
                    %dont throw error if only track data is loaded
                    ax = app.UIAxes;
                    frame = ceil(app.Slider.Value);
                    image(ax,app.ImageData(:,:,frame),'CDataMapping','scaled');
                end
            else
                %nothing
            end
        end
        
        function updateSlider(app)
            %adjusts the slider limits depending on the microscopy data
            app.Slider.Limits = [1 size(app.ImageData,3)];
        end
        
        function filterTracksbyLength(app, tracklength)
            %lets one filter out tracks that are shorter then the given
            %number in the GUI
            edges = unique(app.TrackData(:,1));
            counts = histc(app.TrackData(:,1),edges); %#ok<ADPROPLC> 
            interest = find(counts > tracklength);
            intracksnr = edges(interest); %#ok<ADPROPLC> 
            filtertracks = [];
            for j = 1:length(intracksnr)
                k = intracksnr(j);
                intresttrackind = find(app.TrackData(:,1) == k);
                filtertracks = cat(1,filtertracks, app.TrackData(intresttrackind,:));
            end
            app.TrackData = filtertracks;
        end
        
        function KaryogramDiffusionTypeSelection(app)
            %checks if diffusion types are available and therefore makes
            %the filter available. if not, only "all" can be selected. Type
            %is stored in colunn 14 of the data.
            dim = size(app.TrackData);
            if dim(2) < 14 %no track type
                app.TrackTypeDropDown.Value = "All";
                app.TrackTypeDropDown.Enable = "off";
            else %all data present
                app.TrackTypeDropDown.Enable = "on";
            end
        end
        
        function updateTrackSelection(app)
            %Fill the selection box with the unique ids of the individual
            %tracks
            IDs = unique(app.TrackData(:,1));
            IDs = IDs.';
            IDs = string(IDs);
            app.TrackIDDropDown.Items = IDs;
        end
        
        function manageSpotsTrackOverlay(app)
            %function with manages the center figure. Will set certain
            %flags depending on the options chosen by the user. then adjust
            %the scaling depending on the values and then plots the figure
            %components
            
            %get information from the GUI
            switch app.Switch_2.Value
                case "3D"
                    flag3d = 1;
                    
                case "2D"
                    flag3d = 0;
                    
            end
            switch app.Switch_3.Value
                case "On"
                    flagOn = 1;
                case "Off"
                    flagOn = 0;
            end
            switch app.Switch.Value
                case "Spots"
                    flagtype = 0;
                case "Tracks"
                    flagtype = 1;
            end
            pixelsize = app.PixelsizeinNmEditField.Value;
            scale = 1/pixelsize;
            frame = ceil(app.Slider.Value);
            spotsize = app.SpotsizeEditField.Value;
            %clear the figure
            cla(app.UIAxes);
            %set the background microscopy image
            updateFigureImage(app);
            hold(app.UIAxes,"on");
            %plots the current marker for the currently localized spot in
            %the background image
            plotupdateTracesOverlay(app.UIAxes,app.TrackData, flag3d, flagOn, flagtype, frame, scale, app.TrackOffsetXEditField.Value, app.TrackOffsetYEditField.Value, app.TrackOffsetZEditField.Value, spotsize);
            %Willl plot the track lines to the current available track if
            %it is selected
            %if flagtype == 1
               % plotupdateTracesOverlay(app.UIAxes,app.TrackData, flag3d, flagOn, 0, frame, scale, app.TrackOffsetXEditField.Value, app.TrackOffsetYEditField.Value, app.TrackOffsetZEditField.Value, spotsize);
            %end
            %plot the red marker again if only 2D is wanted
            %if flag3d == 0
            %plotupdateTracesOverlay(app.UIAxes,app.TrackData, flag3d, flagOn, flagtype, frame, scale, app.TrackOffsetXEditField.Value, app.TrackOffsetYEditField.Value, app.TrackOffsetZEditField.Value, spotsize);    
            %end
            hold(app.UIAxes,"off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %set some warnings of
            warning("off", 'MATLAB:table:ModifiedAndSavedVarnames');
            
            %link to the calling app
            app.SelectionWindow = mainapp;
            app.SettingsChangedFlag = 0;
            %make track selection unavailable as long as no setting have
            %been chosen
            app.BrowseButton_2.Enable = "off";
            %set the colormap for the microscopy data
            colormap(app.UIAxes,gray);
            %this will usually open a new figure, so we hide it
            h = gcf;
            set(h, "visible", "off");         
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ValidationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {635, 635};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
            %prevent another settings window
            app.ImportSettingsButton.Enable = "off";
            %prevent loading of tracks
            app.BrowseButton_2.Enable = "off";
            %if a settings window is already existing, make it visibile, if
            %not make a new one
            if isa(app.ImportSettingsValidationWindow, "ImportSettingsValidationWindow")
                app.ImportSettingsValidationWindow.ImportSettingsUIFigure.Visible = "on";
            else
                app.ImportSettingsValidationWindow = ImportSettingsValidationWindow(app); %#ok<ADPROPLC> 
            end
            app.BrowseButton_2.Enable = "off";
        end

        % Close request function: ValidationUIFigure
        function ValidationUIFigureCloseRequest(app, event)
            app.SelectionWindow.rdPeakUIFigure.Visible = "on";
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %Button to select the microscopy image data. Search in file
            %system. Will load tiff-stacks

            % Display uigetfile dialog
            filterspec = {'*.tif'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            
            %using a tiff loading adapter script as matlab tiff loading is
            %VERY slow. Will trigger warnings, but works.
            warning('off', 'imageio:tiffmexutils:libtiffWarning');
            warning('off', 'MATLAB:imagesci:tiffmexutils:libtiffWarning');
            
            %show a loading widow so the user does not get impatient
            d = uiprogressdlg(app.ValidationUIFigure,'Title','Loading Tiff', 'Indeterminate','on');
            drawnow
            %now load the tiff image
            imagestack = TIFFStack(fullfile(p,f));
            imagestack = cast(imagestack,"double");
            %set the image data and adjust the GUI properties depending on
            %that
            app.ImageData = imagestack;
            app.updateSlider();
            app.updateFigureImage();
            if ~isempty(imagestack)
                app.BrowseButton_2.Enable = "on";
            end
            %close the loading window
            close(d);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            %Button to select the trace data matching the microscopy data.
            %Get the settings
            updateSettings(app)
            %check if settings changed. if yes clear the data
            if app.SettingsChangedFlag
                app.TrackData = [];
                app.ImageData = [];
                cla(app.UIAxes);
            end
            %Load the tracks
            app.loadTracks();
            %make sure that the data is not empty, then set the GUI
            if ~isempty(app.TrackData)
                app.updateTrackSelection();
                manageSpotsTrackOverlay(app);
                updateFigureImage(app);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %adjusts track and image data to the newly selected frame
            %get the value
            value = app.Slider.Value;
            %set the new frame number
            app.FrameEditField.Value = ceil(value);
            rotate3d(app.UIAxes,'off');
            %update image and tracks
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Key press function: ValidationUIFigure
        function ValidationUIFigureKeyPress(app, event)
            %allows the user the navigate the frames using the left and
            %right arrow keys
            value = app.Slider.Value; % get the slider value
            rotate3d(app.UIAxes,'off');
	        key = event.Key; % get the pressed key value
            step = app.StepSizeEditField.Value;
            if strcmp(key,'leftarrow')
        	    value = value-step; % left value
            elseif strcmp(key,'rightarrow')
                value = value+step; % right value
            end
            app.Slider.Value = value; % set the slider value
            app.FrameEditField.Value = value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            %changingValue = event.Value;
            
        end

        % Button pushed function: FlipImageButton
        function FlipImageButtonPushed(app, event)
            %Flips/Mirrors the image in the background
            app.ImageData = flip(app.ImageData,2);
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            %Rotates the image 90° counterclockwise
            app.ImageData = rot90(app.ImageData);
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Button pushed function: PlotKaryogramButton
        function PlotKaryogramButtonPushed(app, event)
            %Plots an overview of all the tracks that match the properties
            %set on the GUI.
            switch app.Switch_2.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            Unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            Karyogramm3DwithType(app.TrackData, app.NrTrackstoshowEditField.Value, flag3d, app.TrackTypeDropDown.Value, app.LocPrecXYEditField.Value, app.LocPrecZEditField.Value, Unit);
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            %decides if tracks and spots will be shown or not
            value = app.Switch_3.Value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged(app, event)
            %will allow to jump to a given frame of a selected track id and
            %will adjust the GUI according to the new frame number etc.
            
            value = str2double(app.TrackIDDropDown.Value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.TrackData(app.TrackData(:,1)==value,2));
            app.FrameEditField.Value = frame;
            app.Slider.Value = frame;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            %decides if 2D or 3D data will be shown. will change the view
            %accordingly
            
            value = app.Switch_2.Value;
            switch value
                case "2D"
                    view(app.UIAxes,2);
                    app.TrackOffsetZEditField.Value = 1;
                rotate3d(app.UIAxes,'off');
                case "3D"
                    app.TrackOffsetZEditField.Value = 500;
                    view(app.UIAxes,45,45);
                    rotate3d(app.UIAxes,'on');
            end
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %decides if spots or tracks will be shown
            value = app.Switch.Value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackOffsetXEditField
        function TrackOffsetXEditFieldValueChanged(app, event)
            %changes the track offset in the x direction
            value = app.TrackOffsetXEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackOffsetYEditField
        function TrackOffsetYEditFieldValueChanged(app, event)
            %changes the track offset in the y direction
            value = app.TrackOffsetYEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: TrackOffsetZEditField
        function TrackOffsetZEditFieldValueChanged(app, event)
            %changes the track offset in the z direction
            value = app.TrackOffsetZEditField.Value;
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            %lets you jump to a frame of your choice
            value = app.FrameEditField.Value;
            app.Slider.Value = value;
            app.updateFigureImage();
            manageSpotsTrackOverlay(app);
        end

        % Value changed function: SpotsizeEditField
        function SpotsizeEditFieldValueChanged(app, event)
            value = app.SpotsizeEditField.Value;
            manageSpotsTrackOverlay(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ValidationUIFigure = uifigure('Visible', 'off');
            app.ValidationUIFigure.Name = 'Validation';
            app.ValidationUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.ValidationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ValidationUIFigureCloseRequest, true);
            app.ValidationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ValidationUIFigure.KeyPressFcn = createCallbackFcn(app, @ValidationUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.ValidationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataImportPanel = uipanel(app.LeftPanel);
            app.DataImportPanel.Title = 'Data Import';
            app.ImportSettingsButton = uibutton(app.DataImportPanel, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Tooltip = {'Set the import settings first to allow for the loading of track data.'; ''};
            app.ImportSettingsButton.Text = 'Import Settings';
            app.BrowseButton = uibutton(app.DataImportPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Tooltip = {'Enables you to load a tiff stack for validation purposes.'};
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.DataImportPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Enable = 'off';
            app.BrowseButton_2.Tooltip = {'Opens a file select dialogue based on the import settings set to laod the track data.'};
            app.BrowseButton_2.Text = 'Browse';
            app.Switch_2 = uiswitch(app.DataImportPanel, 'slider');
            app.Switch_2.Items = {'3D', '2D'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'Switches between 3D and 2D visualisation of your data.'};
            app.Switch_2.Value = '3D';
            app.ImageDataLabel = uilabel(app.DataImportPanel);
            app.ImageDataLabel.Text = 'Image Data';
            app.TrackDataLabel = uilabel(app.DataImportPanel);
            app.TrackDataLabel.Text = 'Track Data';
            app.MinTrackLengthEditFieldLabel = uilabel(app.DataImportPanel);
            app.MinTrackLengthEditFieldLabel.Text = 'Min. Track Length';
            app.MinTrackLengthEditField = uieditfield(app.DataImportPanel, 'numeric');
            app.MinTrackLengthEditField.Limits = [1 Inf];
            app.MinTrackLengthEditField.Tooltip = {'Filter the tracks by a given minimal track lenght'};
            app.MinTrackLengthEditField.Value = 5;
            app.ImageTrackOverlayPanel = uipanel(app.LeftPanel);
            app.ImageTrackOverlayPanel.Title = 'Image - Track Overlay';
            app.Switch = uiswitch(app.ImageTrackOverlayPanel, 'slider');
            app.Switch.Items = {'Spots', 'Tracks'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Switches between the single localisations (Spots) and connected Tracks.'};
            app.Switch.Value = 'Spots';
            app.TrackIDDropDownLabel = uilabel(app.ImageTrackOverlayPanel);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.ImageTrackOverlayPanel);
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged, true);
            app.TrackIDDropDown.Tooltip = {'Allows to switch to a track with the selected ID'};
            app.TrackOffsetXEditFieldLabel = uilabel(app.ImageTrackOverlayPanel);
            app.TrackOffsetXEditFieldLabel.Text = 'Track Offset X';
            app.TrackOffsetXEditField = uieditfield(app.ImageTrackOverlayPanel, 'numeric');
            app.TrackOffsetXEditField.ValueChangedFcn = createCallbackFcn(app, @TrackOffsetXEditFieldValueChanged, true);
            app.TrackOffsetXEditField.Tooltip = {'Moves the tracks in x direction to correct for an possible offset.'};
            app.TrackOffsetYEditFieldLabel = uilabel(app.ImageTrackOverlayPanel);
            app.TrackOffsetYEditFieldLabel.Text = 'Track Offset Y';
            app.TrackOffsetYEditField = uieditfield(app.ImageTrackOverlayPanel, 'numeric');
            app.TrackOffsetYEditField.ValueChangedFcn = createCallbackFcn(app, @TrackOffsetYEditFieldValueChanged, true);
            app.TrackOffsetYEditField.Tooltip = {'Moves the tracks in Y direction to correct for possible offset.'};
            app.TrackOffsetZEditFieldLabel = uilabel(app.ImageTrackOverlayPanel);
            app.TrackOffsetZEditFieldLabel.Text = 'Track Offset Z';
            app.TrackOffsetZEditField = uieditfield(app.ImageTrackOverlayPanel, 'numeric');
            app.TrackOffsetZEditField.ValueChangedFcn = createCallbackFcn(app, @TrackOffsetZEditFieldValueChanged, true);
            app.TrackOffsetZEditField.Tooltip = {'Moves the tracks in Z direction to correct for possible offset.'};
            app.TrackOffsetZEditField.Value = 500;
            app.FlipImageButton = uibutton(app.ImageTrackOverlayPanel, 'push');
            app.FlipImageButton.ButtonPushedFcn = createCallbackFcn(app, @FlipImageButtonPushed, true);
            app.FlipImageButton.Tooltip = {'Flips the background image if necessary.'};
            app.FlipImageButton.Text = 'Flip Image';
            app.RotateImageButton = uibutton(app.ImageTrackOverlayPanel, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Tooltip = {'Rotates the image counter-clockwise if ncessary.'};
            app.RotateImageButton.Text = 'Rotate Image';
            app.Switch_3 = uiswitch(app.ImageTrackOverlayPanel, 'slider');
            app.Switch_3.Items = {'On', 'Off'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Tooltip = {'Enables disables the track overlay.'};
            app.Switch_3.Value = 'On';
            app.PixelsizeinNmEditFieldLabel = uilabel(app.ImageTrackOverlayPanel);
            app.PixelsizeinNmEditFieldLabel.Text = 'Pixelsize in Nm';
            app.PixelsizeinNmEditField = uieditfield(app.ImageTrackOverlayPanel, 'numeric');
            app.PixelsizeinNmEditField.Limits = [1e-05 Inf];
            app.PixelsizeinNmEditField.Tooltip = {'Pixelsize of the image. Rescales the tracks to fit it.'};
            app.PixelsizeinNmEditField.Value = 160;
            app.SpotsizeEditFieldLabel = uilabel(app.ImageTrackOverlayPanel);
            app.SpotsizeEditFieldLabel.Text = 'Spot size';
            app.SpotsizeEditField = uieditfield(app.ImageTrackOverlayPanel, 'numeric');
            app.SpotsizeEditField.ValueChangedFcn = createCallbackFcn(app, @SpotsizeEditFieldValueChanged, true);
            app.SpotsizeEditField.Value = 10;
            app.KaryogramPanel = uipanel(app.LeftPanel);
            app.KaryogramPanel.Title = 'Karyogram';
            app.NrTrackstoshowEditFieldLabel = uilabel(app.KaryogramPanel);
            app.NrTrackstoshowEditFieldLabel.Text = 'Nr. Tracks to show';
            app.NrTrackstoshowEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.NrTrackstoshowEditField.Limits = [0 Inf];
            app.NrTrackstoshowEditField.Tooltip = {'Defines how many tracks will be plotted.'};
            app.TrackTypeDropDownLabel = uilabel(app.KaryogramPanel);
            app.TrackTypeDropDownLabel.Text = 'Track Type';
            app.TrackTypeDropDown = uidropdown(app.KaryogramPanel);
            app.TrackTypeDropDown.Items = {'All', 'Diffusion', 'Immobile', 'Directed', 'Directed_Diffusion', 'None'};
            app.TrackTypeDropDown.Tooltip = {'Filter the tracks by the tracktype.'};
            app.TrackTypeDropDown.Value = 'All';
            app.PlotKaryogramButton = uibutton(app.KaryogramPanel, 'push');
            app.PlotKaryogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotKaryogramButtonPushed, true);
            app.PlotKaryogramButton.Tooltip = {'Will polt an overview of all tracks available in an extra window, arranged in an square.'};
            app.PlotKaryogramButton.Text = 'Plot Karyogram';
            app.LocPrecXYEditFieldLabel = uilabel(app.KaryogramPanel);
            app.LocPrecXYEditFieldLabel.Text = 'LocPrecXY';
            app.LocPrecXYEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.LocPrecZEditFieldLabel = uilabel(app.KaryogramPanel);
            app.LocPrecZEditFieldLabel.Text = 'LocPrecZ';
            app.LocPrecZEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, 'X [px]')
            ylabel(app.UIAxes, 'Y [px]')
            zlabel(app.UIAxes, 'Z [px]')
            app.Slider = uislider(app.RightPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Tooltip = {'Allows to move trhough the video frame by frame using the mouse or the left and right arrow keys.'};
            app.StepSizeEditFieldLabel = uilabel(app.RightPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.RightPanel, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.Tooltip = {'Defines the timestep size when using the left and right arrow keys to move through the video.'};
            app.StepSizeEditField.Value = 1;
            app.FrameEditFieldLabel = uilabel(app.RightPanel);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.RightPanel, 'numeric');
            app.FrameEditField.Limits = [0 Inf];
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tooltip = {'Shows the given frame, but also allows to switch to a specific frame.'};
            % Show the figure after all components are created
            app.ValidationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ValidationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ValidationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ValidationUIFigure)
        end
    end
end