classdef VisualisationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualisationUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        KaryogramPanel                  matlab.ui.container.Panel
        TrackTypeDropDown               matlab.ui.control.DropDown
        TrackTypeDropDownLabel          matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        LocPrecXYEditField              matlab.ui.control.NumericEditField
        LocPrecXYEditFieldLabel         matlab.ui.control.Label
        PlotKaryogramButton             matlab.ui.control.Button
        NrTrackstoshowEditField         matlab.ui.control.NumericEditField
        NrTrackstoshowEditFieldLabel    matlab.ui.control.Label
        DimensionsandPlotPanel          matlab.ui.container.Panel
        IsFilterSetLabel                matlab.ui.control.Label
        UseFIlterfromAllTrackAnalysisCheckBox  matlab.ui.control.CheckBox
        SpotsizeEditField               matlab.ui.control.NumericEditField
        SpotsizeEditFieldLabel          matlab.ui.control.Label
        TrackIDDropDown                 matlab.ui.control.DropDown
        TrackIDDropDownLabel            matlab.ui.control.Label
        AxisScaleSwitch                 matlab.ui.control.Switch
        AxisScaleSwitchLabel            matlab.ui.control.Label
        NextTracksButton                matlab.ui.control.Button
        PrevTracksButton                matlab.ui.control.Button
        Switch_6                        matlab.ui.control.Switch
        MinTrackLengthEditField         matlab.ui.control.NumericEditField
        MinTrackLengthEditFieldLabel    matlab.ui.control.Label
        ScaleZEditField                 matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel            matlab.ui.control.Label
        ScaleXYEditField                matlab.ui.control.NumericEditField
        ScaleXYEditFieldLabel           matlab.ui.control.Label
        ShowTracksSwitch                matlab.ui.control.Switch
        ShowTracksSwitchLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        ImageSettingsPanel              matlab.ui.container.Panel
        Switch_5                        matlab.ui.control.Switch
        OffsetZEditField                matlab.ui.control.NumericEditField
        OffsetZEditFieldLabel           matlab.ui.control.Label
        OffsetYEditField                matlab.ui.control.NumericEditField
        OffsetYEditFieldLabel           matlab.ui.control.Label
        OffsetXEditField                matlab.ui.control.NumericEditField
        OffsetXEditFieldLabel           matlab.ui.control.Label
        FrameEditField                  matlab.ui.control.NumericEditField
        FrameEditFieldLabel             matlab.ui.control.Label
        AverageIntensityCheckBox        matlab.ui.control.CheckBox
        FlipButton                      matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        ImportTracksandImagesPanel      matlab.ui.container.Panel
        PerformDataDriftCorrectionButton  matlab.ui.control.Button
        TrackFileDropDown               matlab.ui.control.DropDown
        TrackFileDropDownLabel          matlab.ui.control.Label
        LoadReferenceImageButton        matlab.ui.control.Button
        LoadTracksButton                matlab.ui.control.Button
        ImportSettingsButton            matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        StepsizeEditField               matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        TimepointEditField              matlab.ui.control.NumericEditField
        TimepointEditFieldLabel         matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        CompleteAnalysisPanel           matlab.ui.container.Panel
        UseROIforAnalysisCheckBox       matlab.ui.control.CheckBox
        TracksButton                    matlab.ui.control.Button
        SpotsButton                     matlab.ui.control.Button
        Switch_4                        matlab.ui.control.Switch
        SupermapGenerationPanel         matlab.ui.container.Panel
        Switch_7                        matlab.ui.control.Switch
        SuperpxZSizeLabel               matlab.ui.control.Label
        SuperpxXYSizeLabel              matlab.ui.control.Label
        SuperpixelSizeZEditField        matlab.ui.control.NumericEditField
        SuperpixelSizeZEditFieldLabel   matlab.ui.control.Label
        TimeWindowsEditField            matlab.ui.control.NumericEditField
        TimeWindowsEditFieldLabel       matlab.ui.control.Label
        SuperpixelSizeXYEditField       matlab.ui.control.NumericEditField
        SuperpixelSizeXYEditFieldLabel  matlab.ui.control.Label
        DensityButton                   matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        VelocityButton                  matlab.ui.control.Button
        ROIselectionPanel               matlab.ui.container.Panel
        SaveAnitROICheckBox             matlab.ui.control.CheckBox
        ROINameEditField                matlab.ui.control.EditField
        ROINameEditFieldLabel           matlab.ui.control.Label
        LoadROIsButton                  matlab.ui.control.Button
        Switch_3                        matlab.ui.control.Switch
        ROIListDropDown                 matlab.ui.control.DropDown
        ROIListDropDownLabel            matlab.ui.control.Label
        DeleteselectedROIButton         matlab.ui.control.Button
        SaveallROIsforBatchAnalysisButton  matlab.ui.control.Button
        SaveROIButton                   matlab.ui.control.Button
        SelectROIButton                 matlab.ui.control.Button
        SingleTrackAnalysisPanel        matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        TrackIDLabel                    matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        SelectTrackButton               matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    properties (Access = public)
        ImportSettingsVisualisationWindow %Link to import settings window
        ImportSettingsStruct %Settings from the Import Settings Window
        CurrentDataTypes %keepts the current data type
    end
    
    properties (Access = private)
        SelectionWindow % Link to selection window
        
        AllTrackAnalysisWindow %Link to AllTrack Analysis
        SingleTrackAnalysisWindow %Link to Single Track Analysis
        SpotStatisticsWindow %Link to spot Statistics Window
        
        
        ImageData %Raw image tiff data from the localisation video
        AveragedImageData %Averaged Intensity Projection of the tiff stack
        TrackData %Imported tracking data
        CurrentTrackFileData %Holds the tracks of the currently selected file
        FilteredTrackData %Only contains the filtered tracks
        Spothandles %Handles for spots to plot
        Trackhandles %Handles for tracks to plot
        TrackIDList %ID list for the track handles
        ROIList %List of all the saved ROIs
        SelectedTrackData %Data of the selected track form track selection
        SelectedTrackID %ID of the selected track
        StoreAxes%Keeps plottet things save
        
        Scalexy %holds the scale value from the settings window
        CurrentROI % holds the current selected roi as a propertey
        SettingsChangedFlag %boolean to tell the mainapp if something has changed in the settings window
    end
    
    methods
        function updateSettings(app)
            %if there is a settings window, get the settings
            if isa(app.ImportSettingsVisualisationWindow, "ImportSettingsVisualisationWindow")
                app.ImportSettingsStruct = app.ImportSettingsVisualisationWindow.getSettings();
                %set properties of GUI depending on the settings
                app.CurrentDataTypes = app.ImportSettingsStruct.customUnits;
            else
                %Nothing
            end
            
        end
                 
        function setSettingsChanged(app, value)
            %changes the settingschangedflag to either 1 or 0
            app.SettingsChangedFlag = value;
        end

        function tracks = getTracks(app)
            %retrieves all track that are left after filtering
            tracks = app.FilteredTrackData;
        end
        
        function IDs = getAllIds(app)
            %get the trackids of the remaining tracks for the selection
            %window
            IDs = app.TrackIDList;
        end
        
        function sz = getPixelsize(app)
            %retrieves the scaling factor for the single track and all
            %track analysis
            sz = app.ScaleXYEditField.Value;
        end
        
        function ts = getTimestep(app)
            %get the timestep for the single track and all track analysis
            ts = app.ImportSettingsStruct.customUnits.Timestep;
        end
        
        function trackdata = getTrackbyID(app, ID)
            %selects a track by its id and returns the corresponding spots
            trackdata = app.CurrentTrackFileData(app.CurrentTrackFileData(:,1) == ID,:);
        end
        
        function intensityData = getIntensityData(app)
            %returns the intensity data of a given track to be used for
            %analysis
            switch app.Switch_4.Value
                case "Current File"
                    flagdata = 0;
                case "All Files"
                    flagdata = 1;
            end
            flagbox = app.UseROIforAnalysisCheckBox.Value;
            intensityData = {};
            
            if flagdata
                %All Files
                for i = 1:size(app.TrackData,1)
                    intensityData{i,1} = app.TrackData{i,2};
                    data = app.TrackData{i,1};
                    intensityData{i,2} = data(:,18);
                end
            else
                %Current File
                intensityData{1,1} = app.TrackFileDropDown.Value;
                intensityData{1,2} = app.CurrentTrackFileData(:,18);
                
            end
            if flagbox
                %only roi data
                roinames = app.ROIListDropDown.Items;
                intensityData = {};
                %data1, names2
                for i = 1:size(roinames,2)
                    intensityData{i,1} = app.ROIList{i,3}(:,18);
                    intensityData{i,2} = roinames{1,i};
                end
            end
        end
        
        function trackdata = getCurrentData(app)
            %gets the data of the currently selected file
            trackdata{1,2} = app.TrackFileDropDown.Value;
            trackdata{1,1} = app.CurrentTrackFileData(:,:);
        end
        
        function trackdata = getTrackAnalysisData(app)
            %gets the data for the all track analysis
            switch app.Switch_4.Value
                case "Current File"
                    flagdata = 0;
                case "All Files"
                    flagdata = 1;
            end
            flagbox = app.UseROIforAnalysisCheckBox.Value;
            trackdata = {};
            if flagdata
                %All Files
                trackdata = app.TrackData;
            else
                %currentfile
                trackdata{1,2} = app.TrackFileDropDown.Value;
                trackdata{1,1} = app.CurrentTrackFileData(:,:);
            end
            if flagbox
                %only roi data
                roinames = app.ROIListDropDown.Items;
                trackdata = {};
                %data1, names2
                for i = 1:size(roinames,2)
                    trackdata{i,1} = app.ROIList{i,3};
                    trackdata{i,2} = roinames{1,i};
                end
            end
        end
        
        function isAxes = IsAxes(app, ax)
            try
                isAxes = strcmp(get(ax, 'type'), 'axes');
            catch
                isAxes = false;
            end
        end
        
    end
    
    methods (Access = private)


        function manageBackgroundImage(app)
            %controls how and if a background image is shown
            ax = app.UIAxes;
            imagedata = [];
            %colormap(ax,gray);
            switch app.Switch_5.Value
                case "Off"
                    %nothing
                    
                case "On"
                    %plot the background image
                    if app.AverageIntensityCheckBox.Value == 0
                        %select a frame for the image
                        frame = app.FrameEditField.Value;
                        imagedata = app.ImageData(:,:,frame);
                    else
                        %use the average intensity projection
                        %if isempty(app.AveragedImageData)
                        imagedata = generateAverageIntensityProjection(app);
                        
                            %app.AveragedImageData = imagedata;
                        %end
                        %imagedata = app.AveragedImageData;
                    end
                    %Create surface for the image
                    startx = 0+app.OffsetXEditField.Value;
                    starty = 0+app.OffsetYEditField.Value;
                    x = startx:startx+size(imagedata,1)-1;
                    y = starty:starty+size(imagedata,2)-1;
                    z = app.OffsetZEditField.Value;
                    [X,Y,Z] = meshgrid(x,y,z);
                    
                    %get the image
                    %I = image(ax,imagedata,'CDataMapping','scaled');
                    I = imagedata;
                    
                    %need to normalize image to fit into the colormap
                    minval = min(min(imagedata));
                    maxval = max(max(imagedata));
                    
                    scale = 65535;
                    
                    normImage = ((I-minval)/(maxval-minval))*scale;

                    %place the image on the surface
                    %axes(ax);
                    %hold(app.UIAxes,"on");
                    h1 = figure();
                    set(h1,"Visible","off");
                    warp(X,Y,Z,normImage,scale);  
                    colormap(ax,"parula"); 
                    copyobj(h1.Children.Children,ax);
                    %hold(app.UIAxes,"off");
            end
        end
        
        function outputImage = generateAverageIntensityProjection(app)
            %generates the average intensity projection if this option is
            %chosen by the user
            imagestack = app.ImageData;
            %generate the averageIntensityProjection
            [rows, columns, ~] = size(imagestack);
            outputImage = zeros(rows, columns);
            for col = 1 : columns
                for row = 1 : rows
                    ZVector = imagestack(row, col, :);
                    meanValue = ceil(mean(ZVector));
                    outputImage(row, col) = meanValue;
                end
            end
            %app.AveragedImageData = outputImage;
        end
        
        function filterTracksbyLength(app, tracklength)
            %filters all the tracks by a given minimal length set by the
            %user. might be necessary for msd analysis
            edges = unique(app.CurrentTrackFileData(:,1));
            counts = histc(app.CurrentTrackFileData(:,1),edges); %#ok<ADPROPLC> 
            interest = find(counts > tracklength);
            intracksnr = edges(interest); %#ok<ADPROPLC> 
            filtertracks = [];
            for j = 1:length(intracksnr)
                k = intracksnr(j);
                intresttrackind = find(app.CurrentTrackFileData(:,1) == k);
                filtertracks = cat(1,filtertracks, app.CurrentTrackFileData(intresttrackind,:));
            end
            if isempty(filtertracks)
                error("No tracks left after filtering!")
            end
            app.FilteredTrackData = filtertracks;
        end
        
        function updateTrackFileSelection(app)
            %Fills the track file selection drop down menue with the
            %availabe files
            filenames = app.TrackData(:,2);
            app.TrackFileDropDown.Items = filenames;
            app.TrackFileDropDown.Value = filenames(1);
        end
        
        function setCurrentTrackData(app)
            %Depending on the selected track file, the current available
            %data is stored in the currentTrackData property
            value = app.TrackFileDropDown.Value;
            namelist = app.TrackData(:,2);
            index = find(contains(app.TrackData(:,2), value));
            app.CurrentTrackFileData = app.TrackData{index,1};
            %Update Track related information
            filterTracksbyLength(app, app.MinTrackLengthEditField.Value);
            updateTrackSelection(app);
        end
        
        function updateTrackSelection(app)
            %Fill the selection box with the unique ids of the individual
            %tracks
            IDs = unique(app.FilteredTrackData(:,1));
            IDs = IDs.';
            IDs = string(IDs);
            app.TrackIDList = IDs;
            app.TrackIDDropDown.Items = IDs;
        end
        
        function manageTrackOverlay(app)
            %controls how and if tracks or psots are shown depending on the
            %selection of the user
            %set the properties for the function
            switch app.Switch.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            switch app.ShowTracksSwitch.Value
                case "On"
                    flagOn = 1;
                case "Off"
                    flagOn = 0;
            end
            switch app.Switch_6.Value
                case "All"
                    flagtrc = 1;
                case "Single Track"
                    flagtrc = 0;
            end
            %get scaling values
            scalexy = app.ScaleXYEditField.Value;
            scalez = app.ScaleZEditField.Value;
            scalexy = 1/scalexy;
            %clear the axes
            cla(app.UIAxes);
            %manageBackgroundImage(app);
            hold(app.UIAxes,"on");
            %plot the traces
            updateVisualisationTracesOverlay(app, flag3d, flagOn, flagtrc, scalexy, scalez, 1); %tracks
            %add the ids to the traces
            app.Trackhandles = app.UIAxes.Children;
            idxs = zeros(size(app.Trackhandles,1),1);
            for i = 1:size(app.Trackhandles,1)
                idxs(i) = isa(app.Trackhandles(i), "matlab.graphics.chart.primitive.Line");
            end
            idxs = logical(idxs);
            app.Trackhandles = app.Trackhandles(idxs);
            
            for i = 1:numel(app.Trackhandles)
                % Add a new row to DataTip showing the DisplayName of the line
                app.Trackhandles(i).DataTipTemplate.DataTipRows(end+1) = dataTipTextRow('Track',repmat({app.Trackhandles(i).DisplayName},size(app.Trackhandles(i).XData))); 
            end
            if flagtrc == 0
                updateVisualisationTracesOverlay(app, flag3d, flagOn, flagtrc, scalexy, scalez, 0); %spots
            end
            %plot the background if necessary
            manageBackgroundImage(app);
            %add the units to the figure
            unitscale = app.ImportSettingsStruct.customUnits.LengthUnit;
            app.UIAxes.XLabel.String = sprintf("X [%s]", unitscale);
            app.UIAxes.YLabel.String = sprintf("Y [%s]", unitscale);
            app.UIAxes.ZLabel.String = sprintf("Z [%s]", unitscale);
            app.UIAxes.Title.String = "";
            hold(app.UIAxes,"off");
        end
        
        function ID = getIDfromDataTip(app)
            %gets the track id from the currently active datatip for single
            %track analysis
            lines = app.UIAxes.Children;
            for i = 1:size(lines,1)
                if ~isempty(lines(i).Children)
                datatip = lines(i).Children;
                end
            end
            ID = datatip.Content(end);
            ID = erase(ID,"Track ");
            ID = str2double(ID);
        end
        
        function updateFrameSliderLimits(app)
            %depending on the track data the frame slider will be set to
            %fit its range
            if ~isempty(app.CurrentTrackFileData)
                %got some trackdata to work with, set slider limts
                %accordingly
                mint = 1;
                maxt = max(app.CurrentTrackFileData(:,2));
                limits = [mint, maxt];
                app.TimeSlider.Limits = limits;
            elseif ~isempty(app.ImageData)
                %ony an image has been loaded
                mint = 1;
                maxt = size(app.ImageData,3);
                limits = [mint, maxt];
                app.TimeSlider.Limits = limits;
            else
                app.TimeSlider.Limits = [1,100];
            end
        end
        
        function updateROIList(app)
            %when a new roi is saved it is added to the selection menue
            names = {};
            for i = 1:size(app.ROIList,1)
                names{i} = strcat(app.ROIList{i,4}, "-", app.ROIList{i,1});
            end
            names = vertcat(names{:});
            app.ROIListDropDown.Items = names;
            
        end
        
        function switchROI(app)
            %when one switches to an already saved roi, the current one
            %needs to be disabled and the new one enabled
            value = app.ROIListDropDown.Value;
            idx = find(strcmp(app.ROIListDropDown.Items,value));
            dim = size(app.ROIListDropDown.Items,2);
            datacell = app.ROIList(idx,:);
            try
                delete(app.CurrentROI);
            catch
            end
            roiidx = flip(linspace(1, dim, dim));
            idx = find(roiidx == idx);
            oldROI = app.StoreAxes.Children(idx);
            app.CurrentROI = copyobj(oldROI,app.UIAxes);       
        end
    
        function adjustDataAndScale(app)
            %when offering more data import option, I have to adjust the
            %data so that is uniform and that it can be used as an overlay
            %for the microscopy image. As I cant scale the image in a sens
            %of making pixel smaller, i will have to work with the
            %track/spot data.
            %4 options: pixel/frame, pixel/unit, unit/frame and unit/unit
            %mic data is pixel/frame, so we have to make the tracks fit
            type = split(convertCharsToStrings(app.ImportSettingsStruct.customUnits.DataType),"/");
            comparison = strcmp(type, ["Pixel"; "Frame"]);
            if comparison %pixel/frame
                %if pixel/frame, nothing much has to be done
                app.ScaleXYEditField.Value = 1;
                app.ScaleXYEditFieldLabel.Text = "Scale XY [px]";
                app.ScaleZEditField.Value = 1;
            elseif comparison(1) & (~comparison(2))%Pixel/Unit
                %if pixel/unit, must convert unit frame
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
                app.ScaleXYEditField.Value = 1;
                app.ScaleXYEditFieldLabel.Text = "Scale XY [px]";
                app.ScaleZEditField.Value = 1;
            elseif (~comparison(1)) & comparison(2)%Unit/Frame
                %if unit/frame, must convert unit to pixel with the scale
                app.ScaleXYEditFieldLabel.Text = join(["Scale XY", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.ScaleXYEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            else
                %if unit/unit, must convert both
                time = app.TrackData(:,2);
                time = round(time/app.ImportSettingsStruct.customUnits.Timestep,0);
                app.TrackData(:,2) = time;
                app.ScaleXYEditFieldLabel.Text = join(["Scale XY", app.ImportSettingsStruct.customUnits.LengthUnit]);
                app.ScaleXYEditField.Value = app.ImportSettingsStruct.customUnits.Pixelsize;
            end
            app.SuperpxXYSizeLabel.Text = app.ImportSettingsStruct.customUnits.LengthUnit;
            app.SuperpxZSizeLabel.Text = app.ImportSettingsStruct.customUnits.LengthUnit;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.SelectionWindow = mainapp;
            app.LoadTracksButton.Enable = "off";
            app.ROIList = {};
            app.StoreAxes = axes();
            set(app.StoreAxes.Parent, "Visible", "off");
            app.Scalexy = app.ScaleXYEditField.Value;
            set(gcf, "visible", "off")
            set(app.StoreAxes, "visible", "off")
        end

        % Button pushed function: ImportSettingsButton
        function ImportSettingsButtonPushed(app, event)
            app.ImportSettingsButton.Enable = "off";
            if isa(app.ImportSettingsVisualisationWindow, "ImportSettingsVisualisationWindow")
                app.ImportSettingsVisualisationWindow.ImportSettingsUIFigure.Visible = "on";
            else
                app.ImportSettingsVisualisationWindow = ImportSettingsVisualisationWindow(app);
            end
            
        end

        % Button pushed function: TracksButton
        function TracksButtonPushed(app, event)
            app.AllTrackAnalysisWindow = AllTrackAnalysisWindow(app);
        end

        % Button pushed function: SpotsButton
        function SpotsButtonPushed(app, event)
            app.SpotStatisticsWindow = SpotStatisticsWindow(app);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            try
                ID = getIDfromDataTip(app);
                app.SelectedTrackID = ID;
                app.Label_3.Text = num2str(ID);
            end

            app.SelectedTrackData = app.FilteredTrackData(app.FilteredTrackData(:,1) == ID,:);
            app.SingleTrackAnalysisWindow = SingleTrackAnalysisWindow(app, app.SelectedTrackData, ID);
        end

        % Close request function: VisualisationUIFigure
        function VisualisationUIFigureCloseRequest(app, event)
            app.SelectionWindow.rdPeakUIFigure.Visible = "on";
            delete(app)
        end

        % Button pushed function: LoadTracksButton
        function LoadTracksButtonPushed(app, event)
            %minTrackLength = app.MinTrackLengthEditField;
            minTrackLength = 2;
            app.TrackData = loadTrackDataFileSelection(app, minTrackLength);
            updateTrackFileSelection(app);
            setCurrentTrackData(app);
            updateFrameSliderLimits(app);
            
            IDs = app.TrackIDList;
            currID = app.TrackIDDropDown.Value;
            if length(IDs) == 1
                idx = 1;
                newID = IDs(idx);
            else
            idx = find(IDs == currID)+1;
            newID = IDs(idx);
            end
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            adjustDataAndScale(app);
            %app.FrameEditField.Value = frame;
            manageBackgroundImage(app);
            manageTrackOverlay(app);

        end

        % Button pushed function: LoadReferenceImageButton
        function LoadReferenceImageButtonPushed(app, event)
            %Button to select the microscopy image data. Search in file
            %system. Will load tiff-stacks

            % Display uigetfile dialog
            filterspec = {'*.tif'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
                error("No file selected")
            end
            %imagestack = tiffreadVolume(fullfile(p,f));
            warning('off', 'imageio:tiffmexutils:libtiffWarning');
            warning('off', 'MATLAB:imagesci:tifftagsread:expectedTagDataFormat');
            w = warning('off', 'MATLAB:imagesci:tiffmexutils:libtiffWarning');
            
            d = uiprogressdlg(app.VisualisationUIFigure,'Title','Loading Tiff', 'Indeterminate','on');
            drawnow
            
            imagestack = TIFFStack(fullfile(p,f));
            imagestack = cast(imagestack,"double");
            
            delete(d);
            %generate the averageIntensityProjection
            outputImage = generateAverageIntensityProjection(app);
            
            app.ImageData = imagestack;
            app.AveragedImageData = outputImage;
            
            updateFrameSliderLimits(app);
            manageBackgroundImage(app);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            %Rotates the image 90° counterclockwise
            app.ImageData = rot90(app.ImageData);
            generateAverageIntensityProjection(app);
            manageBackgroundImage(app);
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            %Flips/Mirrors the image in the background
            app.ImageData = flip(app.ImageData,2);
            generateAverageIntensityProjection(app);
            manageBackgroundImage(app);
        end

        % Value changed function: AverageIntensityCheckBox
        function AverageIntensityCheckBoxValueChanged(app, event)
            value = app.AverageIntensityCheckBox.Value;
            generateAverageIntensityProjection(app);
            manageBackgroundImage(app);
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            value = app.FrameEditField.Value;
            %manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: OffsetXEditField
        function OffsetXEditFieldValueChanged(app, event)
            value = app.OffsetXEditField.Value;
            %manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: OffsetYEditField
        function OffsetYEditFieldValueChanged(app, event)
            value = app.OffsetYEditField.Value;
            %manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: OffsetZEditField
        function OffsetZEditFieldValueChanged(app, event)
            value = app.OffsetZEditField.Value;
            %manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: MinTrackLengthEditField
        function MinTrackLengthEditFieldValueChanged(app, event)
            value = app.MinTrackLengthEditField.Value;
            filterTracksbyLength(app, value);
            manageTrackOverlay(app);
        end

        % Value changed function: TrackFileDropDown
        function TrackFileDropDownValueChanged(app, event)
            value = app.TrackFileDropDown.Value;
            setCurrentTrackData(app);
            cla(app.UIAxes);
            
             IDs = app.TrackIDList;
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)+1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            %app.FrameEditField.Value = frame;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Callback function
        function TrackIDDropDownValueChanged(app, event)
            value = str2double(app.TrackIDDropDown.Value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            app.FrameEditField.Value = frame;
            cla(app.UIAxes);
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Button pushed function: PrevTracksButton
        function PrevTracksButtonPushed(app, event)
            IDs = app.TrackIDList;
            %currID = str2double(app.TrackIDDropDown.Value);
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)-1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            %app.FrameEditField.Value = frame;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Button pushed function: NextTracksButton
        function NextTracksButtonPushed(app, event)
            IDs = app.TrackIDList;
            currID = app.TrackIDDropDown.Value;
            idx = find(IDs == currID)+1;
            newID = IDs(idx);
            app.TrackIDDropDown.Value = newID;
            value = str2double(newID);
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            %app.FrameEditField.Value = frame;
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Key press function: VisualisationUIFigure
        function VisualisationUIFigureKeyPress(app, event)
            value = app.TimeSlider.Value; % get the slider value
            rotate3d(app.UIAxes,'off');
	        key = event.Key; % get the pressed key value
            step = app.StepsizeEditField.Value;
            if strcmp(key,'leftarrow')
        	    value = value-step; % left value
            elseif strcmp(key,'rightarrow')
                value = value+step; % right value
            end
            app.TimeSlider.Value = value; % set the slider value
            app.TimepointEditField.Value = value;
        end

        % Button pushed function: SelectTrackButton
        function SelectTrackButtonPushed(app, event)
            %Shut off the background image before selecting a track,
            %otherwise the image can also be selected.
            app.Switch_5.Value = "Off";
            manageBackgroundImage(app);
            manageTrackOverlay(app);
            
            datacursormode(app.VisualisationUIFigure,"toggle");
        end

        % Size changed function: SingleTrackAnalysisPanel
        function SingleTrackAnalysisPanelSizeChanged(app, event)
            position = app.SingleTrackAnalysisPanel.Position;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            
        end

        % Value changed function: ShowTracksSwitch
        function ShowTracksSwitchValueChanged(app, event)
            value = app.ShowTracksSwitch.Value;
            manageTrackOverlay(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.Switch_3.Value = value;
            cla(app.UIAxes);
            manageTrackOverlay(app);
            switch value
                case "2D"
                    view(app.UIAxes,2);
                    rotate3d(app.UIAxes,'off');
                case "3D"
                    view(app.UIAxes,45,45);
            end
        end

        % Value changed function: TimepointEditField
        function TimepointEditFieldValueChanged(app, event)
            value = app.TimepointEditField.Value;
            app.TimeSlider.Value = value;
            manageTrackOverlay(app);
        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            if datacursormode.Enable == "on"
                try
                    app.Label_3 = getIDfromDataTip(app);
                end
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = ceil(app.TimeSlider.Value);
            app.TimepointEditField.Value = value;
            manageTrackOverlay(app);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            value = ceil(app.TimeSlider.Value);
            app.TimepointEditField.Value = value;
            manageTrackOverlay(app);
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            value = app.Switch_6.Value;
            cla(app.UIAxes);
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Value changed function: ScaleXYEditField
        function ScaleXYEditFieldValueChanged(app, event)
            value = app.ScaleXYEditField.Value;
            app.Scalexy = app.ScaleXYEditField.Value;
            cla(app.UIAxes);
            manageTrackOverlay(app);
        end

        % Value changed function: ScaleZEditField
        function ScaleZEditFieldValueChanged(app, event)
            value = app.ScaleZEditField.Value;
            cla(app.UIAxes);
            manageTrackOverlay(app);
        end

        % Value changed function: AxisScaleSwitch
        function AxisScaleSwitchValueChanged(app, event)
            value = app.AxisScaleSwitch.Value;
            
            switch value
                case "Auto"
                    app.ScaleXYEditField.Value = app.Scalexy;
                    axis(app.UIAxes,'normal')
                case "Equal"
                    app.ScaleXYEditField.Value = 1;
                    axis(app.UIAxes,'equal')
            end
            cla(app.UIAxes);
            manageTrackOverlay(app);
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            %Shut off the background image before selecting a track,
            %otherwise the image can also be selected.
            app.Switch_5.Value = "Off";
            manageBackgroundImage(app);
            manageTrackOverlay(app);
            
            %first make sure that no other ROI is in the axes
            try
                delete(app.CurrentROI);
            catch
                             
            end
            
            switch app.Switch_3.Value
                case "2D"
                    roi = drawrectangle(app.UIAxes);
                case "3D"
                    roi = drawcuboid(app.UIAxes);
            end
            app.CurrentROI = roi;          
        end

        % Button pushed function: SaveROIButton
        function SaveROIButtonPushed(app, event)
            scale = app.ScaleXYEditField.Value;
            if strcmp(app.CurrentROI.Type,"images.roi.cuboid") || strcmp(app.CurrentROI.Type,"images.roi.rectangle")
                name = string(app.ROINameEditField.Value);
                roiobj = app.CurrentROI;
                if IsAxes(app, app.StoreAxes)
                    roiobj = copyobj(roiobj,app.StoreAxes);
                else
                    app.StoreAxes = axes();
                    set(app.StoreAxes.Parent, "Visible", "off");
                    roiobj = copyobj(roiobj,app.StoreAxes);
                end
                
                if app.Switch_3.Value == "3D"
                    dataidx = inROI(roiobj, app.FilteredTrackData(:,3)/scale,app.FilteredTrackData(:,4)/scale,app.FilteredTrackData(:,5)/app.ScaleZEditField.Value);
                else
                    dataidx = inROI(roiobj, app.FilteredTrackData(:,3)/scale,app.FilteredTrackData(:,4)/scale);
                end
                if size(app.ROIList,1) == 0
                else
                    allROInames = [];
                    for i = 1:size(app.ROIList,1)
                        allROInames(i) = string(app.ROIList{i,1});
                    end
                    if any(string(allROInames) == name)
                        name = strcat(name, "_1");
                    end
                end
                
                app.ROIList{end+1,1} = name; %name
                app.ROIList{end,2} = roiobj; %object
                app.ROIList{end,3} = app.FilteredTrackData(dataidx,:);%data
                app.ROIList{end,4} = app.TrackFileDropDown.Value; %filename
                
                if app.SaveAnitROICheckBox.Value
                    app.ROIList{end+1,1} = strcat("Anti-", name); %name
                    app.ROIList{end,2} = roiobj; %object
                    app.ROIList{end,3} = app.FilteredTrackData(~dataidx,:);%data
                    app.ROIList{end,4} = app.TrackFileDropDown.Value; %filename
                end
            end
            updateROIList(app);
            app.ROIListDropDown.Value = app.ROIListDropDown.Items(end);
     
        end

        % Value changed function: ROIListDropDown
        function ROIListDropDownValueChanged(app, event)
            switchROI(app)           
        end

        % Button pushed function: DeleteselectedROIButton
        function DeleteselectedROIButtonPushed(app, event)
            value = app.ROIListDropDown.Value;
            idx = find(strcmp(app.ROIListDropDown.Items, value));
            delete(app.StoreAxes.Children(idx));
            app.ROIList(idx,:) = [];
            updateROIList(app);
            app.ROIListDropDown.Value = app.ROIListDropDown.Items(1);
        end

        % Button pushed function: SaveallROIsforBatchAnalysisButton
        function SaveallROIsforBatchAnalysisButtonPushed(app, event)
            if size(app.ROIList,1) > 0 
                % Display uigetfile dialog
                filterspec = {'*.mat'};
                [f, p] = uiputfile(filterspec);
                % Make sure user didn't cancel uigetfile dialog
                if ~ischar(p)
                    error("No file selected")
                end
    
                fulfile = fullfile(p,f);
                ROIs = app.ROIList;
                storeax = app.StoreAxes;
                save(fulfile, "ROIs", "storeax");
            end
        end

        % Button pushed function: LoadROIsButton
        function LoadROIsButtonPushed(app, event)
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if ~ischar(p)
            error("No file selected")
            end
            loadfile = load(fullfile(p,f));
            if size(loadfile,2) == 4 && size(loadfile,1) > 0
                app.ROIList = loadfile.ROIs;
                app.StoreAxes = loadfile.storeax;
                updateROIList(app);
            end
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            nump = 1;
            filter = 0;
            filtersize = 3;
            unit = [app.ImportSettingsStruct.customUnits.LengthUnit "/" app.ImportSettingsStruct.customUnits.TimeUnit];
            unit = join(unit,"");
            makeVelocityMap(app, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize, superZsize, unit);
            
        end

        % Button pushed function: DriftButton
        function DriftButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            nump = 1;
            filter = 0;
            filtersize = 3;
            makeDriftMap(app, flag3d, superpxsize, nmbrTimeWins, minAcqDT, nump, filter, filtersize, superZsize);
        end

        % Button pushed function: DensityButton
        function DensityButtonPushed(app, event)
            switch app.Switch_2.Value
                case "2D"
                    flag3d = 0;
                case "3D"
                    flag3d = 1;
            end
            superpxsize = app.SuperpixelSizeXYEditField.Value;
            superZsize = app.SuperpixelSizeZEditField.Value;
            nmbrTimeWins = app.TimeWindowsEditField.Value;
            minAcqDT = app.ImportSettingsStruct.customUnits.Timestep; 
            switch app.Switch_7.Value;
                case DENS
                    dopt = "DENS";
                case NPTS
                    dopt = "NPTS";
            end
            %dopt = "DENS"; % "LOGDENS" "NPTS"
            filter = 0;
            filtersize = 1;
            
            makeDensityMap(app, flag3d, superpxsize, nmbrTimeWins, minAcqDT, dopt, filter, filtersize, superZsize);
        end

        % Button pushed function: PlotKaryogramButton
        function PlotKaryogramButtonPushed(app, event)
            switch app.Switch.Value
                case "3D"
                    flag3d = 1;
                case "2D"
                    flag3d = 0;
            end
            Unit = app.ImportSettingsStruct.customUnits.LengthUnit;
            Karyogramm3DwithType(app.CurrentTrackFileData, app.NrTrackstoshowEditField.Value, flag3d, app.TrackTypeDropDown.Value, app.LocPrecXYEditField.Value, app.ZEditField.Value, Unit);
        end

        % Button pushed function: PerformDataDriftCorrectionButton
        function PerformDataDriftCorrectionButtonPushed(app, event)
            performDriftCorrection(app.TrackData);
        end

        % Value changed function: TrackIDDropDown
        function TrackIDDropDownValueChanged2(app, event)
            value = app.TrackIDDropDown.Value;
            value = str2double(value);
            %want to jump to the timepoint where the selected track starts
            frame = min(app.FilteredTrackData(app.FilteredTrackData(:,1)==value,2));
            app.TimepointEditField.Value = frame;
            app.TimeSlider.Value = frame;
            app.FrameEditField.Value = frame;
            cla(app.UIAxes);
            manageBackgroundImage(app);
            manageTrackOverlay(app);
        end

        % Window button down function: VisualisationUIFigure
        function VisualisationUIFigureWindowButtonDown(app, event)
            datacursormode(app.VisualisationUIFigure,"off");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.VisualisationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {920, 920, 920};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {920, 920};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {234, '1x', 232};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualisationUIFigure = uifigure('Visible', 'off');
            app.VisualisationUIFigure.Name = 'Visualisation';
            app.VisualisationUIFigure.Icon = 'auxin6_3rdPeak1.png';
            app.VisualisationUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualisationUIFigureCloseRequest, true);
            app.VisualisationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.VisualisationUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @VisualisationUIFigureWindowButtonDown, true);
            app.VisualisationUIFigure.KeyPressFcn = createCallbackFcn(app, @VisualisationUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.VisualisationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImportTracksandImagesPanel = uipanel(app.LeftPanel);
            app.ImportTracksandImagesPanel.Title = 'Import Tracks and Images';
            app.ImportSettingsButton = uibutton(app.ImportTracksandImagesPanel, 'push');
            app.ImportSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSettingsButtonPushed, true);
            app.ImportSettingsButton.Tooltip = {'Defines the data type of the tracks to use.'};
            app.ImportSettingsButton.Text = 'Import Settings';
            app.LoadTracksButton = uibutton(app.ImportTracksandImagesPanel, 'push');
            app.LoadTracksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTracksButtonPushed, true);
            app.LoadTracksButton.Tooltip = {'Allows the selection of mutliple track files defined by the import settings.'};
            app.LoadTracksButton.Text = 'Load Tracks';
            app.LoadReferenceImageButton = uibutton(app.ImportTracksandImagesPanel, 'push');
            app.LoadReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceImageButtonPushed, true);
            app.LoadReferenceImageButton.Tooltip = {'Can load a single reference image to be used as a background.'};
            app.LoadReferenceImageButton.Text = 'Load Reference Image';
            app.TrackFileDropDownLabel = uilabel(app.ImportTracksandImagesPanel);
            app.TrackFileDropDownLabel.Text = 'Track File';
            app.TrackFileDropDown = uidropdown(app.ImportTracksandImagesPanel);
            app.TrackFileDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackFileDropDownValueChanged, true);
            app.PerformDataDriftCorrectionButton = uibutton(app.ImportTracksandImagesPanel, 'push');
            app.PerformDataDriftCorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @PerformDataDriftCorrectionButtonPushed, true);
            app.PerformDataDriftCorrectionButton.Text = 'Perform Data Drift Correction';
            app.ImageSettingsPanel = uipanel(app.LeftPanel);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.RotateButton = uibutton(app.ImageSettingsPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Tooltip = {'Rotates the background image.'};
            app.RotateButton.Text = 'Rotate';
            app.FlipButton = uibutton(app.ImageSettingsPanel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Tooltip = {'Flips the background image.'};
            app.FlipButton.Text = 'Flip';
            app.AverageIntensityCheckBox = uicheckbox(app.ImageSettingsPanel);
            app.AverageIntensityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AverageIntensityCheckBoxValueChanged, true);
            app.AverageIntensityCheckBox.Tooltip = {'Geneartes an average intensity projection of the background image stack.'};
            app.AverageIntensityCheckBox.Text = 'Average Intensity';
            app.FrameEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tooltip = {'Select a frame of the backgorund image stack.'};
            app.FrameEditField.Value = 1;
            app.OffsetXEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.OffsetXEditFieldLabel.Text = 'Offset X';
            app.OffsetXEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.OffsetXEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetXEditFieldValueChanged, true);
            app.OffsetXEditField.Tooltip = {'Offset for the image in X.'};
            app.OffsetYEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.OffsetYEditFieldLabel.Text = 'Offset Y';
            app.OffsetYEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.OffsetYEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetYEditFieldValueChanged, true);
            app.OffsetYEditField.Tooltip = {'Offset for the image in Y.'};
            app.OffsetZEditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.OffsetZEditFieldLabel.Text = 'Offset Z';
            app.OffsetZEditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.OffsetZEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetZEditFieldValueChanged, true);
            app.OffsetZEditField.Tooltip = {'Offset for the image in Z.'};
            app.OffsetZEditField.Value = -1;
            app.Switch_5 = uiswitch(app.ImageSettingsPanel, 'slider');
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Tooltip = {'Plots the background image or not.'};
            app.DimensionsandPlotPanel = uipanel(app.LeftPanel);
            app.DimensionsandPlotPanel.Title = 'Dimensions and Plot';
            app.Switch = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.Switch.Items = {'2D', '3D'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Plots the tracks in 2D or 3D.'};
            app.Switch.Value = '2D';
            app.ShowTracksSwitchLabel = uilabel(app.DimensionsandPlotPanel);
            app.ShowTracksSwitchLabel.Text = 'Show Tracks';
            app.ShowTracksSwitch = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.ShowTracksSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowTracksSwitchValueChanged, true);
            app.ShowTracksSwitch.Tooltip = {'Enalbes/Disables the tracks.'};
            app.ShowTracksSwitch.Value = 'On';
            app.ScaleXYEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.ScaleXYEditFieldLabel.Text = 'Scale XY';
            app.ScaleXYEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.ScaleXYEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleXYEditFieldValueChanged, true);
            app.ScaleXYEditField.Tooltip = {'Scales the tracks according to the Value. Should match the pixel size of the background image.'};
            app.ScaleXYEditField.Value = 160;
            app.ScaleZEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.ScaleZEditField.Limits = [0.0001 Inf];
            app.ScaleZEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleZEditFieldValueChanged, true);
            app.ScaleZEditField.Tooltip = {'Scales the Tracks in Z. '};
            app.ScaleZEditField.Value = 1;
            app.MinTrackLengthEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.MinTrackLengthEditFieldLabel.Text = 'Min. Track Length';
            app.MinTrackLengthEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.MinTrackLengthEditField.Limits = [2 Inf];
            app.MinTrackLengthEditField.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthEditFieldValueChanged, true);
            app.MinTrackLengthEditField.Tooltip = {'Filters the tracks by a minimal track length given.'};
            app.MinTrackLengthEditField.Value = 5;
            app.Switch_6 = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.Switch_6.Items = {'Single Track', 'All'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Tooltip = {'Just plot single tracks depending on the timepoint or plot all tracks.'};
            app.Switch_6.Value = 'All';
            app.PrevTracksButton = uibutton(app.DimensionsandPlotPanel, 'push');
            app.PrevTracksButton.ButtonPushedFcn = createCallbackFcn(app, @PrevTracksButtonPushed, true);
            app.PrevTracksButton.Tooltip = {'Jump to the previous track id.'};
            app.PrevTracksButton.Text = 'Prev. Tracks';
            app.NextTracksButton = uibutton(app.DimensionsandPlotPanel, 'push');
            app.NextTracksButton.ButtonPushedFcn = createCallbackFcn(app, @NextTracksButtonPushed, true);
            app.NextTracksButton.Tooltip = {'Jump to the next track id.'};
            app.NextTracksButton.Text = 'Next Tracks';
            app.AxisScaleSwitchLabel = uilabel(app.DimensionsandPlotPanel);
            app.AxisScaleSwitchLabel.Text = 'Axis Scale';
            app.AxisScaleSwitch = uiswitch(app.DimensionsandPlotPanel, 'slider');
            app.AxisScaleSwitch.Items = {'Auto', 'Equal'};
            app.AxisScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @AxisScaleSwitchValueChanged, true);
            app.AxisScaleSwitch.Tooltip = {'Set axis scale to auto or equal.'};
            app.AxisScaleSwitch.Value = 'Auto';
            app.TrackIDDropDownLabel = uilabel(app.DimensionsandPlotPanel);
            app.TrackIDDropDownLabel.Text = 'Track ID';
            app.TrackIDDropDown = uidropdown(app.DimensionsandPlotPanel);
            app.TrackIDDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackIDDropDownValueChanged2, true);
            app.TrackIDDropDown.Tooltip = {'Allows to switch to a track with the selected ID'};
            app.SpotsizeEditFieldLabel = uilabel(app.DimensionsandPlotPanel);
            app.SpotsizeEditFieldLabel.Text = 'Spot size';
            app.SpotsizeEditField = uieditfield(app.DimensionsandPlotPanel, 'numeric');
            app.SpotsizeEditField.Value = 10;
            app.UseFIlterfromAllTrackAnalysisCheckBox = uicheckbox(app.DimensionsandPlotPanel);
            app.UseFIlterfromAllTrackAnalysisCheckBox.Text = 'Use FIlter from All Track Analysis';
            app.IsFilterSetLabel = uilabel(app.DimensionsandPlotPanel);
            app.IsFilterSetLabel.Text = 'IsFilterSetLabel';
            app.KaryogramPanel = uipanel(app.LeftPanel);
            app.KaryogramPanel.Title = 'Karyogram';
            app.NrTrackstoshowEditFieldLabel = uilabel(app.KaryogramPanel);
            app.NrTrackstoshowEditFieldLabel.Text = 'Nr. Tracks to show';
            app.NrTrackstoshowEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.NrTrackstoshowEditField.Limits = [0 Inf];
            app.NrTrackstoshowEditField.Tooltip = {'Defines how many tracks will be plotted. Zero means all tracks.'};
            app.PlotKaryogramButton = uibutton(app.KaryogramPanel, 'push');
            app.PlotKaryogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotKaryogramButtonPushed, true);
            app.PlotKaryogramButton.Tooltip = {'Will polt an overview of all tracks available in an extra window, arranged in an square.'};
            app.PlotKaryogramButton.Text = 'Plot Karyogram';
            app.LocPrecXYEditFieldLabel = uilabel(app.KaryogramPanel);
            app.LocPrecXYEditFieldLabel.Text = 'LocPrec XY';
            app.LocPrecXYEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.LocPrecXYEditField.Limits = [0 Inf];
            app.LocPrecXYEditField.Value = 40;
            app.ZEditFieldLabel = uilabel(app.KaryogramPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.KaryogramPanel, 'numeric');
            app.ZEditField.Limits = [0 Inf];
            app.ZEditField.Value = 80;
            app.TrackTypeDropDownLabel = uilabel(app.KaryogramPanel);
            app.TrackTypeDropDownLabel.Text = 'Track Type';
            app.TrackTypeDropDown = uidropdown(app.KaryogramPanel);
            app.TrackTypeDropDown.Items = {'All', 'Diffusion', 'Immobile', 'Directed', 'Directed_Diffusion', 'None'};
            app.TrackTypeDropDown.Tooltip = {'Filter the tracks by the tracktype.'};
            app.TrackTypeDropDown.Value = 'All';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TimeSliderLabel = uilabel(app.CenterPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.CenterPanel);
            app.TimeSlider.Limits = [1 100];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Value = 1;
            app.TimepointEditFieldLabel = uilabel(app.CenterPanel);
            app.TimepointEditFieldLabel.Text = 'Timepoint';
            app.TimepointEditField = uieditfield(app.CenterPanel, 'numeric');
            app.TimepointEditField.Limits = [1 Inf];
            app.TimepointEditField.ValueChangedFcn = createCallbackFcn(app, @TimepointEditFieldValueChanged, true);
            app.TimepointEditField.Tooltip = {'Shows the current frame number. Can also be used to go to a certain frame by entering the number'};
            app.TimepointEditField.Value = 1;
            app.StepsizeEditFieldLabel = uilabel(app.CenterPanel);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.StepsizeEditField.Tooltip = {'Defines the step size taken when using the left and right arrow keys to navigate.'};
            app.StepsizeEditField.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.SingleTrackAnalysisPanel = uipanel(app.RightPanel);
            app.SingleTrackAnalysisPanel.Title = 'Single Track Analysis';
            app.SingleTrackAnalysisPanel.SizeChangedFcn = createCallbackFcn(app, @SingleTrackAnalysisPanelSizeChanged, true);
            app.SelectTrackButton = uibutton(app.SingleTrackAnalysisPanel, 'push');
            app.SelectTrackButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrackButtonPushed, true);
            app.SelectTrackButton.Tooltip = {'Enables a point to select a track in the figure. Usefull  when all tracks are plotted. '};
            app.SelectTrackButton.Text = 'Select Track';
            app.AnalyzeButton = uibutton(app.SingleTrackAnalysisPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Tooltip = {'Analyze the single track.'};
            app.AnalyzeButton.Text = 'Analyze';
            app.TrackIDLabel = uilabel(app.SingleTrackAnalysisPanel);
            app.TrackIDLabel.Text = 'TrackID:';
            app.Label_3 = uilabel(app.SingleTrackAnalysisPanel);
            app.Label_3.Text = '1';
            app.ROIselectionPanel = uipanel(app.RightPanel);
            app.ROIselectionPanel.Title = 'ROI selection';
            app.SelectROIButton = uibutton(app.ROIselectionPanel, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Tooltip = {'Allows the selection of a ROI.'};
            app.SelectROIButton.Text = 'Select ROI';
            app.SaveROIButton = uibutton(app.ROIselectionPanel, 'push');
            app.SaveROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIButtonPushed, true);
            app.SaveROIButton.Tooltip = {'Saves the ROI in the list below temporarily.'};
            app.SaveROIButton.Text = 'Save ROI';
            app.SaveallROIsforBatchAnalysisButton = uibutton(app.ROIselectionPanel, 'push');
            app.SaveallROIsforBatchAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallROIsforBatchAnalysisButtonPushed, true);
            app.SaveallROIsforBatchAnalysisButton.Tooltip = {'Saves all the ROIS and their data in a file to be used for later analysis.'};
            app.SaveallROIsforBatchAnalysisButton.Text = 'Save all ROIs for Batch Analysis';
            app.DeleteselectedROIButton = uibutton(app.ROIselectionPanel, 'push');
            app.DeleteselectedROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedROIButtonPushed, true);
            app.DeleteselectedROIButton.Tooltip = {'Deletes the selcted ROI from the list.'};
            app.DeleteselectedROIButton.Text = 'Delete selected ROI';
            app.ROIListDropDownLabel = uilabel(app.ROIselectionPanel);
            app.ROIListDropDownLabel.Text = 'ROI List';
            app.ROIListDropDown = uidropdown(app.ROIselectionPanel);
            app.ROIListDropDown.Items = {'Nothing', ''};
            app.ROIListDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIListDropDownValueChanged, true);
            app.ROIListDropDown.Value = 'Nothing';
            app.Switch_3 = uiswitch(app.ROIselectionPanel, 'slider');
            app.Switch_3.Items = {'2D', '3D'};
            app.Switch_3.Enable = 'off';
            app.Switch_3.Tooltip = {'Select if a 2D or 3D should be generated.'};
            app.Switch_3.Value = '2D';
            app.LoadROIsButton = uibutton(app.ROIselectionPanel, 'push');
            app.LoadROIsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROIsButtonPushed, true);
            app.LoadROIsButton.Tooltip = {'Loads saved ROIs.'};
            app.LoadROIsButton.Text = 'Load ROIs';
            app.ROINameEditFieldLabel = uilabel(app.ROIselectionPanel);
            app.ROINameEditFieldLabel.Text = 'ROI Name';
            app.ROINameEditField = uieditfield(app.ROIselectionPanel, 'text');
            app.ROINameEditField.Tooltip = {'Name for the ROI to be saved.'};
            app.ROINameEditField.Value = '1';
            app.SaveAnitROICheckBox = uicheckbox(app.ROIselectionPanel);
            app.SaveAnitROICheckBox.Tooltip = {'Additionally save all the data in an Anti-ROI that was not included in the original ROI. '};
            app.SaveAnitROICheckBox.Text = 'Save Anit-ROI';
            app.SupermapGenerationPanel = uipanel(app.RightPanel);
            app.SupermapGenerationPanel.Title = 'Supermap Generation';
            app.VelocityButton = uibutton(app.SupermapGenerationPanel, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Tooltip = {'Generates a diffusion map of the data.'};
            app.VelocityButton.Text = 'Velocity';
            app.Switch_2 = uiswitch(app.SupermapGenerationPanel, 'slider');
            app.Switch_2.Items = {'2D', '3D'};
            app.Switch_2.Tooltip = {'Determines if the supermaps will be generated in 2D or 3D.'};
            app.Switch_2.Value = '2D';
            app.DriftButton = uibutton(app.SupermapGenerationPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @DriftButtonPushed, true);
            app.DriftButton.Tooltip = {'Will generate a drift map of the data. Will depict it using arrows.'};
            app.DriftButton.Text = 'Drift';
            app.DensityButton = uibutton(app.SupermapGenerationPanel, 'push');
            app.DensityButton.ButtonPushedFcn = createCallbackFcn(app, @DensityButtonPushed, true);
            app.DensityButton.Tooltip = {'Generates a data density map of the data to pinpoint the number of detections in a given area.'};
            app.DensityButton.Text = 'Density';
            app.SuperpixelSizeXYEditFieldLabel = uilabel(app.SupermapGenerationPanel);
            app.SuperpixelSizeXYEditFieldLabel.Text = 'Superpixel Size XY';
            app.SuperpixelSizeXYEditField = uieditfield(app.SupermapGenerationPanel, 'numeric');
            app.SuperpixelSizeXYEditField.Tooltip = {'Defines the supermap pixel size in X and Y.'};
            app.SuperpixelSizeXYEditField.Value = 160;
            app.TimeWindowsEditFieldLabel = uilabel(app.SupermapGenerationPanel);
            app.TimeWindowsEditFieldLabel.Text = 'Time Windows';
            app.TimeWindowsEditField = uieditfield(app.SupermapGenerationPanel, 'numeric');
            app.TimeWindowsEditField.Tooltip = {'Will split the data in the determined number of time windows. Useful if something changes during the movie during a given time to compare before/after.'};
            app.TimeWindowsEditField.Value = 1;
            app.SuperpixelSizeZEditFieldLabel = uilabel(app.SupermapGenerationPanel);
            app.SuperpixelSizeZEditFieldLabel.Text = 'Superpixel Size Z';
            app.SuperpixelSizeZEditField = uieditfield(app.SupermapGenerationPanel, 'numeric');
            app.SuperpixelSizeZEditField.Tooltip = {'Defines the supermap pixel size.in Z'};
            app.SuperpixelSizeZEditField.Value = 160;
            app.SuperpxXYSizeLabel = uilabel(app.SupermapGenerationPanel);
            app.SuperpxXYSizeLabel.Text = 'SuperpxXYSize';
            app.SuperpxZSizeLabel = uilabel(app.SupermapGenerationPanel);
            app.SuperpxZSizeLabel.Text = 'SuperpxZSize';
            app.Switch_7 = uiswitch(app.SupermapGenerationPanel, 'slider');
            app.Switch_7.Items = {'DENS', 'NPTS'};
            app.Switch_7.Value = 'DENS';
            app.CompleteAnalysisPanel = uipanel(app.RightPanel);
            app.CompleteAnalysisPanel.Title = 'Complete Analysis';
            app.Switch_4 = uiswitch(app.CompleteAnalysisPanel, 'slider');
            app.Switch_4.Items = {'Current File', 'All Files'};
            app.Switch_4.Tooltip = {'Defines if only the selected tracks or all loaded tracks should be analyzed.'};
            app.Switch_4.Value = 'Current File';
            app.SpotsButton = uibutton(app.CompleteAnalysisPanel, 'push');
            app.SpotsButton.ButtonPushedFcn = createCallbackFcn(app, @SpotsButtonPushed, true);
            app.SpotsButton.Tooltip = {'Perform a spot analysis. Only shows the intensities at the moment. '};
            app.SpotsButton.Text = 'Spots';
            app.TracksButton = uibutton(app.CompleteAnalysisPanel, 'push');
            app.TracksButton.ButtonPushedFcn = createCallbackFcn(app, @TracksButtonPushed, true);
            app.TracksButton.Tooltip = {'Perform an analysis on the tracks.'};
            app.TracksButton.Text = 'Tracks';
            app.UseROIforAnalysisCheckBox = uicheckbox(app.CompleteAnalysisPanel);
            app.UseROIforAnalysisCheckBox.Tooltip = {'Instead uses the data of the selected ROI for analysis. '};
            app.UseROIforAnalysisCheckBox.Text = 'Use ROI for Analysis';
            % Show the figure after all components are created
            app.VisualisationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualisationWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualisationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualisationUIFigure)
        end
    end
end